classdef ENCoRP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ENCoRPUIFigure                  matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        DebugModeMenu                   matlab.ui.container.Menu
        FileMenu                        matlab.ui.container.Menu
        NewModelMenu                    matlab.ui.container.Menu
        OpenModelMenu                   matlab.ui.container.Menu
        SaveModelMenu                   matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        HelpMenu_2                      matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        EMFieldsButton                  matlab.ui.control.Button
        TroubleshootingPanel            matlab.ui.container.Panel
        ErrorMsg                        matlab.ui.control.Label
        ErrorType                       matlab.ui.control.Label
        VisualizeResultsButton          matlab.ui.control.Button
        RunModelButton                  matlab.ui.control.Button
        WiringDefinitionButton          matlab.ui.control.Button
        NodeDefinitionButton            matlab.ui.control.Button
        GeometryDefinitionButton        matlab.ui.control.Button
        DataSelectionLabel              matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TabGroup4                       matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        GeometryTab                     matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        NodesTab_3                      matlab.ui.container.Tab
        LoadNodesButton                 matlab.ui.control.Button
        DeleteAllNodesButton            matlab.ui.control.Button
        mLabel_3                        matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        vButton                         matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        RemoveSelectedButton            matlab.ui.control.Button
        SystemNodesListBox              matlab.ui.control.ListBox
        SystemNodesListBoxLabel         matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        NodeLocationsLabel              matlab.ui.control.Label
        AddNodeButton                   matlab.ui.control.Button
        WiresTab                        matlab.ui.container.Tab
        LoadWiresButton                 matlab.ui.control.Button
        DeleteAllWiresButton            matlab.ui.control.Button
        SwapNodesButton                 matlab.ui.control.Button
        RemoveSelectedButton_2          matlab.ui.control.Button
        WireListListBox                 matlab.ui.control.ListBox
        WireListListBoxLabel            matlab.ui.control.Label
        AddWireButton                   matlab.ui.control.Button
        DestinationNodeListBox          matlab.ui.control.ListBox
        DestinationNodeListBoxLabel     matlab.ui.control.Label
        SourceNodeListBox               matlab.ui.control.ListBox
        SourceNodeListBoxLabel          matlab.ui.control.Label
        NodesTab                        matlab.ui.container.Tab
        ClearAllConfigurationsButton    matlab.ui.control.Button
        SaveNodeConfigurationButton     matlab.ui.control.Button
        ClearNodeConfigurationButton    matlab.ui.control.Button
        LoadDefinitionPanel             matlab.ui.container.Panel
        VariableDropDown                matlab.ui.control.DropDown
        VariableDropDownLabel           matlab.ui.control.Label
        FileLabel_2                     matlab.ui.control.Label
        LoadButton_2                    matlab.ui.control.Button
        PL9Label                        matlab.ui.control.Label
        PL8Label                        matlab.ui.control.Label
        PL7Label                        matlab.ui.control.Label
        PL6Label                        matlab.ui.control.Label
        PL5Label                        matlab.ui.control.Label
        PL4Label                        matlab.ui.control.Label
        PL3Label                        matlab.ui.control.Label
        PL2Label                        matlab.ui.control.Label
        PL1Label                        matlab.ui.control.Label
        SaveLoadConfigurationButton     matlab.ui.control.Button
        Param12EditFieldLabel           matlab.ui.control.Label
        Param12EditField                matlab.ui.control.EditField
        Param11EditFieldLabel           matlab.ui.control.Label
        Param11EditField                matlab.ui.control.EditField
        Param10EditFieldLabel           matlab.ui.control.Label
        Param10EditField                matlab.ui.control.EditField
        Param9EditFieldLabel            matlab.ui.control.Label
        Param9EditField                 matlab.ui.control.EditField
        Param8EditFieldLabel            matlab.ui.control.Label
        Param8EditField                 matlab.ui.control.EditField
        Param7EditFieldLabel            matlab.ui.control.Label
        Param7EditField                 matlab.ui.control.EditField
        Param6EditFieldLabel            matlab.ui.control.Label
        Param6EditField                 matlab.ui.control.EditField
        Param5EditFieldLabel            matlab.ui.control.Label
        Param5EditField                 matlab.ui.control.EditField
        Param4EditFieldLabel            matlab.ui.control.Label
        Param4EditField                 matlab.ui.control.EditField
        Param3EditFieldLabel            matlab.ui.control.Label
        Param3EditField                 matlab.ui.control.EditField
        Param2EditFieldLabel            matlab.ui.control.Label
        Param2EditField                 matlab.ui.control.EditField
        Param1EditFieldLabel            matlab.ui.control.Label
        Param1EditField                 matlab.ui.control.EditField
        LoadTypeLabel                   matlab.ui.control.Label
        LoadTypeDropDown                matlab.ui.control.DropDown
        NodeTypeDropDown                matlab.ui.control.DropDown
        NodeTypeDropDownLabel           matlab.ui.control.Label
        AvailableNodesListBox           matlab.ui.control.ListBox
        AvailableNodesListBoxLabel      matlab.ui.control.Label
        WiringTab                       matlab.ui.container.Tab
        ParasiticsDropDown              matlab.ui.control.DropDown
        ParasiticsDropDownLabel         matlab.ui.control.Label
        epsilonEditField                matlab.ui.control.NumericEditField
        epsilonEditFieldLabel           matlab.ui.control.Label
        eps0FmLabel                     matlab.ui.control.Label
        InsulatorMaterialDropDown       matlab.ui.control.DropDown
        InsulatorMaterialDropDownLabel  matlab.ui.control.Label
        SeparationDistanceEditField     matlab.ui.control.EditField
        SeparationDistanceEditField_2Label  matlab.ui.control.Label
        mLabel_12                       matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        OLabel                          matlab.ui.control.Label
        OOLabel_4                       matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        GLabel_3                        matlab.ui.control.Label
        OOLabel_3                       matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        GLabel_2                        matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        GLabel                          matlab.ui.control.Label
        OOLabel                         matlab.ui.control.Label
        OOLabel_2                       matlab.ui.control.Label
        WireArrangementLabel            matlab.ui.control.Label
        ClearAllDefinitionsButton       matlab.ui.control.Button
        SquareLabel                     matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        RemoveWireDefinitionButton      matlab.ui.control.Button
        ApplytoAllConductorsButton      matlab.ui.control.Button
        ApplytoAllWiresButton           matlab.ui.control.Button
        WireLengthLabel_2               matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        GroundTab                       matlab.ui.container.Tab
        Label_8                         matlab.ui.control.Label
        xLabel                          matlab.ui.control.Label
        SmLabel                         matlab.ui.control.Label
        sigmaEditField                  matlab.ui.control.NumericEditField
        sigmaEditFieldLabel             matlab.ui.control.Label
        mmLabel_2                       matlab.ui.control.Label
        WireMaterialDropDown_1          matlab.ui.control.DropDown
        WireMaterialDropDownLabel       matlab.ui.control.Label
        WireRadiusEditField_1           matlab.ui.control.EditField
        WireRadiusEditFieldLabel        matlab.ui.control.Label
        Phase1Tab                       matlab.ui.container.Tab
        sigmaEditField_2                matlab.ui.control.NumericEditField
        sigmaEditField_2Label           matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        xLabel_2                        matlab.ui.control.Label
        SmLabel_2                       matlab.ui.control.Label
        mmLabel_3                       matlab.ui.control.Label
        WireMaterialDropDown_2          matlab.ui.control.DropDown
        WireMaterialDropDown_3Label     matlab.ui.control.Label
        WireRadiusEditField_2           matlab.ui.control.EditField
        WireRadiusEditField_6Label      matlab.ui.control.Label
        Phase2Tab                       matlab.ui.container.Tab
        sigmaEditField_3                matlab.ui.control.NumericEditField
        sigmaEditField_3Label           matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        xLabel_3                        matlab.ui.control.Label
        SmLabel_3                       matlab.ui.control.Label
        mmLabel_4                       matlab.ui.control.Label
        WireMaterialDropDown_3          matlab.ui.control.DropDown
        WireMaterialDropDown_4Label     matlab.ui.control.Label
        WireRadiusEditField_3           matlab.ui.control.EditField
        WireRadiusEditField_7Label      matlab.ui.control.Label
        Phase3Tab                       matlab.ui.container.Tab
        sigmaEditField_4                matlab.ui.control.NumericEditField
        sigmaEditField_4Label           matlab.ui.control.Label
        WireMaterialDropDown_4          matlab.ui.control.DropDown
        WireMaterialDropDown_5Label     matlab.ui.control.Label
        WireRadiusEditField_4           matlab.ui.control.EditField
        WireRadiusEditField_8Label      matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        xLabel_4                        matlab.ui.control.Label
        SmLabel_4                       matlab.ui.control.Label
        mmLabel_5                       matlab.ui.control.Label
        NumberofConductorsDropDown      matlab.ui.control.DropDown
        NumberofConductorsLabel         matlab.ui.control.Label
        WireListListBox_2               matlab.ui.control.ListBox
        WireListListBox_2Label          matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        ModelTab                        matlab.ui.container.Tab
        SimulateButton                  matlab.ui.control.Button
        SaveModelButton                 matlab.ui.control.Button
        SimulationDetailsPanel          matlab.ui.container.Panel
        SPICECheckBox                   matlab.ui.control.CheckBox
        cmLabel                         matlab.ui.control.Label
        InjectionButtonGroup            matlab.ui.container.ButtonGroup
        oEditField_3Label               matlab.ui.control.Label
        oEditField_3                    matlab.ui.control.NumericEditField
        oEditField_2Label               matlab.ui.control.Label
        oEditField_2                    matlab.ui.control.NumericEditField
        oEditField                      matlab.ui.control.NumericEditField
        oEditFieldLabel                 matlab.ui.control.Label
        VLabel_3                        matlab.ui.control.Label
        VLabel_2                        matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        Phase3EditField                 matlab.ui.control.EditField
        Phase3EditFieldLabel            matlab.ui.control.Label
        Phase2EditField                 matlab.ui.control.EditField
        Phase2EditFieldLabel            matlab.ui.control.Label
        Phase1EditField                 matlab.ui.control.EditField
        Phase1EditFieldLabel            matlab.ui.control.Label
        CurrentButton_2                 matlab.ui.control.ToggleButton
        VoltageButton_2                 matlab.ui.control.ToggleButton
        FrequencyButtonGroup            matlab.ui.container.ButtonGroup
        Label_25                        matlab.ui.control.Label
        FileLabel                       matlab.ui.control.Label
        HzLabel_3                       matlab.ui.control.Label
        HzLabel_2                       matlab.ui.control.Label
        UseLoadedFrequenciesCheckBox    matlab.ui.control.CheckBox
        LoadButton                      matlab.ui.control.Button
        ORLabel                         matlab.ui.control.Label
        StepsEditField                  matlab.ui.control.NumericEditField
        StepsEditFieldLabel             matlab.ui.control.Label
        FinishEditField                 matlab.ui.control.NumericEditField
        FinishEditFieldLabel            matlab.ui.control.Label
        StartEditField                  matlab.ui.control.NumericEditField
        StartEditFieldLabel             matlab.ui.control.Label
        LinearButton_2                  matlab.ui.control.ToggleButton
        LogButton                       matlab.ui.control.ToggleButton
        Panel_2                         matlab.ui.container.Panel
        Panel_3                         matlab.ui.container.Panel
        WireResolutionEditField         matlab.ui.control.NumericEditField
        WireResolutionEditFieldLabel    matlab.ui.control.Label
        VisualizationTab                matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        SpatialVoltageandCurrentTab     matlab.ui.container.Tab
        ScalingButtonGroup              matlab.ui.container.ButtonGroup
        LinearButton_3                  matlab.ui.control.ToggleButton
        LogButton_2                     matlab.ui.control.ToggleButton
        HzLabel_4                       matlab.ui.control.Label
        ExportDataButton                matlab.ui.control.Button
        OutputTypeButtonGroup           matlab.ui.container.ButtonGroup
        VoltageButton                   matlab.ui.control.ToggleButton
        CurrentButton                   matlab.ui.control.ToggleButton
        GeneratePNGButton               matlab.ui.control.Button
        FrequencySlider                 matlab.ui.control.Slider
        FrequencySliderLabel            matlab.ui.control.Label
        LineWidthEditField              matlab.ui.control.NumericEditField
        LineWidthEditFieldLabel         matlab.ui.control.Label
        PhaseSelectionButtonGroup       matlab.ui.container.ButtonGroup
        GroundButton                    matlab.ui.control.ToggleButton
        Phase3Button                    matlab.ui.control.ToggleButton
        Phase2Button                    matlab.ui.control.ToggleButton
        Phase1Button                    matlab.ui.control.ToggleButton
        AnimatedOutputFilePanel         matlab.ui.container.Panel
        sLabel                          matlab.ui.control.Label
        FrameDelayEditField             matlab.ui.control.NumericEditField
        FrameDelayEditFieldLabel        matlab.ui.control.Label
        GenerateAnimatedGIFButton       matlab.ui.control.Button
        TransmissionParametersTab       matlab.ui.container.Tab
        DropDown                        matlab.ui.control.DropDown
        Label_26                        matlab.ui.control.Label
        ExportDataButton_2              matlab.ui.control.Button
        GeneratePNGButton_2             matlab.ui.control.Button
        TurnOffAllButton                matlab.ui.control.Button
        TurnOnAllButton                 matlab.ui.control.Button
        PlottedElementsPanel            matlab.ui.container.Panel
        ofBlockLabel                    matlab.ui.control.Label
        Label_24                        matlab.ui.control.Label
        Label_23                        matlab.ui.control.Label
        Label_22                        matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        CheckBox_13                     matlab.ui.control.CheckBox
        CheckBox_12                     matlab.ui.control.CheckBox
        CheckBox_11                     matlab.ui.control.CheckBox
        CheckBox_10                     matlab.ui.control.CheckBox
        CheckBox_9                      matlab.ui.control.CheckBox
        CheckBox_8                      matlab.ui.control.CheckBox
        CheckBox_7                      matlab.ui.control.CheckBox
        CheckBox_6                      matlab.ui.control.CheckBox
        CheckBox_5                      matlab.ui.control.CheckBox
        CheckBox_4                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox_1                      matlab.ui.control.CheckBox
        OhmsLabel                       matlab.ui.control.Label
        ReferenceImpedanceLabel         matlab.ui.control.Label
        Z_0EditField                    matlab.ui.control.NumericEditField
        Z_0EditFieldLabel               matlab.ui.control.Label
        TransmissionParametersDropDown  matlab.ui.control.DropDown
        TransmissionParametersDropDownLabel  matlab.ui.control.Label
        EMTab                           matlab.ui.container.Tab
        HzLabel                         matlab.ui.control.Label
        FrequencyEditField_2            matlab.ui.control.NumericEditField
        FrequencyEditField_2Label       matlab.ui.control.Label
        FieldButtonGroup                matlab.ui.container.ButtonGroup
        MagneticButton                  matlab.ui.control.ToggleButton
        ElectricButton                  matlab.ui.control.ToggleButton
        EXPORTTOCOMSOL_2Button          matlab.ui.control.Button
        SimulateRemotelyButton          matlab.ui.control.Button
        QuiverDensityPanel              matlab.ui.container.Panel
        ZEditField_2                    matlab.ui.control.NumericEditField
        ZEditField_2Label               matlab.ui.control.Label
        YEditField_2                    matlab.ui.control.NumericEditField
        YEditField_2Label               matlab.ui.control.Label
        XEditField_2                    matlab.ui.control.NumericEditField
        XEditField_2Label               matlab.ui.control.Label
        DomainBoundsPanel               matlab.ui.container.Panel
        mLabel_18                       matlab.ui.control.Label
        mLabel_17                       matlab.ui.control.Label
        mLabel_16                       matlab.ui.control.Label
        mLabel_15                       matlab.ui.control.Label
        mLabel_14                       matlab.ui.control.Label
        mLabel_13                       matlab.ui.control.Label
        ZmaxEditField                   matlab.ui.control.NumericEditField
        ZmaxEditFieldLabel              matlab.ui.control.Label
        ZminEditField                   matlab.ui.control.NumericEditField
        ZminEditFieldLabel              matlab.ui.control.Label
        YmaxEditField                   matlab.ui.control.NumericEditField
        YmaxEditFieldLabel              matlab.ui.control.Label
        YminEditField                   matlab.ui.control.NumericEditField
        YminEditFieldLabel              matlab.ui.control.Label
        XmaxEditField                   matlab.ui.control.NumericEditField
        XmaxEditFieldLabel              matlab.ui.control.Label
        XminEditField                   matlab.ui.control.NumericEditField
        XminEditFieldLabel              matlab.ui.control.Label
        NetworkDiagramPanel             matlab.ui.container.Panel
        UIAxes3                         matlab.ui.control.UIAxes
        NetworkDiagramPanel_2           matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        StoredData   % Track network topology
    end
    
    methods (Access = private)      

        function results = ResetWiringTab(app,dt)

            if nargin == 1
                dt = 1;
            end

            % Called to "rmfield" + reset other values
            try
                app.StoredData = rmfield(app.StoredData,'WireProps');
            end

            % Now reset values not stored in WireProps
            app.StoredData.WireLengths = [];

            % Compute wire lengths
            if isempty(app.StoredData.WireLengths)
                app.StoredData.WireLengths = zeros(1,size(app.StoredData.Wires,1));
                for wind = 1:size(app.StoredData.Wires,1)
                    Xv=app.StoredData.X(app.StoredData.Wires(wind,:));
                    Yv=app.StoredData.Y(app.StoredData.Wires(wind,:));
                    Zv=app.StoredData.Z(app.StoredData.Wires(wind,:));
                    len = norm([diff(Xv),diff(Yv),diff(Zv)]);
                    app.StoredData.WireLengths(wind) = len;
                end
            end
            
            app.StoredData.SigPrev = 5.85*ones(1,4);
            app.StoredData.EpsPrev = 3.6*ones(1,4);

            if dt
                WireListListBox_2ValueChanged(app,nan)
            end
            CorrectWireBGaV(app)

        end
        
        function results = UpdatePlotList(app) %#ok<*STOUT> 

            % Turn off hold and remove title if any
            hold(app.UIAxes3,'off')
            title(app.UIAxes3,'')
            
            % Plot any nodal data if present
            if ~isempty(app.StoredData.X)
                temp = app.UIAxes3.View;
                plot3(app.UIAxes3,app.StoredData.X,app.StoredData.Y,app.StoredData.Z,'Color','k','Marker',"o",'LineStyle',"none",'MarkerSize',7);
                app.UIAxes3.View = temp;
            else
                % Or clear it if none present
                temp = app.UIAxes3.View;
                cla(app.UIAxes3);
                app.UIAxes3.View = temp;
            end
            drawnow;

            % Update the drop down list
            listv = {};
            for idx = 1:length(app.StoredData.X)
                listv{idx} = ['Node',num2str(idx),' - (',sprintf('%5.5f',app.StoredData.X(idx)),', ',sprintf('%5.5f',app.StoredData.Y(idx)),', ',sprintf('%5.5f',app.StoredData.Z(idx)),')']; %#ok<*AGROW> 
            end

            % If there was a previously selected node, highlight it 
            if app.StoredData.lastEntity == 1
                hold(app.UIAxes3,'on')
                temp = app.UIAxes3.View;
                plot3(app.UIAxes3,app.StoredData.EntityList(1),app.StoredData.EntityList(2),app.StoredData.EntityList(3),'Color','k','Marker',"o",'LineStyle',"none",'MarkerSize',10,'MarkerFaceColor',[0.5,0.5,0]);
                app.UIAxes3.View = temp;
            end

            % Squeeze out extra entries and display
            try
                [~,SNLBval] = ismember(app.SystemNodesListBox.Value,app.SystemNodesListBox.Items);
            end
            try
                [~,DNLBval] = ismember(app.DestinationNodeListBox.Value, app.DestinationNodeListBox.Items);
            end
            try
                [~,SoNLBval] = ismember(app.SourceNodeListBox.Value, app.SourceNodeListBox.Items);
            end
            app.SystemNodesListBox.Items = squeeze(listv);
            app.DestinationNodeListBox.Items = squeeze(listv);
            app.SourceNodeListBox.Items = squeeze(listv);
            try
                app.SystemNodesListBox.Value = app.SystemNodesListBox.Items{SNLBval};
            end
            try
                app.DestinationNodeListBox.Value = app.DestinationNodeListBox.Items{DNLBval};
            end
            try
                app.SourceNodeListBox.Value = app.SourceNodeListBox.Items{SoNLBval};
            end
            try
                WLT = app.LoadTypeDropDown.Value;
            end

            oflag = 0;
            for idx = 1:length(app.StoredData.X)
                % Perform checks here to see if nodes are properly defined
                try
                    flag = 0;
                    if app.StoredData.Nodes(idx).NodeType>1
                        if app.StoredData.Nodes(idx).NodeType==5 || app.StoredData.Nodes(idx).NodeType == 3 % Outlet or receiver
                            PLT_Specific(app,idx)
                            if app.StoredData.Nodes(idx).LoadType == ''
                                flag = 1;
                                oflag = 1;
                            end %#ok<*BDSCA> 
                            for pdx = 1:length(app.StoredData.Loads)
                                if app.StoredData.Nodes(idx).LoadType == app.StoredData.Loads(pdx).Letter
                                    idx3 = pdx;
                                    break;
                                end
                            end
                            for pdx = 1:length(app.StoredData.Loads(idx3).Parameters)
                               if any([(app.StoredData.Nodes(idx).LoadParam(pdx)=='') , isnan(app.StoredData.Nodes(idx).LoadParam(pdx)) , (app.StoredData.Nodes(idx).LoadParam(pdx)==[])]) 
                                   flag = 1;
                                   oflag = 1;
                               end
                            end
                            if app.StoredData.Nodes(idx).LoadType == "X"
                                if isempty(app.StoredData.Nodes(idx).VarSelected)
                                    flag = 1;
                                    oflag = 1;
                                end
                            end
                        end
                    else
                        flag = 1; 
                        oflag = 1;
                    end
                    if flag == 1
                        listv{idx} = ['*Node',num2str(idx),' - (',sprintf('%5.5f',app.StoredData.X(idx)),', ',sprintf('%5.5f',app.StoredData.Y(idx)),', ',sprintf('%5.5f',app.StoredData.Z(idx)),')'];
                    end 
                catch
                    oflag = 1;

                    %Variables are not defined for the node.  Let's prepend an * to the Node list
                    listv{idx} = ['*Node',num2str(idx),' - (',sprintf('%5.5f',app.StoredData.X(idx)),', ',sprintf('%5.5f',app.StoredData.Y(idx)),', ',sprintf('%5.5f',app.StoredData.Z(idx)),')'];
                end
            end

            % Squeeze and display
            try
                [~,ANLBval] = ismember(app.AvailableNodesListBox.Value, app.AvailableNodesListBox.Items);
            end
            app.AvailableNodesListBox.Items = squeeze(listv);
            try
                app.AvailableNodesListBox.Value = app.AvailableNodesListBox.Items{ANLBval};
            end
            try
                Populate_LoadTypes(app)
            end
            try
                app.LoadTypeDropDown.Value = WLT;
            end

            % If all nodes are defined properly and there are wires (nodes
            % can only be defined if proper number of wires), unlock wiring
            % definition tab
            if ((oflag==0) && (size(app.StoredData.Wires,1) > 0)) && (strcmp(app.AvailableNodesListBox.Enable,'on'))
                app.WiringDefinitionButton.Enable = "on";
                app.WiringDefinitionButton.Tooltip = '';
            else
                app.WiringDefinitionButton.Enable = "off";
                app.WiringDefinitionButton.Tooltip = 'Define all node types and associated parameters for this tab to unlock.';
            end

        end

        function results = DealwithCOMSOL(app,forexport)

            disableinterface = false;
            if disableinterface
                app.Menu.Enable = 'off'; %#ok<*UNRCH> 
                app.HelpMenu.Enable = 'off';
                app.FileMenu.Enable = 'off';
                app.LeftPanel.Enable = 'off';
                app.Panel_4.Enable = 'off';
            end

            tnc = str2num(app.NumberofConductorsDropDown.Value); %#ok<*ST2NM> 

            eorm = 0; %#ok<*NASGU> 
            if strcmp(app.FieldButtonGroup.SelectedObject.Text,'Electric')
                eorm = 1;
            elseif strcmp(app.FieldButtonGroup.SelectedObject.Text,'Magnetic')
                eorm = 2;
            else
                error('Coding flaw... eorm.');
            end

            if tnc == 4
                CMnotIW = false;
            else
                CMnotIW = true;
            end

            if CMnotIW % 2 conductor

            % Start server
            app.ErrorType.Text = 'Export to COMSOL';
            app.ErrorType.FontColor = [0 0.5 0];
            app.ErrorMsg.Text = ['Activating COMSOL server...'];
            app.ErrorMsg.FontColor = [0 0.5 0];

            drawnow;

            try
                import com.comsol.model.* %#ok<*SIMPT> 
                import com.comsol.model.util.*
                %model = ModelUtil.create('Model');
                if eorm == 2
                    ModelUtil.clear;
                    model = mphload([app.StoredData.PWD,'Radiative/NetworklessMagMPH.mph']);
                else
                    ModelUtil.clear;
                    model = mphload([app.StoredData.PWD,'Radiative/NetworklessMPH.mph']);
                end

                % ADD LINE TO SAVE MODEL TO TEST IF SERVER FULL?
            catch
                % Make this user-defined
                try
                    addpath(app.StoredData.COMSOLPATH)
                catch
                    error('COMSOL Path to MLI improperly set.')
                end

                system(['sh ',app.StoredData.PWD,'Radiative/KillCOMSOLServer.sh']);
                clc
                pause(0.1);
    
                failedstart = system(['sh ',app.StoredData.PWD,'Radiative/StartCOMSOLServer.sh']);
                
                if failedstart
                    % Write sh file to adapt to current file system
                    tmpstartfid = fopen([app.StoredData.PWD,'Radiative/StartCOMSOLServer.sh'],'w');
                    COMSOLPATHMLI = app.StoredData.COMSOLPATH;
                    COMSOLPATHBIN = [COMSOLPATHMLI,'/../bin'];
                    fprintf(tmpstartfid,['screen -S comsolserverENCoRP -d -m bash -c ''PATH=$PATH:',COMSOLPATHMLI,'; ','PATH=$PATH:',COMSOLPATHBIN,'; comsol mphserver -allowexternalmatlab''']);
                    fclose(tmpstartfid);
                    
                    system(['sh ',app.StoredData.PWD,'Radiative/StartCOMSOLServer.sh']);
                end

                clc
                pause(1)
    
                % Connecting
                app.ErrorType.Text = 'Export to COMSOL';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Activated COMSOL server.' newline newline 'Establishing connection with MATLAB...'];
                app.ErrorMsg.FontColor = [0 0.5 0];
                drawnow;

                successconnect = true;
                maxattempts = 100;
                attempts = 0;
                app.ErrorMsg.Text = ['Activating COMSOL server and establishing connection with MATLAB...' newline newline 'This may take a few minutes...'];
                drawnow;
                tic
                while successconnect
                    attempts = attempts+1;
                    pause(3)
                    try
                        try 
                            mphstart
                        catch
                            mphstart(2035+mod(attempts,50)); % I think this will actually remain here once the server is started rather than erroring out, hence we cannot output an ETA since it will not return to MATLAB until the connection has been established.
                        end
                        clc
                        successconnect = false;
                    catch
                        successconnect = true;
                    end
                    tracktime = toc;
                    if (attempts == maxattempts) || (tracktime > 300)
                        app.ErrorMsg.Text = 'Max attempts (100 attempts / 5 minutes) reached. Connection failed to establish in that time frame.';
                        app.ErrorMsg.FontColor = 'r';
                        return
                    else
                        app.ErrorMsg.Text = ['Activating COMSOL server and establishing connection with MATLAB...' newline newline 'This may take a few minutes...' newline 'Ellapsed time: ',num2str(round(tracktime)),' seconds' ];
                    end
                end

                import com.comsol.model.*
                import com.comsol.model.util.*
                %model = ModelUtil.create('Model');
                if eorm == 2
                    model = mphload([app.StoredData.PWD,'Radiative/NetworklessMagMPH.mph']);
                else
                    model = mphload([app.StoredData.PWD,'Radiative/NetworklessMPH.mph']);
                end
            end
            ModelUtil.showProgress(true);
            clc

            % Construct geometry
            app.ErrorType.Text = 'Export to COMSOL';
            app.ErrorType.FontColor = [0 0.5 0];
            app.ErrorMsg.Text = ['Activated COMSOL server.' newline newline 'Established connection with MATLAB.' newline newline 'Translating to COMSOL model...'];
            app.ErrorMsg.FontColor = [0 0.5 0];
            drawnow;

            % Define COMSOL model / LOAD COMSOL MODEL
            %model = ModelUtil.create('Model');
            network1 = model.geom('geom1');

            % Create workplane
            wp1 = network1.feature.create('wpN','WorkPlane');
            wp1.set('planetype','quick');
            wp1.set('quickplane','xy');
            network1.run;
            
            % Network
            wires = app.StoredData.results.WireID;
            
            nodes = app.StoredData.results.Nodes;

            % Create lines for wires
            for i = 1:size(wires,1)

                % Create Bezier Polygon to define each wire as a line
                % rather than cylinder
                network1.feature.create(sprintf('lineN%d',i), 'BezierPolygon');
                network1.feature(sprintf('lineN%d',i)).set('p',[nodes(wires(i,1),:); nodes(wires(i,2),:)].');
                network1.run;

                % Create cylinder
                %network1.feature.create(sprintf('line%d',i), 'Cylinder');
            
                % Initialize bottom
                %network1.feature(sprintf('line%d',i)).set('pos', {num2str(nodes(wires(i,1),1)) num2str(nodes(wires(i,1),2)) num2str(nodes(wires(i,1),3))});
            
                % Define radius
                %network1.feature(sprintf('line%d',i)).set('r', {num2str(rw(i))});
                
                % Define length
                %len = norm(nodes(wires(i,2),:) - nodes(wires(i,1),:));
                %network1.feature(sprintf('line%d',i)).set('h',{num2str(len)});
            
                % Define direction
                %direc = nodes(wires(i,2),:) - nodes(wires(i,1),:);
                %network1.feature(sprintf('line%d',i)).set('axis', {num2str(direc(1)) num2str(direc(2)) num2str(direc(3))});
            end

            % Add all wires to POT1
            if eorm == 2
                pot1 = model.physics('mf').feature('edc1');
                pot1.selection.set([9:8+size(wires,1)]);
                network1.run;
            else
                pot1 = model.physics('esbe').feature('pot1');
                pot1.selection.set([1:size(wires,1)]);
                network1.run;
            end

            % Set frequency
            if forexport
                freqval = app.StoredData.results.Freq;
            else
                freqval = app.StoredData.EMfreq;
            end
            fv = model.study('std1').feature('freq');
            fv.set('plist',freqval);
            network1.run;

            % Change domain
            chbd = model.result.dataset('grid1');
            domb = app.StoredData.EMdom;
            chbd.set('parmax1',num2str(domb(2))); network1.run;
            chbd.set('parmin1',num2str(domb(1))); network1.run;
            chbd.set('parmax2',num2str(domb(4))); network1.run;
            chbd.set('parmin2',num2str(domb(3))); network1.run;
            chbd.set('parmax3',num2str(domb(6))); network1.run;
            chbd.set('parmin3',num2str(domb(5))); network1.run;

            % Change resolution
            chbd.set('res1',num2str(min(100,round(100*abs(domb(2)-domb(1)))))); network1.run;
            chbd.set('res2',num2str(min(100,round(100*abs(domb(4)-domb(3)))))); network1.run;
            chbd.set('res3',num2str(min(100,round(100*abs(domb(6)-domb(5)))))); network1.run;

            % Change box size if magnetic field
            if eorm == 2
                model.geom('geom1').feature('blk1').set('lx',num2str(domb(2)-domb(1)));
                model.geom('geom1').feature('blk1').set('ly',num2str(domb(4)-domb(3)));
                model.geom('geom1').feature('blk1').set('lz',num2str(domb(6)-domb(5)));
                model.geom('geom1').feature('blk1').set('x',num2str(domb(1)));
                model.geom('geom1').feature('blk1').set('y',num2str(domb(3)));
                model.geom('geom1').feature('blk1').set('z',num2str(domb(5)));
            end

            % Change quiver density
            quiv = app.StoredData.EMQD;
            model.result('pg1').feature('arwv1').set('xnumber',num2str(quiv(1))); network1.run;
            model.result('pg1').feature('arwv1').set('ynumber',num2str(quiv(2))); network1.run;
            model.result('pg1').feature('arwv1').set('znumber',num2str(quiv(3))); network1.run;

            % Launch COMSOL
            app.ErrorType.Text = 'Export to COMSOL';
            app.ErrorType.FontColor = [0 0.5 0];
            if forexport
                app.ErrorMsg.Text = ['Activated COMSOL server.' newline newline 'Established connection with MATLAB.' newline newline 'Translated to COMSOL model.' newline newline 'Launching COMSOL...'];
            else
                app.ErrorMsg.Text = ['Activated COMSOL server.' newline newline 'Established connection with MATLAB.' newline newline 'Translated to COMSOL model.' newline newline 'Importing results...'];
            end            
            app.ErrorMsg.FontColor = [0 0.5 0];
            drawnow;

            try
                delete([app.StoredData.PWD,'Radiative/NetworkMPH.mph']);
            end
            clc

            %mphgeom(model,'network1')
            mphrun(model);

            % Add lines
            if eorm == 2
                lnsl = model.result('pg1').feature('line3').feature('sel1');
                lnsl.selection.set([9:8+size(wires,1)]);
            else
                lnsl = model.result('pg1').feature('line2').feature('sel1');
                lnsl.selection.set([1:size(wires,1)]);
            end
            network1.run;

            % save
            try
                mphsave(model,[app.StoredData.PWD,'Radiative/NetworkMPH.mph']);
            end

            if forexport
                try
                    modelrenamed = mphload([app.StoredData.PWD,'Radiative/NetworkMPH.mph']);
                    mphlaunch(modelrenamed)
                catch
                    try
                        mphlaunch(model)
                    catch
                        error('COMSOL server full, launch comsol and open NetworkMPH.mph.')
                    end
                end
            else
                hf=figure('visible','off');       
                mphplot(model);
                ha=gca;
    
                cla(app.UIAxes3)
                copyobj(ha.Children,app.UIAxes3);
    
                close(hf);
            end

            else % 4 conductor case

            % Start server
            app.ErrorType.Text = 'Export to COMSOL';
            app.ErrorType.FontColor = [0 0.5 0];
            app.ErrorMsg.Text = ['Activating COMSOL server...'];
            app.ErrorMsg.FontColor = [0 0.5 0];

            drawnow;

            try
                import com.comsol.model.*
                import com.comsol.model.util.*
                %model = ModelUtil.create('Model');
                if eorm == 2
                    ModelUtil.clear;
                    model = mphload([app.StoredData.PWD,'Radiative/NetworklessMag4MPH.mph']);
                else
                    ModelUtil.clear;
                    model = mphload([app.StoredData.PWD,'Radiative/Networkless4MPH.mph']);
                end
            catch
                % User needs to provide location
                try
                    addpath(app.StoredData.COMSOLPATH)
                catch
                    error('COMSOL Path to MLI improperly set.')
                end

                system(['sh ',app.StoredData.PWD,'Radiative/KillCOMSOLServer.sh']);
                clc
                pause(0.1);
    
                failedstart = system(['sh ',app.StoredData.PWD,'Radiative/StartCOMSOLServer.sh']);
                
                if failedstart
                    % Write sh file to adapt to current file system
                    tmpstartfid = fopen([app.StoredData.PWD,'Radiative/StartCOMSOLServer.sh'],'w');
                    COMSOLPATHMLI = app.StoredData.COMSOLPATH;
                    COMSOLPATHBIN = [COMSOLPATHMLI,'/../bin'];
                    fprintf(tmpstartfid,['screen -S comsolserverENCoRP -d -m bash -c ''PATH=$PATH:',COMSOLPATHMLI,'; ','PATH=$PATH:',COMSOLPATHBIN,'; comsol mphserver -allowexternalmatlab''']);
                    fclose(tmpstartfid);
                    
                    system(['sh ',app.StoredData.PWD,'Radiative/StartCOMSOLServer.sh']);
                end
                
                clc
                pause(1)
    
                % Connecting
                app.ErrorType.Text = 'Export to COMSOL';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Activated COMSOL server.' newline newline 'Establishing connection with MATLAB...'];
                app.ErrorMsg.FontColor = [0 0.5 0];
                drawnow;

                successconnect = true;
                maxattempts = 100;
                attempts = 0;
                app.ErrorMsg.Text = ['Activating COMSOL server and establishing connection with MATLAB...' newline newline 'This may take a few minutes...'];
                drawnow;
                tic
                while successconnect
                    attempts = attempts+1;
                    pause(3)
                    try
                        try 
                            mphstart
                        catch
                            mphstart(2035+mod(attempts,50)); % I think this will actually remain here once the server is started rather than erroring out, hence we cannot output an ETA since it will not return to MATLAB until the connection has been established.
                        end
                        clc
                        successconnect = false;
                    catch
                        successconnect = true;
                    end
                    tracktime = toc;
                    if (attempts == maxattempts) || (tracktime > 300)
                        app.ErrorMsg.Text = 'Max attempts (100 attempts / 5 minutes) reached. Connection failed to establish in that time frame.';
                        app.ErrorMsg.FontColor = 'r';
                        return
                        %error('Max attempts (100 attempts / 5 minutes) reached. Connected failed to establish in that time frame.')
                    %else
                    %    app.ErrorMsg.Text = ['Activated COMSOL server.' newline newline 'Establishing connection with MATLAB...' newline newline 'ETA ',sprintf('%d sec',max(0,60-attempts))];
                    else
                        app.ErrorMsg.Text = ['Activating COMSOL server and establishing connection with MATLAB...' newline newline 'This may take a few minutes...' newline newline 'Ellapsed time: ',num2str(round(tracktime)),' seconds' ];
                    end
                end

                import com.comsol.model.*
                import com.comsol.model.util.*
                %model = ModelUtil.create('Model');
                if eorm == 2
                    model = mphload([app.StoredData.PWD,'Radiative/NetworklessMag4MPH.mph']);
                else
                    model = mphload([app.StoredData.PWD,'Radiative/Networkless4MPH.mph']);
                end
            end
            ModelUtil.showProgress(true);
            clc

            % Construct geometry
            app.ErrorType.Text = 'Export to COMSOL';
            app.ErrorType.FontColor = [0 0.5 0];
            app.ErrorMsg.Text = ['Activated COMSOL server.' newline newline 'Established connection with MATLAB.' newline newline 'Translating to COMSOL model...'];
            app.ErrorMsg.FontColor = [0 0.5 0];
            drawnow;

            % Define COMSOL model / LOAD COMSOL MODEL
            %model = ModelUtil.create('Model');
            network1 = model.geom('geom1');

            % Create workplane
            wp1 = network1.feature.create('wpN','WorkPlane');
            wp1.set('planetype','quick');
            wp1.set('quickplane','xy');
            network1.run;
            
            % Network
            wires = app.StoredData.results.WireID;
            
            nodes = app.StoredData.results.Nodes;

            % Create all four lines
            allnodes = nan(4*size(wires,1),2*(tnc-1));
            alltags = cell(4*size(wires,1),1);
            allind = 1;

            % Precompute ball radii
            dynamic_br = true;
            rmin = 0.005;
            if dynamic_br
                try
                    nbr = zeros(size(nodes,1),1);
                    eps_s = 0.003;
                    dseps = 1e-3*(1+randi(9,[size(wires,1),1]));
                    
                    % Iterate through all nodes
                    for nbrindn = 1:size(nodes,1)
                    
                        Vj45 = [];
                        Vjw45 = [];
                        
                        % Iterate through all wires
                        for nbrindw = 1:size(wires,1)
                        
                            % Check if wire relates to node
                            if wires(nbrindw,1) == nbrindn
                                Vj45 = [Vj45; nodes(wires(nbrindw,2),:)-nodes(wires(nbrindw,1),:)];
                                Vjw45 = [Vjw45; nbrindw];
                            elseif wires(nbrindw,2) == nbrindn
                                Vj45 = [Vj45; nodes(wires(nbrindw,1),:)-nodes(wires(nbrindw,2),:)];
                                Vjw45 = [Vjw45; nbrindw];
                            end
                        end
                    
                        % If only one wire, use minimum r
                        if length(Vjw45) == 1
                            radiilist = rmin;
                        else
                            % Compute angle between all wires given unique separation distances
                            wirecombs = [];
                            wireangles = [];
                    
                            for indwc1 = 1:length(Vjw45)
                                for indwc2 = indwc1+1:length(Vjw45)
                                    wireangles = [wireangles; atan2(norm(cross(Vj45(indwc1,:).',Vj45(indwc2,:).')),dot(Vj45(indwc1,:),Vj45(indwc2,:)))];
                                    wirecombs = [wirecombs; [Vjw45(indwc1), Vjw45(indwc2)]];
                                end
                            end
                    
                            % If two wires are roughly aligned... default to rmin and let user figure it out
                            epsang = 1*pi/180;
                            if (length(Vjw45) == 2) && (abs(wireangles) < epsang)
                                radiilist = rmin;
                    
                            % If two wires are roughly pi apart, force minimum rmin
                            elseif (length(Vjw45) == 2) && (abs(wireangles-pi) < epsang)
                                radiilist = rmin;
                    
                            else
                                % Determine radii to minimize for each
                                radiilist = zeros(size(wireangles));
                                for warlind = 1:length(wireangles)
                    
                                    if (abs(wireangles(warlind)-pi) < epsang) || (abs(wireangles(warlind)) < epsang)
                                        radiilist(warlind) = rmin;
                                    else
                                        dsepj = 1e-3 * app.StoredData.WireProps(wirecombs(warlind,1)).DC;
                                        dsepk = 1e-3 * app.StoredData.WireProps(wirecombs(warlind,2)).DC;
                                        r1lb = sqrt(dsepj^2/(2*sin(wireangles(warlind))^2) + dsepk*dsepj/(sin(wireangles(warlind))^2)*cos(wireangles(warlind)) + dsepk^2/(2*sin(wireangles(warlind))^2));
                                        Qj = @(r) [sqrt(r^2 - dsepj^2/2), sqrt(2)/2*dsepj];
                                        if abs(wireangles(warlind) - pi/2) > epsang
                                            Qk1 = @(r) sqrt(2)/2*dsepk*sin(wireangles(warlind)) + 1/2*sign(cos(wireangles(warlind)))*sqrt(2*dsepk^2*sin(wireangles(warlind))^2-2*dsepk^2+4*r^2*cos(wireangles(warlind))^2);
                                            Qk = @(r) [Qk1(r), tan(wireangles(warlind))*Qk1(r)-sqrt(2)/2*dsepk/cos(wireangles(warlind))];
                                        else
                                            Qk = @(r) [sqrt(2)/2*dsepk, sqrt(r^2 - dsepk^2/2)];
                                        end
                                        radiilist(warlind) = max(rmin, fminbnd(@(r) abs(eps_s - norm(Qj(r) - Qk(r))),r1lb,1e6));
                                    end
                                end
                            end
                        end
                    
                        % Pick rball
                        nbr(nbrindn) = max(radiilist);
                    
                    end
                catch
                    nbr = rmin*ones(size(nodes,1),1);
                end
            else
                nbr = rmin*ones(size(nodes,1),1);
            end
            
            for i = 1:size(wires,1)

                % Pull separation distance for current wire
                dbc = 1e-3 * app.StoredData.WireProps(i).DC;
                if tnc == 4
                    offsetbase = dbc/2; % dbc diag/2
                    offset = offsetbase*[1 -1 -1 1; -1 -1 1 1; 0 0 0 0];
                    % display in y to avoid new segments
                else
                    % TBD
                    error('Incomplete, need to implement 2 and 3 wire shape once consensus on direction.')
                end

                % Create Bezier Polygon to define each wire as a line
                % rather than cylinder

                % Rotate square of points to match wire direction
                % Obtain unit direction
                direc = nodes(wires(i,2),:) - nodes(wires(i,1),:);
                direc = direc/norm(direc);

                % Obtain rotation angles
                [Theta,Phi,~] = cart2sph(direc(1),direc(2),direc(3));
                Phi = pi/2-Phi;

                % Construct 3D yaw and pitch rotations
                Rotxy = [cos(Theta) -sin(Theta) 0; sin(Theta) cos(Theta) 0; 0 0 1];
                Rotz = [cos(Phi) 0 sin(Phi); 0 1 0; -sin(Phi) 0 cos(Phi)];

                % Rotate offset 
                OffRot = Rotxy*Rotz*offset;   

                % Define tmpnodes to be a ball away from intersections
                
                % Pull ball radius
                ballr1 = nbr(wires(i,1));
                ballr2 = nbr(wires(i,2));

                % Add ball radius
                adjballr1 = max(rmin,sqrt(ballr1^2 - dbc^2/2));
                if ~isreal(adjballr1)
                    adjballr1 = rmin;
                end
                adjballr2 = max(rmin,sqrt(ballr2^2 - dbc^2/2));
                if ~isreal(adjballr2)
                    adjballr2 = rmin;
                end
                node1 = nodes(wires(i,1),:)+direc*adjballr1;
                node2 = nodes(wires(i,2),:)-direc*adjballr2;
                
                for ncond = 1:tnc
                    %if ncond == 1
                        network1.feature.create(sprintf('lineN%dC%d',i,ncond), 'BezierPolygon');
                        network1.feature(sprintf('lineN%dC%d',i,ncond)).set('p',([node1; node2].')+OffRot(:,ncond));
                        network1.run;
    
                        sortnodes = sortrows([node1+OffRot(:,ncond).'; node2+OffRot(:,ncond).']);
                        allnodes(allind,:) = [sortnodes(1,:), sortnodes(2,:)];
                        alltags{allind} = sprintf('lineN%dC%d',i,ncond);
                        allind = allind + 1;
%                     elseif ncond == 2
%                         network2.feature.create(sprintf('lineN%dC%d',i,ncond), 'BezierPolygon');
%                         network2.feature(sprintf('lineN%dC%d',i,ncond)).set('p',([nodes(wires(i,1),:); nodes(wires(i,2),:)].')+offset(:,ncond));
%                         network2.run;
%     
%                         sortnodes = sortrows([nodes(wires(i,1),:)+offset(:,ncond).'; nodes(wires(i,2),:)+offset(:,ncond).']);
%                         allnodes(allind,:) = [sortnodes(1,:), sortnodes(2,:)];
%                         alltags{allind} = sprintf('lineN%dC%d',i,ncond);
%                         allind = allind + 1;
%                     elseif ncond == 3
%                         network3.feature.create(sprintf('lineN%dC%d',i,ncond), 'BezierPolygon');
%                         network3.feature(sprintf('lineN%dC%d',i,ncond)).set('p',([nodes(wires(i,1),:); nodes(wires(i,2),:)].')+offset(:,ncond));
%                         network3.run;
%     
%                         sortnodes = sortrows([nodes(wires(i,1),:)+offset(:,ncond).'; nodes(wires(i,2),:)+offset(:,ncond).']);
%                         allnodes(allind,:) = [sortnodes(1,:), sortnodes(2,:)];
%                         alltags{allind} = sprintf('lineN%dC%d',i,ncond);
%                         allind = allind + 1;
%                     elseif ncond == 4
%                         network4.feature.create(sprintf('lineN%dC%d',i,ncond), 'BezierPolygon');
%                         network4.feature(sprintf('lineN%dC%d',i,ncond)).set('p',([nodes(wires(i,1),:); nodes(wires(i,2),:)].')+offset(:,ncond));
%                         network4.run;
%     
%                         sortnodes = sortrows([nodes(wires(i,1),:)+offset(:,ncond).'; nodes(wires(i,2),:)+offset(:,ncond).']);
%                         allnodes(allind,:) = [sortnodes(1,:), sortnodes(2,:)];
%                         alltags{allind} = sprintf('lineN%dC%d',i,ncond);
%                         allind = allind + 1;
%                     end
                end
                
%                 % Create cylinder
%                 network1.feature.create(sprintf('line%d',i), 'Cylinder');
%             
%                 % Initialize bottom
%                 network1.feature(sprintf('line%d',i)).set('pos', {num2str(nodes(wires(i,1),1)) num2str(nodes(wires(i,1),2)) num2str(nodes(wires(i,1),3))});
%             
%                 % Define radius
%                 network1.feature(sprintf('line%d',i)).set('r', {num2str(rw(i))});
%                 
%                 % Define length
%                 len = norm(nodes(wires(i,2),:) - nodes(wires(i,1),:));
%                 network1.feature(sprintf('line%d',i)).set('h',{num2str(len)});
%             
%                 % Define direction
%                 direc = nodes(wires(i,2),:) - nodes(wires(i,1),:);
%                 network1.feature(sprintf('line%d',i)).set('axis', {num2str(direc(1)) num2str(direc(2)) num2str(direc(3))});
            end

            % Compute edge indices via immitating COMSOL
            [~,GInds2] = sortrows(allnodes); 
            GInds = nan(size(GInds2));
            for gind = 1:length(GInds2)
                GInds(gind) = find(GInds2==gind);
            end
            
            if eorm == 2
                pot1 = model.physics('mf').feature('edc1');
                pot1.selection.set(8+GInds([1:4:4*size(wires,1)]));
            else
                pot1 = model.physics('esbe').feature('pot1');
                pot1.selection.set(GInds([1:4:4*size(wires,1)]));
            end
            network1.run;

            if tnc > 2
                % Add all second wires to pot2
                if eorm == 2
                    pot2 = model.physics('mf').feature('edc2');
                    pot2.selection.set(8+GInds([2:4:4*size(wires,1)]));
                else
                    pot2 = model.physics('esbe').feature('pot2');
                    pot2.selection.set(GInds([2:4:4*size(wires,1)]));
                end
                network1.run;
            end

            if tnc > 3
                % Add all third wires to pot3
                if eorm == 2
                    pot3 = model.physics('mf').feature('edc3');
                    pot3.selection.set(8+GInds([3:4:4*size(wires,1)]));
                else
                    pot3 = model.physics('esbe').feature('pot3');
                    pot3.selection.set(GInds([3:4:4*size(wires,1)]));
                end
                network1.run;
            end
           
            % Add all ground wires to gnd2
            if eorm == 2
                gnd2 = model.physics('mf').feature('edc4');
                gnd2.selection.set(8+GInds([4:4:4*size(wires,1)]));
            else
                gnd2 = model.physics('esbe').feature('gnd2');
                gnd2.selection.set(GInds([4:4:4*size(wires,1)]));
            end
            network1.run;
            
            % Change freq
            if forexport
                freqval = app.StoredData.results.Freq;
            else
                freqval = app.StoredData.EMfreq;
            end
            fv = model.study('std1').feature('freq');
            fv.set('plist',freqval);
            network1.run;

            % Change domain
            domb = app.StoredData.EMdom;
            chbd = model.result.dataset('grid1');
            chbd.set('parmax1',num2str(domb(2))); network1.run;
            chbd.set('parmin1',num2str(domb(1))); network1.run;
            chbd.set('parmax2',num2str(domb(4))); network1.run;
            chbd.set('parmin2',num2str(domb(3))); network1.run;
            chbd.set('parmax3',num2str(domb(6))); network1.run;
            chbd.set('parmin3',num2str(domb(5))); network1.run;
 
            % Change resolution % 4 wire
            chbd.set('res1',num2str(min(100,round(100*abs(domb(2)-domb(1)))))); network1.run;
            chbd.set('res2',num2str(min(100,round(100*abs(domb(4)-domb(3)))))); network1.run;
            chbd.set('res3',num2str(min(100,round(100*abs(domb(6)-domb(5)))))); network1.run;

            % Change quiver density
            quiv = app.StoredData.EMQD;
            model.result('pg1').feature('arwv1').set('xnumber',num2str(quiv(1))); network1.run;
            model.result('pg1').feature('arwv1').set('ynumber',num2str(quiv(2))); network1.run;
            model.result('pg1').feature('arwv1').set('znumber',num2str(quiv(3))); network1.run;

            % Change box size if magnetic field
            if eorm == 2
                model.geom('geom1').feature('blk1').set('lx',num2str(domb(2)-domb(1)));
                model.geom('geom1').feature('blk1').set('ly',num2str(domb(4)-domb(3)));
                model.geom('geom1').feature('blk1').set('lz',num2str(domb(6)-domb(5)));
                model.geom('geom1').feature('blk1').set('x',num2str(domb(1)));
                model.geom('geom1').feature('blk1').set('y',num2str(domb(3)));
                model.geom('geom1').feature('blk1').set('z',num2str(domb(5)));
            end

            % Launch COMSOL
            app.ErrorType.Text = 'Export to COMSOL';
            app.ErrorType.FontColor = [0 0.5 0];
            if forexport
                app.ErrorMsg.Text = ['Activated COMSOL server.' newline newline 'Established connection with MATLAB.' newline newline 'Translated to COMSOL model.' newline newline 'Launching COMSOL...'];
            else
                app.ErrorMsg.Text = ['Activated COMSOL server.' newline newline 'Established connection with MATLAB.' newline newline 'Translated to COMSOL model.' newline newline 'Importing results...'];
            end
            app.ErrorMsg.FontColor = [0 0.5 0];
            drawnow;
            
            try
                delete([app.StoredData.PWD,'Radiative/Network4MPH.mph']);
            end
            clc
            
            mphrun(model);
            
            % Add lines
            if eorm == 2
                lnsl = model.result('pg1').feature('line3').feature('sel1');
                lnsl.selection.set([9:8+4*size(wires,1)]);
            else
                lnsl = model.result('pg1').feature('line2').feature('sel1');
                lnsl.selection.set([1:4*size(wires,1)]);
            end
            network1.run;
            
            % save
            try
                mphsave(model,[app.StoredData.PWD,'Radiative/Network4MPH.mph']);
            end

            if forexport
                try
                    modelrenamed = mphload([app.StoredData.PWD,'Radiative/Network4MPH.mph']);
                    mphlaunch(modelrenamed)
                catch
                    try
                        mphlaunch(model)
                    catch
                        error('COMSOL server full, launch comsol and open Network4MPH.mph.')
                    end
                end
            else
                hf=figure('visible','off');       
                mphplot(model);
                ha=gca;
                
                cla(app.UIAxes3)
                copyobj(ha.Children,app.UIAxes3);
    
                close(hf);
            end
            end

            if disableinterface
                app.Menu.Enable = 'on';
                app.HelpMenu.Enable = 'on';
                app.FileMenu.Enable = 'on';
                app.LeftPanel.Enable = 'on';
                app.Panel_4.Enable = 'on';
            end

        end

        function results = SetDC(app,num,val)
            
            % Pull wire index
            [~, idx] = ismember(app.WireListListBox_2.Value, app.WireListListBox_2.Items);

            % If num == 1, val is legit, double check not empty
            if isempty(val)
                num = 2;
            end
            
            % Since legit, save and reset background
            if num == 1 
                app.StoredData.WireProps(idx).DC = str2num(val);
                app.SeparationDistanceEditField.Value = val;
                app.SeparationDistanceEditField.BackgroundColor = [1 1 1];

            % Illegitimate, remove saved value and from field
            elseif num == 2 
                app.StoredData.WireProps(idx).DC = [];
                app.SeparationDistanceEditField.Value = '';
            end

        end

        function results = SetTPCB(app)
            
            if strcmp(app.NumberofConductorsDropDown.Value,'4')
                % Turn on and check all labels and boxes
                app.Label_12.Visible = 'on';
                app.Label_13.Visible = 'on';
                app.Label_14.Visible = 'on';
                app.Label_15.Visible = 'on';
                app.Label_16.Visible = 'on';
                app.Label_17.Visible = 'on';
                app.Label_18.Visible = 'on';
                app.Label_19.Visible = 'on';
                app.Label_20.Visible = 'on';

                app.Label_21.Visible = 'off';
                app.Label_22.Visible = 'off';
                app.Label_23.Visible = 'off';
                app.Label_24.Visible = 'off';

                app.CheckBox_1.Visible = 'on';
                app.CheckBox_2.Visible = 'on';
                app.CheckBox_3.Visible = 'on';
                app.CheckBox_4.Visible = 'on';
                app.CheckBox_5.Visible = 'on';
                app.CheckBox_6.Visible = 'on';
                app.CheckBox_7.Visible = 'on';
                app.CheckBox_8.Visible = 'on';
                app.CheckBox_9.Visible = 'on';

                app.CheckBox_10.Visible = 'off';
                app.CheckBox_11.Visible = 'off';
                app.CheckBox_12.Visible = 'off';
                app.CheckBox_13.Visible = 'off';

                app.CheckBox_1.Value = true;
                for i = 2:9
                    eval(['app.CheckBox_',num2str(i),'.Value = true;']);
                end
            else
                % Turn on and check all boxes
                app.Label_12.Visible = 'off';
                app.Label_13.Visible = 'off';
                app.Label_14.Visible = 'off';
                app.Label_15.Visible = 'off';
                app.Label_16.Visible = 'off';
                app.Label_17.Visible = 'off';
                app.Label_18.Visible = 'off';
                app.Label_19.Visible = 'off';
                app.Label_20.Visible = 'off';

                app.Label_21.Visible = 'on';
                app.Label_22.Visible = 'on';
                app.Label_23.Visible = 'on';
                app.Label_24.Visible = 'on';

                app.CheckBox_1.Visible = 'off';
                app.CheckBox_2.Visible = 'off';
                app.CheckBox_3.Visible = 'off';
                app.CheckBox_4.Visible = 'off';
                app.CheckBox_5.Visible = 'off';
                app.CheckBox_6.Visible = 'off';
                app.CheckBox_7.Visible = 'off';
                app.CheckBox_8.Visible = 'off';
                app.CheckBox_9.Visible = 'off';

                app.CheckBox_10.Visible = 'on';
                app.CheckBox_11.Visible = 'on';
                app.CheckBox_12.Visible = 'on';
                app.CheckBox_13.Visible = 'on';

                for i = 10:13
                    eval(['app.CheckBox_',num2str(i),'.Value = true;']);
                end
            end

        end
       
        function results = CheckWire(app)

            % Disable wire button by default
            app.AddWireButton.Enable=false; 

            try %#ok<*TRYNC>
                % Find indices for source and destination nodes
                [~, idx1] = ismember(app.SourceNodeListBox.Value, app.SourceNodeListBox.Items);
                [~, idx2] = ismember(app.DestinationNodeListBox.Value, app.DestinationNodeListBox.Items);
                
                % If they're the same or either is not selected, keep disabled, else enable
                if (idx1==idx2) || (isempty(idx1)) || (isempty(idx2))
                    app.AddWireButton.Enable=false;   
                else
                    app.AddWireButton.Enable=true;
                end
            end

        end

        function results = ResetRMT(app)

            app.StartEditField.Value = 1;
            app.FinishEditField.Value = 1e8;
            app.FrequencyButtonGroup.SelectedObject = app.LogButton;
            app.Label_25.Text = '';
            app.Label_25.Tooltip = '';
            app.FileLabel.Visible = 'off';
            app.UseLoadedFrequenciesCheckBox.Value = false;
            app.UseLoadedFrequenciesCheckBox.Enable = 'off';
            app.StepsEditField.Value = 100;
            app.InjectionButtonGroup.SelectedObject = app.VoltageButton_2;
            app.Phase1EditField.Value = '';
            app.Phase2EditField.Value = '';
            app.Phase3EditField.Value = '';
            app.oEditField.Value = 0;
            app.oEditField_2.Value = 0;
            app.oEditField_3.Value = 0;
            app.VLabel.Text = 'V';
            app.VLabel_2.Text = 'V';
            app.VLabel_3.Text = 'V';
            app.SPICECheckBox.Value = false;
            app.WireResolutionEditField.Value = 1;
            if strcmp(app.NumberofConductorsDropDown.Value,'4')
                app.Phase2EditField.Visible = true;
                app.Phase3EditField.Visible = true;
                app.VLabel_2.Visible = true;
                app.VLabel_3.Visible = true;
                app.oEditField_2Label.Visible = true;
                app.oEditField_3Label.Visible = true;
                app.oEditField_2.Visible = true;
                app.oEditField_3.Visible = true;
            else
                app.Phase2EditField.Visible = false;
                app.Phase3EditField.Visible = false;
                app.VLabel_2.Visible = false;
                app.VLabel_3.Visible = false;
                app.oEditField_2Label.Visible = false;
                app.oEditField_3Label.Visible = false;
                app.oEditField_2.Visible = false;
                app.oEditField_3.Visible = false;
            end
            app.Phase1EditField.BackgroundColor = [1 1 1];
            app.Phase2EditField.BackgroundColor = [1 1 1];
            app.Phase3EditField.BackgroundColor = [1 1 1];
            app.StepsEditField.Enable = 'on';
            app.StoredData.Freq = [];

        end

        function results = CheckRWandDC_FCW(app,num)
            
            try
                % Pull wire index
                idx = num;
    
                % Load DC and radii
                DC = app.StoredData.WireProps(idx).DC;
                rad = app.StoredData.WireProps(idx).Radius;
    
                % If DC not fully defined, return false
                if isempty(DC) || ~isnumeric(DC) || ~isreal(DC) || isnan(DC) || DC<=0 || DC>app.StoredData.DCUL
                    results = -1;
                    return;
                end
    
                % Pull number of conductors
                ncond = str2num(app.NumberofConductorsDropDown.Value);
    
                % DC for diagonal
                DCD = sqrt(2) * DC;
    
                % Otherwise DC is good, so compare with radii
                for ind = 1:ncond
                    % Check all radii are good
                    crad = rad(ind);
                    if isempty(crad) || isnan(crad) || ~isnumeric(crad) || ~isreal(crad) || crad<=0 || crad>app.StoredData.WRUL
                        results = -1;
                        return;
                    end
                end 
    
                % Reaching this point means all radii and dc are acceptably
                % defined, proceed to calculate they're relatively correct
                if ncond == 2
                    if sum(rad) < DC
                        results = 1;
                    else
                        results = -1;
                    end
                elseif ncond == 4
                    if (rad(1)+rad(2) < DC) && (rad(1)+rad(4) < DC) && (rad(2)+rad(3) < DC) && ...
                            (rad(3)+rad(4) < DC) && (rad(1)+rad(3) < DCD) && (rad(2)+rad(4) < DCD)
                        results = 1;
                    else
                        results = -1;
                    end
                end
            catch
                results = -1;
            end

        end

        function results = PartialCheckRWandDC_FCW(app,wv)

            % num is wire index, wv is which value, 1:4 for rw, 5 for dc

            % This function uses available values to check
            % and yellows issues accordingly

            % Pull number of conductors
            ncond = str2num(app.NumberofConductorsDropDown.Value);

            % Load available DC and radii
            try
                DC = str2num(app.SeparationDistanceEditField.Value);
            catch
                DC = Inf;
            end
            epsilon = 0;
            try
                rad(1) = str2num(app.WireRadiusEditField_1.Value);
            catch
                rad(1) = epsilon;
            end
            try
                rad(2) = str2num(app.WireRadiusEditField_2.Value);
            catch
                rad(2) = epsilon;
            end

            if ncond == 4
                try
                    rad(3) = str2num(app.WireRadiusEditField_3.Value);
                catch
                    rad(3) = epsilon;
                end
                try
                    rad(4) = str2num(app.WireRadiusEditField_4.Value);
                catch
                    rad(4) = epsilon;
                end
            end

            % If DC not fully defined, return false
            if isempty(DC) || ~isnumeric(DC) || ~isreal(DC) || isnan(DC) || DC<=0 || DC>app.StoredData.DCUL
                UseDC = 0;
            else
                UseDC = 1;
            end

            % DC for diagonal
            DCD = sqrt(2) * DC;

            % Check radii are good independent of dc
            for ind = 1:ncond
                try
                    crad = rad(ind);
                    if isempty(crad) || isnan(crad) || ~isnumeric(crad) || ~isreal(crad) || crad<=0 || crad>app.StoredData.WRUL
                        UseRad(ind) = 0;
                    else
                        UseRad(ind) = 1;
                    end
                end
            end 
              
            % Check combinations and return status
            if ncond == 2
                if wv ~= 5
                    if UseRad(wv)
                        if UseDC
                            if UseRad(3-wv)
                                if sum(rad(1:2)) < DC
                                    results = 1;
                                    return;
                                else
                                    results = 0;
                                    return;
                                end
                            else
                                if rad(wv) < DC
                                    results = 1;
                                    return;
                                else
                                    results = 0;
                                    return;
                                end
                            end
                        else
                            results = 1;
                            return;
                        end
                    else
                        results = -1;
                        return;
                    end
                else % wv == 5
                    if UseDC
                        if sum(UseRad(1:2)) > 0
                            if DC > sum(rad(UseRad==1))
                                results = 1;
                                return;
                            else
                                results = 0.1;
                                return;
                            end
                        else
                            results = 1;
                            return;
                        end
                    else
                        results = -1;
                        return;
                    end
                end

            elseif ncond == 4
                
                if wv == 5
                    if UseDC
                        if sum(UseRad) > 0
                            for ind2 = 1:4
                                if UseRad(ind2) && DC <= rad(ind2)
                                    results = 0.2+ind2/100;
                                    return;
                                end
                            end
                            if UseRad(1) && UseRad(2) && DC <= rad(1)+rad(2)
                                results = 0.3;
                                return;
                            end
                            if UseRad(2) && UseRad(3) && DC <= rad(2)+rad(3)
                                results = 0.4;
                                return;
                            end
                            if UseRad(3) && UseRad(4) && DC <= rad(3)+rad(4)
                                results = 0.5;
                                return;
                            end
                            if UseRad(1) && UseRad(4) && DC <= rad(1)+rad(4)
                                results = 0.6;
                                return;
                            end
                            if UseRad(1) && UseRad(3) && DCD <= rad(1)+rad(3)
                                results = 0.7;
                                return;
                            end
                            if UseRad(2) && UseRad(4) && DCD <= rad(2)+rad(4)
                                results = 0.8;
                                return;
                            end
                        end
                        results = 1;
                        return;
                    else
                        results = -1;
                        return;
                    end
                else % wv = 1,2,3, or 4
                    if UseRad(wv)
                        if UseDC
                            if DC <= rad(wv)
                                results = 0;
                                return;
                            end
                            tmpnext = mod(wv+1,4);
                            if tmpnext == 0
                                tmpnext = 4;
                            end
                            tmpprev = mod(wv-1,4);
                            if tmpprev == 0
                                tmpprev = 4;
                            end
                            tmpopp = mod(wv+2,4);
                            if tmpopp == 0
                                tmpopp = 4;
                            end
                            if UseRad(tmpprev) && rad(tmpprev)+rad(wv) >= DC
                                results = 0;
                                return;
                            end
                            if UseRad(tmpnext) && rad(tmpnext)+rad(wv) >= DC
                                results = 0;
                                return;
                            end
                            if UseRad(tmpopp) && rad(tmpopp)+rad(wv) >= DCD
                                results = 0;
                                return;
                            end
                            results = 1;
                            return;
                        else
                            results = 1;
                            return;
                        end
                    else
                        results = -1;
                        return;
                    end
                end
            end
            
        end
        
        function results = UpdateWireList(app)

            % Maintain selection
            try
                [~,returnto] = ismember(app.WireListListBox_2.Value,app.WireListListBox_2.Items);
            end
            try
                [~,return2] = ismember(app.WireListListBox.Value,app.WireListListBox.Items);
            end

            try
                listv = {};
                if ~isempty(app.StoredData.Wires)

                    % Add all wires to list
                    for idx = 1:size(app.StoredData.Wires,1)
                        listv{idx} = ['Wire',num2str(idx),' - Node ',num2str(app.StoredData.Wires(idx,1)),' --> Node ',num2str(app.StoredData.Wires(idx,2))];
                    end

                    % If a new wire was added, check if it already exists
                    if length(app.StoredData.EntityList)==2 && app.StoredData.lastEntity==2
                        wireexists = DoesWireExist(app);
                        if wireexists == 0 
                            listv{idx+1} = ['Wire',num2str(idx+1),' - Node ',num2str(app.StoredData.EntityList(1,1)),' --> Node ',num2str(app.StoredData.EntityList(1,2))];
                            app.StoredData.Wires(idx+1,1:2) = app.StoredData.EntityList;
                        end
                    end
                else

                    % This is the first wire of list since list was empty
                    if length(app.StoredData.EntityList)==2 && app.StoredData.lastEntity==2
                        idx = 1;
                        listv{idx} = ['Wire',num2str(idx),' - Node ',num2str(app.StoredData.EntityList(idx,1)),' --> Node ',num2str(app.StoredData.EntityList(idx,2))];
                        app.StoredData.Wires(idx,1:2) = app.StoredData.EntityList;
                    end
                end
            end
            try
                [~,WLLB] = ismember(app.WireListListBox.Value, app.WireListListBox.Items);
            end
            app.WireListListBox.Items = squeeze(listv);
            try
                app.WireListListBox.Value = app.WireListListBox.Items{WLLB};
            end

            % Modify the wire list to include an asterisk for ill-defined wires
            try
                oflag = 2;

                % Iterate through wires
                for idx = 1:size(app.StoredData.Wires,1)
                    flag = 0;

                    % Check all conditions met for rw and dc for idx wire
                    res = CheckRWandDC_FCW(app,idx);
                    if res == -1
                        flag = 1;
                        oflag = 1;
                    elseif res == 1
                        if oflag == 2
                            oflag = 0;
                        end
                    end

                    if flag == 1

                        % Append asterisk if ill-defined
                        txt = listv{idx};
                        listv{idx} = ['*',txt];
                    end
                end
            end

            % Update wire list box 2
            try
                [~,WLLB2] = ismember(app.WireListListBox_2.Value,app.WireListListBox_2.Items);
            end
            app.WireListListBox_2.Items = squeeze(listv);
            try
                app.WireListListBox_2.Value = app.WireListListBox_2.Items{WLLB2};
            end
            if oflag == 0 
                if strcmp(app.WireListListBox_2.Enable,'on')
                    app.RunModelButton.Enable = "on";
                    app.RunModelButton.Tooltip = '';
                else
                    app.RunModelButton.Enable = "off";
                    app.RunModelButton.Tooltip = 'Define the properties for all conductors in all wires for this tab to unlock.';
                end

                if app.StoredData.tmpvalue == 0
                    app.SimulationDetailsPanel.Enable = 'off';
                    app.SimulateButton.Enable = 'off';
                    app.VisualizeResultsButton.Enable = 'off';
                    app.EMFieldsButton.Enable = 'off';
                    try
                        app.StoredData = rmfield(app.StoredData,'EMTabAxes');
                    end
                end
            else
                app.SimulationDetailsPanel.Enable = 'off';
                app.SimulateButton.Enable = 'off';
                %ResetRMT(app);
                app.RunModelButton.Enable = "off";
                app.VisualizeResultsButton.Enable = 'off';
                app.EMFieldsButton.Enable = 'off';
                try
                    app.StoredData = rmfield(app.StoredData,'EMTabAxes');
                end
                app.RunModelButton.Tooltip = 'Define the properties for all conductors in all wires for this tab to unlock.';
            end

            % Plot the wire roster
            PlotWires(app) 

            % Reapply initial selection
            try
                app.WireListListBox.Value = app.WireListListBox.Items{return2};
            end
            try
                app.WireListListBox_2.Value = app.WireListListBox_2.Items{returnto};
            end

        end
        
        function results = DoesWireExist(app)

            % Wire does not exist by default
            results=0; 

            % Iterate through wires
            for idx=1:length(app.StoredData.Wires(:,1))

                % If it matches wire
                if app.StoredData.Wires(idx,1)==app.StoredData.EntityList(1,1)
                    if app.StoredData.Wires(idx,2)==app.StoredData.EntityList(1,2)
                        results=1;  % Wire already exists; do not add              
                    end
                end

                % If it matches reverse wire
                if app.StoredData.Wires(idx,2)==app.StoredData.EntityList(1,1)
                    if app.StoredData.Wires(idx,1)==app.StoredData.EntityList(1,2)
                        results=1;  % Wire already exists; do not add
                    end
                end
            end

        end
        
        function results = PlotWires(app)

            % Hold off and update nodal lists
            hold(app.UIAxes3,'off')
            UpdatePlotList(app)

            % Plot all defined wires
            try

                % Plot all wires in blue
                for idx=1:size(app.StoredData.Wires,1)

                    % Pull X, Y, Z data
                    Xv=app.StoredData.X(app.StoredData.Wires(idx,1:2));
                    Yv=app.StoredData.Y(app.StoredData.Wires(idx,1:2));
                    Zv=app.StoredData.Z(app.StoredData.Wires(idx,1:2));
                    hold(app.UIAxes3,'on')

                    % Plot
                    plot3(app.UIAxes3,Xv,Yv,Zv,'Color',[0 0.9 0.9],'LineWidth',2);

                end

                % Plot the selected wire in dark yellow, if one is selected
                if app.StoredData.lastEntity==2

                    % Pull X, Y, Z data
                    Xv=app.StoredData.X(app.StoredData.EntityList);
                    Yv=app.StoredData.Y(app.StoredData.EntityList);
                    Zv=app.StoredData.Z(app.StoredData.EntityList);

                    % Plot
                    plot3(app.UIAxes3,Xv,Yv,Zv,'Color',[0.5 0.5 0],'LineWidth',2)
                    plot3(app.UIAxes3,Xv,Yv,Zv,'Color',[0.5 0.5 0],'LineWidth',4);
                    
                end
            end

        end
        
        function results = PlotSD(app)

            % Pull source and destination node IDs
            [~, idx1] = ismember(app.SourceNodeListBox.Value, app.SourceNodeListBox.Items);
            [~, idx2] = ismember(app.DestinationNodeListBox.Value, app.DestinationNodeListBox.Items);

            % Hold axes on and save view angle
            hold(app.UIAxes3,'on')
            temp=app.UIAxes3.View;

            % Plot the two nodes in special colors
            try
                plot3(app.UIAxes3,app.StoredData.X(idx1),app.StoredData.Y(idx1),app.StoredData.Z(idx1),'Color','k','Marker',"o",'LineStyle',"none",'MarkerSize',10,'MarkerFaceColor',"g");
            end
            try
                plot3(app.UIAxes3,app.StoredData.X(idx2),app.StoredData.Y(idx2),app.StoredData.Z(idx2),'Color','k','Marker',"o",'LineStyle',"none",'MarkerSize',10,'MarkerFaceColor',"r");
            end

            % Restore view angle
            app.UIAxes3.View=temp;

        end
        
        function results = PlotSelectedWire(app,event)

            % Determine which wire list box we're dealing with and pull index
            if app.TabGroup.SelectedTab == app.WiringTab
                [~, idx1] = ismember(app.WireListListBox_2.Value, app.WireListListBox_2.Items);
            else
                [~, idx1] = ismember(app.WireListListBox.Value, app.WireListListBox.Items);
            end

            % Pull X, Y, Z data
            Xv=app.StoredData.X(app.StoredData.Wires(idx1,:));
            Yv=app.StoredData.Y(app.StoredData.Wires(idx1,:));
            Zv=app.StoredData.Z(app.StoredData.Wires(idx1,:));

            % Plot selected wire as red
            plot3(app.UIAxes3,Xv,Yv,Zv,'Color',[0.5 0.5 0],'LineWidth',2)
            plot3(app.UIAxes3,Xv,Yv,Zv,'Color',[0.5 0.5 0],'LineWidth',4);

        end
        
        function results = Populate_LoadTypes(app)
            
            % Load the load info corresponding to outlet or receiver 
            if strcmp(app.NodeTypeDropDown.Value, 'Receiver')
                app.StoredData.Loads = app.StoredData.LoadsR;
            else
                app.StoredData.Loads = app.StoredData.LoadsL;
            end     

            % Load load labels and drop down items based on selection
            for idx=1:length(app.StoredData.Loads)
               app.StoredData.LoadLabels(idx)=app.StoredData.Loads(idx).Name;
            end
            app.LoadTypeDropDown.Items=app.StoredData.LoadLabels;

        end

        function results = PLT_Specific(app,idx)
            
            % Load the specific loads for outlet or receiver (5,3,resp)
            if app.StoredData.Nodes(idx).NodeType == 3
                app.StoredData.Loads = app.StoredData.LoadsR;
            elseif app.StoredData.Nodes(idx).NodeType == 5
                app.StoredData.Loads = app.StoredData.LoadsL;
            end             

            % Load load labels and drop down items based on selection
            for idx = 1:length(app.StoredData.Loads)
               app.StoredData.LoadLabels(idx) = app.StoredData.Loads(idx).Name;
            end
            app.LoadTypeDropDown.Items = app.StoredData.LoadLabels;

        end
        
        function results = Populate_WireTypes(app)

            % Load wire materials from hardcoded save
            for idx=1:length(app.StoredData.Wiredata.Materials)
                app.StoredData.WireLabels(idx)=app.StoredData.Wiredata.Materials{idx};
            end
            
            % Update wire material drop down menus on available options
            app.WireMaterialDropDown_1.Items=app.StoredData.WireLabels;
            app.WireMaterialDropDown_2.Items=app.StoredData.WireLabels;
            app.WireMaterialDropDown_3.Items=app.StoredData.WireLabels;
            app.WireMaterialDropDown_4.Items=app.StoredData.WireLabels;

        end

        function results = ParamFieldTooltips(app)

            % Defines tooltips for all of the param fields
            [~,idx] = ismember(app.LoadTypeDropDown.Value,app.LoadTypeDropDown.Items);
            if ~isempty(idx)
                for idx2 = 1:size(app.StoredData.Loads(idx).Bounds,1)
                    if app.StoredData.Loads(idx).Bounds(idx2,2) > 1e4
                        tmpstr = sprintf('Expects value in [%.1f, 1e%d] %s.',app.StoredData.Loads(idx).Bounds(idx2,1),round(log10(app.StoredData.Loads(idx).Bounds(idx2,2))),app.StoredData.Loads(idx).Units{idx2});
                    else
                        tmpstr = sprintf('Expects value in [%.1f, %.1f] %s.',app.StoredData.Loads(idx).Bounds(idx2,1),app.StoredData.Loads(idx).Bounds(idx2,2),app.StoredData.Loads(idx).Units{idx2});
                    end
                    eval(['app.Param',num2str(idx2),'EditField.Tooltip = ''',tmpstr,''';']);
                end
            end

        end

        function results = verifyDom(app)
            
            % If EM value 1 is larger, swap
            if app.StoredData.EMdom(1) > app.StoredData.EMdom(2)
                tmp = app.StoredData.EMdom(1);
                app.StoredData.EMdom(1) = app.StoredData.EMdom(2);
                app.StoredData.EMdom(2) = tmp;

            % If EM values are equal, diversify by 1 each
            elseif app.StoredData.EMdom(1) == app.StoredData.EMdom(2)
                app.StoredData.EMdom(1) = app.StoredData.EMdom(1) - 1;
                app.StoredData.EMdom(2) = app.StoredData.EMdom(2) + 1;
            end

            % Repeat for Y (was X previously)
            if app.StoredData.EMdom(3) > app.StoredData.EMdom(4)
                tmp = app.StoredData.EMdom(3);
                app.StoredData.EMdom(3) = app.StoredData.EMdom(4);
                app.StoredData.EMdom(4) = tmp;
            elseif app.StoredData.EMdom(3) == app.StoredData.EMdom(4)
                app.StoredData.EMdom(3) = app.StoredData.EMdom(3) - 1;
                app.StoredData.EMdom(4) = app.StoredData.EMdom(4) + 1;
            end

            % Repeat for Z
            if app.StoredData.EMdom(5) > app.StoredData.EMdom(6)
                tmp = app.StoredData.EMdom(5);
                app.StoredData.EMdom(5) = app.StoredData.EMdom(6);
                app.StoredData.EMdom(6) = tmp;
            elseif app.StoredData.EMdom(5) == app.StoredData.EMdom(6)
                app.StoredData.EMdom(5) = app.StoredData.EMdom(5) - 1;
                app.StoredData.EMdom(6) = app.StoredData.EMdom(6) + 1;
            end

        end

        function results = CorrectParamBG(app)  %#ok<*MANU> 

            % Return all 12 background colors to white
            for allpar = 1:12
                eval(['app.Param',num2str(allpar),'EditField.BackgroundColor = [1 1 1];']);
            end

        end

        function results =  CheckRW(app,value)

            results = 1;
            if ~isempty(value)
                val = str2double(value);
                if isnan(val) || ~isnumeric(val) || ~isreal(val) || val <= 0 || val > app.StoredData.WRUL
                    results = 0;
                end
            end

        end

        function results = CorrectWireBGaV(app)

            app.WireRadiusEditField_1.BackgroundColor = [1 1 1];
            app.WireRadiusEditField_2.BackgroundColor = [1 1 1];
            app.WireRadiusEditField_3.BackgroundColor = [1 1 1];
            app.WireRadiusEditField_4.BackgroundColor = [1 1 1];

            if CheckRW(app,app.WireRadiusEditField_1.Value) == 0
                app.WireRadiusEditField_1.Value = '';
            end
            if CheckRW(app,app.WireRadiusEditField_2.Value) == 0
                app.WireRadiusEditField_2.Value = '';
            end
            if CheckRW(app,app.WireRadiusEditField_3.Value) == 0
                app.WireRadiusEditField_3.Value = '';
            end
            if CheckRW(app,app.WireRadiusEditField_4.Value) == 0
                app.WireRadiusEditField_4.Value = '';
            end

            res = PartialCheckRWandDC_FCW(app,5);
            if res == -1
                app.SeparationDistanceEditField.Value = '';
                app.SeparationDistanceEditField.BackgroundColor = [1 1 1];
            elseif res == 0
                app.SeparationDistanceEditField.BackgroundColor = [0.9 0.9 0];
            elseif res == 1
                app.SeparationDistanceEditField.BackgroundColor = [1 1 1];
            end

        end

        function results = CheckParamField(app,value,num,event)

            % Default is valid is unacceptable
            validval = false;

            % Identify load type and pull parameter bounds
            [~,whichLoad] = ismember(app.LoadTypeDropDown.Value,app.LoadTypeDropDown.Items);
            lowerbound = app.StoredData.Loads(whichLoad).Bounds(num,1);
            upperbound = app.StoredData.Loads(whichLoad).Bounds(num,2);

            % Check if value is empty, if so accept and reset
            if isempty(value)
                app.ErrorType.Text = 'Node Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Select the node type for each node.' newline newline 'Save the configuration once you have fully defined the node.' newline newline 'Once all node types are defined, proceed to the ''Wiring Definition'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];
                eval(['app.Param',num2str(num),'EditField.BackgroundColor = [1 1 1];']);
            else

                % If not empty, error check
                if isnan(str2double(value))

                    % Error, not a numeric value
                    app.ErrorType.Text = 'Error';
                    app.ErrorType.FontColor = 'r';
                    app.ErrorMsg.Text = ['Load parameter ',num2str(num),' (',reshape(char(app.StoredData.Loads(whichLoad).Parameters{num}),1,numel(char(app.StoredData.Loads(whichLoad).Parameters{num}))),') is expected to be a numeric value.'];
                    app.ErrorMsg.FontColor = 'r';
                    eval(['app.Param',num2str(num),'EditField.BackgroundColor = [1 0.75 0.75];']);
                elseif ~isreal(str2double(value))

                    % Error, not a real value
                    app.ErrorType.Text = 'Error';
                    app.ErrorType.FontColor = 'r';
                    app.ErrorMsg.Text = ['Load parameter ',num2str(num),' (',reshape(char(app.StoredData.Loads(whichLoad).Parameters{num}),1,numel(char(app.StoredData.Loads(whichLoad).Parameters{num}))),') is expected to be real-valued.'];
                    app.ErrorMsg.FontColor = 'r';
                    eval(['app.Param',num2str(num),'EditField.BackgroundColor = [1 0.75 0.75];']);
                else

                    % Last check is for within bounds
                    if (str2double(value) >= lowerbound) && (str2double(value) <= upperbound)
                        validval = true;
                    end

                    % If value passed all criteria, clean and keep
                    if validval
                        app.ErrorType.Text = 'Node Definition';
                        app.ErrorType.FontColor = [0 0.5 0];
                        app.ErrorMsg.Text = ['Select the node type for each node.' newline newline 'Save the configuration once you have fully defined the node.' newline newline 'Once all node types are defined, proceed to the ''Wiring Definition'' tab.'];
                        app.ErrorMsg.FontColor = [0 0.5 0];
                        eval(['app.Param',num2str(num),'EditField.BackgroundColor = [1 1 1];']);
                        value = num2str(str2double(value));
                        eval(['app.Param',num2str(num),'EditField.Value = value;']); % clean up number
                    else

                        % Do nothing but highlight and provide error message
                        app.ErrorType.Text = 'Error';
                        app.ErrorType.FontColor = 'r';
                        if strcmp(app.StoredData.Loads(whichLoad).Units{num},' ')
                            unitstr = '';
                        else
                            unitstr = [' ',reshape(char(app.StoredData.Loads(whichLoad).Units{num}),1,numel(char(app.StoredData.Loads(whichLoad).Units{num})))];
                        end
                        app.ErrorMsg.Text = ['Load parameter ',num2str(num),' (',reshape(char(app.StoredData.Loads(whichLoad).Parameters{num}),1,numel(char(app.StoredData.Loads(whichLoad).Parameters{num}))),') must have a value in the range [',num2str(lowerbound),', ',num2str(upperbound),']',unitstr,'.'];
                        app.ErrorMsg.FontColor = 'r';
                        eval(['app.Param',num2str(num),'EditField.BackgroundColor = [1 0.75 0.75];']);
                    end
                end
            end

        end

        function results = WireRadiusUpdate(app,event,num)

            % For warnings if any
            errno = -1;

            % Load corresponding wire radii entry
            value = eval(['app.WireRadiusEditField_',num,'.Value;']);

            % Check that input value is acceptable
            valislegit = true;

            % Not empty
            if ~isempty(value)
                val = str2double(value);

                % Not nan (autofilled to nan if wrong elsewhere)
                if isnan(val)
                    valislegit = false;
                    errno = 1;
                end
    
                % Check if not real
                if valislegit && ~isreal(val)
                    valislegit = false;
                    errno = 2;
                end
    
                % Check if out of bounds
                if valislegit && (val<=0 || val>app.StoredData.WRUL)
                    valislegit = false;
                    errno = 3;
                end

                % Check DC is correct before comparison
                validdc = true;
                tmpval = str2num(app.SeparationDistanceEditField.Value);
                if isempty(tmpval) || ~isreal(tmpval) || isnan(tmpval) || tmpval<=0 || tmpval>app.StoredData.DCUL
                    validdc = false;
                end

                % Compare with DC, 2*r < DC
                if valislegit && validdc 
                    res = PartialCheckRWandDC_FCW(app,5);
                    if res == -1
                        error('Missed check.');
                    elseif res == 1
                        % All is good, fix DC BG
                        app.SeparationDistanceEditField.BackgroundColor = [1 1 1];
                    else
                        app.SeparationDistanceEditField.BackgroundColor = [0.9 0.9 0];
                        errno = 4;
                    end
                end
    
                % If relation satisfied and Separation distance was red BG
                if valislegit

                    % Update material properties
                    SetWireProps(app,event,str2num(num))

                    % Check if separation distance is satisfied (redundant?)
                    CheckApplyBoxes(app)

                    % Pull wire index before changing list
                    [~,idx1] = ismember(app.WireListListBox_2.Value,app.WireListListBox_2.Items);

                    % Update the wire list (remove * if appropriate)
                    UpdateWireList(app)
        
                    % Reobtain wire index
                    app.WireListListBox_2.Value = app.WireListListBox_2.Items{idx1};

                    % Prep adjusted wire for special plotting
                    app.StoredData.lastEntity = 2;
                    o1=app.StoredData.Wires(idx1,1);
                    o2=app.StoredData.Wires(idx1,2);
                    app.StoredData.EntityList = [o1,o2];

                    % Plot wires
                    PlotWires(app)

                    % Remove special privilege and update troubleshooting
                    app.StoredData.lastEntity = 0;
                    app.ErrorType.Text = 'Wiring Definition';
                    app.ErrorType.FontColor = [0 0.5 0];
                    app.ErrorMsg.Text = ['Define the properties of each wire.' newline newline 'Select the number of conductors. Then, swap between the Wires and Conductor tabs or use the ''Apply to All'' buttons to set values.' newline newline 'Once all wires and conductors have been defined, proceed to the ''Run Model'' tab.'];
                    app.ErrorMsg.FontColor = [0 0.5 0];

                    % Make the BG white and correct the WR tracker
                    eval(['app.WireRadiusEditField_',num,'.BackgroundColor = [1 1 1];']);

                    % Enable since sigma must be acceptably defined
                    app.ApplytoAllConductorsButton.Enable = 'on';
                    
                else

                    % Value is illegitimate
                    % Value can remain, unsaved

                    % Store index before wire list potentially changes
                    [~,idx1] = ismember(app.WireListListBox_2.Value,app.WireListListBox_2.Items);

                    % Change value 
                    app.StoredData.WireProps(idx1).Radius(str2num(num)) = nan;

                    % Update apply buttons
                    CheckApplyBoxes(app)

                    % Update wires
                    UpdateWireList(app)
        
                    % Reobtain wire index
                    app.WireListListBox_2.Value = app.WireListListBox_2.Items{idx1};

                    % Plot adjusted wire with privilege then remove
                    app.StoredData.lastEntity = 2;
                    o1=app.StoredData.Wires(idx1,1);
                    o2=app.StoredData.Wires(idx1,2);
                    app.StoredData.EntityList = [o1,o2];
                    PlotWires(app)
                    app.StoredData.lastEntity = 0;

                    % Make BG red and update WR accordingly
                    eval(['app.WireRadiusEditField_',num,'.BackgroundColor = [1 0.75 0.75];']);

                    % Produce output depending on error encountered
                    if errno == 1
                        app.ErrorType.Text = 'Error';
                        app.ErrorType.FontColor = 'r';
                        app.ErrorMsg.Text = 'Wire radius must be a numeric value.';
                        app.ErrorMsg.FontColor = 'r';
                    elseif errno == 2
                        app.ErrorType.Text = 'Error';
                        app.ErrorType.FontColor = 'r';
                        app.ErrorMsg.Text = 'Wire radius must be real-valued.';
                        app.ErrorMsg.FontColor = 'r';
                    elseif errno == 3
                        app.ErrorType.Text = 'Error';
                        app.ErrorType.FontColor = 'r';
                        app.ErrorMsg.Text = ['Wire radius must be in the range (0, ',num2str(app.StoredData.WRUL),'] mm.'];
                        app.ErrorMsg.FontColor = 'r';
                    elseif errno == 4
                        error('Should not be reachable.')
                    end

                    % Turn off buttons
                    app.ApplytoAllConductorsButton.Enable = 'off';
                    app.ApplytoAllWiresButton.Enable = 'off';

                    validdc = true;
        
                    % Pull DC
                    tmpval = str2num(app.SeparationDistanceEditField.Value);
    
                    % Check DC is acceptable
                    if isempty(tmpval) || isnan(tmpval) || ~isreal(tmpval) || tmpval<=0 || tmpval>app.StoredData.DCUL
                        validdc = false;
                    end
    
                    % If red but valid, correct DC
                    if validdc
                        value = eval(['app.WireRadiusEditField_',num,'.Value;']);
                        eval(['app.WireRadiusEditField_',num,'.Value = '''';']);
                        res = PartialCheckRWandDC_FCW(app,5);
                        eval(['app.WireRadiusEditField_',num,'.Value = value;']);
                        if res == -1
                            error('Missed check.');
                        elseif res == 1
                            % All is good, fix DC BG
                            app.SeparationDistanceEditField.BackgroundColor = [1 1 1];
                        else
                            app.SeparationDistanceEditField.BackgroundColor = [0.9 0.9 0];
                            errno = 4;
                        end
                    end
                end
            else

                % Store index before changing list
                [~,idx1] = ismember(app.WireListListBox_2.Value,app.WireListListBox_2.Items);
                app.StoredData.WireProps(idx1).Radius(str2num(num)) = nan;

                % Is empty, unred field and define as acceptable
                validdc = true;

                % Pull DC
                tmpval = str2num(app.SeparationDistanceEditField.Value);

                % Check DC is acceptable
                if isempty(tmpval) || isnan(tmpval) || ~isreal(tmpval) || tmpval<=0 || tmpval>app.StoredData.DCUL
                    validdc = false;
                end

                % If red but valid, correct DC
                if validdc
                    res = PartialCheckRWandDC_FCW(app,5);
                    if res == -1
                        error('Missed check.');
                    elseif res == 1
                        % All is good, fix DC BG
                        app.SeparationDistanceEditField.BackgroundColor = [1 1 1];
                    else
                        app.SeparationDistanceEditField.BackgroundColor = [0.9 0.9 0];
                        errno = 4;
                    end
                end

                % Check apply functionality
                CheckApplyBoxes(app)

                % Update wire lists
                UpdateWireList(app) 
    
                % Reload index
                app.WireListListBox_2.Value = app.WireListListBox_2.Items{idx1};

                % Plot with privilege and remove privilege after
                app.StoredData.lastEntity = 2;
                o1=app.StoredData.Wires(idx1,1);
                o2=app.StoredData.Wires(idx1,2);
                app.StoredData.EntityList = [o1,o2];
                PlotWires(app)
                app.StoredData.lastEntity = 0;

                % No errors with an empty entry
                app.ErrorType.Text = 'Wiring Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Define the properties of each wire.' newline newline 'Select the number of conductors. Then, swap between the Wires and Conductor tabs or use the ''Apply to All'' buttons to set values.' newline newline 'Once all wires and conductors have been defined, proceed to the ''Run Model'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];

                % Change BG to white and correct WR
                eval(['app.WireRadiusEditField_',num,'.BackgroundColor = [1 1 1];']);
            end
            if errno == 4
                app.ErrorType.Text = 'Error';
                app.ErrorType.FontColor = 'r';
                app.ErrorMsg.FontColor = 'r';
                if abs(res - 0.1) < 1e-6
                    app.ErrorMsg.Text = ['The entered wire radius violates the physical geometry.' newline newline 'The separation distance must be greater than the individual wire radii and the sum of the wire radii.'];
                elseif abs(res - 0.21) < 1e-6
                    app.ErrorMsg.Text = ['The entered wire radius violates the physical geometry.' newline newline 'The separation distance must be greater than all individual wire radii.' newline newline 'The ground wire''s radius equals or exceeds the separation distance.'];
                elseif abs(res - 0.22) < 1e-6
                    app.ErrorMsg.Text = ['The entered wire radius violates the physical geometry.' newline newline 'The separation distance must be greater than all individual wire radii.' newline newline 'The first phase wire''s radius equals or exceeds the separation distance.'];
                elseif abs(res - 0.23) < 1e-6
                    app.ErrorMsg.Text = ['The entered wire radius violates the physical geometry.' newline newline 'The separation distance must be greater than all individual wire radii.' newline newline 'The second phase wire''s radius equals or exceeds the separation distance.'];
                elseif abs(res - 0.24) < 1e-6
                    app.ErrorMsg.Text = ['The entered wire radius violates the physical geometry.' newline newline 'The separation distance must be greater than all individual wire radii.' newline newline 'The third phase wire''s radius equals or exceeds the separation distance.'];
                elseif abs(res - 0.3) < 1e-6
                    app.ErrorMsg.Text = ['The entered wire radius violates the physical geometry.' newline newline 'The separation distance must be greater than the sum of neighboring wire radii.' newline newline 'The combined ground and first phase wire''s radii equals or exceeds the separation distance.'];
                elseif abs(res - 0.4) < 1e-6
                    app.ErrorMsg.Text = ['The entered wire radius violates the physical geometry.' newline newline 'The separation distance must be greater than the sum of neighboring wire radii.' newline newline 'The combined first and second phase wire''s radii equals or exceeds the separation distance.'];
                elseif abs(res - 0.5) < 1e-6
                    app.ErrorMsg.Text = ['The entered wire radius violates the physical geometry.' newline newline 'The separation distance must be greater than the sum of neighboring wire radii.' newline newline 'The combined second and third phase wire''s radii equals or exceeds the separation distance.'];
                elseif abs(res - 0.6) < 1e-6
                    app.ErrorMsg.Text = ['The entered wire radius violates the physical geometry.' newline newline 'The separation distance must be greater than the sum of neighboring wire radii.' newline newline 'The combined ground and third phase wire''s radii equals or exceeds the separation distance.'];
                elseif abs(res - 0.7) < 1e-6
                    app.ErrorMsg.Text = ['The entered wire radius violates the physical geometry.' newline newline 'The diagonal distance implied by the separation distance must be greater than the sum of opposing wire radii.' newline newline 'The combined ground and second phase wire''s radii equals/exceeds the diagonal.'];
                elseif abs(res - 0.8) < 1e-6
                    app.ErrorMsg.Text = ['The entered wire radius violates the physical geometry.' newline newline 'The diagonal distance implied by the separation distance must be greater than the sum of opposing wire radii.' newline newline 'The combined first and third phase wire''s radii equals/exceeds the diagonal.'];
                end
            end

        end
        
        function results = UpdateStoredLoads(app,event) %#ok<*INUSD> 

            % Pull indices of node, node type, and load type for current selection
            [~, SelectedNode] = ismember(app.AvailableNodesListBox.Value, app.AvailableNodesListBox.Items);
            [~, idx] = ismember(app.LoadTypeDropDown.Value, app.LoadTypeDropDown.Items);
            [~, idx2] = ismember(app.NodeTypeDropDown.Value,app.NodeTypeDropDown.Items);

            % If the node type is not outlet and not receiver
            if idx2 ~= 5 && idx2 ~= 3
                % Store -1 to indicate no load
                app.StoredData.Nodes(SelectedNode).LoadTypenum=-1;  
            else
                % Store the load type ID to indicate load type
                app.StoredData.Nodes(SelectedNode).LoadTypenum=idx;  
            end

        end
                
        function results = LoadLoadTypes2(app,event)
            
            % Pull index of node
            [~, idx2] = ismember(app.AvailableNodesListBox.Value, app.AvailableNodesListBox.Items);
            
            % Index of load type
            [~, idx] = ismember(app.LoadTypeDropDown.Value, app.LoadTypeDropDown.Items);
            
            % Iterate through all 12 (9) params and turn them + labels off
            for xdx=1:12
                eval(['app.Param',num2str(xdx),'EditField.Visible="off";']); %#ok<*EVLDOT> 
                eval(['app.Param',num2str(xdx),'EditFieldLabel.Visible="off";']);
                if xdx <= 9
                    eval(['app.PL',num2str(xdx),'Label.Visible = "off";']);
                end
            end

            % Also turn off custom load options
            app.LoadButton_2.Visible = 'off';
            app.FileLabel_2.Visible = 'off';
            app.VariableDropDown.Visible = 'off';
            app.VariableDropDownLabel.Visible = 'off';

            if ~strcmp(app.LoadTypeDropDown.Value,'Custom')
                % Iterate through and turn on labels + fields depending on selected load
                for xdx=1:length(app.StoredData.Loads(idx).Parameters)
                    if xdx<=length(app.StoredData.Loads(idx).Parameters)
                        eval(['app.Param',num2str(xdx),'EditField.Visible="on";']);
                        eval(['app.Param',num2str(xdx),'EditFieldLabel.Visible="on";']);
                        if xdx <= 9
                            eval(['app.PL',num2str(xdx),'Label.Visible = "on";']);
                            eval(['app.PL',num2str(xdx),'Label.Text = ''',app.StoredData.Loads(idx).Units{xdx},''';']);
                        end
    
                        % (Like above if) add corresponding labels
                        txt=app.StoredData.Loads(idx).Parameters{xdx};
                        eee=join(strtrim(['app.Param',strtrim(num2str(xdx)),'EditFieldLabel.Text=''',strtrim(txt),''';']),'');
                        eval(eee);
    
                        % Fill in fields with saved values, if any
                        try
                            if idx == app.StoredData.Nodes(idx2).LoadTypenum
                                try
                                    if isnan(app.StoredData.Nodes(idx2).LoadParam(xdx)) 
                                        eee=join(strtrim(['app.Param',strtrim(num2str(xdx)),'EditField.Value='''';']),'');
                                        eval(eee);
                                    else
                                        eee=join(strtrim(['app.Param',strtrim(num2str(xdx)),'EditField.Value=''',num2str(app.StoredData.Nodes(idx2).LoadParam(xdx)),''';']),'');
                                        eval(eee);
                                    end
                                catch
                                    eee=join(strtrim(['app.Param',strtrim(num2str(xdx)),'EditField.Value='''';']),'');
                                    eval(eee);
                                end
                            end
                        end
                    end
                end
    
                % Add tooltips to all parameter fields
                try
                    ParamFieldTooltips(app)
                end
            else
                app.LoadButton_2.Visible = 'on';
                app.FileLabel_2.Visible = 'on';
                app.VariableDropDown.Visible = 'on';
                app.VariableDropDownLabel.Visible = 'on';

                try
                    app.FileLabel_2.Text = ['File:  ',app.StoredData.Nodes(idx2).Filename];
                    app.FileLabel_2.Tooltip = app.StoredData.Nodes(idx2).Filename;
                    app.VariableDropDown.Items = app.StoredData.Nodes(idx2).VarList;
                    app.VariableDropDown.Value = app.StoredData.Nodes(idx2).VarSelected;                
                end
            end

        end
        
        function results = LoadLoadTypes(app,event)
            
            % Pull index of node
            [~, idx2] = ismember(app.AvailableNodesListBox.Value, app.AvailableNodesListBox.Items);
            
            % Attempt to load data if anything saved
            
            try
                app.LoadTypeDropDown.Value=app.LoadTypeDropDown.Items{app.StoredData.Nodes(idx2).LoadTypenum}; 

                % If nothing saved, default to first option or stay with current selection
            catch
                if isempty(app.LoadTypeDropDown.Value)
                    app.LoadTypeDropDown.Value=app.LoadTypeDropDown.Items{1};
                end
            end

            % Index of load type
            [~, idx] = ismember(app.LoadTypeDropDown.Value, app.LoadTypeDropDown.Items);
            
            % Iterate through all 12 (9) params and turn them + labels off
            for xdx=1:12
                eval(['app.Param',num2str(xdx),'EditField.Visible="off";']); %#ok<*EVLDOT> 
                eval(['app.Param',num2str(xdx),'EditFieldLabel.Visible="off";']);
                if xdx <= 9
                    eval(['app.PL',num2str(xdx),'Label.Visible = "off";']);
                end
            end

            % Turn off for custom
            app.LoadButton_2.Visible = 'off';
            app.FileLabel_2.Visible = 'off';
            app.VariableDropDown.Visible = 'off';
            app.VariableDropDownLabel.Visible = 'off';

            if ~strcmp(app.LoadTypeDropDown.Value,'Custom')
                % Iterate through and turn on labels + fields depending on selected load
                for xdx=1:length(app.StoredData.Loads(idx).Parameters)
                    if xdx<=length(app.StoredData.Loads(idx).Parameters)
                        eval(['app.Param',num2str(xdx),'EditField.Visible="on";']);
                        eval(['app.Param',num2str(xdx),'EditFieldLabel.Visible="on";']);
                        if xdx <= 9
                            eval(['app.PL',num2str(xdx),'Label.Visible = "on";']);
                            eval(['app.PL',num2str(xdx),'Label.Text = ''',app.StoredData.Loads(idx).Units{xdx},''';']);
                        end
    
                        % (Like above if) add corresponding labels
                        txt=app.StoredData.Loads(idx).Parameters{xdx};
                        eee=join(strtrim(['app.Param',strtrim(num2str(xdx)),'EditFieldLabel.Text=''',strtrim(txt),''';']),'');
                        eval(eee);
    
                        % Fill in fields with saved values, if any
                        try
                            if isnan(app.StoredData.Nodes(idx2).LoadParam(xdx)) 
                                eee=join(strtrim(['app.Param',strtrim(num2str(xdx)),'EditField.Value='''';']),'');
                                eval(eee);
                            else
                                eee=join(strtrim(['app.Param',strtrim(num2str(xdx)),'EditField.Value=''',num2str(app.StoredData.Nodes(idx2).LoadParam(xdx)),''';']),'');
                                eval(eee);
                            end
                        catch
                            eee=join(strtrim(['app.Param',strtrim(num2str(xdx)),'EditField.Value='''';']),'');
                            eval(eee);
                        end
                    end
                end
    
                % Add tooltips to all parameter fields
                try
                    ParamFieldTooltips(app)
                end
            else
                app.LoadButton_2.Visible = 'on';
                app.FileLabel_2.Visible = 'on';
                app.VariableDropDown.Visible = 'on';
                app.VariableDropDownLabel.Visible = 'on';

                try
                    app.FileLabel_2.Text = ['File:  ',app.StoredData.Nodes(idx2).Filename];
                    app.FileLabel_2.Tooltip = app.StoredData.Nodes(idx2).Filename;
                    app.VariableDropDown.Items = app.StoredData.Nodes(idx2).VarList;
                    app.VariableDropDown.Value = app.StoredData.Nodes(idx2).VarSelected;                
                end
            end

        end
        
        function results = PlotModelResults(app)

            try
                delete(app.UIAxes3.Colorbar);
            end
            clc

            % Hold off plot to overwrite
            hold(app.UIAxes3,'off')

            % Clean plot
            app.StoredData.lastEntity = 0;
            UpdatePlotList(app);

            % Load corresponding nodal data
            Nodes = app.StoredData.results.Nodes;
            Wires = app.StoredData.results.WireID;

            % Iterate through wires
            XT = [];
            YT = [];
            ZT = [];
            CT = [];
            for idx = 1:size(Wires,1)

                % Load the corresponding nodal data
                Xv = [Nodes(Wires(idx,1),1), Nodes(Wires(idx,2),1)];
                Yv = [Nodes(Wires(idx,1),2), Nodes(Wires(idx,2),2)];
                Zv = [Nodes(Wires(idx,1),3), Nodes(Wires(idx,2),3)];

                % Turn on plot
                hold(app.UIAxes3,'on')

                % Pull wire res
                res = app.StoredData.results.WireRes;

                % Scale slider
                freq = 10^(app.FrequencySlider.Value);

                % Pull length of wire
                %len2 = norm([Xv(2) Yv(2) Zv(2)] - [Xv(1) Yv(1) Zv(1)]);
                %len = res*(size(app.StoredData.results.VI{1,idx},1)-1);

                % Number of points
                npts = size(app.StoredData.results.VI{1,idx},1);

                % Vector in wire direction of length res
                %uv = res*([Xv(2) Yv(2) Zv(2)] - [Xv(1) Yv(1) Zv(1)])/len;

                % Define points for coloring
                Xvi = linspace(Xv(1),Xv(2),npts);
                Yvi = linspace(Yv(1),Yv(2),npts);
                Zvi = linspace(Zv(1),Zv(2),npts);

                % Pull nphase and which phase/VI to plot
                nphase = size(app.StoredData.results.VI{1,1},2)/2;
                COL = (app.StoredData.plottype-1)*nphase+app.StoredData.plotfcn;
                
                % Find the closest frequency
                [~,ix2] = min(abs(app.StoredData.results.Freq-freq));

                % If ground chosen, else pull values
                if app.StoredData.plotfcn == 4
                    ci = zeros(1,npts);
                else
                    % Load the data to use for color
                    ci = abs(squeeze(app.StoredData.results.VI{ix2,idx}(:,COL))).';
                end

                % Store all in one for shared colorbar
                XT = [XT Xvi nan];
                YT = [YT Yvi nan];
                ZT = [ZT Zvi nan];
                CT = [CT ci nan];

            end

            % Determine scale
            scale = 0; % linear
            if app.ScalingButtonGroup.SelectedObject == app.LogButton_2
                scale = 1; % log
                CT = log10(CT);
                CT(isinf(CT)) = -16;
            end

            % Plot colors along wire
            CT = CT'; % transpose to avoid internal MATLAB assumption 1x3=rgb
            patch(app.UIAxes3, XT, YT, ZT, CT, 'edgecolor', 'interp','LineWidth',app.LineWidthEditField.Value);
            
            % Add colorbar and use jet colormap and add title
            hcb = colorbar(app.UIAxes3);
  
            % Set label
            if app.OutputTypeButtonGroup.SelectedObject == app.VoltageButton
                if scale
                    hcb.Label.String = 'log V (V)';
                else
                    hcb.Label.String = 'V (V)';
                end
            else
                if scale
                    hcb.Label.String = 'log I (A)';
                else
                    hcb.Label.String = 'I (A)';
                end
            end

            colormap(app.UIAxes3,jet);

            freq2 = app.StoredData.results.Freq(ix2);
            if freq2 >= 1e6
                title(app.UIAxes3,['Frequency = ',sprintf('%.3f',freq2/1e6),' MHz']);
            elseif freq2 >= 1e3
                title(app.UIAxes3,['Frequency = ',sprintf('%.3f',freq2/1e3),' kHz']);
            else
                title(app.UIAxes3,['Frequency = ',sprintf('%.3f',freq2),' Hz']);
            end

        end

        function results = SetWireProps(app,event,num)

            % Pull current wire index and material index of num-th wire
            [~, idx] = ismember(app.WireListListBox_2.Value, app.WireListListBox_2.Items);
            [~, idx2] = eval(['ismember(app.WireMaterialDropDown_',num2str(num),'.Value,app.WireMaterialDropDown_',num2str(num),'.Items);']);
            
            % Save value if field defined and value present, else nan
            try 
                app.StoredData.WireProps(idx).Radius(num) = str2num(eval(['app.WireRadiusEditField_',num2str(num),'.Value;']));
            catch
                app.StoredData.WireProps(idx).Radius(num) = nan; 
            end

            % Save material index if not empty and field defined
            try
                app.StoredData.WireProps(idx).Matl(num)=idx2;
            end

            % If not the default copper, enable sigma for editing
            if num == 1
                if idx2 == 2
                    app.sigmaEditField.Enable = 'on';
    
                    % If previous sigma is acceptable, display
                    if ~isnan(app.StoredData.SigPrev(idx,num))
                        app.sigmaEditField.Value = app.StoredData.SigPrev(idx,num);
                    end
                else
                    % Set sigma values to default and disable field
                    app.sigmaEditField.Enable = 'off';
                    app.sigmaEditField.Value = 5.85;
                end
            else
                if idx2 == 2
                    eval(['app.sigmaEditField_',num2str(num),'.Enable = ''on'';']);
    
                    % If previous sigma is acceptable, display
                    if ~isnan(app.StoredData.SigPrev(idx,num))
                        eval(['app.sigmaEditField_',num2str(num),'.Value = app.StoredData.SigPrev(idx,num);']);
                    end
                else
                    % Set sigma values to default and disable field
                    eval(['app.sigmaEditField_',num2str(num),'.Enable = ''off'';']);
                    eval(['app.sigmaEditField_',num2str(num),'.Value = 5.85;']);
                end
            end

            % Troubleshooting window
            app.ErrorType.Text = 'Wiring Definition';
            app.ErrorType.FontColor = [0 0.5 0];
            app.ErrorMsg.Text = ['Define the properties of each wire.' newline newline 'Select the number of conductors. Then, swap between the Wires and Conductor tabs or use the ''Apply to All'' buttons to set values.' newline newline 'Once all wires and conductors have been defined, proceed to the ''Run Model'' tab.'];
            app.ErrorMsg.FontColor = [0 0.5 0];

            app.SimulationDetailsPanel.Enable = 'off';
            app.SimulateButton.Enable = 'off';
            app.VisualizeResultsButton.Enable = 'off';
            app.EMFieldsButton.Enable = 'off';
            try
                app.StoredData = rmfield(app.StoredData,'EMTabAxes');
            end

        end

        function results = PlotTPResults(app)

            app.Panel_4.Enable = 'off';
            app.LeftPanel.Enable = 'off';
            drawnow;
            
            % Pull number of conductors
            ncond = str2num(app.NumberofConductorsDropDown.Value);

            % Pull ABCD values and frequencies
            ABCD = app.StoredData.results.ABCD;
            ABCD2 = ABCD;
            try
                lastwarn('','');
                for abcdind = 1:size(ABCD,3)
                    ABCD2(:,:,abcdind) = inv(ABCD(:,:,abcdind));
                    [~,wb] = lastwarn();
                    if ~isempty(wb)
                        clc
                        error('Singular matrix.')
                    end
                end
                ABCD = ABCD2;
            end
            
            freq = app.StoredData.results.Freq;
            WC = 'ABCD';

            % Convert based on selection
            if strcmp(app.TransmissionParametersDropDown.Value,'S')
                ABCD = abcd2s(ABCD,app.Z_0EditField.Value);
                WC = 'S';
            elseif strcmp(app.TransmissionParametersDropDown.Value,'Y')
                ABCD = abcd2y(ABCD);
                WC = 'Y';
            elseif strcmp(app.TransmissionParametersDropDown.Value,'Z')
                ABCD = abcd2z(ABCD);
                WC = 'Z';
            end

            % Cycle through plotting based on selection
            if ncond == 4

                % Take subset based on DropDown
                [~,WSS] = ismember(app.DropDown.Value,app.DropDown.Items);
                if WSS == 1
                    ABCD = ABCD(1:3,1:3,:);
                elseif WSS == 2
                    ABCD = ABCD(1:3,4:6,:);
                elseif WSS == 3
                    ABCD = ABCD(4:6,1:3,:);
                elseif WSS == 4
                    ABCD = ABCD(4:6,4:6,:);
                else
                    error('Should be unreachable (out of bounds ABCD index).')
                end
               
                % Determine number of plots to be generated
                nplots = 0;
                PW = [];
                for ind = 1:9
                    if eval(['app.CheckBox_',num2str(ind),'.Value'])
                        nplots = nplots + 1;
                        PW = [PW ind];
                    end
                end

                % Set size of overall plot and plot
                ax1 = cell(9,1);
                ax2 = cell(9,1);
                subplot(1,1,1,'Parent',app.NetworkDiagramPanel_2);
                if nplots == 0
                    ax1{1} = subplot(1,1,1,'Parent',app.NetworkDiagramPanel_2);
                    plot(ax1{1},[0 1 0 1],[0 1 1 0],'.k')
                    text(ax1{1},0.5,0.5,'Check a Box to Display Transmission Parameters','FontSize',12,'HorizontalAlignment','center')
                    set(ax1{1},'XTick',[],'YTick',[])
                elseif nplots <= 3
                    for ind2 = 1:nplots
                        ax1{ind2} = subplot(2,nplots,ind2,'Parent',app.NetworkDiagramPanel_2);
                        PlotTPMag(app,freq,ABCD,PW(ind2),WC,ax1,ind2,nplots);
                        ax2{ind2} = subplot(2,nplots,ind2+nplots,'Parent',app.NetworkDiagramPanel_2);
                        PlotTPPhase(app,freq,ABCD,PW(ind2),WC,ax2,ind2,nplots);
                        drawnow;
                    end
                elseif nplots <= 6
                    for ind2 = 1:3
                        ax1{ind2} = subplot(4,3,ind2,'Parent',app.NetworkDiagramPanel_2);
                        PlotTPMag(app,freq,ABCD,PW(ind2),WC,ax1,ind2,nplots);
                        ax2{ind2} = subplot(4,3,ind2+3,'Parent',app.NetworkDiagramPanel_2);
                        PlotTPPhase(app,freq,ABCD,PW(ind2),WC,ax2,ind2,nplots);
                        drawnow;
                    end
                    for ind2 = 4:nplots
                        ax1{ind2} = subplot(4,3,ind2+3,'Parent',app.NetworkDiagramPanel_2);
                        PlotTPMag(app,freq,ABCD,PW(ind2),WC,ax1,ind2,nplots);
                        ax2{ind2} = subplot(4,3,ind2+6,'Parent',app.NetworkDiagramPanel_2);
                        PlotTPPhase(app,freq,ABCD,PW(ind2),WC,ax2,ind2,nplots);
                        drawnow;
                    end
                elseif nplots <= 9
                    nplots2 = 9;
                    for ind2 = 1:3
                        ax1{ind2} = subplot(6,3,ind2,'Parent',app.NetworkDiagramPanel_2);
                        PlotTPMag(app,freq,ABCD,PW(ind2),WC,ax1,ind2,nplots2);
                        ax2{ind2} = subplot(6,3,ind2+3,'Parent',app.NetworkDiagramPanel_2);
                        PlotTPPhase(app,freq,ABCD,PW(ind2),WC,ax2,ind2,nplots2);
                        drawnow;
                    end
                    for ind2 = 4:6
                        ax1{ind2} = subplot(6,3,ind2+3,'Parent',app.NetworkDiagramPanel_2);
                        PlotTPMag(app,freq,ABCD,PW(ind2),WC,ax1,ind2,nplots2);
                        ax2{ind2} = subplot(6,3,ind2+6,'Parent',app.NetworkDiagramPanel_2);
                        PlotTPPhase(app,freq,ABCD,PW(ind2),WC,ax2,ind2,nplots2);
                        drawnow;
                    end
                    for ind2 = 7:nplots
                        ax1{ind2} = subplot(6,3,ind2+6,'Parent',app.NetworkDiagramPanel_2);
                        PlotTPMag(app,freq,ABCD,PW(ind2),WC,ax1,ind2,nplots2);
                        ax2{ind2} = subplot(6,3,ind2+9,'Parent',app.NetworkDiagramPanel_2);
                        PlotTPPhase(app,freq,ABCD,PW(ind2),WC,ax2,ind2,nplots2);
                        drawnow;
                    end
                    for ind2 = nplots+1:9
                        ax1{ind2} = subplot(6,3,ind2+6,'Parent',app.NetworkDiagramPanel_2);
                        PlotTPMag(app,freq,ABCD,-1,WC,ax1,ind2,nplots2);
                    end
                end

            elseif ncond == 2

                % Determine number of plots to be generated
                nplots = 0;
                PW = [];
                for ind = [13 11 12 10]
                    if eval(['app.CheckBox_',num2str(ind),'.Value'])
                        nplots = nplots + 1;
                        PW = [PW ind];
                    end
                end

                % Set size of overall plot and plot
                ax1 = cell(4,1);
                ax2 = cell(4,1);
                subplot(1,1,1,'Parent',app.NetworkDiagramPanel_2);
                if nplots == 0
                    ax1{1} = subplot(1,1,1,'Parent',app.NetworkDiagramPanel_2);
                    plot(ax1{1},[0 1 0 1],[0 1 1 0],'.k')
                    text(ax1{1},0.5,0.5,'Check a Box to Display Transmission Parameters','FontSize',12,'HorizontalAlignment','center')
                    set(ax1{1},'XTick',[],'YTick',[])
                elseif nplots <= 2
                    for ind2 = 1:nplots
                        ax1{ind2} = subplot(2,nplots,ind2,'Parent',app.NetworkDiagramPanel_2);
                        PlotTPMag(app,freq,ABCD,PW(ind2),WC,ax1,ind2,nplots);
                        ax2{ind2} = subplot(2,nplots,ind2+nplots,'Parent',app.NetworkDiagramPanel_2);
                        PlotTPPhase(app,freq,ABCD,PW(ind2),WC,ax2,ind2,nplots);
                        drawnow;
                    end
                elseif nplots <= 4
                    for ind2 = 1:2
                        ax1{ind2} = subplot(4,2,ind2,'Parent',app.NetworkDiagramPanel_2);
                        PlotTPMag(app,freq,ABCD,PW(ind2),WC,ax1,ind2,nplots);
                        ax2{ind2} = subplot(4,2,ind2+2,'Parent',app.NetworkDiagramPanel_2);
                        PlotTPPhase(app,freq,ABCD,PW(ind2),WC,ax2,ind2,nplots);
                        drawnow;
                    end
                    for ind2 = 3:nplots
                        ax1{ind2} = subplot(4,2,ind2+2,'Parent',app.NetworkDiagramPanel_2);
                        PlotTPMag(app,freq,ABCD,PW(ind2),WC,ax1,ind2,nplots);
                        ax2{ind2} = subplot(4,2,ind2+4,'Parent',app.NetworkDiagramPanel_2);
                        PlotTPPhase(app,freq,ABCD,PW(ind2),WC,ax2,ind2,nplots);
                        drawnow;
                    end
                end
            end
            if nplots > 0
                l1 = line(ax1{1},nan, nan, 'Linestyle', 'none', 'Marker', 'none', 'Color', 'none');
                l2 = line(ax1{1},nan, nan, 'Linestyle', 'none', 'Marker', 'none', 'Color', 'none');
                leg = legend(ax1{1},[l1 l2],'\color{red}Magnitude','\color{blue}Phase');
                leg.FontSize = 12;
                leg.Color = [0.94 0.94 0.94];
                leg.EdgeColor = [0.94 0.94 0.94];
                leg.Units = 'pixels';
                leg.Position(1:2) = [-35,160];
            end

            app.LeftPanel.Enable = 'on';
            app.Panel_4.Enable = 'on';

        end

        function results = PlotTPMag(app,freq,ABCD,PW,WC,ax,ind,nplots)

            if PW == -1
                semilogx(ax{ind},[0.95*min(freq),1.05*max(freq)],[0 0],'-k','LineWidth',0.01)
                xlim(ax{ind},[0.95*min(freq),1.05*max(freq)])
                ax{ind}.Position(4) = 0;
                set(ax{ind},'YTick',[])
                xlabel(ax{ind},'f (Hz)')
                return
            end

            % Convert ind to element
            if PW == 1
                element = [1,1];
            elseif PW == 2
                element = [1,2];
            elseif PW == 3
                element = [1,3];
            elseif PW == 4
                element = [2,1];
            elseif PW == 5
                element = [2,2];
            elseif PW == 6
                element = [2,3];
            elseif PW == 7
                element = [3,1];
            elseif PW == 8
                element = [3,2];
            elseif PW == 9
                element = [3,3];
            elseif PW == 10
                element = [2,2];
            elseif PW == 11
                element = [1,2];
            elseif PW == 12
                element = [2,1];
            elseif PW == 13
                element = [1,1];
            end

            % Plot in loglog
            ncond = str2num(app.NumberofConductorsDropDown.Value);
            mag = squeeze(abs(ABCD(element(1),element(2),:)));
            if (min(mag) == 0) && (max(mag) == 0)
                semilogx(ax{ind},freq,mag,'LineWidth',2,'Color','r')
            else
                loglog(ax{ind},freq,mag,'LineWidth',2,'Color','r');
            end
            xlim(ax{ind},[0.95*min(freq),1.05*max(freq)])
            if (max(mag) == 0) && (min(mag) == 0)
                ylim(ax{ind},[-0.1 0.1])
            else
                ylim(ax{ind},[0.95*min(mag),1.05*max(mag)])
            end
            set(ax{ind},'XTickLabels',{})
            if ncond == 4
                if any(ind==[1 4 7])
                    ylabel(ax{ind},'|\cdot|   ','rotation',0)
                end
            elseif ncond == 2
                if any(ind==[1 3])
                    ylabel(ax{ind},'|\cdot|   ','rotation',0)
                end
            end

        end

        function results = PlotTPPhase(app,freq,ABCD,PW,WC,ax,ind,nplots)

            % Convert ind to element
            if PW == 1
                element = [1,1];
            elseif PW == 2
                element = [1,2];
            elseif PW == 3
                element = [1,3];
            elseif PW == 4
                element = [2,1];
            elseif PW == 5
                element = [2,2];
            elseif PW == 6
                element = [2,3];
            elseif PW == 7
                element = [3,1];
            elseif PW == 8
                element = [3,2];
            elseif PW == 9
                element = [3,3];
            elseif PW == 10
                element = [2,2];
            elseif PW == 11
                element = [1,2];
            elseif PW == 12
                element = [2,1];
            elseif PW == 13
                element = [1,1];
            end

            % Plot in loglog
            phase = squeeze(angle(ABCD(element(1),element(2),:)));
            semilogx(ax{ind},freq,phase,'LineWidth',2,'Color','b');
            xlim(ax{ind},[0.95*min(freq),1.05*max(freq)])
            ylim(ax{ind},[-pi,pi])
            set(ax{ind},'YTick',[-pi,0,pi])
            set(ax{ind},'YTickLabels',{'-\pi','0','\pi'})
            if ind+3 > nplots
                xlabel(ax{ind},'f (Hz)')
            else 
                set(ax{ind},'XTick',[])
            end
            ncond = str2num(app.NumberofConductorsDropDown.Value);
            if ncond == 4
                [~,WSS] = ismember(app.DropDown.Value,app.DropDown.Items);
                if any(ind==[1 4 7])
                    ylabel(ax{ind},'\angle\cdot   ','rotation',0)
                end
                if WSS == 1
                    % Do nothing
                elseif WSS == 2
                    element(2) = element(2)+3;
                elseif WSS == 3
                    element(1) = element(1)+3;
                elseif WSS == 4
                    element = element+3;
                else
                    eror('Unexpected value of WSS.');
                end
            elseif ncond == 2
                if any(ind==[1 3])
                    ylabel(ax{ind},'\angle\cdot   ','rotation',0)
                end
            end
            
            title(ax{ind},[WC,'_{',num2str(element(1)),num2str(element(2)),'}'])

        end

        function results = CheckApplyBoxes(app)

            % Obtain number of conductors
            rr = app.NumberofConductorsDropDown.Value;
            rr = str2num(rr);

            % Determine current tab
            if strcmp(app.TabGroup3.SelectedTab.Title,'Ground')
                CurrTab = 1;
            else 
                % Use last character {1,2,3}+1
                CurrTab = str2num(app.TabGroup3.SelectedTab.Title(end))+1;
            end

            % Check legality of wire radii and separation distance and sigma
            flag = 0; % Checks all conductors for a given wire are properly defined

            % Load separation distance
            newv2 = app.SeparationDistanceEditField.Value;
            nuv2 = str2num(newv2);

            % Iterate through to check each conductor tab
            for idx = 1:rr
                flag2 = 1; % Checks if currtab is properly defined

                % Load the idx-th wire radius and sigma
                newv = eval(['app.WireRadiusEditField_',num2str(idx),'.Value;']);
                nuv = str2num(newv);
                if idx == 1
                    sig = app.sigmaEditField.Value;
                else
                    sig = eval(['app.sigmaEditField_',num2str(idx),'.Value;']);
                end

                % Check if any are undefined, or unreal, or outside of bounds
                if isempty(newv) || isempty(nuv) || ...
                        ~isreal(nuv) || ~isreal(sig) || (nuv<=0) || ...
                        (nuv>app.StoredData.WRUL) || ...
                        (sig <= 0) || (sig>app.StoredData.SUL)
                    flag = 1;
                    flag2 = 0;
                end
                [~,idxnew] = ismember(app.WireListListBox_2.Value,app.WireListListBox_2.Items);
                res = CheckRWandDC_FCW(app,idxnew);
                if res == -1
                    flag = 1;
                end

                % If the separation isn't defined, the radius/sigma are acceptable 
                if idx == CurrTab
                    if logical(flag2)
                        app.ApplytoAllConductorsButton.Enable = 'on';
                    else
                        app.ApplytoAllConductorsButton.Enable = "off";
                    end
                end
            end

            % If all are good turn on, else off
            if flag == 1
                app.ApplytoAllWiresButton.Enable = "off";
            else
                app.ApplytoAllWiresButton.Enable = "on";
            end

        end
        
        function results = Load_LoadOptions(app)

            % This is a listing of all the nodal load types and the corresponding parameters
            Loads(1).Name="Constant";
            Loads(1).Parameters={"R"}; %#ok<*STRSCALR> 
            Loads(1).Letter="C";
            Loads(1).Bounds = [10 5000];
            Loads(1).Units = {char(937)};
            Loads(1).Scale = [1];
            for lind = 1:length(Loads(1).Scale)
                Loads(1).Bounds(lind,:) = Loads(1).Bounds(lind,:)/Loads(1).Scale(lind);
            end
            
            Loads(2).Name="Motor";
            Loads(2).Parameters={"L","R","C","Cg","r"};
            Loads(2).Letter="M";
            Loads(2).Bounds = [.005 .0155;
                               2000 15000;
                               1e-10,1e-9;
                               0.2e-9,5e-9;
                               0 100];
            Loads(2).Units = {'mH', ['k',char(937)], 'nF', 'nF', char(937)};
            Loads(2).Scale = [1e-3, 1e3, 1e-9, 1e-9, 1];
            for lind = 1:length(Loads(2).Scale)
                Loads(2).Bounds(lind,:) = Loads(2).Bounds(lind,:)/Loads(2).Scale(lind);
            end
            
            Loads(3).Name="Series RLC";
            Loads(3).Parameters={"R","L","C"};
            Loads(3).Letter="S";
            Loads(3).Bounds = [0 1e9;
                               0 1e3;
                               0 1];
            Loads(3).Units = {char(937), [char(956),'H'],'nF'};
            Loads(3).Scale = [1, 1e-6, 1e-9];
            for lind = 1:length(Loads(3).Scale)
                Loads(3).Bounds(lind,:) = Loads(3).Bounds(lind,:)/Loads(3).Scale(lind);
            end
            
            Loads(4).Name="Parallel RLC";
            Loads(4).Parameters={"R","L","C"};
            Loads(4).Letter="P";
            Loads(4).Bounds = [0 1e9;
                               0 1e3;
                               0 1];
            Loads(4).Units = {char(937), [char(956),'H'],'nF'};
            Loads(4).Scale = [1, 1e-6, 1e-9];
            for lind = 1:length(Loads(4).Scale)
                Loads(4).Bounds(lind,:) = Loads(4).Bounds(lind,:)/Loads(4).Scale(lind);
            end
            
            Loads(5).Name="Double RLC";
            Loads(5).Parameters={["R",char(8347),char(8337),char(7523)],[char(969),char(8347),char(8337),char(7523)],[char(950),char(8347),char(8337),char(7523)],["R",char(8346),char(8336),char(7523)],[char(969),char(8346),char(8336),char(7523)],[char(950),char(8346),char(8336),char(7523)],[char(916),char(8321),char(8323)],[char(916),char(8322),char(8323)]};
            Loads(5).Letter="D";
            Loads(5).Bounds = [10 4e3;
                               1 3e7;
                               0.1 2;
                               10 4e3;
                               1 3e7;
                               0.1 2;
                               -0.1 0.1;
                               -0.1 0.1];
            Loads(5).Units = {char(937), 'Hz', ' ', char(937), 'Hz', ' ', '%', '%'};
            Loads(5).Scale = [1, 1, 1, 1, 1, 1, 0.01, 0.01];
            for lind = 1:length(Loads(5).Scale)
                Loads(5).Bounds(lind,:) = Loads(5).Bounds(lind,:)/Loads(5).Scale(lind);
            end
            
            Loads(6).Name="Open Circuit (High Impedance)";
            Loads(6).Parameters={};
            Loads(6).Letter="O";
            Loads(6).Bounds = [];
            Loads(6).Units = {};
            Loads(6).Scale = [];
            for lind = 1:length(Loads(6).Scale)
                Loads(6).Bounds(lind,:) = Loads(6).Bounds(lind,:)/Loads(6).Scale(lind);
            end
            
            Loads(7).Name="Custom";
            Loads(7).Parameters={};
            Loads(7).Letter="X";
            Loads(7).Bounds = [];
            Loads(7).Units = {};
            Loads(7).Scale = [];
            for lind = 1:length(Loads(7).Scale)
                Loads(7).Bounds(lind,:) = Loads(7).Bounds(lind,:)/Loads(7).Scale(lind);
            end

            app.StoredData.LoadsL = Loads;

            clear('Loads')

            % Now load receiver options
            Loads(1).Name="Constant";
            Loads(1).Parameters={"R"};
            Loads(1).Letter="C";
            Loads(1).Bounds = [10 5000];
            Loads(1).Units = {char(937)};
            Loads(1).Scale = [1];
            for lind = 1:length(Loads(1).Scale)
                Loads(1).Bounds(lind,:) = Loads(1).Bounds(lind,:)/Loads(1).Scale(lind);
            end
            
            Loads(2).Name="Motor";
            Loads(2).Parameters={"L","R","C","Cg","r"};
            Loads(2).Letter="M";
            Loads(2).Bounds = [.005 .0155;
                               2000 15000;
                               1e-10,1e-9;
                               0.2e-9,5e-9;
                               0 100];
            Loads(2).Units = {'mH', ['k',char(937)], 'nF', 'nF', char(937)};
            Loads(2).Scale = [1e-3, 1e3, 1e-9, 1e-9, 1];
            for lind = 1:length(Loads(2).Scale)
                Loads(2).Bounds(lind,:) = Loads(2).Bounds(lind,:)/Loads(2).Scale(lind);
            end
            
            Loads(3).Name="Series RLC";
            Loads(3).Parameters={"R","L","C"};
            Loads(3).Letter="S";
            Loads(3).Bounds = [0 1e9;
                               0 1e3;
                               0 1];
            Loads(3).Units = {char(937), [char(956),'H'],'nF'};
            Loads(3).Scale = [1, 1e-6, 1e-9];
            for lind = 1:length(Loads(3).Scale)
                Loads(3).Bounds(lind,:) = Loads(3).Bounds(lind,:)/Loads(3).Scale(lind);
            end
            
            Loads(4).Name="Parallel RLC";
            Loads(4).Parameters={"R","L","C"};
            Loads(4).Letter="P";
            Loads(4).Bounds = [0 1e9;
                               0 1e3;
                               0 1];
            Loads(4).Units = {char(937), [char(956),'H'],'nF'};
            Loads(4).Scale = [1, 1e-6, 1e-9];
            for lind = 1:length(Loads(4).Scale)
                Loads(4).Bounds(lind,:) = Loads(4).Bounds(lind,:)/Loads(4).Scale(lind);
            end
            
            Loads(5).Name="Double RLC";
            Loads(5).Parameters={["R",char(8347),char(8337),char(7523)],[char(969),char(8347),char(8337),char(7523)],[char(950),char(8347),char(8337),char(7523)],["R",char(8346),char(8336),char(7523)],[char(969),char(8346),char(8336),char(7523)],[char(950),char(8346),char(8336),char(7523)],[char(916),char(8321),char(8323)],[char(916),char(8322),char(8323)]};
            Loads(5).Letter="D";
            Loads(5).Bounds = [10 4e3;
                               1 3e7;
                               0.1 2;
                               10 4e3;
                               1 3e7;
                               0.1 2;
                               -0.1 0.1;
                               -0.1 0.1];
            Loads(5).Units = {char(937), 'Hz', ' ', char(937), 'Hz', ' ', '%', '%'};
            Loads(5).Scale = [1, 1, 1, 1, 1, 1, 0.01, 0.01];
            for lind = 1:length(Loads(5).Scale)
                Loads(5).Bounds(lind,:) = Loads(5).Bounds(lind,:)/Loads(5).Scale(lind);
            end
            
            Loads(6).Name="Grounded (Zero Impedance)";
            Loads(6).Parameters={};
            Loads(6).Letter="0";
            Loads(6).Bounds = [];
            Loads(6).Units = {};
            Loads(6).Scale = [];
            for lind = 1:length(Loads(6).Scale)
                Loads(6).Bounds(lind,:) = Loads(6).Bounds(lind,:)/Loads(6).Scale(lind);
            end
            
            Loads(7).Name="Custom";
            Loads(7).Parameters={};
            Loads(7).Letter="X";
            Loads(7).Bounds = [];
            Loads(7).Units = {};
            Loads(7).Scale = [];
            for lind = 1:length(Loads(7).Scale)
                Loads(7).Bounds(lind,:) = Loads(7).Bounds(lind,:)/Loads(7).Scale(lind);
            end

            app.StoredData.LoadsR = Loads;

        end
        
        function results = Load_CondOptions(app)  

            % Load list of wire materials and store 
            Wires.Materials = {"Copper","Custom"}; %#ok<*CLARRSTR> 
            app.StoredData.Wiredata = Wires;

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % If it exists, return to working directory before resetting
            new = false;
            try
                cd(app.StoredData.PWD)
            catch
                new = true;
            end

            % Keep loaded sim frequencies if any and if not new
            if ~new
                tmpf = app.StoredData.Freq;
                try
                    tmpp = app.StoredData.PopUpPref;
                catch
                    tmpp = true;
                end
            end

            % Move back 1 level if started from wrong folder
            curdirec = pwd;
            if strcmp(curdirec(end-8:end),'Interface')
                cd('..')
            end

            % Initialize data storage structure for eventual transfer
            app.StoredData = struct;                   % Initialize struct

            % Initialize PWD for tracking (Run_GUI ensures call from
            % directory GUI_V1 and checks for others)
            app.StoredData.PWD = [pwd,'/'];

            app.StoredData.X=[];                     % Prepare X nodal data
            app.StoredData.Y=[];                     % Prepare Y nodal data
            app.StoredData.Z=[];                     % Prepare Z nodal data
            app.StoredData.tmpvalue = 0;
            app.StoredData.TLV = 0;
            app.StoredData.Wires=[];                 % Matrix of nodeIDs indicating wires
            app.StoredData.Spice = 0;                % Compare with SPICE? Default 0=No
            app.StoredData.WireRes = 0.01;           % Default resolution of simulation (1cm)
            app.StoredData.WireLengths = [];         % Allows for slack
            app.StoredData.plotfcn = 1;              % Phase {1,2,3,4} {1,2,3,G}
            app.StoredData.plottype = 2;             % V or I (1 or 2)
            app.StoredData.SigPrev = 5.85*ones(1,4); % Track custom sigma values
            app.StoredData.EpsPrev = 3.6*ones(1,4);  % Track custom epsilon values
            app.StoredData.WRUL = 50;                % Upper limit in mm of wire rad
            app.StoredData.DCUL = 150;               % Upper limit in mm of separation distance
            app.StoredData.SUL = 10;                 % Upper limit in 10^7 S/m of sigma
            app.StoredData.EUL = 10;                 % Upper limit on relative permittivity of insulator
            app.StoredData.TLV = 0;
            FNtmp = fopen('COMSOL_Path');
            app.StoredData.COMSOLPATH = fgets(FNtmp);
            fclose(FNtmp);
            % Not listed (dynamically created later): NodeType, LoadTypenum, LoadParam, Freq, InputVIType, InputVI, WhichRec, RecVal, Sigma, Epsilon, Ncond, Radius, DC

            % For internal use
            app.StoredData.count=0;                  % Tracks number of nodes
            app.StoredData.EntityList=[];            % Pairs with lastEntity=2
            app.StoredData.lastEntity=0;             % 1 = selected node (UPL)

            % Initialize for COMSOL EM
            app.StoredData.EMQD = [10 10 10];
            app.FieldButtonGroup.SelectedObject.Text = "Electric";

            % Prepare interface options
            Load_LoadOptions(app)                    % Loads load options for loads/rec
            Load_CondOptions(app)                    % Loads material options
            UpdatePlotList(app)                      % Initializes empty lists
            UpdateWireList(app)
            Populate_LoadTypes(app)                  % Initializes load/rec options
            Populate_WireTypes(app)                  % Initializes wire material options
            app.LoadButton_2.Visible = 'off';
            app.FileLabel_2.Visible = 'off';
            app.VariableDropDown.Visible = 'off';
            app.VariableDropDownLabel.Visible = 'off';
            app.FileLabel_2.Text = 'File:  ';
            app.FileLabel_2.Tooltip = '';
            app.VariableDropDown.Items = {};
            try
                delete(app.UIAxes3.Colorbar);
            end
            clc

            % Add GeometryDefinition section guidance
            app.ErrorType.Text = 'Geometry Definition';
            app.ErrorType.FontColor = [0 0.5 0];
            app.ErrorMsg.Text = ['Build or load-in a network.' newline newline 'Swap between the ''Nodes'' and ''Wires'' tabs to define your network.' newline newline 'Once a full network topology has been constructed (n nodes, n-1 wires), proceed to the ''Node Definition'' tab.'];
            app.ErrorMsg.FontColor = [0 0.5 0];

            % Disable/enable/visible everything here
            app.NetworkDiagramPanel.Visible = 'on';
            app.NetworkDiagramPanel_2.Visible = 'off';
            app.AddWireButton.Enable = 'off';
            app.AvailableNodesListBox.Enable = 'on';
            app.NodeTypeDropDown.Enable = 'on';
            app.ClearNodeConfigurationButton.Enable = 'on';
            app.RemoveSelectedButton.Enable = 'off';
            app.DeleteAllNodesButton.Enable = 'off';
            app.Button.Visible = 'on';
            app.vButton.Visible = 'on';
            app.Button.Enable = 'off';
            app.vButton.Enable = 'off';
            app.SwapNodesButton.Enable = 'off';
            app.RemoveSelectedButton_2.Enable = 'off';
            app.DeleteAllWiresButton.Enable = 'off';
            app.sigmaEditFieldLabel.Text = ['Conductor ',char(963)];
            app.sigmaEditField.Enable = 'off';
            app.sigmaEditField.Value = 5.85;
            app.sigmaEditField_2Label.Text = ['Conductor ',char(963)];
            app.sigmaEditField_2.Enable = 'off';
            app.sigmaEditField_2.Value = 5.85;
            app.sigmaEditField_3Label.Text = ['Conductor ',char(963)];
            app.sigmaEditField_3.Enable = 'off';
            app.sigmaEditField_3.Value = 5.85;
            app.sigmaEditField_4Label.Text = ['Conductor ',char(963)];
            app.sigmaEditField_4.Enable = 'off';
            app.sigmaEditField_4.Value = 5.85;
            app.epsilonEditFieldLabel.Text = ['Insulator ',char(949)];
            app.epsilonEditField.Enable = 'off';
            app.epsilonEditField.Value = 3.6;
            app.ParasiticsDropDown.Visible = 'on';
            app.ParasiticsDropDownLabel.Visible = 'on';
            app.eps0FmLabel.Text = [char(949),char(8320),' (F/m)'];
            app.SaveNodeConfigurationButton.Enable = 'on';
            app.SaveLoadConfigurationButton.Enable = 'on';
            app.Phase1Button.Enable = 'on';
            app.Phase2Button.Enable = 'on';
            app.Phase3Button.Enable = 'on';
            app.EMFieldsButton.Enable = 'off';
            try
                app.StoredData = rmfield(app.StoredData,'EMTabAxes');
            end
            app.SimulateButton.Enable = 'on';
            if new
                app.UseLoadedFrequenciesCheckBox.Enable = 'off';
                app.FileLabel.Visible = 'off';
            end
            for offind = 1:12
                eval(['app.Param',num2str(offind),'EditField.Visible = ''off'';']);
                eval(['app.Param',num2str(offind),'EditFieldLabel.Visible = ''off'';']);
                if offind <= 9
                    eval(['app.PL',num2str(offind),'Label.Visible = ''off'';']);
                end
            end
            app.SimulationDetailsPanel.Enable = 'off';
            app.SimulateButton.Enable = 'off';
            app.SaveNodeConfigurationButton.Visible = 'off';
            app.LoadDefinitionPanel.Visible = 'off';
            app.ApplytoAllConductorsButton.Enable = 'off';
            app.ApplytoAllWiresButton.Enable = 'off';
            app.ClearAllConfigurationsButton.Enable = 'off';
            app.WireListListBox_2.Enable = 'on';
            app.NumberofConductorsDropDown.Enable = 'on';
            app.NumberofConductorsDropDown.Value = app.NumberofConductorsDropDown.Items{2}; % 3 phase
            app.Phase2Tab.Title = 'Phase 2';
            app.Phase3Tab.Title = 'Phase 3';
            app.WireMaterialDropDown_1.Enable = 'on';
            app.WireRadiusEditField_1.Enable = 'on';
            app.WireMaterialDropDown_2.Enable = 'on';
            app.WireRadiusEditField_2.Enable = 'on';
            app.WireMaterialDropDown_3.Enable = 'on';
            app.WireRadiusEditField_3.Enable = 'on';
            app.WireMaterialDropDown_4.Enable = 'on';
            app.WireRadiusEditField_4.Enable = 'on';
            app.SeparationDistanceEditField.Enable = 'on';
            app.RemoveWireDefinitionButton.Enable = 'off';
            app.Button.Visible = 'on';
            app.vButton.Visible = 'on';
            app.OOLabel.Visible = 'on';
            app.OOLabel_2.Visible = 'on';
            app.GLabel.Visible = 'on';
            app.Label.Visible = 'on';
            app.Label_2.Visible = 'on';
            app.Label_3.Visible = 'on';
            app.Label_4.Visible = 'off';
            app.GLabel_2.Visible = 'off';
            app.OOLabel_3.Visible = 'off';
            app.OOLabel_4.Visible = 'off';
            app.OLabel.Visible = 'off';
            app.GLabel_3.Visible = 'off';
            app.Label_5.Visible = 'off';
            app.Label_6.Visible = 'off';

            % Transmission parameter tab
            app.Z_0EditField.Visible = 'off';
            app.Z_0EditFieldLabel.Text = ['Z',char(8320)];
            app.OhmsLabel.Text = char(937);
            app.Label_26.Visible = 'on';
            app.TransmissionParametersDropDown.Value = 'ABCD';
            SetTPCB(app);

            % Initialize LHS button tooltips and en/disable temporarily
            app.GeometryDefinitionButton.Enable = 'on';
            app.NodeDefinitionButton.Enable = 'off';
            app.WiringDefinitionButton.Enable = 'off';
            if new
                app.StoredData.PopUpPref = true;
                ResetRMT(app);
            else
                app.StoredData.PopUpPref = tmpp;
                app.StoredData.Freq = tmpf;
            end
            app.RunModelButton.Enable = 'off';
            app.VisualizeResultsButton.Enable = 'off';
            app.EMFieldsButton.Enable = 'off';
            try
                app.StoredData = rmfield(app.StoredData,'EMTabAxes');
            end
            app.NodeDefinitionButton.Tooltip = 'Define a network of n nodes and n-1 wires for this tab to unlock.';
            app.WiringDefinitionButton.Tooltip = 'Define all node types and associated parameters for this tab to unlock.';
            app.RunModelButton.Tooltip = 'Define the properties for all conductors in all wires for this tab to unlock.';
            app.VisualizeResultsButton.Tooltip = 'Save your network and simulate the model for this tab to unlock.';
            app.EMFieldsButton.Tooltip = 'This will unlock once the Visualize Results tab has been visited.';
            app.TabGroup.SelectedTab = app.GeometryTab;
            if app.TabGroup2.SelectedTab ~= app.WiresTab
                app.TabGroup2.SelectedTab = app.NodesTab_3;
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ENCoRPUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {742, 742};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {280, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: GeometryDefinitionButton
        function GeometryDefinitionButtonPushed(app, event)

            if app.StoredData.TLV
                app.NetworkDiagramPanel.Visible = 'on';
                app.NetworkDiagramPanel_2.Visible = 'off';
                app.StoredData.TLV = 0;
            end

            try
                delete(app.UIAxes3.Colorbar);
            end
            clc

            if app.TabGroup.SelectedTab ~= app.GeometryTab
                % Remove selections
                app.StoredData.lastEntity = 0; 
                PlotWires(app)
                app.SystemNodesListBox.Value = {};
                app.SourceNodeListBox.Value = {};
                app.DestinationNodeListBox.Value = {};
                app.WireListListBox.Value = {};
                app.TabGroup.SelectedTab = app.GeometryTab;
            end

            % Load tab and set guidance
            app.ErrorType.Text = 'Geometry Definition';
            app.ErrorType.FontColor = [0 0.5 0];
            app.ErrorMsg.Text = ['Build or load-in a network.' newline newline 'Swap between the ''Nodes'' and ''Wires'' tabs to define your network.' newline newline 'Once a full network topology has been constructed (n nodes, n-1 wires), proceed to the ''Node Definition'' tab.'];
            app.ErrorMsg.FontColor = [0 0.5 0];
            
        end

        % Button pushed function: NodeDefinitionButton
        function NodeDefinitionButtonPushed(app, event)

            if app.StoredData.TLV
                app.NetworkDiagramPanel.Visible = 'on';
                app.NetworkDiagramPanel_2.Visible = 'off';
                app.StoredData.TLV = 0;
            end

            try
                delete(app.UIAxes3.Colorbar);
            end
            clc

            if app.TabGroup.SelectedTab ~= app.NodesTab
    
                % Whiten all backgrounds
                CorrectParamBG(app);

                % Lock buttons until network found to be legitimate
                app.AvailableNodesListBox.Enable = 'off';
                app.NodeTypeDropDown.Enable = 'off';
                app.ClearNodeConfigurationButton.Enable = 'off';
                app.SaveNodeConfigurationButton.Visible = 'off';
                app.ClearAllConfigurationsButton.Enable = 'off';
                app.LoadDefinitionPanel.Enable = 'off';
    
                legalgeom = true;
                % Check all nodes present in wires
                for cnwc = 1:length(app.StoredData.X) 
                    if ~ismember(cnwc,app.StoredData.Wires)
                        legalgeom = false;
                        break;
                    end
                end

                % Report error
                if ~legalgeom
                    app.ErrorType.Text = 'Error';
                    app.ErrorType.FontColor = 'r';
                    app.ErrorMsg.Text = ['Unsupported geometry detected.' newline newline 'One or more nodes are not connected to any wires.' newline newline 'Please return to the ''Geometry Definition'' tab and ensure all nodes are included in the network.'];
                    app.ErrorMsg.FontColor = 'r';
                else
        
                    % Check no loops in network
                    % Impossible with n nodes, n-1 wires constraint!
        
                    % Check its one connected network
                    % To do this, we'll ensure we can start at node 1 and visit
                    % all nodes, if we can't, the network is broken
        
                    % Cute solution
                    copywires = app.StoredData.Wires;
                    for cwi = 1:size(copywires,1)
                        gnv = max(copywires(cwi,:));
                        lnv = min(copywires(cwi,:));
                        copywires(copywires==lnv) = gnv;
                    end
                    if length(unique(copywires)) ~= 1
                        legalgeom = false;
                    end
        
                    % Report error
                    if ~legalgeom
                        app.ErrorType.Text = 'Error';
                        app.ErrorType.FontColor = 'r';
                        app.ErrorMsg.Text = ['Unsupported geometry detected.' newline newline 'Found ',num2str(length(unique(copywires))),' distinct networks; expected 1.' newline newline 'Please return to the ''Geometry Definition'' tab and ensure a single network is defined.'];
                        app.ErrorMsg.FontColor = 'r';
                    end
        
                    % If legitimate, add default Node Definition statement
                    % and unlock buttons!
                    if legalgeom
                        app.AvailableNodesListBox.Enable = 'on';
                        app.AvailableNodesListBox.Value = app.AvailableNodesListBox.Items{1};
                        app.NodeTypeDropDown.Enable = 'on';
                        app.NodeTypeDropDown.Value = app.NodeTypeDropDown.Items{1};
                        app.NodeTypeDropDownLabel.Text=['Node ',num2str(1),' Type'];
                        app.StoredData.lastEntity = 1;
                        app.StoredData.EntityList = [app.StoredData.X(1), app.StoredData.Y(1), app.StoredData.Z(1)];
                        PlotWires(app)
                        app.StoredData.lastEntity = 0;
                        app.LoadDefinitionPanel.Visible = 'off';
                        app.LoadDefinitionPanel.Enable = 'on';
                        app.ClearNodeConfigurationButton.Enable = 'on';
                        app.ErrorType.Text = 'Node Definition';
                        app.ErrorType.FontColor = [0 0.5 0];
                        app.ErrorMsg.Text = ['Select the node type for each node.' newline newline 'Save the configuration once you have fully defined the node.' newline newline 'Once all node types are defined, proceed to the ''Wiring Definition'' tab.'];
                        app.ErrorMsg.FontColor = [0 0.5 0];
                        app.ClearAllConfigurationsButton.Enable = 'on';
                        app.vButton.Visible = 'off';
                        app.Button.Visible = 'off'; % Not dealing with nodal shift shenanigans
    
                        % Create structure for available nodes
                        if isfield(app.StoredData,'Nodes') && length(app.StoredData.Nodes) == length(app.StoredData.X)
                            % Attempt to load first node
                            try
                                app.NodeTypeDropDown.Value = app.NodeTypeDropDown.Items{app.StoredData.Nodes(1).NodeType};
                                app.SaveNodeConfigurationButton.Visible = 'on';
                            end
                        elseif ~isfield(app.StoredData,'Nodes')
                            app.StoredData.Nodes(length(app.StoredData.X)) = struct;              
                        else
                            app.StoredData = rmfield(app.StoredData,'Nodes');
                            app.StoredData.Nodes(length(app.StoredData.X)) = struct;
                            UpdatePlotList(app)
                        end

                        % Now, fill in Node 1 values if they persisted
                        if isfield(app.StoredData,'Nodes') && isfield(app.StoredData.Nodes,'NodeType')
                            try
                                LoadLoadTypes(app);
                                if app.StoredData.Nodes(1).NodeType == 5 || app.StoredData.Nodes(1).NodeType == 3
                                    app.LoadDefinitionPanel.Visible = 'on';
                                    app.SaveNodeConfigurationButton.Visible = 'off';
                                end
                            end
                            try
                                if strcmp(app.NodeTypeDropDown.Value,'Receiver')
                                    AvailableNodesListBoxValueChanged(app,event)
                                end
                            end
                        end
                    end
                end

                % Indicate on Nodes tab
                app.TabGroup.SelectedTab = app.NodesTab;

            else
                % Do nothing other than refresh troubleshooting
                if strcmp('on',app.AvailableNodesListBox.Enable)
                    app.ErrorType.Text = 'Node Definition';
                    app.ErrorType.FontColor = [0 0.5 0];
                    app.ErrorMsg.Text = ['Select the node type for each node.' newline newline 'Save the configuration once you have fully defined the node.' newline newline 'Once all node types are defined, proceed to the ''Wiring Definition'' tab.'];
                    app.ErrorMsg.FontColor = [0 0.5 0];
                end
            end
            
        end

        % Button pushed function: AddNodeButton
        function AddNodeButtonPushed(app, event)

            if (isfield(app.StoredData,'Nodes') || isfield(app.StoredData,'WireProps')) && (app.StoredData.PopUpPref)
                answer = uiconfirm(app.ENCoRPUIFigure,'Changing the network topology resets all node and wire specifications. Proceed?','Confirmation','Options',{'Yes','Yes, don''t ask this again','No'},'Icon','warning');
            else
                answer = 'Yes';
            end

            if strcmp(answer,'No')
                return
            elseif ~strcmp(answer,'Yes')
                app.StoredData.PopUpPref = false;
            end

            % Increment count and append new node to list
            app.StoredData.count=app.StoredData.count+1;
            app.StoredData.X=[app.StoredData.X,double(app.XEditField.Value)];
            app.StoredData.Y=[app.StoredData.Y,double(app.YEditField.Value)];
            app.StoredData.Z=[app.StoredData.Z,double(app.ZEditField.Value)];

            % Check if duplicate node
            isdupe = false;
            dupeind = length(app.StoredData.X)-1;
            if dupeind > 0
                trackdupes = [];
                for dupeind2 = 1:dupeind
                    if (app.StoredData.X(dupeind2) == app.StoredData.X(dupeind+1)) && (app.StoredData.Y(dupeind2) == app.StoredData.Y(dupeind+1)) && (app.StoredData.Z(dupeind2) == app.StoredData.Z(dupeind+1))
                        isdupe = true;
                        trackdupes = [trackdupes; dupeind2];
                    end
                end
            end

            % Set newly created node as highlighted entity
            app.StoredData.lastEntity=1;
            app.StoredData.EntityList=[double(app.XEditField.Value),double(app.YEditField.Value),double(app.ZEditField.Value)];

            % Plot nodes and wires
            PlotWires(app)

            % Remove special entity privilege
            app.StoredData.lastEntity=0;

            % We want to unlock the next tab when nn=nw+1 regardless of
            % legality, which is addressed when attempting to move forward
            if length(app.StoredData.X)>0 && (size(app.StoredData.Wires,1) > 0) 
                if size(app.StoredData.Wires,1)+1 == length(app.StoredData.X)
                    app.NodeDefinitionButton.Enable=true;
                    app.NodeDefinitionButton.Tooltip = '';
                else
                    app.NodeDefinitionButton.Enable = false;
                    app.NodeDefinitionButton.Tooltip = 'Define a network of n nodes and n-1 wires for this tab to unlock.';
                end
            end

            % Turn on buttons depending on number of nodes
            if length(app.StoredData.X)>0
                app.RemoveSelectedButton.Enable = 'on';
                app.DeleteAllNodesButton.Enable = 'on';
            end
            if length(app.StoredData.X)>1
                app.Button.Enable = 'on';
                app.vButton.Enable = 'on';
            end

            % Warn user of duplicate nodes or maintain guidance
            if isdupe
                if length(trackdupes) == 1
                    app.ErrorType.Text = 'Warning';
                    app.ErrorType.FontColor = [0.5 0.5 0];
                    app.ErrorMsg.Text = ['The newest node is a duplicate of node ',num2str(trackdupes),'.' newline newline 'Please ensure this is intentional.'];
                    app.ErrorMsg.FontColor = [0.5 0.5 0];
                else
                    app.ErrorType.Text = 'Warning';
                    app.ErrorType.FontColor = [0.5 0.5 0];
                    dupenodes = '';
                    for trackdupesind = 1:length(trackdupes)-1
                        dupenodes = [dupenodes,num2str(trackdupes(trackdupesind)),', '];
                    end
                    if trackdupesind > 1
                        dupenodes = [dupenodes,'and ',num2str(trackdupes(trackdupesind+1))];
                    else
                        dupenodes = [dupenodes(1:length(dupenodes)-2),' ','and ',num2str(trackdupes(trackdupesind+1))];
                    end
                    app.ErrorMsg.Text = ['The newest node is a duplicate of nodes ',dupenodes,'.' newline newline 'Please ensure this is intentional.'];
                    app.ErrorMsg.FontColor = [0.5 0.5 0];
                end
            else
                app.ErrorType.Text = 'Geometry Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Build or load-in a network.' newline newline 'Swap between the ''Nodes'' and ''Wires'' tabs to define your network.' newline newline 'Once a full network topology has been constructed (n nodes, n-1 wires), proceed to the ''Node Definition'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            end

            % Highlight newly added node in box
            app.SystemNodesListBox.Value = app.SystemNodesListBox.Items{end};

            % Remove all nodal and wire definitions since geometry has changed
            if isfield(app.StoredData,'Nodes')
                app.StoredData = rmfield(app.StoredData,'Nodes');
            end
            %if isfield(app.StoredData,'WireProps')
            ResetWiringTab(app,0);
            %end

            % Turn off other buttons
            app.WiringDefinitionButton.Enable = 'off';
            app.RunModelButton.Enable = 'off';
            %ResetRMT(app);
            app.SimulateButton.Enable = 'off';
            app.SimulationDetailsPanel.Enable = 'off';
            app.VisualizeResultsButton.Enable = 'off';
            app.EMFieldsButton.Enable = 'off';
            try
                app.StoredData = rmfield(app.StoredData,'EMTabAxes');
            end
            app.WiringDefinitionButton.Tooltip = 'Define all node types and associated parameters for this tab to unlock.';
            app.RunModelButton.Tooltip = 'Define the properties for all conductors in all wires for this tab to unlock.';
            app.VisualizeResultsButton.Tooltip = 'Save your network and simulate the model for this tab to unlock.';
            app.EMFieldsButton.Tooltip = 'This will unlock once the Visualize Results tab has been visited.';
            
        end

        % Button pushed function: RemoveSelectedButton
        function RemoveSelectedButtonPushed(app, event)
           
            % Track what does and does not work
            trykindaworked = false;
            noselection = false;
            try          
                % This removes the selected item from the list
                [~, idx] = ismember(app.SystemNodesListBox.Value, app.SystemNodesListBox.Items);
                
                % Determine how many associated wires
                whichassocwires = [];
                if isempty(idx)
                    assocwires = 0;
                else
                    if (isfield(app.StoredData,'Nodes') || isfield(app.StoredData,'WireProps')) && (app.StoredData.PopUpPref)
                        answer = uiconfirm(app.ENCoRPUIFigure,'Changing the network topology resets all node and wire specifications. Proceed?','Confirmation','Options',{'Yes','Yes, don''t ask this again','No'},'Icon','warning');
                    else
                        answer = 'Yes';
                    end
        
                    if strcmp(answer,'No')
                        return
                    elseif ~strcmp(answer,'Yes')
                        app.StoredData.PopUpPref = false;
                    end
                    assocwires = sum(sum(app.StoredData.Wires == idx));
                    for wawind = 1:size(app.StoredData.Wires,1)
                        if sum(app.StoredData.Wires(wawind,:) == idx)
                            whichassocwires = [whichassocwires, wawind];
                        end
                    end
                end
                
                % Indicate which wires are being removed
                if length(app.StoredData.X) > 0 
                    if (size(app.StoredData.Wires,1) > 0) && (assocwires > 0)
                        app.ErrorType.Text = 'Warning';
                        app.ErrorType.FontColor = [0.5 0.5 0];
                        if assocwires == 1
                            app.ErrorMsg.Text = ['Wire ', num2str(whichassocwires),' connecting to the deleted node was removed.'];
                            app.ErrorMsg.FontColor = [0.5 0.5 0];
                        else
                            if assocwires == 2
                                strofassocwires = [num2str(whichassocwires(1)),' and ',num2str(whichassocwires(2))];
                            else
                                strofassocwires = '';
                                for soawind = 1:length(whichassocwires)-1
                                    strofassocwires = [strofassocwires,num2str(whichassocwires(soawind)),', ']; 
                                end
                                strofassocwires = [strofassocwires,'and ',num2str(whichassocwires(end))];
                            end    
                            app.ErrorMsg.Text = ['Wires ', strofassocwires,' connecting to the deleted node were removed.'];
                            app.ErrorMsg.FontColor = [0.5 0.5 0];
                        end
                    else
                        trykindaworked = true;
                    end
                end
                
                % If no node selected
                if isempty(idx)
                    noselection = true;
                end

                % First remove wires associated with the node
                try
                    for xdx=1:2 % Traverse through starting and ending nodes
                        if ~isempty(app.StoredData.Wires(:,1))
                            app.StoredData.Wires(app.StoredData.Wires(:,xdx)==idx,:)=[];
                        end
                    end
                end

                % Rewrite the references to nodes >=idx
                try
                    for xdx=1:2 % Traverse through starting and ending nodes
                        app.StoredData.Wires(app.StoredData.Wires(:,xdx)>=idx,xdx)=app.StoredData.Wires(app.StoredData.Wires(:,xdx)>=idx,xdx)-1;
                    end
                end

                % Remove deleted node
                app.StoredData.Wires;
                app.StoredData.X(idx)=[];
                app.StoredData.Y(idx)=[];
                app.StoredData.Z(idx)=[];

                % Enable based on results
                if length(app.StoredData.X) < 2
                    app.Button.Enable = 'off';
                    app.vButton.Enable = 'off';
                end
                if length(app.StoredData.X) == 0  
                    app.RemoveSelectedButton.Enable = 'off';
                    app.DeleteAllNodesButton.Enable = 'off';
                end
                
                % Update wires and nodes (again)
                app.StoredData.lastEntity=0;
                UpdateWireList(app)

                if size(app.StoredData.Wires,1) == 0
                    app.SwapNodesButton.Enable = 'off';
                    app.RemoveSelectedButton_2.Enable = 'off';
                    app.DeleteAllWiresButton.Enable = 'off';
                end

            end

            % Something was chosen, remove node field since geometry
            % changed and if there were no deleted wires print normal
            if ~noselection
                if isfield(app.StoredData,'Nodes')
                    app.StoredData = rmfield(app.StoredData,'Nodes');
                end
                %if isfield(app.StoredData,'WireProps')
                ResetWiringTab(app,0);
                %end
                app.SystemNodesListBox.Value = {}; % no selection = empty cell array
                if trykindaworked
                    app.ErrorType.Text = 'Geometry Definition';
                    app.ErrorType.FontColor = [0 0.5 0];
                    app.ErrorMsg.Text = ['Build or load-in a network.' newline newline 'Swap between the ''Nodes'' and ''Wires'' tabs to define your network.' newline newline 'Once a full network topology has been constructed (n nodes, n-1 wires), proceed to the ''Node Definition'' tab.'];
                    app.ErrorMsg.FontColor = [0 0.5 0];
                end

                % Turn off other buttons
                app.WiringDefinitionButton.Enable = 'off';
                app.RunModelButton.Enable = 'off';
                %ResetRMT(app);
                app.SimulateButton.Enable = 'off';
                app.SimulationDetailsPanel.Enable = 'off';
                app.VisualizeResultsButton.Enable = 'off';
                app.EMFieldsButton.Enable = 'off';
                try
                    app.StoredData = rmfield(app.StoredData,'EMTabAxes');
                end
                app.WiringDefinitionButton.Tooltip = 'Define all node types and associated parameters for this tab to unlock.';
                app.RunModelButton.Tooltip = 'Define the properties for all conductors in all wires for this tab to unlock.';
                app.VisualizeResultsButton.Tooltip = 'Save your network and simulate the model for this tab to unlock.';
                app.EMFieldsButton.Tooltip = 'This will unlock once the Visualize Results tab has been visited.';
            
            end

            % If nothing was selected, return error based on number of available nodes
            if noselection
                if trykindaworked
                    app.ErrorType.Text = 'Error';
                    app.ErrorType.FontColor = 'r';
                    app.ErrorMsg.Text = 'Please select a node to remove.';
                    app.ErrorMsg.FontColor = 'r';
                elseif length(app.StoredData.X) == 0 %#ok<*ISMT> 
                    app.ErrorType.Text = 'Error';
                    app.ErrorType.FontColor = 'r';
                    app.ErrorMsg.Text = ['How can one remove' newline 'that which isn''t?'];
                    app.ErrorMsg.FontColor = 'r';
                end
            end

            % Enable Node Definition button if conditions met
            if (size(app.StoredData.Wires,1)+1==length(app.StoredData.X)) && (size(app.StoredData.Wires,1) > 0)
                app.NodeDefinitionButton.Enable = 'on';
                app.NodeDefinitionButton.Tooltip = '';
            else
                app.NodeDefinitionButton.Enable = 'off';
                app.NodeDefinitionButton.Tooltip = 'Define a network of n nodes and n-1 wires for this tab to unlock.';
            end

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)

            % Shift the selected item up
            [~, idx] = ismember(app.SystemNodesListBox.Value, app.SystemNodesListBox.Items);

            if idx==1 % Do nothing, already at top
                app.ErrorType.Text = 'Geometry Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Build or load-in a network.' newline newline 'Swap between the ''Nodes'' and ''Wires'' tabs to define your network.' newline newline 'Once a full network topology has been constructed (n nodes, n-1 wires), proceed to the ''Node Definition'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];
                    
            elseif (isempty(idx)) && (length(app.StoredData.X) == 0)  
                app.ErrorType.Text = 'Error';
                app.ErrorType.FontColor = 'r';
                app.ErrorMsg.Text = ['Did you expect' newline 'something to happen?'];
                app.ErrorMsg.FontColor = 'r';

            elseif isempty(idx)
                app.ErrorType.Text = 'Error';
                app.ErrorType.FontColor = 'r';
                app.ErrorMsg.Text = 'Please select a node to shift up.';
                app.ErrorMsg.FontColor = 'r';
                    
            else
                app.ErrorType.Text = 'Geometry Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Build or load-in a network.' newline newline 'Swap between the ''Nodes'' and ''Wires'' tabs to define your network.' newline newline 'Once a full network topology has been constructed (n nodes, n-1 wires), proceed to the ''Node Definition'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];

                % Swap nodes and wires
                copyofwires = app.StoredData.Wires;
                firstnode = idx;
                secondnode = idx-1;
                cow = copyofwires;
                cow(cow==firstnode) = secondnode;
                cow(copyofwires==secondnode) = firstnode;
                app.StoredData.Wires = cow;

                % Swap nodal definitions
                tmp=[app.StoredData.X;app.StoredData.Y;app.StoredData.Z]';
                tmp(idx:end+1,:)=tmp(idx-1:end,:);
                tmp(idx-1,:)=tmp(idx+1,:);
                tmp(idx+1,:)=[];
                app.StoredData.X=tmp(:,1)';
                app.StoredData.Y=tmp(:,2)';
                app.StoredData.Z=tmp(:,3)';
                
                % Plot with privilege
                app.StoredData.lastEntity=1;
                app.StoredData.EntityList=[double(app.StoredData.X(idx-1)),double(app.StoredData.Y(idx-1)),double(app.StoredData.Z(idx-1))];
                UpdateWireList(app)
                app.StoredData.lastEntity = 0;

                % Select shifted item
                app.SystemNodesListBox.Value=app.SystemNodesListBox.Items{idx-1};
            end

        end

        % Button pushed function: vButton
        function vButtonPushed(app, event)

            % Shift the selected item down
            [~, idx] = ismember(app.SystemNodesListBox.Value, app.SystemNodesListBox.Items);

            % Error check possibilities
            if idx==length(app.StoredData.X) 
                app.ErrorType.Text = 'Geometry Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Build or load-in a network.' newline newline 'Swap between the ''Nodes'' and ''Wires'' tabs to define your network.' newline newline 'Once a full network topology has been constructed (n nodes, n-1 wires), proceed to the ''Node Definition'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];

            elseif (isempty(idx)) && (length(app.StoredData.X) == 0) 
                app.ErrorType.Text = 'Error';
                app.ErrorType.FontColor = 'r';
                app.ErrorMsg.Text = ['Did you expect' newline 'something to happen?'];
                app.ErrorMsg.FontColor = 'r';

            elseif isempty(idx)
                app.ErrorType.Text = 'Error';
                app.ErrorType.FontColor = 'r';
                app.ErrorMsg.Text = 'Please select a node to shift down.';
                app.ErrorMsg.FontColor = 'r';
            else
                app.ErrorType.Text = 'Geometry Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Build or load-in a network.' newline newline 'Swap between the ''Nodes'' and ''Wires'' tabs to define your network.' newline newline 'Once a full network topology has been constructed (n nodes, n-1 wires), proceed to the ''Node Definition'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];
              
                % Swap nodes AND wires containing associated nodes
                copyofwires = app.StoredData.Wires;
                firstnode = idx;
                secondnode = idx+1;
                cow = copyofwires;
                cow(cow==firstnode) = secondnode;
                cow(copyofwires==secondnode) = firstnode;
                app.StoredData.Wires = cow;

                % Shift node down
                tmp=[app.StoredData.X;app.StoredData.Y;app.StoredData.Z]';
                tmp(idx+1:end+1,:)=tmp(idx:end,:);
                tmp(idx,:)=tmp(idx+2,:);
                tmp(idx+2,:)=[];
                app.StoredData.X=tmp(:,1)';
                app.StoredData.Y=tmp(:,2)';
                app.StoredData.Z=tmp(:,3)';

                % Plot with privilege
                app.StoredData.lastEntity=1;
                app.StoredData.EntityList=[double(app.StoredData.X(idx+1)),double(app.StoredData.Y(idx+1)),double(app.StoredData.Z(idx+1))];
                UpdateWireList(app)                
                app.StoredData.lastEntity=0;

                % Select shifted item
                app.SystemNodesListBox.Value=app.SystemNodesListBox.Items{idx+1};
            end

        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)

            selectedTab = app.TabGroup2.SelectedTab;
            if selectedTab==app.WiresTab
                % If wires tab, deselect, default message, plot no privilege
                app.WireListListBox.Value = {};
                app.SourceNodeListBox.Value = {};
                app.DestinationNodeListBox.Value = {};
                app.ErrorType.Text = 'Geometry Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Build or load-in a network.' newline newline 'Swap between the ''Nodes'' and ''Wires'' tabs to define your network.' newline newline 'Once a full network topology has been constructed (n nodes, n-1 wires), proceed to the ''Node Definition'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];
                app.StoredData.lastEntity = 0;
                app.AddWireButton.Enable = 'off';
                PlotWires(app)
            else
                % If nodes tab, default message
                app.ErrorType.Text = 'Geometry Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Build or load-in a network.' newline newline 'Swap between the ''Nodes'' and ''Wires'' tabs to define your network.' newline newline 'Once a full network topology has been constructed (n nodes, n-1 wires), proceed to the ''Node Definition'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];

                % Turn off special plot privilege, plot, deselect
                app.StoredData.lastEntity = 0;
                PlotWires(app)
                app.SystemNodesListBox.Value = {};
            end

        end

        % Value changed function: SourceNodeListBox
        function SourceNodeListBoxValueChanged(app, event)

            value = app.SourceNodeListBox.Value;

            % Check legality of selection and turn off wirebox selection
            CheckWire(app)
            app.WireListListBox.Value = {};

            app.ErrorType.Text = 'Geometry Definition';
            app.ErrorType.FontColor = [0 0.5 0];
            app.ErrorMsg.Text = ['Build or load-in a network.' newline newline 'Swap between the ''Nodes'' and ''Wires'' tabs to define your network.' newline newline 'Once a full network topology has been constructed (n nodes, n-1 wires), proceed to the ''Node Definition'' tab.'];
            app.ErrorMsg.FontColor = [0 0.5 0];

            % Update the plot to show the selected node
            app.StoredData.lastEntity = 0;
            PlotWires(app)
            PlotSD(app)

        end

        % Value changed function: DestinationNodeListBox
        function DestinationNodeListBoxValueChanged(app, event)

            value = app.DestinationNodeListBox.Value;
            
            % Check legality of selection and turn off wirebox selection
            CheckWire(app)
            app.WireListListBox.Value = {};

            app.ErrorType.Text = 'Geometry Definition';
            app.ErrorType.FontColor = [0 0.5 0];
            app.ErrorMsg.Text = ['Build or load-in a network.' newline newline 'Swap between the ''Nodes'' and ''Wires'' tabs to define your network.' newline newline 'Once a full network topology has been constructed (n nodes, n-1 wires), proceed to the ''Node Definition'' tab.'];
            app.ErrorMsg.FontColor = [0 0.5 0];

            % Update the plot to show the selected node
            app.StoredData.lastEntity = 0;
            PlotWires(app)
            PlotSD(app)

        end

        % Button pushed function: AddWireButton
        function AddWireButtonPushed(app, event)
            
            % Draw a line between the two nodes
            value1 = app.SourceNodeListBox.Value;
            value2 = app.DestinationNodeListBox.Value;

            % Find indices
            [~, idx1] = ismember(app.SourceNodeListBox.Value, app.SourceNodeListBox.Items);
            [~, idx2] = ismember(app.DestinationNodeListBox.Value, app.DestinationNodeListBox.Items);

            % Store for privileged plotting, this also adds to list
            app.StoredData.EntityList=[idx1,idx2];
            app.StoredData.lastEntity=2;

            % Check for duplicate wires 
            isdupewire = false;
            for sfdwind = 1:size(app.StoredData.Wires,1)
                if (app.StoredData.Wires(sfdwind,1) == idx1) && (app.StoredData.Wires(sfdwind,2) == idx2)
                    isdupewire = true;
                    break;
                elseif (app.StoredData.Wires(sfdwind,1) == idx2) && (app.StoredData.Wires(sfdwind,2) == idx1)
                    isdupewire = true;
                    break;
                end
            end

            if ~isdupewire
                if (isfield(app.StoredData,'Nodes') || isfield(app.StoredData,'WireProps')) && (app.StoredData.PopUpPref)
                    answer = uiconfirm(app.ENCoRPUIFigure,'Changing the network topology resets all node and wire specifications. Proceed?','Confirmation','Options',{'Yes','Yes, don''t ask this again','No'},'Icon','warning');
                else
                    answer = 'Yes';
                end
    
                if strcmp(answer,'No')
                    return
                elseif ~strcmp(answer,'Yes')
                    app.StoredData.PopUpPref = false;
                end
            end

            % Update wires
            UpdateWireList(app)

            app.StoredData.lastEntity = 0;
            
            % Check requirements to unlock Node Def Tab
            if ~isempty(app.StoredData.X) && (size(app.StoredData.Wires,1) > 0)
                if size(app.StoredData.Wires,1)+1 == length(app.StoredData.X)
                    app.NodeDefinitionButton.Enable=true;
                    app.NodeDefinitionButton.Tooltip = '';
                else
                    app.NodeDefinitionButton.Enable = false;
                    app.NodeDefinitionButton.Tooltip = 'Define a network of n nodes and n-1 wires for this tab to unlock.';
                end
            end
            
            % Turn on other buttons if requirements met
            if size(app.StoredData.Wires,1) > 0
                app.RemoveSelectedButton_2.Enable = 'on';
                app.DeleteAllWiresButton.Enable = 'on';
            end
            if size(app.StoredData.Wires,1) > 0
                app.SwapNodesButton.Enable = 'on';
            end

            % Warning or guidance
            if isdupewire
                app.ErrorType.Text = 'Warning';
                app.ErrorType.FontColor = [0.5,0.5,0];
                app.ErrorMsg.Text = ['There is already a wire between the selected nodes (Wire ',num2str(sfdwind),').' newline newline 'No additional wire was generated.'];
                app.ErrorMsg.FontColor = [0.5,0.5,0];
            else
                % Delete any nodal/wire definitions
                if isfield(app.StoredData,'Nodes')
                    app.StoredData = rmfield(app.StoredData,'Nodes');
                end
                %if isfield(app.StoredData,'WireProps')
                ResetWiringTab(app,0);
                %end
                app.ErrorType.Text = 'Geometry Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Build or load-in a network.' newline newline 'Swap between the ''Nodes'' and ''Wires'' tabs to define your network.' newline newline 'Once a full network topology has been constructed (n nodes, n-1 wires), proceed to the ''Node Definition'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];

                % Highlight newly added wire
                PlotSD(app)
                PlotSelectedWire(app,event)
                app.WireListListBox.Value = app.WireListListBox.Items{end};

                % Turn off other buttons
                app.WiringDefinitionButton.Enable = 'off';
                app.RunModelButton.Enable = 'off';
                %ResetRMT(app);
                app.SimulateButton.Enable = 'off';
                app.SimulationDetailsPanel.Enable = 'off';
                app.VisualizeResultsButton.Enable = 'off';
                app.EMFieldsButton.Enable = 'off';
                try
                    app.StoredData = rmfield(app.StoredData,'EMTabAxes');
                end
                app.WiringDefinitionButton.Tooltip = 'Define all node types and associated parameters for this tab to unlock.';
                app.RunModelButton.Tooltip = 'Define the properties for all conductors in all wires for this tab to unlock.';
                app.VisualizeResultsButton.Tooltip = 'Save your network and simulate the model for this tab to unlock.';
                app.EMFieldsButton.Tooltip = 'This will unlock once the Visualize Results tab has been visited.';
            end

        end

        % Button pushed function: RemoveSelectedButton_2
        function RemoveSelectedButton_2Pushed(app, event)

            % Pull ID and error if none
            [~, idx] = ismember(app.WireListListBox.Value, app.WireListListBox.Items);
            if (isempty(idx)) && (size(app.StoredData.Wires,1) == 0)
                app.ErrorType.Text = 'Error';
                app.ErrorType.FontColor = 'r';
                app.ErrorMsg.Text = ['Difficulty deleting' newline 'nonexistent wire.'];
                app.ErrorMsg.FontColor = 'r';
            elseif (isempty(idx)) && (size(app.StoredData.Wires,1) > 0)
                app.ErrorType.Text = 'Error';
                app.ErrorType.FontColor = 'r';
                app.ErrorMsg.Text = 'Please select a wire to remove.';
                app.ErrorMsg.FontColor = 'r';
            else

                if (isfield(app.StoredData,'Nodes') || isfield(app.StoredData,'WireProps')) && (app.StoredData.PopUpPref)
                    answer = uiconfirm(app.ENCoRPUIFigure,'Changing the network topology resets all node and wire specifications. Proceed?','Confirmation','Options',{'Yes','Yes, don''t ask this again','No'},'Icon','warning');
                else
                    answer = 'Yes';
                end
    
                if strcmp(answer,'No')
                    return
                elseif ~strcmp(answer,'Yes')
                    app.StoredData.PopUpPref = false;
                end

                % Delete all nodal data (other than position)
                if isfield(app.StoredData,'Nodes')
                    app.StoredData = rmfield(app.StoredData,'Nodes');
                end
                %if isfield(app.StoredData,'WireProps')
                ResetWiringTab(app,0);
                %end

                % Guidance
                app.ErrorType.Text = 'Geometry Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Build or load-in a network.' newline newline 'Swap between the ''Nodes'' and ''Wires'' tabs to define your network.' newline newline 'Once a full network topology has been constructed (n nodes, n-1 wires), proceed to the ''Node Definition'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];

                % Remove wire from list and update + deselect options
                app.StoredData.Wires(idx,:)=[];
                app.StoredData.EntityList=[];
                app.StoredData.lastEntity=0;
                UpdateWireList(app)
                app.WireListListBox.Value = {};
                app.SourceNodeListBox.Value = {};
                app.DestinationNodeListBox.Value = {};

                % Turn off other buttons
                app.WiringDefinitionButton.Enable = 'off';
                app.RunModelButton.Enable = 'off';
                %ResetRMT(app);
                app.SimulateButton.Enable = 'off';
                app.SimulationDetailsPanel.Enable = 'off';
                app.VisualizeResultsButton.Enable = 'off';
                app.EMFieldsButton.Enable = 'off';
                try
                    app.StoredData = rmfield(app.StoredData,'EMTabAxes');
                end
                app.WiringDefinitionButton.Tooltip = 'Define all node types and associated parameters for this tab to unlock.';
                app.RunModelButton.Tooltip = 'Define the properties for all conductors in all wires for this tab to unlock.';
                app.VisualizeResultsButton.Tooltip = 'Save your network and simulate the model for this tab to unlock.';
                app.EMFieldsButton.Tooltip = 'This will unlock once the Visualize Results tab has been visited.';
            end
            
            % Disable and enable buttons appropriately
            if size(app.StoredData.Wires,1) == 0
                app.NodeDefinitionButton.Enable = 'off';
                app.NodeDefinitionButton.Tooltip = 'Define a network of n nodes and n-1 wires for this tab to unlock.';
                app.RemoveSelectedButton_2.Enable = 'off';
                app.DeleteAllWiresButton.Enable = 'off';
            end
            if size(app.StoredData.Wires,1)+1 ~= length(app.StoredData.X)
                app.NodeDefinitionButton.Enable = 'off';
                app.NodeDefinitionButton.Tooltip = 'Define a network of n nodes and n-1 wires for this tab to unlock.';
            elseif size(app.StoredData.Wires,1)>0
                app.NodeDefinitionButton.Enable = 'on';
                app.NodeDefinitionButton.Tooltip = '';
            end
            if size(app.StoredData.Wires,1) == 0
                app.SwapNodesButton.Enable = 'off';
            end

        end

        % Value changed function: AvailableNodesListBox
        function AvailableNodesListBoxValueChanged(app, event)

            value = app.AvailableNodesListBox.Value;

            % Applies white backgrounds to all
            CorrectParamBG(app);
            
            % Guidance
            app.ErrorType.Text = 'Node Definition';
            app.ErrorType.FontColor = [0 0.5 0];
            app.ErrorMsg.Text = ['Select the node type for each node.' newline newline 'Save the configuration once you have fully defined the node.' newline newline 'Once all node types are defined, proceed to the ''Wiring Definition'' tab.'];
            app.ErrorMsg.FontColor = [0 0.5 0]; 

            % Find selected member
            [~, idx] = ismember(app.AvailableNodesListBox.Value, app.AvailableNodesListBox.Items);

            % Plot and add selected node
            app.StoredData.lastEntity=1;
            app.StoredData.EntityList = [app.StoredData.X(idx), app.StoredData.Y(idx), app.StoredData.Z(idx)];
            PlotWires(app)
            app.StoredData.lastEntity=0;

            % Change label to match selected node
            app.NodeTypeDropDownLabel.Text=['Node ',num2str(idx),' Type'];

            % If saved values, load them in
            try
                app.NodeTypeDropDown.Value=app.NodeTypeDropDown.Items{app.StoredData.Nodes(idx).NodeType};
            catch
                app.NodeTypeDropDown.Value=app.NodeTypeDropDown.Items{1};
            end

            % Alert NodeTypeDropDown to change
            NodeTypeDropDownValueChanged(app, event)

        end

        % Button pushed function: WiringDefinitionButton
        function WiringDefinitionButtonPushed(app, event)
            
            if app.StoredData.TLV
                app.NetworkDiagramPanel.Visible = 'on';
                app.NetworkDiagramPanel_2.Visible = 'off';
                app.StoredData.TLV = 0;
            end

            try
                delete(app.UIAxes3.Colorbar);
            end
            clc

            if app.TabGroup.SelectedTab ~= app.WiringTab
                % Initialize sigprev
                if any(size(app.StoredData.SigPrev) ~= [size(app.StoredData.Wires,1),4])
                    app.StoredData.SigPrev = 5.85*ones(size(app.StoredData.Wires,1),4);
                end
    
                % Initialize eps prev
                if length(app.StoredData.EpsPrev) ~= size(app.StoredData.Wires,1)
                    app.StoredData.EpsPrev = 3.6*ones(size(app.StoredData.Wires,1),1);
                end
            
                % Plot without privilege
                app.StoredData.lastEntity = 0;
                PlotWires(app)
    
                % Check that there are indeed one transmitter and receiver
                % All other errors should have been handled in the Nodes tab
                nodesdefinedwrong=false;
                numreci=0;
                numtrans=0;
                for ndw = 1:length(app.StoredData.Nodes)
                    if app.StoredData.Nodes(ndw).NodeType == 4
                        numtrans = numtrans+1;
                    elseif app.StoredData.Nodes(ndw).NodeType == 3
                        numreci = numreci+1;
                    end
                end
    
                % Check one of each
                if (numreci~=1) || (numtrans~=1)
                    nodesdefinedwrong=true;
                end
    
                % If not, error with details
                if nodesdefinedwrong
                    app.ErrorType.Text = 'Error';
                    app.ErrorType.FontColor = 'r';
                    app.ErrorMsg.Text = ['Selection of node types is unsupported.' newline newline 'One transmitter (',num2str(numtrans),' detected) and one receiver (',num2str(numreci),' detected) is currently permitted/required.' newline newline 'Please return to the ''Node Definition'' tab to redefine the node types appropriately.'];
                    app.ErrorMsg.FontColor = 'r';
    
                    % Turn off all interactivity in Nodes tab until fixed
                    app.ApplytoAllConductorsButton.Enable = 'off';
                    app.ApplytoAllWiresButton.Enable = 'off';
    
                    app.WireListListBox_2.Enable = 'off';
                    app.NumberofConductorsDropDown.Enable = 'off';
                    app.ParasiticsDropDown.Enable = 'off';
                    app.WireMaterialDropDown_1.Enable = 'off';
                    app.WireRadiusEditField_1.Enable = 'off';
                    app.WireMaterialDropDown_2.Enable = 'off';
                    app.WireRadiusEditField_2.Enable = 'off';
                    app.WireMaterialDropDown_3.Enable = 'off';
                    app.WireRadiusEditField_3.Enable = 'off';
                    app.WireMaterialDropDown_4.Enable = 'off';
                    app.WireRadiusEditField_4.Enable = 'off';
                    app.SeparationDistanceEditField.Enable = 'off';
                    app.RemoveWireDefinitionButton.Enable = 'off';
                    app.ClearAllDefinitionsButton.Enable = 'off';
                    app.InsulatorMaterialDropDown.Enable = 'off';
                    app.EditField.Enable = 'off';
                    app.sigmaEditField.Enable = 'off';
                    app.sigmaEditField_2.Enable = 'off';
                    app.sigmaEditField_3.Enable = 'off';
                    app.sigmaEditField_4.Enable = 'off';
                    
                    if strcmp(app.NumberofConductorsDropDown.Value,'2')
                        app.ParasiticsDropDown.Visible = 'off';
                        app.ParasiticsDropDownLabel.Visible = 'off';
                    else
                        app.ParasiticsDropDown.Visible = 'on';
                        app.ParasiticsDropDownLabel.Visible = 'on';
                    end
                    app.RunModelButton.Enable = 'off';
                    app.RunModelButton.Tooltip = 'Define the properties for all conductors in all wires for this tab to unlock.';
                
                    % Switch to selected tab
                    app.TabGroup.SelectedTab = app.WiringTab;
                
                else
    
                    % Initialize WireProps struct
                    if isfield(app.StoredData,'WireProps') && length(app.StoredData.WireProps) == length(app.StoredData.X)-1
                        % Do nothing
                    elseif ~isfield(app.StoredData,'WireProps')
                        app.StoredData.WireProps(length(app.StoredData.X)-1) = struct;            
                    else
                        ResetWiringTab(app);
                        app.StoredData.WireProps(length(app.StoredData.X)-1) = struct;
                    end
    
                    % Compute wire lengths
                    if isempty(app.StoredData.WireLengths)
                        app.StoredData.WireLengths = zeros(1,size(app.StoredData.Wires,1));
                        for wind = 1:size(app.StoredData.Wires,1)
                            Xv=app.StoredData.X(app.StoredData.Wires(wind,:));
                            Yv=app.StoredData.Y(app.StoredData.Wires(wind,:));
                            Zv=app.StoredData.Z(app.StoredData.Wires(wind,:));
                            len = norm([diff(Xv),diff(Yv),diff(Zv)]);
                            app.StoredData.WireLengths(wind) = len;
                        end
                    end

                    % Switch to selected tab
                    app.TabGroup.SelectedTab = app.WiringTab;

                    % Enable/Disable number of conductors if necessary
                    ncc = 0;
                    for ncddind = 1:length(app.StoredData.Nodes)
                        if app.StoredData.Nodes(ncddind).LoadType == "X"
                            if size(app.StoredData.Nodes(ncddind).LoadParam,2) == 1
                                ncc = 1;
                                break;
                            else
                                ncc = 3;
                                break;
                            end
                        end
                    end
                    if ncc == 0
                        RerunNCD = false;
                        app.NumberofConductorsDropDown.Enable = 'on';
                        app.NumberofConductorsDropDown.Tooltip = '';
                        %NumberofConductorsDropDownValueChanged(app,'SkipDLG')
                    elseif ncc == 1
                        app.NumberofConductorsDropDown.Enable = 'off';
                        if strcmp(app.NumberofConductorsDropDown.Value,'2')
                            RerunNCD = false;
                        else
                            RerunNCD = true;
                        end
                        app.NumberofConductorsDropDown.Value = '2';
                        app.NumberofConductorsDropDown.Tooltip = 'The number of conductors has been set and locked in accordance with user supplied data for custom load(s).';
                    elseif ncc == 3
                        if strcmp(app.NumberofConductorsDropDown.Value,'4')
                            RerunNCD = false;
                        else
                            RerunNCD = true;
                        end
                        app.NumberofConductorsDropDown.Enable = 'off';
                        app.NumberofConductorsDropDown.Value = '4';
                        app.NumberofConductorsDropDown.Tooltip = 'The number of conductors has been set and locked in accordance with user supplied data for custom load(s).';
                    end
                    if RerunNCD
                        NumberofConductorsDropDownValueChanged(app,'SkipDLG')
                    end

                    % Initialize box to first wire and turn on all interactivity
                    app.WireListListBox_2.Value=app.WireListListBox_2.Items{1};
                    WireListListBox_2ValueChanged(app,event)
                    app.WireListListBox_2.Enable = 'on';
                    app.ParasiticsDropDown.Enable = 'on';
                    app.WireMaterialDropDown_1.Enable = 'on';
                    app.WireRadiusEditField_1.Enable = 'on';
                    app.WireMaterialDropDown_2.Enable = 'on';
                    app.WireRadiusEditField_2.Enable = 'on';
                    app.WireMaterialDropDown_3.Enable = 'on';
                    app.WireRadiusEditField_3.Enable = 'on';
                    app.WireMaterialDropDown_4.Enable = 'on';
                    app.WireRadiusEditField_4.Enable = 'on';
                    app.SeparationDistanceEditField.Enable = 'on';
                    app.RemoveWireDefinitionButton.Enable = 'on';
                    app.ClearAllDefinitionsButton.Enable = 'on';
                    if strcmp(app.WireMaterialDropDown_1.Value,'Copper')
                        app.sigmaEditField.Enable = 'off';
                    else
                        app.sigmaEditField.Enable = 'on';
                    end
                    if strcmp(app.WireMaterialDropDown_2.Value,'Copper')
                        app.sigmaEditField_2.Enable = 'off';
                    else
                        app.sigmaEditField_2.Enable = 'on';
                    end
                    if strcmp(app.WireMaterialDropDown_3.Value,'Copper')
                        app.sigmaEditField_3.Enable = 'off';
                    else
                        app.sigmaEditField_3.Enable = 'on';
                    end
                    if strcmp(app.WireMaterialDropDown_4.Value,'Copper')
                        app.sigmaEditField_4.Enable = 'off';
                    else
                        app.sigmaEditField_4.Enable = 'on';
                    end
                    app.InsulatorMaterialDropDown.Enable = 'on';
                    app.EditField.Enable = 'on';
                    if strcmp(app.NumberofConductorsDropDown.Value,'2')
                        app.ParasiticsDropDown.Visible = 'off';
                        app.ParasiticsDropDownLabel.Visible = 'off';
                    else
                        app.ParasiticsDropDown.Visible = 'on';
                        app.ParasiticsDropDownLabel.Visible = 'on';
                    end
    
                    % Update troubleshooting panel
                    app.ErrorType.Text = 'Wiring Definition';
                    app.ErrorType.FontColor = [0 0.5 0];
                    app.ErrorMsg.Text = ['Define the properties of each wire.' newline newline 'Select the number of conductors. Then, swap between the Wires and Conductor tabs or use the ''Apply to All'' buttons to set values.' newline newline 'Once all wires and conductors have been defined, proceed to the ''Run Model'' tab.'];
                    app.ErrorMsg.FontColor = [0 0.5 0];
                end
    
                % Default to Ground tab
                app.TabGroup3.SelectedTab = app.GroundTab;
    
                app.StoredData.tmpvalue = 1;
                UpdateWireList(app)
                app.StoredData.tmpvalue = 0;

                PlotSelectedWire(app)

            else
                if strcmp('on',app.WireListListBox_2.Enable)
                    app.ErrorType.Text = 'Wiring Definition';
                    app.ErrorType.FontColor = [0 0.5 0];
                    app.ErrorMsg.Text = ['Define the properties of each wire.' newline newline 'Select the number of conductors. Then, swap between the Wires and Conductor tabs or use the ''Apply to All'' buttons to set values.' newline newline 'Once all wires and conductors have been defined, proceed to the ''Run Model'' tab.'];
                    app.ErrorMsg.FontColor = [0 0.5 0];
                end
            end
            
        end

        % Value changed function: WireListListBox
        function WireListListBoxValueChanged(app, event)

            value = app.WireListListBox.Value;

            % Turn off other options
            app.SourceNodeListBox.Value = {};
            app.DestinationNodeListBox.Value = {};
            app.AddWireButton.Enable = 'off';

            app.ErrorType.Text = 'Geometry Definition';
            app.ErrorType.FontColor = [0 0.5 0];
            app.ErrorMsg.Text = ['Build or load-in a network.' newline newline 'Swap between the ''Nodes'' and ''Wires'' tabs to define your network.' newline newline 'Once a full network topology has been constructed (n nodes, n-1 wires), proceed to the ''Node Definition'' tab.'];
            app.ErrorMsg.FontColor = [0 0.5 0];

            % Plot all wires
            app.StoredData.lastEntity=0;
            PlotWires(app)

            % Plot selection
            PlotSelectedWire(app,event)

        end

        % Value changed function: WireListListBox_2
        function WireListListBox_2ValueChanged(app, event)

            % Plot newly selected wire
            app.StoredData.lastEntity=0;
            PlotWires(app)
            PlotSelectedWire(app,event)

            % Default to GroundTab
            app.TabGroup3.SelectedTab = app.GroundTab;
            
            % Pull index of new wire
            [~, idx2] = ismember(app.WireListListBox_2.Value, app.WireListListBox_2.Items); 
            if isempty(idx2)
                idx2 = 1;
            end

            % Attempt to update values based on saved data and clear color
            % if acceptable
            for idx=1:4
                try
                    if isnan(app.StoredData.WireProps(idx2).Radius(idx))
                        error('NaN, clear the value.')
                    end
                    eval(['app.WireRadiusEditField_',num2str(idx),'.Value=num2str(app.StoredData.WireProps(idx2).Radius(idx));']);
                    if isempty(app.StoredData.WireProps(idx2).Radius(idx)) 
                    end
                catch
                    eval(['app.WireRadiusEditField_',num2str(idx),'.Value='''';']);
                end
                try
                    if isnan(app.StoredData.WireProps(idx2).DC)
                        error('Nan, clear the value.')
                    end
                    if ~isempty(app.StoredData.WireProps(idx2).DC)
                        app.SeparationDistanceEditField.Value = num2str(app.StoredData.WireProps(idx2).DC);
                    else
                        error('Empty separation distance.')
                    end

                    res = PartialCheckRWandDC_FCW(app,5);
                    if (res >= 0) && (res < 1)
                        app.SeparationDistanceEditField.BackgroundColor = [0.9 0.9 0];
                    end

                catch
                    app.SeparationDistanceEditField.Value = '';
                end
         
                try
                    eval(['app.WireMaterialDropDown_',num2str(idx),'.Value=app.WireMaterialDropDown_',num2str(idx),'.Items{app.StoredData.WireProps(idx2).Matl(idx)};']);
                catch
                    eval(['app.WireMaterialDropDown_',num2str(idx),'.Value=app.WireMaterialDropDown_',num2str(idx),'.Items{1};']);
                end
                try
                    if eval(['strcmp(app.WireMaterialDropDown_',num2str(idx),'.Value, ''Copper'')'])
                        if idx == 1
                            app.sigmaEditField.Value = 5.85;
                            app.sigmaEditField.Enable = 'off';
                        else
                            eval(['app.sigmaEditField_',num2str(idx),'.Value = 5.85;']);
                            eval(['app.sigmaEditField_',num2str(idx),'.Enable = ''off'';']);
                        end
                    else
                        if idx == 1
                            app.sigmaEditField.Value = app.StoredData.SigPrev(idx2,1);
                            app.sigmaEditField.Enable = 'on';
                        else
                            eval(['app.sigmaEditField_',num2str(idx),'.Value = app.StoredData.SigPrev(',num2str(idx2),',',num2str(idx),');']);
                            eval(['app.sigmaEditField_',num2str(idx),'.Enable = ''on'';']);
                        end
                    end
                end
            end

            % Update insulator value
            try
                if app.StoredData.WireProps(idx2).InsMatl == 1
                    app.InsulatorMaterialDropDown.Value = 'PVC';
                    app.epsilonEditField.Enable = 'off';
                    app.epsilonEditField.Value = 3.6;
                elseif app.StoredData.WireProps(idx2).InsMatl == 2
                    app.InsulatorMaterialDropDown.Value = 'Custom';
                    app.epsilonEditField.Enable = 'on';
                    app.epsilonEditField.Value = app.StoredData.EpsPrev(idx2);
                else
                    error('Not yet defined.')
                end
            catch
                app.InsulatorMaterialDropDown.Value = 'PVC';
                app.epsilonEditField.Enable = 'off';
                app.epsilonEditField.Value = 3.6;
            end
        
            % Correct ground radius if zero
            if app.WireRadiusEditField_1.Value == '0'
                app.WireRadiusEditField_1.Value = '';
            end

            % Check if apply buttons can be activated
            CheckApplyBoxes(app)

            % Update troubleshooting
            app.ErrorType.Text = 'Wiring Definition';
            app.ErrorType.FontColor = [0 0.5 0];
            app.ErrorMsg.Text = ['Define the properties of each wire.' newline newline 'Select the number of conductors. Then, swap between the Wires and Conductor tabs or use the ''Apply to All'' buttons to set values.' newline newline 'Once all wires and conductors have been defined, proceed to the ''Run Model'' tab.'];
            app.ErrorMsg.FontColor = [0 0.5 0];
            
            % Update the WireLength Text
            try
                app.EditField.Value = app.StoredData.WireLengths(idx2);
            end
            
            % Clean up
            CorrectWireBGaV(app)
            
        end

        % Button pushed function: RunModelButton
        function RunModelButtonPushed(app, event)
    
            if app.StoredData.TLV
                app.NetworkDiagramPanel.Visible = 'on';
                app.NetworkDiagramPanel_2.Visible = 'off';
                app.StoredData.TLV = 0;
            end

            try
                delete(app.UIAxes3.Colorbar);
            end
            clc

            if app.TabGroup.SelectedTab == app.ModelTab
                % Troubleshooting
                if ~app.SimulationDetailsPanel.Enable
                    app.ErrorType.Text = 'Run Model';
                    app.ErrorType.FontColor = [0 0.5 0];
                    app.ErrorMsg.Text = ['Save the model.' newline newline 'The simulation details will be made available to you once the model save is complete.'];
                    app.ErrorMsg.FontColor = [0 0.5 0];
                else
                    app.ErrorType.Text = 'Run Model';
                    app.ErrorType.FontColor = [0 0.5 0];
                    app.ErrorMsg.Text = ['Set the simulation parameters.' newline newline 'Define the frequency, injected signal, resolution of simulation, and check the box if you would like to compare against SPICE (see documentation for details).'];
                    app.ErrorMsg.FontColor = [0 0.5 0];
                end
            else

                % Check and correct loaded frequencies if kept
                UseLoadedFrequenciesCheckBoxValueChanged(app, event)

                % Adjust visibility before switching tabs
                ncond = str2num(app.NumberofConductorsDropDown.Value);
                if ncond == 2
                    app.Phase2EditField.Visible = 'off';
                    app.Phase2EditFieldLabel.Visible = 'off';
                    app.VLabel_2.Visible = 'off';
                    app.Phase3EditField.Visible = 'off';
                    app.Phase3EditFieldLabel.Visible = 'off';
                    app.VLabel_3.Visible = 'off';
                    app.oEditField_2Label.Visible = false;
                    app.oEditField_3Label.Visible = false;
                    app.oEditField_2.Visible = false;
                    app.oEditField_3.Visible = false;
                elseif ncond == 4
                    app.oEditField_2Label.Visible = true;
                    app.oEditField_3Label.Visible = true;
                    app.oEditField_2.Visible = true;
                    app.oEditField_3.Visible = true;
                    app.Phase2EditField.Visible = 'on';
                    app.Phase2EditFieldLabel.Visible = 'on';
                    app.VLabel_2.Visible = 'on';
                    app.Phase3EditField.Visible = 'on';
                    app.Phase3EditFieldLabel.Visible = 'on';
                    app.VLabel_3.Visible = 'on';
                else
                    error('Unexpected number of conductors selected.')
                end
    
                % Plot without privilege
                app.StoredData.lastEntity = 0;
                PlotWires(app)
    
                % Troubleshooting
                if ~app.SimulationDetailsPanel.Enable
                    if isempty(strtrim(app.Label_25.Text))
                        app.UseLoadedFrequenciesCheckBox.Enable = 'off';
                        app.FileLabel.Visible = 'off';
                        app.Label_25.Tooltip = '';
                        app.UseLoadedFrequenciesCheckBox.Value = false;
                    end
                    app.ErrorType.Text = 'Run Model';
                    app.ErrorType.FontColor = [0 0.5 0];
                    app.ErrorMsg.Text = ['Save the model.' newline newline 'The simulation details will be made available to you once the model save is complete.'];
                    app.ErrorMsg.FontColor = [0 0.5 0];
                else
                    app.ErrorType.Text = 'Run Model';
                    app.ErrorType.FontColor = [0 0.5 0];
                    app.ErrorMsg.Text = ['Set the simulation parameters.' newline newline 'Define the frequency, injected signal, resolution of simulation, and check the box if you would like to compare against SPICE (see documentation for details).'];
                    app.ErrorMsg.FontColor = [0 0.5 0];
                end

                app.TabGroup.SelectedTab = app.ModelTab;

            end

        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)

%             Answer = uiconfirm(app.ENCoRPUIFigure,'Utilize the Troubleshooting panel, tooltips by hovering various buttons, or refer to the documentation for assistance.','Help','Options',{'Okay','That doesn''t help'},'Icon','question');
%             if strcmp(Answer,'That doesn''t help')
%                 Answer2 = uiconfirm(app.ENCoRPUIFigure,'If you suspect you''ve encountered a bug, or if you have questions not addressed in the aforementioned material, send an email to: encorphelp@gmail.com.','More Information','Options',{'Okay','Cancel'},'Icon','info');
%             end

        end

        % Value changed function: NumberofConductorsDropDown
        function NumberofConductorsDropDownValueChanged(app, event)

            value = app.NumberofConductorsDropDown.Value;

            % This will delete all data, check first
            try
                if strcmp(event,'SkipDLG')
                    Answer = 'Yes';
                else
                    error('No.')
                end
            catch
                Answer = uiconfirm(app.ENCoRPUIFigure,'This will delete or reset all of the parameter values for all of the wires. Proceed?','Confirmation','Options',{'Yes','No'},'Icon','warning');
            end
            if strcmp(Answer,'Yes')
                % Reset wire definitions
                ResetWiringTab(app)
                WiringDefinitionButtonPushed(app, event)
                app.TabGroup3.SelectedTab = app.GroundTab;
                app.WireListListBox_2.Value = app.WireListListBox_2.Items{1};
                    
                % Default to standard troubleshooting message
                app.ErrorType.Text = 'Wiring Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Define the properties of each wire.' newline newline 'Select the number of conductors. Then, swap between the Wires and Conductor tabs or use the ''Apply to All'' buttons to set values.' newline newline 'Once all wires and conductors have been defined, proceed to the ''Run Model'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];
    
                % If 2 is chosen
                if strcmp(value,'2')
                    app.Phase2Tab.Title = [char(8201),char(8201),char(8201),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160)];
                    app.Phase3Tab.Title = [char(8201),char(8201),char(8201),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160)];
                    app.OOLabel.Visible = 'off';
                    app.OOLabel_2.Visible = 'off';
                    app.GLabel.Visible = 'off';
                    app.Label.Visible = 'off';
                    app.Label_2.Visible = 'off';
                    app.Label_3.Visible = 'off';
                    app.SquareLabel.Text = ['Line'];
                    app.Label_4.Visible = 'on';
                    app.GLabel_2.Visible = 'on';
                    app.OOLabel_3.Visible = 'on';
                    app.OOLabel_4.Visible = 'off';
                    app.OLabel.Visible = 'off';
                    app.GLabel_3.Visible = 'off';
                    app.Label_5.Visible = 'off';
                    app.Label_6.Visible = 'off';
                    app.ParasiticsDropDown.Visible = 'off';
                    app.ParasiticsDropDownLabel.Visible = 'off';
                    app.PhaseSelectionButtonGroup.SelectedObject = app.Phase1Button;
                    app.StoredData.plotfcn = 1;
    
                % If 3 is chosen
                elseif strcmp(value,'3')
                    app.Phase2Tab.Title = 'Phase 2';
                    app.Phase3Tab.Title = [char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160)];
                    app.OOLabel.Visible = 'off';
                    app.OOLabel_2.Visible = 'off';
                    app.GLabel.Visible = 'off';
                    app.Label.Visible = 'off';
                    app.Label_2.Visible = 'off';
                    app.Label_3.Visible = 'off';
                    app.SquareLabel.Text = 'Triangle';
                    app.Label_4.Visible = 'off';
                    app.GLabel_2.Visible = 'off';
                    app.OOLabel_3.Visible = 'off';
                    app.OOLabel_4.Visible = 'on';
                    app.OLabel.Visible = 'on';
                    app.GLabel_3.Visible = 'on';
                    app.Label_5.Visible = 'on';
                    app.Label_6.Visible = 'on';
                    app.ParasiticsDropDown.Visible = 'on';
                    app.ParasiticsDropDownLabel.Visible = 'on';
    
                % If 4 is chosen
                elseif strcmp(value,'4')
                    app.SquareLabel.Text = ['Square'];
                    app.OOLabel_4.Visible = 'off';
                    app.OLabel.Visible = 'off';
                    app.GLabel_3.Visible = 'off';
                    app.Label_5.Visible = 'off';
                    app.Label_6.Visible = 'off';
                    app.Phase2Tab.Title = 'Phase 2';
                    app.Phase3Tab.Title = 'Phase 3';
                    app.OOLabel.Visible = 'on';
                    app.OOLabel_2.Visible = 'on';
                    app.GLabel.Visible = 'on';
                    app.Label.Visible = 'on';
                    app.Label_2.Visible = 'on';
                    app.Label_3.Visible = 'on';
                    app.Label_4.Visible = 'off';
                    app.GLabel_2.Visible = 'off';
                    app.OOLabel_3.Visible = 'off';
                    app.ParasiticsDropDown.Visible = 'on';
                    app.ParasiticsDropDownLabel.Visible = 'on';              
                else
                    error('You''ve somehow hacked the matrix...')
                end

                SetTPCB(app);
               
                % Default to ground tab and check apply boxes
                app.TabGroup3.SelectedTab = app.GroundTab;
    
                % Check if buttons should be activated
                CheckApplyBoxes(app)
    
                % UpdateWireList
                app.StoredData.lastEntity = 2;
                app.StoredData.EntityList = app.StoredData.Wires(1,:);
                UpdateWireList(app)
                app.StoredData.lastEntity = 0;
            else
                if strcmp(value,'2')
                    app.NumberofConductorsDropDown.Value = '4';
                elseif strcmp(value,'4')
                    app.NumberofConductorsDropDown.Value = '2';
                end
            end

        end

        % Selection change function: TabGroup3
        function TabGroup3SelectionChanged(app, event)

            % Check if changed to ground or a phase wire
            if strcmp(event.NewValue.Title,'Ground')
                tabval = 1;
            elseif ~isempty(str2num(event.NewValue.Title(end)))
                tabval = str2num(event.NewValue.Title(end))+1;
            else
                app.TabGroup3.SelectedTab = event.OldValue;
                if strcmp(event.OldValue.Title,'Ground')
                    tabval = 1;
                else
                    tabval = str2num(event.OldValue.Title(end))+1;
                end
            end

            % Remove zeros and nans from wire radii if present
            if app.WireRadiusEditField_1.Value == '0'% || isnan(app.WireRadiusEditField_1.Value)
                app.WireRadiusEditField_1.Value = '';
            end
            if app.WireRadiusEditField_2.Value == '0'% || isnan(app.WireRadiusEditField_2.Value)
                app.WireRadiusEditField_2.Value = '';
            end
            if app.WireRadiusEditField_3.Value == '0'% || isnan(app.WireRadiusEditField_3.Value)
                app.WireRadiusEditField_3.Value = '';
            end
            if app.WireRadiusEditField_4.Value == '0'% || isnan(app.WireRadiusEditField_4.Value)
                app.WireRadiusEditField_4.Value = '';
            end

            % Check if background is red... lol... easier than all the
            % value checking
           
            if eval(['isempty(app.WireRadiusEditField_',num2str(tabval),'.Value)']) || eval(['all(app.WireRadiusEditField_',num2str(tabval),'.BackgroundColor == [1 0.75 0.75])'])
                app.ApplytoAllConductorsButton.Enable = 'off';
            else
                if strcmp(app.WireListListBox_2.Enable,'on')
                    app.ApplytoAllConductorsButton.Enable = 'on';
                end
            end

            % If background is red, that's fine as long as value good
            if eval(['all(app.WireRadiusEditField_',num2str(tabval),'.BackgroundColor == [1 0.75 0.75])'])
                tmpval = str2num(eval(['app.WireRadiusEditField_',num2str(tabval),'.Value;']));
                if ~isempty(tmpval) && isreal(tmpval) && tmpval>0 && tmpval<=app.StoredData.WRUL
                    if strcmp(app.WireListListBox_2.Enable,'on')
                        app.ApplytoAllConductorsButton.Enable = 'on';
                    end
                end
            end

        end

        % Value changed function: NodeTypeDropDown
        function NodeTypeDropDownValueChanged(app, event)

            % Node index
            value = app.NodeTypeDropDown.Value;
            [~, idx2] = ismember(app.AvailableNodesListBox.Value, app.AvailableNodesListBox.Items);  
            
            % Whiten background
            CorrectParamBG(app);

            % Check that a selection is valid based on network topology, if
            % not, respond accordingly
            if strcmp(value,"Outlet") || strcmp(value,"Receiver")
                Populate_LoadTypes(app)
                app.LoadDefinitionPanel.Visible="on";
                try
                    idxsave = 1;
                    for idxsaveind = 1:length(app.StoredData.Loads)
                        if app.StoredData.Nodes(idx2).LoadType == app.StoredData.Loads(idxsaveind).Letter
                            idxsave = idxsaveind;
                            break;
                        end
                    end
                    app.LoadTypeDropDown.Value = app.LoadTypeDropDown.Items{idxsave};
                catch
                    app.LoadTypeDropDown.Value = app.LoadTypeDropDown.Items{1};
                end
                app.ErrorType.Text = 'Node Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Select the node type for each node.' newline newline 'Save the configuration once you have fully defined the node.' newline newline 'Once all node types are defined, proceed to the ''Wiring Definition'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            else
                app.LoadDefinitionPanel.Visible="off";
            end
            if strcmp(value,"None")==false & strcmp(value,"Outlet")==false & strcmp(value,"Receiver")==false %#ok<AND2> 
                app.SaveNodeConfigurationButton.Visible="on";
            else
                app.SaveNodeConfigurationButton.Visible="off";
            end

            % If none, fix troubleshooting
            if strcmp(value,"None")
                app.ErrorType.Text = 'Node Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Select the node type for each node.' newline newline 'Save the configuration once you have fully defined the node.' newline newline 'Once all node types are defined, proceed to the ''Wiring Definition'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            end

            % Check: outlets can be anywhere, except at branching locations
            if strcmp(value,"Outlet")
                if sum(sum(idx2==app.StoredData.Wires)) <= 2
                    % All is good
                    app.ErrorType.Text = 'Node Definition';
                    app.ErrorType.FontColor = [0 0.5 0];
                    app.ErrorMsg.Text = ['Select the node type for each node.' newline newline 'Save the configuration once you have fully defined the node.' newline newline 'Once all node types are defined, proceed to the ''Wiring Definition'' tab.'];
                    app.ErrorMsg.FontColor = [0 0.5 0];
                    app.LoadDefinitionPanel.Enable = 'on';
                else
                    % Warning, can't be a receiver
                    app.ErrorType.Text = 'Error';
                    app.ErrorType.FontColor = 'r';
                    app.ErrorMsg.Text = ['The selected node cannot be an outlet.' newline newline 'Outlets do not branch into more than one outgoing wire.'];
                    app.ErrorMsg.FontColor = 'r';
                    app.LoadDefinitionPanel.Enable = 'off';
                end
            end

            % Check: receiver must have only one connection
            if strcmp(value,"Receiver")
                if sum(sum(idx2==app.StoredData.Wires)) ~= 1
                    % Warning, can't be a receiver
                    app.ErrorType.Text = 'Error';
                    app.ErrorType.FontColor = 'r';
                    app.ErrorMsg.Text = ['The selected node cannot be a receiver.' newline newline 'Receivers are connected to the network by a single wire.'];
                    app.ErrorMsg.FontColor = 'r';
                    app.LoadDefinitionPanel.Enable = 'off';
                else
                    % All is good
                    app.ErrorType.Text = 'Node Definition';
                    app.ErrorType.FontColor = [0 0.5 0];
                    app.ErrorMsg.Text = ['Select the node type for each node.' newline newline 'Save the configuration once you have fully defined the node.' newline newline 'Once all node types are defined, proceed to the ''Wiring Definition'' tab.'];
                    app.ErrorMsg.FontColor = [0 0.5 0];
                    app.LoadDefinitionPanel.Enable = 'on';
                end
            end

            % Check: transmitter must have only one connection
            if strcmp(value,"Transmitter")
                if sum(sum(idx2==app.StoredData.Wires)) ~= 1
                    % warning, can't be a transmitter
                    app.ErrorType.Text = 'Error';
                    app.ErrorType.FontColor = 'r';
                    app.ErrorMsg.Text = ['The selected node cannot be a transmitter.' newline newline 'Transmitters are connected to the network by a single wire.'];
                    app.ErrorMsg.FontColor = 'r';
                    app.SaveNodeConfigurationButton.Enable = 'off';
                else
                    % all is good
                    app.ErrorType.Text = 'Node Definition';
                    app.ErrorType.FontColor = [0 0.5 0];
                    app.ErrorMsg.Text = ['Select the node type for each node.' newline newline 'Save the configuration once you have fully defined the node.' newline newline 'Once all node types are defined, proceed to the ''Wiring Definition'' tab.'];
                    app.ErrorMsg.FontColor = [0 0.5 0];
                    app.SaveNodeConfigurationButton.Enable = 'on';
                end
            end

            % Check: Derivation box
            if strcmp(value,"Derivation Box")
                if sum(sum(idx2==app.StoredData.Wires)) < 2
                    % Warning, can't be a db
                    app.ErrorType.Text = 'Error';
                    app.ErrorType.FontColor = 'r';
                    app.ErrorMsg.Text = ['The selected node cannot be a derivation box.' newline newline 'Derivation boxes are connected to the network by at least two wires.'];
                    app.ErrorMsg.FontColor = 'r';
                    app.SaveNodeConfigurationButton.Enable = 'off';
                else
                    % All is good
                    app.ErrorType.Text = 'Node Definition';
                    app.ErrorType.FontColor = [0 0.5 0];
                    app.ErrorMsg.Text = ['Select the node type for each node.' newline newline 'Save the configuration once you have fully defined the node.' newline newline 'Once all node types are defined, proceed to the ''Wiring Definition'' tab.'];
                    app.ErrorMsg.FontColor = [0 0.5 0];
                    app.SaveNodeConfigurationButton.Enable = 'on';
                end
            end

            % Load load types
            LoadLoadTypes(app,event)

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)

            selectedTab = app.TabGroup.SelectedTab;
            app.TabGroup.SelectedTab = event.OldValue;

        end

        % Menu selected function: SaveModelMenu
        function SaveModelMenuSelected(app, event)

            % Name save file
            try
                [filename, pathname] = uiputfile('*.mat', 'Save As...');
                
                SD=app.StoredData;
                SD.PWD = [];
                SD.COMSOLPATH = [];
                SD.NCond = str2num(app.NumberofConductorsDropDown.Value);
                SD.ParSel = app.ParasiticsDropDown.Value;

                % Add Unlocked as a reminder when reload
                SD.Unlocked = 1;
                if app.EMFieldsButton.Enable == true
                    SD.Unlocked = 6;
                elseif app.VisualizeResultsButton.Enable == true
                    SD.Unlocked = 5;
                elseif app.RunModelButton.Enable == true
                    SD.Unlocked = 4;
                elseif app.WiringDefinitionButton.Enable == true
                    SD.Unlocked = 3;
                elseif app.NodeDefinitionButton.Enable == true
                    SD.Unlocked = 2;
                end
    
                % Save stored data to a file
                save(fullfile(pathname,filename),'SD','-mat');
            end

            app.ENCoRPUIFigure.focus

        end

        % Value changed function: LoadTypeDropDown
        function LoadTypeDropDownValueChanged(app, event)

            % Whiten
            CorrectParamBG(app);

            % Parameter visibility
            LoadLoadTypes2(app,event)
            
            % Don't delete stored values, just displayed values
            app.Param1EditField.Value = '';
            app.Param2EditField.Value = '';
            app.Param3EditField.Value = '';
            app.Param4EditField.Value = '';
            app.Param5EditField.Value = '';
            app.Param6EditField.Value = '';
            app.Param7EditField.Value = '';
            app.Param8EditField.Value = '';
            app.Param9EditField.Value = '';
            app.Param10EditField.Value = '';
            app.Param11EditField.Value = '';
            app.Param12EditField.Value = '';
   
            allowLT = true; % This disables load types "under construction"

            if allowLT

                % Update troubleshooting
                app.ErrorType.Text = 'Node Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Select the node type for each node.' newline newline 'Save the configuration once you have fully defined the node.' newline newline 'Once all node types are defined, proceed to the ''Wiring Definition'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];

                % Enable all param edit fields (only visible ones are truly enabled)
                for ind = 1:12
                    eval(['app.Param',num2str(ind),'EditField.Enable = ''on'';']);
                end
                app.SaveLoadConfigurationButton.Enable = 'on';

                % Load saved values into fields, if any
                if strcmp(app.LoadTypeDropDown.Value,'Custom')
                    try

                        % Pull node index
                        [~, idx1] = ismember(app.AvailableNodesListBox.Value, app.AvailableNodesListBox.Items);
    
                        % Check if filename stored/variable
                        % selected/variable list available
                        FN = app.StoredData.Nodes(idx1).Filename;
                        VarSel = app.StoredData.Nodes(idx1).VarSelected;
                        VarList = app.StoredData.Nodes(idx1).VarList;

                        % Fill values
                        app.FileLabel_2.Text = ['File:  ',FN];
                        app.FileLabel_2.Tooltip = FN;
                        app.VariableDropDown.Items = VarList;
                        app.VariableDropDown.Value = VarSel;

                    catch

                        % Clear all three
                        app.FileLabel_2.Text = 'File:  ';
                        app.FileLabel_2.Tooltip = '';
                        app.VariableDropDown.Items = {};

                    end
                else
                    try
                        % Pull indices
                        [~, idx1] = ismember(app.AvailableNodesListBox.Value, app.AvailableNodesListBox.Items);
                        [~, idx3] = ismember(app.LoadTypeDropDown.Value, app.LoadTypeDropDown.Items);
            
                        % If the stored node matches current load letter
                        if app.StoredData.Nodes(idx1).LoadType == app.StoredData.Loads(idx3).Letter
                            
                            % Iterate through parameters
                            for fefwdlp = 1:length(app.StoredData.Nodes(idx1).LoadParam)
                                try
                                    % Attempt to input saved values, if any
                                    if isnan(app.StoredData.Nodes(idx1).LoadParam(fefwdlp))
                                        eval(['app.Param',num2str(fefwdlp),'EditField.Value = '''';']);
                                    else
                                        eval(['app.Param',num2str(fefwdlp),'EditField.Value = ''',num2str(app.StoredData.Nodes(idx1).LoadParam(fefwdlp)),''';']);
                                    end
                                end
                            end
                        end
                    end
                    try
                        % Add tooltips for fields
                        ParamFieldTooltips(app)
                    end
                end
            else
                % Load limitations if applicable
            end

        end

        % Menu selected function: DebugModeMenu
        function DebugModeMenuSelected(app, event)

            % This function is used to control
            % functionality/visibility/enable/disable for debugging
            % purposes.

%             app.Panel_4.Enable = 'on';
%             app.GeometryDefinitionButton.Enable = 'on';
%             app.NodeDefinitionButton.Enable = 'on';
%             app.WiringDefinitionButton.Enable = 'on';
%             app.RunModelButton.Enable = 'on';
%             app.VisualizeResultsButton.Enable = 'on';
%             app.EMFieldsButton.Enable = 'on';

        end

        % Value changed function: Param1EditField
        function Param1EditFieldValueChanged(app, event)

            value = app.Param1EditField.Value;

            CheckParamField(app,value,1,event);

        end

        % Value changed function: Param2EditField
        function Param2EditFieldValueChanged(app, event)

            value = app.Param2EditField.Value;
           
            CheckParamField(app,value,2,event);

        end

        % Value changed function: Param3EditField
        function Param3EditFieldValueChanged(app, event)

            value = app.Param3EditField.Value;

            CheckParamField(app,value,3,event);

        end

        % Value changed function: Param4EditField
        function Param4EditFieldValueChanged(app, event)

            value = app.Param4EditField.Value;

            CheckParamField(app,value,4,event);

        end

        % Value changed function: Param5EditField
        function Param5EditFieldValueChanged(app, event)

            value = app.Param5EditField.Value;

            CheckParamField(app,value,5,event);

        end

        % Value changed function: Param6EditField
        function Param6EditFieldValueChanged(app, event)

            value = app.Param6EditField.Value;

            CheckParamField(app,value,6,event);

        end

        % Value changed function: Param7EditField
        function Param7EditFieldValueChanged(app, event)

            value = app.Param7EditField.Value;

            CheckParamField(app,value,7,event);

        end

        % Value changed function: Param8EditField
        function Param8EditFieldValueChanged(app, event)

            value = app.Param8EditField.Value;
           
            CheckParamField(app,value,8,event);

        end

        % Value changed function: Param9EditField
        function Param9EditFieldValueChanged(app, event)

            value = app.Param9EditField.Value;

            CheckParamField(app,value,9,event);

        end

        % Value changed function: Param10EditField
        function Param10EditFieldValueChanged(app, event)

            value = app.Param10EditField.Value;

            CheckParamField(app,value,10,event);

        end

        % Value changed function: Param11EditField
        function Param11EditFieldValueChanged(app, event)

            value = app.Param11EditField.Value;

            CheckParamField(app,value,11,event);

        end

        % Value changed function: Param12EditField
        function Param12EditFieldValueChanged(app, event)

            value = app.Param12EditField.Value;

            CheckParamField(app,value,12,event);

        end

        % Button pushed function: VisualizeResultsButton
        function VisualizeResultsButtonPushed(app, event)
            
            if app.TabGroup.SelectedTab ~= app.VisualizationTab

                app.TabGroup.SelectedTab = app.VisualizationTab;
                
                nwires = str2num(app.NumberofConductorsDropDown.Value);

                if nwires == 2
                    app.Phase3Button.Visible = 'off';
                    app.Phase2Button.Visible = 'off';
                    app.Phase2EditField.Visible = 'off';
                    app.Phase3EditField.Visible = 'off';
                    app.oEditField_2Label.Visible = false;
                    app.oEditField_3Label.Visible = false;
                    app.oEditField_2.Visible = false;
                    app.oEditField_3.Visible = false;
                elseif nwires == 3 % outdated
                    app.Phase3Button.Visible = 'off';
                    app.Phase2Button.Visible = 'on';
                elseif nwires == 4
                    app.oEditField_2Label.Visible = true;
                    app.oEditField_3Label.Visible = true;
                    app.oEditField_2.Visible = true;
                    app.oEditField_3.Visible = true;
                    app.Phase2EditField.Visible = 'on';
                    app.Phase3EditField.Visible = 'on';
                    app.Phase2Button.Visible = 'on';
                    app.Phase3Button.Visible = 'on';
                end
    
                app.ErrorType.Text = 'Visualize Results';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Spatial Voltage and Current Tab:' newline 'Use the slider to visualize the results for the nearest available frequency. Optionally export the results in MAT, PNG, or animated GIF form.' newline newline 'Transmission Parameters Tab:' newline 'Use the drop downs and check boxes to display transmission parameters from the transmitter to the receiver.'];
                app.ErrorMsg.FontColor = [0 0.5 0];
    
                app.EMFieldsButton.Enable = 'on';
                app.EMFieldsButton.Tooltip = '';

                app.TabGroup5.SelectedTab = app.SpatialVoltageandCurrentTab;
                TabGroup5SelectionChanged(app, event);

            else
                app.ErrorType.Text = 'Visualize Results';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Spatial Voltage and Current Tab:' newline 'Use the slider to visualize the results for the nearest available frequency. Optionally export the results in MAT, PNG, or animated GIF form.' newline newline 'Transmission Parameters Tab:' newline 'Use the drop downs and check boxes to display transmission parameters from the transmitter to the receiver.'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            end

        end

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)

            value = app.FrequencySlider.Value;
            PlotModelResults(app)

        end

        % Button pushed function: GeneratePNGButton
        function GeneratePNGButtonPushed(app, event)

            try
                [filename, pathname] = uiputfile('*.png', 'Save As...');
                try
                    exportgraphics(app.UIAxes3,fullfile(pathname,filename));
                catch
                    if ~strcmp(filename(end-3:end),'.png')
                        exportgraphics(app.UIAxes3,fullfile(pathname,filename,'.png'));
                    end
                end
            end

            app.ENCoRPUIFigure.focus

        end

        % Button pushed function: GenerateAnimatedGIFButton
        function GenerateAnimatedGIFButtonPushed(app, event)

            try
                [filename, pathname] = uiputfile('*.gif', 'Save As...');
    
                if ~strcmp(filename(end-3:end),'.gif')
                    filename = [filename,'.gif'];
                end
                
                if ischar(filename) && ischar(pathname)
    
                    app.ENCoRPUIFigure.focus
    
                    Answer2 = uiconfirm(app.ENCoRPUIFigure,['This will cycle through all of the ',num2str(length(app.StoredData.results.Freq)),' simulated frequencies and may take some time depending on the system''s computational ability. Proceed?'],'Confirmation','Options',{'Yes','No'},'Icon','warning');
                    if strcmp(Answer2,'No')
                        return
                    end
    
                    % Generate a list of frequencies to simulate
                    fqs = app.StoredData.results.Freq;
        
                    % Iterate through frequencies
                    loops = Inf;
    
                    app.Menu.Enable = 'off';
                    app.HelpMenu.Enable = 'off';
                    app.FileMenu.Enable = 'off';
                    app.LeftPanel.Enable = 'off';
                    app.Panel_4.Enable = 'off';
    
                    % BG
                    app.NetworkDiagramPanel.BackgroundColor = [1 1 1];
        
                    for idx = 1:length(fqs)
                        app.FrequencySlider.Value = log10(fqs(idx));
                        PlotModelResults(app)
                        drawnow;
        
                        % Read in i-th frame
                        frame = getframe(app.ENCoRPUIFigure,[311 345 868 355]);
                        im{idx} = frame2im(frame);
                        [M,c_map] = rgb2ind(im{idx},256);
        
                        % Append or create if first frame
                        if idx == 1
                            imwrite(M,c_map,fullfile(pathname,filename),'gif','LoopCount',loops,'DelayTime',app.FrameDelayEditField.Value);
                        else
                            imwrite(M,c_map,fullfile(pathname,filename),'gif','WriteMode','append','DelayTime',app.FrameDelayEditField.Value); %#ok<*NBRAK2> 
                        end
                    end
    
                    % BG
                    app.NetworkDiagramPanel.BackgroundColor = [0.94 0.94 0.94];
    
                    app.Menu.Enable = 'on';
                    app.HelpMenu.Enable = 'on';
                    app.FileMenu.Enable = 'on';
                    app.LeftPanel.Enable = 'on';
                    app.Panel_4.Enable = 'on';
    
                end
            end

            app.ENCoRPUIFigure.focus

        end

        % Selection changed function: PhaseSelectionButtonGroup
        function PhaseSelectionButtonGroupSelectionChanged(app, event)

            selectedButton = app.PhaseSelectionButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,"Phase 1")
                app.StoredData.plotfcn=1;
            elseif strcmp(selectedButton.Text,"Phase 2")
                app.StoredData.plotfcn=2;
            elseif strcmp(selectedButton.Text,"Phase 3")
                app.StoredData.plotfcn=3;
            else
                app.StoredData.plotfcn=4;
            end
            
            PlotModelResults(app)

        end

        % Selection changed function: OutputTypeButtonGroup
        function OutputTypeButtonGroupSelectionChanged(app, event)

            selectedButton = app.OutputTypeButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,"Current")
                app.StoredData.plottype = 2;
            elseif strcmp(selectedButton.Text,"Voltage")
                app.StoredData.plottype = 1;
            end
            PlotModelResults(app)

        end

        % Button pushed function: SwapNodesButton
        function SwapNodesButtonPushed(app, event)

            % Pull index of wire 
            [~, idx1] = ismember(app.WireListListBox.Value, app.WireListListBox.Items); 

            % Error check index
            if (isempty(idx1)) && (size(app.StoredData.Wires,1) == 0)
                app.ErrorType.Text = 'Error';
                app.ErrorType.FontColor = 'r';
                app.ErrorMsg.Text = ['No wires defined' newline 'for node swap.'];
                app.ErrorMsg.FontColor = 'r';
            elseif (isempty(idx1)) && (size(app.StoredData.Wires,1) > 0)
                app.ErrorType.Text = 'Error';
                app.ErrorType.FontColor = 'r';
                app.ErrorMsg.Text = 'Please select a wire whose nodes you would like to swap.';
                app.ErrorMsg.FontColor = 'r';
            else
                % Guidance
                app.ErrorType.Text = 'Geometry Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Build or load-in a network.' newline newline 'Swap between the ''Nodes'' and ''Wires'' tabs to define your network.' newline newline 'Once a full network topology has been constructed (n nodes, n-1 wires), proceed to the ''Node Definition'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];

                % Swap
                o1=app.StoredData.Wires(idx1,1);
                o2=app.StoredData.Wires(idx1,2);
                app.StoredData.lastEntity=2; 
                app.StoredData.EntityList=[o1,o2];
                
                app.StoredData.Wires(idx1,1:2)=[o2,o1];
                UpdateWireList(app)
                app.StoredData.lastEntity = 0; 

                % Highlight
                app.SourceNodeListBox.Value = {};
                app.DestinationNodeListBox.Value = {};
                app.WireListListBox.Value=app.WireListListBox.Items(idx1);
            end

        end

        % Button pushed function: SaveLoadConfigurationButton
        function SaveLoadConfigurationButtonPushed(app, event)

            % We want to save the information contained within the boxes for
            % passing into the model
            [~, idx1] = ismember(app.AvailableNodesListBox.Value, app.AvailableNodesListBox.Items);
            [~, idx2] = ismember(app.NodeTypeDropDown.Value, app.NodeTypeDropDown.Items);
            [~, idx3] = ismember(app.LoadTypeDropDown.Value, app.LoadTypeDropDown.Items);
            UpdateStoredLoads(app,event)

            % First, clear existing information if resaving
            app.StoredData.Nodes(idx1).LoadParam = []; 
            app.StoredData.Nodes(idx1).NodeType = idx2;
            app.StoredData.Nodes(idx1).LoadType = app.StoredData.Loads(idx3).Letter;
            app.StoredData.Nodes(idx1).Filename = '';
            app.StoredData.Nodes(idx1).VarList = {};
            app.StoredData.Nodes(idx1).VarSelected = '';
            try
                failed = 0;
                for idx = 1:length(app.StoredData.Loads(idx3).Parameters)
                    try
                        app.StoredData.Nodes(idx1).LoadParam(idx) = str2num(eval(['app.Param',num2str(idx),'EditField.Value']));
                        lowb = app.StoredData.Loads(idx3).Bounds(idx,1);
                        uppb = app.StoredData.Loads(idx3).Bounds(idx,2);
                        tmpval = app.StoredData.Nodes(idx1).LoadParam(idx);
                        if ~(~isempty(tmpval) && ~isnan(tmpval) && isreal(tmpval) && (tmpval <= uppb) && (tmpval >= lowb))
                            error('Invalid load parameter.')
                        end
                    catch
                        app.StoredData.Nodes(idx1).LoadParam(idx) = nan;
                        failed = failed+1;
                    end
                end

                % Check for custom load
                if strcmp(app.LoadTypeDropDown.Value,'Custom')
                    
                    if isempty(app.VariableDropDown.Value)
                        failed = -1;
                        error('No file or variable selected.');
                    else
                        nc1 = [];
                        nc2 = 2;
                        for nccind = 1:length(app.StoredData.Nodes)
                            if nccind ~= idx1
                                try
                                    if app.StoredData.Nodes(nccind).LoadType == "X"
                                        if size(app.StoredData.Nodes(nccind).LoadParam,2) == 1
                                            nc1 = 1;
                                            break
                                        else
                                            nc1 = 3;
                                            break
                                        end
                                    end
                                end
                            end
                        end
                        if eval(['size(app.StoredData.Nodes(idx1).tmpLD.',app.VariableDropDown.Value,',2) == 1'])
                            nc2 = 1;
                        else
                            nc2 = 3;
                        end
                        
                        if ~isempty(nc1) && (nc1 ~= nc2)
                            app.StoredData.Nodes(idx1).VarSelected = '';
                            
                            % Plot with privilege
                            app.StoredData.lastEntity = 1;
                            app.StoredData.EntityList = [app.StoredData.X(idx1), app.StoredData.Y(idx1), app.StoredData.Z(idx1)];
                            PlotWires(app) 
                            app.StoredData.lastEntity = 0;
                            failed = -1;
                            Answer = uiconfirm(app.ENCoRPUIFigure,'The user supplied custom load could not be saved. The dimensionality of the data conflicts with previously defined custom loads (i.e. indicating a single- versus three-phase network). This custom load was not saved.','Error','Options',{'Ok','Cancel'},'Icon','error');
                            error('Does not conform.')
                        end

                        app.StoredData.Nodes(idx1).Filename = app.FileLabel_2.Text(8:end);
                        app.StoredData.Nodes(idx1).VarSelected = app.VariableDropDown.Value;
                        app.StoredData.Nodes(idx1).VarList = app.VariableDropDown.Items;
                        app.StoredData.Nodes(idx1).LoadParam = eval(['app.StoredData.Nodes(idx1).tmpLD.',app.VariableDropDown.Value,';']);
                    end
                end

                % Turn off other buttons
                app.WiringDefinitionButton.Enable = 'off';
                app.RunModelButton.Enable = 'off';
                %ResetRMT(app);
                app.SimulateButton.Enable = 'off';
                app.SimulationDetailsPanel.Enable = 'off';
                app.VisualizeResultsButton.Enable = 'off';
                app.EMFieldsButton.Enable = 'off';
                try
                    app.StoredData = rmfield(app.StoredData,'EMTabAxes');
                end
                app.WiringDefinitionButton.Tooltip = 'Define all node types and associated parameters for this tab to unlock.';
                app.RunModelButton.Tooltip = 'Define the properties for all conductors in all wires for this tab to unlock.';
                app.VisualizeResultsButton.Tooltip = 'Save your network and simulate the model for this tab to unlock.';
                app.EMFieldsButton.Tooltip = 'This will unlock once the Visualize Results tab has been visited.';

                % Plot with privilege
                app.StoredData.lastEntity = 1;
                app.StoredData.EntityList = [app.StoredData.X(idx1), app.StoredData.Y(idx1), app.StoredData.Z(idx1)];
                PlotWires(app) 
                app.StoredData.lastEntity = 0;

                if failed > 0 % breakout 
                    error('Not all load params defined properly.')
                end

                % Delete saved wire info
                %if isfield(app.StoredData,'WireProps')
                %    ResetWiringTab(app)
                %end

                app.ErrorType.Text = 'Node Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Select the node type for each node.' newline newline 'Save the configuration once you have fully defined the node.' newline newline 'Once all node types are defined, proceed to the ''Wiring Definition'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];

            catch
                app.ErrorType.Text = 'Warning';
                app.ErrorType.FontColor = [0.5 0.5 0];
                if failed > 1
                    app.ErrorMsg.Text = ['Your progress has been saved, but ',num2str(failed),' load parameters are missing to fully define this load type.'];
                elseif failed == 1
                    app.ErrorMsg.Text = ['Your progress has been saved, but ',num2str(failed),' load parameter is missing to fully define this load type.'];
                elseif failed == -1
                    app.ErrorMsg.Text = 'You must select an appropriate variable from a loaded file before saving this load configuration.';
                    
                    % Turn off other buttons
                    app.WiringDefinitionButton.Enable = 'off';
                    app.RunModelButton.Enable = 'off';
                    %ResetRMT(app);
                    app.SimulateButton.Enable = 'off';
                    app.SimulationDetailsPanel.Enable = 'off';
                    app.VisualizeResultsButton.Enable = 'off';
                    app.EMFieldsButton.Enable = 'off';
                    try
                        app.StoredData = rmfield(app.StoredData,'EMTabAxes');
                    end
                    app.WiringDefinitionButton.Tooltip = 'Define all node types and associated parameters for this tab to unlock.';
                    app.RunModelButton.Tooltip = 'Define the properties for all conductors in all wires for this tab to unlock.';
                    app.VisualizeResultsButton.Tooltip = 'Save your network and simulate the model for this tab to unlock.';
                    app.EMFieldsButton.Tooltip = 'This will unlock once the Visualize Results tab has been visited.';
    
                    % Plot with privilege
                    app.StoredData.lastEntity = 1;
                    app.StoredData.EntityList = [app.StoredData.X(idx1), app.StoredData.Y(idx1), app.StoredData.Z(idx1)];
                    PlotWires(app) 
                    app.StoredData.lastEntity = 0;
                end
                app.ErrorMsg.FontColor = [0.5 0.5 0];
            end
            
        end

        % Button pushed function: ClearNodeConfigurationButton
        function ClearNodeConfigurationButtonPushed(app, event)

            % Whiten 
            CorrectParamBG(app);

            try
                % Pull node index
                [~, idx1] = ismember(app.AvailableNodesListBox.Value, app.AvailableNodesListBox.Items);
                
                % Reset nodal data
                try
                    if app.StoredData.Nodes(idx1).LoadType == "X"
                        app.StoredData.Nodes(idx1).Filename = '';
                        app.StoredData.Nodes(idx1).VarSelected = [];
                        app.StoredData.Nodes(idx1).VarList = {};
                        app.FileLabel_2.Text = 'File:  ';
                        app.FileLabel_2.Tooltip = '';
                        app.VariableDropDown.Items = {};
                    end
                end
                app.StoredData.Nodes(idx1).LoadTypenum = -1;
                app.StoredData.Nodes(idx1).LoadParam=[];
                app.StoredData.Nodes(idx1).LoadType='';
                app.StoredData.Nodes(idx1).NodeType=0; 
                
                % Default to same node in list box
                AvailableNodesListBoxValueChanged(app,event)

                % Delete saved wire info
                %if isfield(app.StoredData,'WireProps')
                %    ResetWiringTab(app);
                %end

                % Turn off other buttons
                app.WiringDefinitionButton.Enable = 'off';
                app.RunModelButton.Enable = 'off';
                %ResetRMT(app);
                app.SimulateButton.Enable = 'off';
                app.SimulationDetailsPanel.Enable = 'off';
                app.VisualizeResultsButton.Enable = 'off';
                app.EMFieldsButton.Enable = 'off';
                try
                    app.StoredData = rmfield(app.StoredData,'EMTabAxes');
                end
                app.WiringDefinitionButton.Tooltip = 'Define all node types and associated parameters for this tab to unlock.';
                app.RunModelButton.Tooltip = 'Define the properties for all conductors in all wires for this tab to unlock.';
                app.VisualizeResultsButton.Tooltip = 'Save your network and simulate the model for this tab to unlock.';
                app.EMFieldsButton.Tooltip = 'This will unlock once the Visualize Results tab has been visited.';
            
                % Update trouble shooting
                app.ErrorType.Text = 'Node Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Select the node type for each node.' newline newline 'Save the configuration once you have fully defined the node.' newline newline 'Once all node types are defined, proceed to the ''Wiring Definition'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            catch
                % Error in troubleshooting
                app.ErrorType.Text = 'Error';
                app.ErrorType.FontColor = 'r';
                app.ErrorMsg.Text = 'A node must be selected before clearing.';
                app.ErrorMsg.FontColor = 'r';
            end
            
        end

        % Button pushed function: SaveNodeConfigurationButton
        function SaveNodeConfigurationButtonPushed(app, event)

            % Delete load parameter information, if any
            try
                [~, idx1] = ismember(app.AvailableNodesListBox.Value, app.AvailableNodesListBox.Items);
                app.StoredData.Nodes(idx1).LoadParam = [];
                app.StoredData.Nodes(idx1).LoadType = '';
                app.StoredData.Nodes(idx1).NodeType = 0;
            end

            try
                [~, idx1] = ismember(app.AvailableNodesListBox.Value, app.AvailableNodesListBox.Items);
                [~, idx2] = ismember(app.NodeTypeDropDown.Value, app.NodeTypeDropDown.Items);
                UpdateStoredLoads(app,event)
                app.StoredData.Nodes(idx1).NodeType=idx2;

                % Turn off other buttons
                app.WiringDefinitionButton.Enable = 'off';
                app.RunModelButton.Enable = 'off';
                %ResetRMT(app);
                app.SimulateButton.Enable = 'off';
                app.SimulationDetailsPanel.Enable = 'off';
                app.VisualizeResultsButton.Enable = 'off';
                app.EMFieldsButton.Enable = 'off';
                try
                    app.StoredData = rmfield(app.StoredData,'EMTabAxes');
                end
                app.WiringDefinitionButton.Tooltip = 'Define all node types and associated parameters for this tab to unlock.';
                app.RunModelButton.Tooltip = 'Define the properties for all conductors in all wires for this tab to unlock.';
                app.VisualizeResultsButton.Tooltip = 'Save your network and simulate the model for this tab to unlock.';
                app.EMFieldsButton.Tooltip = 'This will unlock once the Visualize Results tab has been visited.';
                
                % Plot with privilege for adjusted node
                app.StoredData.lastEntity = 1;
                app.StoredData.EntityList = [app.StoredData.X(idx1), app.StoredData.Y(idx1), app.StoredData.Z(idx1)];
                PlotWires(app)
                app.StoredData.lastEntity = 0;

                % Delete saved wire info
                %if isfield(app.StoredData,'WireProps')
                %    ResetWiringTab(app)
                %end
                
                app.ErrorType.Text = 'Node Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Select the node type for each node.' newline newline 'Save the configuration once you have fully defined the node.' newline newline 'Once all node types are defined, proceed to the ''Wiring Definition'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            catch
                app.ErrorType.Text = 'Error';
                app.ErrorType.FontColor = 'r';
                app.ErrorMsg.Text = 'A node must be selected before saving.';
                app.ErrorMsg.FontColor = 'r';
            end
        end

        % Button pushed function: ApplytoAllConductorsButton
        function ApplytoAllConductorsButtonPushed(app, event)

            % Number of conductors
            rr=app.NumberofConductorsDropDown.Value;
            rr=str2num(rr);

            % Current tab number
            if strcmp(app.TabGroup3.SelectedTab.Title,'Ground')
                CurrTab = 1;
            else
                CurrTab=str2num(app.TabGroup3.SelectedTab.Title(end))+1;
            end

            % Ensure relation to DC is satisfied
            conditionsmet = 2;
            RW = str2num(eval(['app.WireRadiusEditField_',num2str(CurrTab),'.Value;']));
            DC = str2num(app.SeparationDistanceEditField.Value);
            if isempty(DC) || isnan(DC) || DC <= 0 || DC > app.StoredData.DCUL
                conditionsmet = 2;
            elseif DC <= 2*RW
                conditionsmet = 1;
            else
                conditionsmet = 3;
            end
            
            if conditionsmet
                % Wire ID
                [~, xdx] = ismember(app.WireListListBox_2.Value, app.WireListListBox_2.Items);  
                if CurrTab == 1
                    newsig = app.sigmaEditField.Value;
                else
                    eval(['newsig = app.sigmaEditField_',num2str(CurrTab),'.Value;']);
                end
                app.StoredData.SigPrev(xdx,:) = newsig;
                for idx=1:rr
                    if idx==CurrTab
                        % Spread these values don't change them!
                    else
                        eval(['app.WireMaterialDropDown_',num2str(idx),'.Value=app.WireMaterialDropDown_',num2str(CurrTab),'.Value;']);
                        eval(['app.WireRadiusEditField_',num2str(idx),'.Value=app.WireRadiusEditField_',num2str(CurrTab),'.Value;']);
                        
                        if idx == 1
                            app.sigmaEditField.Value = newsig; 
                        else
                            eval(['app.sigmaEditField_',num2str(idx),'.Value = newsig;']);
                        end
                    end
                    SetWireProps(app,event,idx);
                end
    
                CheckApplyBoxes(app);
                UpdateWireList(app);
    
                app.StoredData.lastEntity = 2;
                o1=app.StoredData.Wires(xdx,1);
                o2=app.StoredData.Wires(xdx,2);
                app.StoredData.EntityList = [o1,o2];
                PlotWires(app)
                app.StoredData.lastEntity = 0;
                if conditionsmet == 2 || conditionsmet == 3
                    app.ErrorType.Text = 'Wiring Definition';
                    app.ErrorType.FontColor = [0 0.5 0];
                    app.ErrorMsg.Text = ['Define the properties of each wire.' newline newline 'Select the number of conductors. Then, swap between the Wires and Conductor tabs or use the ''Apply to All'' buttons to set values.' newline newline 'Once all wires and conductors have been defined, proceed to the ''Run Model'' tab.'];
                    app.ErrorMsg.FontColor = [0 0.5 0];
                elseif conditionsmet == 1
                    app.ErrorType.Text = 'Error';
                    app.ErrorType.FontColor = [1 0 0];
                    app.ErrorMsg.Text = ['The wire radius is not less than half the separation distance.' newline newline 'The operation proceeded, but values need to be adjusted accordingly.'];
                    app.ErrorMsg.FontColor = [1 0 0];
                end
    
                % Return to initial wire list box item
                app.WireListListBox_2.Value = app.WireListListBox_2.Items{xdx};

                % Reset all backgrounds
                app.WireRadiusEditField_1.BackgroundColor = [1 1 1];
                app.WireRadiusEditField_2.BackgroundColor = [1 1 1];
                app.WireRadiusEditField_3.BackgroundColor = [1 1 1];
                app.WireRadiusEditField_4.BackgroundColor = [1 1 1];
                if conditionsmet == 3
                    app.SeparationDistanceEditField.BackgroundColor = [1 1 1];
                elseif conditionsmet == 1
                    app.SeparationDistanceEditField.BackgroundColor = [0.9 0.9 0];
                end
            end

        end

        % Button pushed function: ApplytoAllWiresButton
        function ApplytoAllWiresButtonPushed(app, event)

            % Pull wire ID
            [~, idx1] = ismember(app.WireListListBox_2.Value, app.WireListListBox_2.Items);

            % Pull number of conductors
            rr = app.NumberofConductorsDropDown.Value;
            rr = str2num(rr);

            % Iterate through all wires
            for idx = 1:size(app.StoredData.Wires,1)

                % Skip wire from which we're copying
                if idx == idx1
                    % Skip
                else 

                    % Initialize overall properties
                    [~, idx3] = ismember(app.InsulatorMaterialDropDown.Value,app.InsulatorMaterialDropDown.Items);
                    app.StoredData.WireProps(idx).InsMatl = idx3;
                    app.StoredData.EpsPrev(idx) = app.epsilonEditField.Value;
                    app.StoredData.WireProps(idx).DC = str2num(app.SeparationDistanceEditField.Value);

                    % Copy properties for each conductor
                    for jdx=1:rr
                        [~, idx2] = eval(['ismember(app.WireMaterialDropDown_',num2str(jdx),'.Value,app.WireMaterialDropDown_',num2str(jdx),'.Items);']);
                        app.StoredData.WireProps(idx).Radius(jdx) = str2num(eval(['app.WireRadiusEditField_',num2str(jdx),'.Value;']));
                        app.StoredData.WireProps(idx).Matl(jdx) = idx2;
                        if jdx == 1
                            app.StoredData.SigPrev(idx,jdx) = app.sigmaEditField.Value;
                        else
                            app.StoredData.SigPrev(idx,jdx) = eval(['app.sigmaEditField_',num2str(jdx),'.Value;']);
                        end
                    end
                end
            end

            % Update wire list
            UpdateWireList(app)

            % Plot with privilege
            app.StoredData.lastEntity = 2;
            o1=app.StoredData.Wires(idx1,1);
            o2=app.StoredData.Wires(idx1,2);
            app.StoredData.EntityList = [o1,o2];
            PlotWires(app)
            app.StoredData.lastEntity = 0;

            % Troubleshooting
            app.ErrorType.Text = 'Wiring Definition';
            app.ErrorType.FontColor = [0 0.5 0];
            app.ErrorMsg.Text = ['Define the properties of each wire.' newline newline 'Select the number of conductors. Then, swap between the Wires and Conductor tabs or use the ''Apply to All'' buttons to set values.' newline newline 'Once all wires and conductors have been defined, proceed to the ''Run Model'' tab.'];
            app.ErrorMsg.FontColor = [0 0.5 0];

        end

        % Value changed function: WireRadiusEditField_4
        function WireRadiusEditField_4ValueChanged(app, event)

            WireRadiusUpdate(app,event,'4');

        end

        % Value changed function: WireRadiusEditField_3
        function WireRadiusEditField_3ValueChanged(app, event)

            WireRadiusUpdate(app,event,'3');

        end

        % Value changed function: WireRadiusEditField_2
        function WireRadiusEditField_2ValueChanged(app, event)

            WireRadiusUpdate(app,event,'2');

        end

        % Value changed function: WireRadiusEditField_1
        function WireRadiusEditField_1ValueChanged(app, event)

            WireRadiusUpdate(app,event,'1');

        end

        % Value changed function: WireMaterialDropDown_1
        function WireMaterialDropDown_1ValueChanged(app, event)

            value = app.WireMaterialDropDown_1.Value;
            SetWireProps(app,event,1)

        end

        % Value changed function: WireMaterialDropDown_2
        function WireMaterialDropDown_2ValueChanged(app, event)

            value = app.WireMaterialDropDown_2.Value;
            SetWireProps(app,event,2)

        end

        % Value changed function: WireMaterialDropDown_3
        function WireMaterialDropDown_3ValueChanged(app, event)

            value = app.WireMaterialDropDown_3.Value;
            SetWireProps(app,event,3)

        end

        % Value changed function: WireMaterialDropDown_4
        function WireMaterialDropDown_4ValueChanged(app, event)

            value = app.WireMaterialDropDown_4.Value;
            SetWireProps(app,event,4)

        end

        % Button pushed function: RemoveWireDefinitionButton
        function RemoveWireDefinitionButtonPushed(app, event)
            
            % Pull wire index
            [~, idx1] = ismember(app.WireListListBox_2.Value, app.WireListListBox_2.Items);
            Xv=app.StoredData.X(app.StoredData.Wires(idx1,:));
            Yv=app.StoredData.Y(app.StoredData.Wires(idx1,:));
            Zv=app.StoredData.Z(app.StoredData.Wires(idx1,:));

            % Correct stored and displayed length
            len = norm([diff(Xv),diff(Yv),diff(Zv)]);
            app.StoredData.WireLengths(idx1) = len;
            app.EditField.Value = len;

            % Empty the radii values, matl values and unset DC
            app.StoredData.WireProps(idx1).Radius=[];
            SetDC(app,2,'unused')
            app.StoredData.WireProps(idx1).InsMatl = [];
            app.StoredData.WireProps(idx1).Matl=[];
            for idx=1:4
                % Fix all edit fields and backgrounds
                eval(strtrim(['app.WireRadiusEditField_',strtrim(num2str(idx)),'.Value='''';']),''); %#ok<*EV2IN> 
                eval(strtrim(['app.WireRadiusEditField_',strtrim(num2str(idx)),'.BackgroundColor=[1 1 1];']),''); %#ok<*EV2IN> 
                eval(strtrim(['app.WireMaterialDropDown_',strtrim(num2str(idx)),'.Value=app.WireMaterialDropDown_',strtrim(num2str(idx)),'.Items{1};']),'');
                if idx == 1
                    app.sigmaEditField.Value = 5.85;
                    app.sigmaEditField.Enable = 'off';
                else
                    eval(['app.sigmaEditField_',num2str(idx),'.Value = 5.85;']);
                    eval(['app.sigmaEditField_',num2str(idx),'.Enable = ''off'';']);
                end
            end
            app.SeparationDistanceEditField.BackgroundColor = [1 1 1];
            app.StoredData.SigPrev(idx1,:) = 5.85;
            app.epsilonEditField.Value = 3.6;
            app.StoredData.EpsPrev(idx1) = 3.6;
            app.epsilonEditField.Enable = 'off';
            app.InsulatorMaterialDropDown.Value = app.InsulatorMaterialDropDown.Items{1};
            CheckApplyBoxes(app)
            UpdateWireList(app)
            app.WireListListBox_2.Value = app.WireListListBox_2.Items{idx1};
            app.StoredData.lastEntity = 2;
            o1=app.StoredData.Wires(idx1,1);
            o2=app.StoredData.Wires(idx1,2);
            app.StoredData.EntityList = [o1,o2];
            PlotWires(app)
            app.StoredData.lastEntity = 0;
            app.ErrorType.Text = 'Wiring Definition';
            app.ErrorType.FontColor = [0 0.5 0];
            app.ErrorMsg.Text = ['Define the properties of each wire.' newline newline 'Select the number of conductors. Then, swap between the Wires and Conductor tabs or use the ''Apply to All'' buttons to set values.' newline newline 'Once all wires and conductors have been defined, proceed to the ''Run Model'' tab.'];
            app.ErrorMsg.FontColor = [0 0.5 0];
        end

        % Button pushed function: SaveModelButton
        function SaveModelButtonPushed(app, event)

            app.SimulateButton.Enable = 'off';
            app.SimulationDetailsPanel.Enable = 'off';

            % Determine boundary sizes and set accordingly for E&M tab
            nodes = [app.StoredData.X' app.StoredData.Y' app.StoredData.Z'];
            minx = min(nodes(:,1)); maxx = max(nodes(:,1));
            miny = min(nodes(:,2)); maxy = max(nodes(:,2));
            minz = min(nodes(:,3)); maxz = max(nodes(:,3));
            xdis = maxx-minx; ydis = maxy-miny; zdis = maxz-minz;

            % Set percentage for minimum domain expansion
            fchbd = 0.1; % exists in two places

            % Store for future use with EM model
            app.StoredData.EMdom = [minx-fchbd*abs(xdis), maxx+fchbd*abs(xdis), miny-fchbd*abs(ydis), maxy+fchbd*abs(ydis), minz-fchbd*abs(zdis), maxz+fchbd*abs(zdis)]; 
            
            % Verifies not swapped
            verifyDom(app);

            % Prepare EM tab
            app.StoredData.MinDom = app.StoredData.EMdom;
            app.XminEditField.Value = app.StoredData.EMdom(1);
            app.XmaxEditField.Value = app.StoredData.EMdom(2);
            app.YminEditField.Value = app.StoredData.EMdom(3);
            app.YmaxEditField.Value = app.StoredData.EMdom(4);
            app.ZminEditField.Value = app.StoredData.EMdom(5);
            app.ZmaxEditField.Value = app.StoredData.EMdom(6);

            % Begin saving for eventual use by python model
            x = app.StoredData;
            if ~isfield(x.Nodes,'LoadTypenum')
                for uuind = 1:length(x.X)
                    x.Nodes(uuind).LoadTypenum = [];
                end
            end
            if ~isfield(x.Nodes,'LoadType')
                for uuind = 1:length(x.X)
                    x.Nodes(uuind).LoadType = [];
                end
            end
            if ~isfield(x.Nodes,'LoadParam')
                for uuind = 1:length(x.X)
                    x.Nodes(uuind).LoadParam = [];
                end
            end
            for idx=1:length(x.Nodes)
                if isempty(x.Nodes(idx).LoadTypenum)
                    x.Nodes(idx).LoadTypenum=-1;
                end
            end

            NetworkData.X = x.X;
            NetworkData.Y = x.Y;
            NetworkData.Z = x.Z;
            NetworkData.Wires = x.Wires;
            pdrad = -ones(size(x.Wires,1),length(x.WireProps(1).Radius));
            pddc = -ones(size(x.Wires,1),1);
            
            % Convert rw and dc to meters before storing
            for pdind = 1:size(x.Wires,1)
                pdrad(pdind,:) = 1e-3*x.WireProps(pdind).Radius;
                pddc(pdind) = 1e-3*x.WireProps(pdind).DC;
            end
            pdnt = -ones(length(x.X),1);
            pdltn = -ones(length(x.X),1);
            pdlt = -ones(length(x.X),1);
            pdlp = cell(length(x.X),1);

            for pdind = 1:length(x.X)
                if isempty(x.Nodes(pdind).NodeType)
                    pdnt(pdind) = -1;
                else
                    pdnt(pdind) = x.Nodes(pdind).NodeType;
                end
                if isempty(x.Nodes(pdind).LoadTypenum)
                    pdltn(pdind) = -1;
                else
                    pdltn(pdind) = x.Nodes(pdind).LoadTypenum;
                end
                if isempty(x.Nodes(pdind).LoadType)
                    pdlt(pdind) = -1;
                else
                    pdlt(pdind) = x.Nodes(pdind).LoadType;
                end
                pdlp{pdind} = x.Nodes(pdind).LoadParam;
            end

            % Fix scaling of load parameters before storing and add PC
            if strcmp(app.ParasiticsDropDown.Value,'On')
                Cap = 1e-9;
            elseif strcmp(app.ParasiticsDropDown.Value,'Minimal')
                Cap = 1e-11;
            else
                error('Unexpected PC selection.')
            end
            for pdind = 1:length(x.X)
                if (pdltn(pdind) ~= -1) && (pdltn(pdind) ~= 7)
                    for pdind2 = 1:length(x.Loads(pdltn(pdind)).Scale)
                        pdlp{pdind}(pdind2) = pdlp{pdind}(pdind2)*x.Loads(pdltn(pdind)).Scale(pdind2);
                    end

                    % Add PC as final parameter
                    pdlp{pdind}(end+1) = Cap;
                end
            end

            % Convert outlets to series/parallel outlets
            for spind = 1:length(pdnt)
                if pdnt(spind) == 5
                    % It's an outlet, determine which:
                    numocc = sum(sum(x.Wires==spind));
                    if numocc > 1
                        pdnt(spind) = 6; % parallel outlet if more than one occurrence
                    end
                end
            end

            NetworkData.DC = pddc;
            NetworkData.Radius = pdrad;
            NetworkData.NodeType = pdnt;
            NetworkData.LoadTypenum = pdltn;
            NetworkData.LoadType = pdlt; % LOAD TYPE HAS NANS BECAUSE CHARACTERS ARE CONVERTED TO NUMERICAL VALUES, BUT IS CORRECT UNTIL THIS FUNCTION CALL!
            NetworkData.LoadParam = pdlp;
            
            % Wire lengths
            NetworkData.WireLength = x.WireLengths;

            % Number of conductors
            if strcmp(app.NumberofConductorsDropDown.Value,'4')
                NetworkData.Ncond = 4;
            elseif strcmp(app.NumberofConductorsDropDown.Value,'2')
                NetworkData.Ncond = 2;
            else
                error('Unexpected Ncond selection.')
            end

            % Add WhichRec
            NetworkData.WhichRec = 1; % equates to Z out off {V,I,Z}
            NetworkData.RecVal = nan; % Unused in current iteration

            % Sigma and epsilon
            eps0 = 8.8541878128e-12;
            sigma = -ones(length(x.X)-1,4);
            epsilon = -ones(length(x.X)-1,1);
            for seind = 1:length(x.X)-1
                for seind2 = 1:NetworkData.Ncond
                    if x.WireProps(seind).Matl(seind2) == 1
                        sigma(seind,seind2) = 1e7 * 5.85;
                    elseif x.WireProps(seind).Matl(seind2) == 2
                        sigma(seind,seind2) = 1e7 * x.SigPrev(seind,seind2);
                    else
                        error('Unexpected sigma selection.')
                    end
                end     
                if isfield(x.WireProps(seind),'InsMatl') && ~isempty(x.WireProps(seind).InsMatl) && x.WireProps(seind).InsMatl == 2
                    epsilon(seind) = eps0 * x.EpsPrev(seind);
                else
                    epsilon(seind) = eps0 * 3.6;
                end
            end

            % Store in NetworkData
            NetworkData.Sigma = sigma;
            NetworkData.Epsilon = epsilon;

            try
                save([app.StoredData.PWD,'Data/ModelHandoff.mat'],'NetworkData','-mat');
            catch
                mkdir([app.StoredData.PWD,'Data']);
                save([app.StoredData.PWD,'Data/ModelHandoff.mat'],'NetworkData','-mat');
            end

            app.SimulationDetailsPanel.Enable = 'on';
            app.SimulateButton.Enable = 'on';

            app.SPICECheckBox.Value = false;
            if strcmp(app.NumberofConductorsDropDown.Value,'4')
                app.SPICECheckBox.Enable = 'off';
                app.SPICECheckBox.Tooltip = 'SPICE comparison only available for single-phase networks.';
            else
                app.SPICECheckBox.Enable = 'on';
                app.SPICECheckBox.Tooltip = '';
            end

            if ~app.SimulationDetailsPanel.Enable
                if isempty(strtrim(app.Label_25.Text))
                    app.UseLoadedFrequenciesCheckBox.Enable = 'off';
                    app.FileLabel.Visible = 'off';
                    app.Label_25.Tooltip = '';
                    app.UseLoadedFrequenciesCheckBox.Value = false;
                end
                app.ErrorType.Text = 'Run Model';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Save the model.' newline newline 'The simulation details will be made available to you once the model save is complete.'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            else
                app.ErrorType.Text = 'Run Model';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Set the simulation parameters.' newline newline 'Define the frequency, injected signal, resolution of simulation, and check the box if you would like to compare against SPICE (see documentation for details).'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            end

            % Tooltip for simbutton
            if ~app.UseLoadedFrequenciesCheckBox.Value
                nsimp = 2*(str2num(app.NumberofConductorsDropDown.Value)-1)*app.StepsEditField.Value*sum(app.StoredData.WireLengths)/(app.WireResolutionEditField.Value/100);
            else
                nsimp = 2*(str2num(app.NumberofConductorsDropDown.Value)-1)*length(app.StoredData.Freq)*sum(app.StoredData.WireLengths)/(app.WireResolutionEditField.Value/100);
            end
            app.SimulateButton.Tooltip = ['The current configuration will generate roughly ',sprintf('%1.1e',nsimp),' data points'];

        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)

            % Turn off tabs
            app.VisualizeResultsButton.Enable =  'off';
            app.EMFieldsButton.Enable = 'off';
            app.VisualizeResultsButton.Tooltip = 'Save your network and simulate the model for this tab to unlock.';
            app.EMFieldsButton.Tooltip = 'This will unlock once the Visualize Results tab has been visited.';
            

            % Check values are correct before attempting simulation
            if strcmp(app.NumberofConductorsDropDown.Value,'2')
                if ~isempty(app.Phase1EditField.Value) && app.Phase1EditField.BackgroundColor(2) ~= 0.75
                    correctPV = true;
                else
                    correctPV = false;
                end
            else
                if ~isempty(app.Phase1EditField.Value) && app.Phase1EditField.BackgroundColor(2) ~= 0.75
                    if ~isempty(app.Phase2EditField.Value) && app.Phase2EditField.BackgroundColor(2) ~= 0.75
                        if ~isempty(app.Phase3EditField.Value) && app.Phase3EditField.BackgroundColor(2) ~= 0.75
                            correctPV = true;
                        else
                            correctPV = false;
                        end
                    else
                        correctPV = false;
                    end
                else
                    correctPV = false;
                end
            end

            if ~correctPV
                % Troubleshooting
                app.ErrorType.Text = 'Error';
                app.ErrorType.FontColor = 'r';
                app.ErrorMsg.Text = ['An injected voltage or current is required for simulation.' newline newline 'Please enter an injection value for each of the available phase wires.'];
                app.ErrorMsg.FontColor = 'r';
            else
                % Troubleshooting
                app.ErrorType.Text = 'Run Model';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = 'Simulating model...';
                app.ErrorMsg.FontColor = [0 0.5 0];
                drawnow;

                % Check frequencies against custom loads, if any
                if app.UseLoadedFrequenciesCheckBox.Value
                    LenF = length(app.StoredData.Freq);
                else
                    LenF = app.StepsEditField.Value;
                end
                szmm = false; % size mismatch
                for lenfind = 1:length(app.StoredData.Nodes)
                    if app.StoredData.Nodes(lenfind).LoadType == "X"
                        if length(size(app.StoredData.Nodes(lenfind).LoadParam)) == 2
                            szLP = size(app.StoredData.Nodes(lenfind).LoadParam,1);
                            if szLP < LenF
                                szmm = true;
                                break;
                            end
                        else
                            szLP = size(app.StoredData.Nodes(lenfind).LoadParam,3);
                            if szLP < LenF
                               szmm = true;
                               break;
                            end
                        end
                    end
                end

                % Troubleshooting
                if szmm
                    UnusedAnswer = uiconfirm(app.ENCoRPUIFigure,'The number of frequencies exceeds the available custom load data. Each row or page of the custom load data corresponds to each sequential frequency defined herein. Please refer to the documentation for additional details.','Error','Options',{'Ok','Cancel'},'Icon','error');
                    app.ErrorType.Text = 'Error';
                    app.ErrorType.FontColor = 'r';
                    app.ErrorMsg.Text = 'The number of simulation frequencies cannot exceed the size of the supplied custom load data.';
                    app.ErrorMsg.FontColor = 'r';
                    return
                end
    
                % Save remaining variables
                % Run Save Model in case variables were "secretly" changed
                % Determine boundary sizes and set accordingly for E&M tab
                nodes = [app.StoredData.X' app.StoredData.Y' app.StoredData.Z'];
                minx = min(nodes(:,1)); maxx = max(nodes(:,1));
                miny = min(nodes(:,2)); maxy = max(nodes(:,2));
                minz = min(nodes(:,3)); maxz = max(nodes(:,3));
                xdis = maxx-minx; ydis = maxy-miny; zdis = maxz-minz;
    
                % Set percentage for minimum domain expansion
                fchbd = 0.1; % exists in two places
    
                % Store for future use with EM model
                app.StoredData.EMdom = [minx-fchbd*abs(xdis), maxx+fchbd*abs(xdis), miny-fchbd*abs(ydis), maxy+fchbd*abs(ydis), minz-fchbd*abs(zdis), maxz+fchbd*abs(zdis)]; 
                
                % Verifies not swapped
                verifyDom(app);
    
                % Prepare EM tab
                app.StoredData.MinDom = app.StoredData.EMdom;
                app.XminEditField.Value = app.StoredData.EMdom(1);
                app.XmaxEditField.Value = app.StoredData.EMdom(2);
                app.YminEditField.Value = app.StoredData.EMdom(3);
                app.YmaxEditField.Value = app.StoredData.EMdom(4);
                app.ZminEditField.Value = app.StoredData.EMdom(5);
                app.ZmaxEditField.Value = app.StoredData.EMdom(6);
    
                % Begin saving for eventual use by python model
                x = app.StoredData;
                if ~isfield(x.Nodes,'LoadTypenum')
                    for uuind = 1:length(x.X)
                        x.Nodes(uuind).LoadTypenum = [];
                    end
                end
                if ~isfield(x.Nodes,'LoadType')
                    for uuind = 1:length(x.X)
                        x.Nodes(uuind).LoadType = [];
                    end
                end
                if ~isfield(x.Nodes,'LoadParam')
                    for uuind = 1:length(x.X)
                        x.Nodes(uuind).LoadParam = [];
                    end
                end
                for idx=1:length(x.Nodes)
                    if isempty(x.Nodes(idx).LoadTypenum)
                        x.Nodes(idx).LoadTypenum=-1;
                    end
                end
    
                NetworkData.X = x.X;
                NetworkData.Y = x.Y;
                NetworkData.Z = x.Z;
                NetworkData.Wires = x.Wires;
                pdrad = -ones(size(x.Wires,1),length(x.WireProps(1).Radius));
                pddc = -ones(size(x.Wires,1),1);
                
                % Convert rw and dc to meters before storing
                for pdind = 1:size(x.Wires,1)
                    pdrad(pdind,:) = 1e-3*x.WireProps(pdind).Radius;
                    pddc(pdind) = 1e-3*x.WireProps(pdind).DC;
                end
                pdnt = -ones(length(x.X),1);
                pdltn = -ones(length(x.X),1);
                pdlt = -ones(length(x.X),1);
                pdlp = cell(length(x.X),1);
    
                for pdind = 1:length(x.X)
                    if isempty(x.Nodes(pdind).NodeType)
                        pdnt(pdind) = -1;
                    else
                        pdnt(pdind) = x.Nodes(pdind).NodeType;
                    end
                    if isempty(x.Nodes(pdind).LoadTypenum)
                        pdltn(pdind) = -1;
                    else
                        pdltn(pdind) = x.Nodes(pdind).LoadTypenum;
                    end
                    if isempty(x.Nodes(pdind).LoadType)
                        pdlt(pdind) = -1;
                    else
                        pdlt(pdind) = x.Nodes(pdind).LoadType;
                    end
                    tmpLP = x.Nodes(pdind).LoadParam;
                    tmpLP(abs(tmpLP)==0) = 1e-16;
                    if length(size(tmpLP)) == 3
                        tmpLP = reshape(pagetranspose(tmpLP),9,size(tmpLP,3)).';
                    end
                    pdlp{pdind} = tmpLP;
                end
    
                % Fix scaling of load parameters before storing and add PC
                if strcmp(app.ParasiticsDropDown.Value,'On')
                    Cap = 1e-9;
                elseif strcmp(app.ParasiticsDropDown.Value,'Minimal')
                    Cap = 1e-11;
                else
                    error('Unexpected PC selection.')
                end
                for pdind = 1:length(x.X)
                    if (pdltn(pdind) ~= -1) && (pdltn(pdind) ~= 7)
                        for pdind2 = 1:length(x.Loads(pdltn(pdind)).Scale)
                            pdlp{pdind}(pdind2) = pdlp{pdind}(pdind2)*x.Loads(pdltn(pdind)).Scale(pdind2);
                        end
    
                        % Add PC as final parameter
                        pdlp{pdind}(end+1) = Cap;
                    end
                end
    
                % Convert outlets to series/parallel outlets
                for spind = 1:length(pdnt)
                    if pdnt(spind) == 5
                        % It's an outlet, determine which:
                        numocc = sum(sum(x.Wires==spind));
                        if numocc > 1
                            pdnt(spind) = 6; % parallel outlet if more than one occurrence
                        end
                    end
                end
    
                NetworkData.DC = pddc;
                NetworkData.Radius = pdrad;
                NetworkData.NodeType = pdnt;
                NetworkData.LoadTypenum = pdltn;
                NetworkData.LoadType = pdlt; % Load type has nans because characters are converted to numerical values, but is correct until this function call
                NetworkData.LoadParam = pdlp;
                
                % Wire lengths
                NetworkData.WireLength = x.WireLengths;
    
                % Number of conductors
                if strcmp(app.NumberofConductorsDropDown.Value,'4')
                    NetworkData.Ncond = 4;
                elseif strcmp(app.NumberofConductorsDropDown.Value,'2')
                    NetworkData.Ncond = 2;
                else
                    error('Unexpected Ncond selection.')
                end
    
                % Add WhichRec
                NetworkData.WhichRec = 1; % equates to Z out off {V,I,Z}
                NetworkData.RecVal = nan; % Unused in current iteration
    
                % Sigma and epsilon
                eps0 = 8.8541878128e-12;
                sigma = -ones(length(x.X)-1,4);
                epsilon = -ones(length(x.X)-1,1);
                for seind = 1:length(x.X)-1
                    for seind2 = 1:NetworkData.Ncond
                        if x.WireProps(seind).Matl(seind2) == 1
                            sigma(seind,seind2) = 1e7 * 5.85;
                        elseif x.WireProps(seind).Matl(seind2) == 2
                            sigma(seind,seind2) = 1e7 * x.SigPrev(seind,seind2);
                        else
                            error('Unexpected sigma selection.')
                        end
                    end                    
                    if isfield(x.WireProps(seind),'InsMatl') && ~isempty(x.WireProps(seind).InsMatl) && x.WireProps(seind).InsMatl == 2
                        epsilon(seind) = eps0 * x.EpsPrev(seind);
                    else
                        epsilon(seind) = eps0 * 3.6;
                    end
                end
    
                % Store in NetworkData
                NetworkData.Sigma = sigma;
                NetworkData.Epsilon = epsilon;
    
                if app.SPICECheckBox.Value
                    NetworkData.Spice = 1;

                    % If Spice true, overwrite sigma and epsilon values
                    NetworkData.Sigma = 5.85*1e7*ones(size(NetworkData.Sigma));
                    NetworkData.Epsilon = 3.6*eps0*ones(size(NetworkData.Epsilon));

                else
                    NetworkData.Spice = 0;
                end

                NetworkData.WireRes = app.WireResolutionEditField.Value/100;
    
                if app.UseLoadedFrequenciesCheckBox.Value
                    NetworkData.Freq = app.StoredData.Freq;
                else
                    start = app.StartEditField.Value;
                    stop = app.FinishEditField.Value;
                    steps = app.StepsEditField.Value;
                    if app.FrequencyButtonGroup.SelectedObject == app.LogButton
                        NetworkData.Freq = logspace(log10(start), log10(stop), steps);
                    elseif app.FrequencyButtonGroup.SelectedObject == app.LinearButton_2
                        NetworkData.Freq = linspace(start, stop, steps);
                    else
                        error('Unexpected log/lin selection.');
                    end
                end
    
                if app.InjectionButtonGroup.SelectedObject == app.VoltageButton_2
                    NetworkData.InputVIType = -1;
                elseif app.InjectionButtonGroup.SelectedObject == app.CurrentButton_2
                    NetworkData.InputVIType = 1;
                else
                    error('Unexpected VI button selection.');
                end
    
                if NetworkData.Ncond == 2
                    Ang1 = app.oEditField.Value;
                    P1EFv = str2double(app.Phase1EditField.Value)*(cosd(Ang1)+1j*sind(Ang1));
                    NetworkData.InputVI = P1EFv;
                elseif NetworkData.Ncond == 4
                    Ang1 = app.oEditField.Value;
                    P1EFv = str2double(app.Phase1EditField.Value)*(cosd(Ang1)+1j*sind(Ang1));
                    Ang2 = app.oEditField_2.Value;
                    P2EFv = str2double(app.Phase2EditField.Value)*(cosd(Ang2)+1j*sind(Ang2));
                    Ang3 = app.oEditField_3.Value;
                    P3EFv = str2double(app.Phase3EditField.Value)*(cosd(Ang3)+1j*sind(Ang3));
                    NetworkData.InputVI = [P1EFv, P2EFv, P3EFv];
                else
                    error('Ncond stored as unexpected value.')
                end

                % SAVE FOR COMSOL 
                save([app.StoredData.PWD,'Data/ModelHandoff.mat'],'NetworkData');

                % Call Python model
                pyenv;
                tmppwd = pwd;
                cd([app.StoredData.PWD,'VI/']);
                try
                    pyrunfile('./Compute_VI.py');
                catch
                    cd(tmppwd);

                    % Check for custom load else contact me
                    CLP = false; % custom load present
                    for erind = 1:length(app.StoredData.Nodes)
                        if app.StoredData.Nodes(erind).LoadType == "X"
                            CLP = true;
                        end
                    end

                    % Print error accordingly
                    if CLP
                        app.ErrorType.Text = 'Error';
                        app.ErrorType.FontColor = 'r';
                        app.ErrorMsg.Text = ['The simulation failed.' newline newline 'A custom load was detected; custom load data may lead to singular matrices if improperly set. If you believe this is not due to the custom load(s), contact the administrator.'];
                        app.ErrorMsg.FontColor = 'r';
                    else
                        app.ErrorType.Text = 'Error';
                        app.ErrorType.FontColor = 'r';
                        app.ErrorMsg.Text = ['The simulation failed.' newline newline 'If there were no illegal manipulations of the data files nor custom loads, contact the administrator.'];
                        app.ErrorMsg.FontColor = 'r';
                    end

                    return
                end
                cd(tmppwd);
                app.StoredData.results=load([app.StoredData.PWD,'Data/VIOut.mat']);

                % Ensure all are doubles
                app.StoredData.results.WireRes = double(app.StoredData.results.WireRes);
                app.StoredData.results.WireID = double(app.StoredData.results.WireID);
                app.StoredData.results.Freq = double(app.StoredData.results.Freq);
                app.StoredData.results.Nodes = double(app.StoredData.results.Nodes);

                % Save for radiative computation
                GUIData = app.StoredData.results;
                GUIData.WiresOld = app.StoredData.Wires;
                GUIData.Radii = -ones(size(app.StoredData.Wires,1),NetworkData.Ncond);
                for unind = 1:size(app.StoredData.Wires,1)
                    GUIData.Radii(unind,:) = app.StoredData.WireProps(unind).Radius / 1e3;
                end
                save([app.StoredData.PWD,'Radiative/GUIData.mat'],'GUIData');
                 
                drawnow;
                app.VisualizeResultsButton.Enable="on";
                app.VisualizeResultsButton.Tooltip = '';

                app.ErrorMsg.Text = ['Simulation complete.' newline newline 'You may now proceed to the ''Visualize Results'' tab.'];

                % Set up frequency slider
                freq = app.StoredData.results.Freq;
                try
                    app.FrequencySlider.Limits = [log10(min(freq)) log10(max(freq))];
                catch
                    try
                        app.FrequencySlider.Limits = [0.9*log10(min(freq)) 1.1*log10(max(freq))];
                    catch
                        app.FrequencySlider.Limits = [0 0.001];
                    end
                end
                app.FrequencySlider.Value = log10(min(freq));

                if ceil(log10(max(freq)))-ceil(log10(min(freq))) >= 1 && floor(log10(max(freq)))-floor(log10(min(freq))) >= 1
                    app.FrequencySlider.MajorTicks = [0 1 2 3 4 5 6 7 8];
                    app.FrequencySlider.MajorTickLabels = {'1','10',['10',char(178)],['10',char(179)],['10',char(8308)],['10',char(8309)],['10',char(8310)],['10',char(8311)],['10',char(8312)]};
                else
                    % Determine character indices
                    charinds = [nan nan 178 179 8308 8309 8310 8311 8312];
                    minchar = charinds(floor(log10(min(freq)))+1);
                    maxchar = charinds(floor(log10(max(freq)))+1);
                    app.FrequencySlider.MajorTicks = [log10(min(freq)) log10(max(freq))];
                    if ~isnan(minchar)
                        app.FrequencySlider.MajorTickLabels = {sprintf('%.2f x 10%c',min(freq)/10^floor(log10(min(freq))),minchar),sprintf('%.2f x 10%c',max(freq)/10^floor(log10(max(freq))),maxchar)};
                    else
                        app.FrequencySlider.MajorTickLabels = {sprintf('%.2f',min(freq)),sprintf('%.2f',max(freq))};
                    end
                end

                PerDecade = logspace(0,log10(2),10);
                MTs = [];
                for MTind = floor(log10(min(freq))):ceil(log10(max(freq)))
                    MTs = [MTs, PerDecade+MTind-1];
                end
                app.FrequencySlider.MinorTicks = MTs;

                % Initialize the screen for both visualize results tabs
%                 if ~isfield(app.StoredData,'SVIScreen') || (isfield(app.StoredData,'Unlocked') && app.StoredData.Unlocked > 4)
%                     if (isfield(app.StoredData,'Unlocked') && app.StoredData.Unlocked > 4)
%                         app.StoredData.Unlocked = 3.5;
%                     end
%                     hf1 = figure('visible','off');       
%                     ha1 = gca;
%                     cla(ha1);
%                     copyobj(app.UIAxes3.Children,ha1);
%         
%                     hf2 = figure('visible','off');       
%                     ha2 = gca;
%                     cla(ha2);
%                     copyobj(app.UIAxes3.Children,ha2);
%                     
%                     app.StoredData.SVIScreen = ha1;
%                     app.StoredData.TPScreen = ha2;
%                 end
                
            end

        end

        % Value changed function: SystemNodesListBox
        function SystemNodesListBoxValueChanged(app, event)
            
            % Update troubleshooting
            app.ErrorType.Text = 'Geometry Definition';
            app.ErrorType.FontColor = [0 0.5 0];
            app.ErrorMsg.Text = ['Build or load-in a network.' newline newline 'Swap between the ''Nodes'' and ''Wires'' tabs to define your network.' newline newline 'Once a full network topology has been constructed (n nodes, n-1 wires), proceed to the ''Node Definition'' tab.'];
            app.ErrorMsg.FontColor = [0 0.5 0];

            % Change index and plot with privilege
            [~,snind] = ismember(app.SystemNodesListBox.Value,app.SystemNodesListBox.Items);
            app.StoredData.lastEntity = 1;
            app.StoredData.EntityList=[double(app.StoredData.X(snind)),double(app.StoredData.Y(snind)),double(app.StoredData.Z(snind))];
            PlotWires(app)
            app.StoredData.lastEntity=0;

        end

        % Button pushed function: EMFieldsButton
        function EMFieldsButtonPushed(app, event)

            if app.TabGroup.SelectedTab == app.EMTab
                % Do nothing
            else

                if app.StoredData.TLV
                    app.NetworkDiagramPanel.Visible = 'on';
                    app.NetworkDiagramPanel_2.Visible = 'off';
                    app.StoredData.TLV = 0;
                end

                try
                    delete(app.UIAxes3.Colorbar);
                end
                clc

                app.StoredData.lastEntity = 0;
                PlotWires(app)
    
                app.FrequencyEditField_2.Value = app.StoredData.results.Freq(1);
                app.StoredData.EMfreq = app.StoredData.results.Freq(1);
    
                app.TabGroup.SelectedTab = app.EMTab;
                
                app.ErrorType.Text = 'E&M Fields';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Enter the desired frequency (will set to nearest available), domain bounds, and quiver density. Then choose whether to visualize the electric or magnetic field.' newline newline 'Next, press ''Simulate (Remotely)'' to visualize the results or press ''Export to COMSOL Session'' to open COMSOL with the corresponding model and network.'];
                app.ErrorMsg.FontColor = [0 0.5 0];

                try
                    copyobj(app.StoredData.EMTabAxes.Children,app.UIAxes3);
                    cla(app.UIAxes3);
                    copyobj(app.StoredData.EMTabAxes.Children,app.UIAxes3);
                end
            end

        end

        % Button pushed function: EXPORTTOCOMSOL_2Button
        function EXPORTTOCOMSOL_2ButtonPushed(app, event)

            DealwithCOMSOL(app,true)

        end

        % Value changed function: FrequencyEditField_2
        function FrequencyEditField_2ValueChanged(app, event)

            value = app.FrequencyEditField_2.Value;
            Freq = app.StoredData.results.Freq;
            [~,nind] = min(abs(Freq-value));
            newval = Freq(nind);
            app.FrequencyEditField_2.Value = newval;
            app.StoredData.EMfreq = newval; % used to update COMSOL

        end

        % Value changed function: ZEditField_2
        function ZEditField_2ValueChanged(app, event)

            value = app.ZEditField_2.Value;
            if value < 1
                app.ZEditField_2.Value = 1;
                app.ZEditField_2.Tooltip = 'Z density must be a positive integer.';
            else
                app.ZEditField_2.Tooltip = '';
            end
            app.StoredData.EMQD(3) = value; % COMSOL

        end

        % Value changed function: YEditField_2
        function YEditField_2ValueChanged(app, event)

            value = app.YEditField_2.Value;
            if value < 1
                app.YEditField_2.Value = 1;
                app.YEditField_2.Tooltip = 'Y density must be a positive integer.';
            else
                app.YEditField_2.Tooltip = '';
            end
            app.StoredData.EMQD(2) = value; % COMSOL

        end

        % Value changed function: XEditField_2
        function XEditField_2ValueChanged(app, event)

            value = app.XEditField_2.Value;
            if value < 1
                app.XEditField_2.Value = 1;
                app.XEditField_2.Tooltip = 'X density must be a positive integer.';
            else
                app.XEditField_2.Tooltip = '';
            end
            app.StoredData.EMQD(1) = value; % COMSOL
            
        end

        % Value changed function: ZmaxEditField
        function ZmaxEditFieldValueChanged(app, event)

            value = app.ZmaxEditField.Value;

            if value < app.StoredData.MinDom(6)
                value = app.StoredData.MinDom(6);
                app.ZmaxEditField.Tooltip = 'There is a +-10% (of domain) lower limit on all bounds.';
            else
                app.ZmaxEditField.Tooltip = '';
            end            
            app.StoredData.EMdom(6) = value; % COMSOL
            verifyDom(app);
            app.ZmaxEditField.Value = app.StoredData.EMdom(6);
            app.ZminEditField.Value = app.StoredData.EMdom(5);

        end

        % Value changed function: ZminEditField
        function ZminEditFieldValueChanged(app, event)

            value = app.ZminEditField.Value;

            if value > app.StoredData.MinDom(5)
                value = app.StoredData.MinDom(5);
                app.ZminEditField.Tooltip = 'There is a +-10% (of domain) lower limit on all bounds.';
            else
                app.ZminEditField.Tooltip = '';
            end
            app.StoredData.EMdom(5) = value; % COMSOL
            verifyDom(app);
            app.ZmaxEditField.Value = app.StoredData.EMdom(6);
            app.ZminEditField.Value = app.StoredData.EMdom(5);

        end

        % Value changed function: YmaxEditField
        function YmaxEditFieldValueChanged(app, event)

            value = app.YmaxEditField.Value;
           
            if value < app.StoredData.MinDom(4)
                value = app.StoredData.MinDom(4);
                app.YmaxEditField.Tooltip = 'There is a +-10% (of domain) lower limit on all bounds.';
            else
                app.YmaxEditField.Tooltip = '';
            end
            app.StoredData.EMdom(4) = value; % COMSOL
            verifyDom(app);
            app.YmaxEditField.Value = app.StoredData.EMdom(4);
            app.YminEditField.Value = app.StoredData.EMdom(3);

        end

        % Value changed function: YminEditField
        function YminEditFieldValueChanged(app, event)

            value = app.YminEditField.Value;

            if value > app.StoredData.MinDom(3)
                value = app.StoredData.MinDom(3);
                app.YminEditField.Tooltip = 'There is a +-10% (of domain) lower limit on all bounds.';
            else
                app.YminEditField.Tooltip = '';
            end
            app.StoredData.EMdom(3) = value; % COMSOL
            verifyDom(app);
            app.YminEditField.Value = app.StoredData.EMdom(3);
            app.YmaxEditField.Value = app.StoredData.EMdom(4);

        end

        % Value changed function: XmaxEditField
        function XmaxEditFieldValueChanged(app, event)

            value = app.XmaxEditField.Value;
            
            if value < app.StoredData.MinDom(2)
                value = app.StoredData.MinDom(2);
                app.XmaxEditField.Tooltip = 'There is a +-10% (of domain) lower limit on all bounds.';
            else
                app.XmaxEditField.Tooltip = '';
            end
            app.StoredData.EMdom(2) = value; % COMSOL
            verifyDom(app);
            app.XmaxEditField.Value = app.StoredData.EMdom(2);
            app.XminEditField.Value = app.StoredData.EMdom(1);

        end

        % Value changed function: XminEditField
        function XminEditFieldValueChanged(app, event)

            value = app.XminEditField.Value;

            if value > app.StoredData.MinDom(1)
                value = app.StoredData.MinDom(1);
                app.XminEditField.Tooltip = 'There is a +-10% (of domain) lower limit on all bounds.';
            else
                app.XminEditField.Tooltip = '';
            end
            app.StoredData.EMdom(1) = value; % COMSOL
            verifyDom(app);
            app.XminEditField.Value = app.StoredData.EMdom(1);
            app.XmaxEditField.Value = app.StoredData.EMdom(2);

        end

        % Selection changed function: FieldButtonGroup
        function FieldButtonGroupSelectionChanged(app, event)

            selectedButton = app.FieldButtonGroup.SelectedObject;
            
        end

        % Button pushed function: SimulateRemotelyButton
        function SimulateRemotelyButtonPushed(app, event)

            DealwithCOMSOL(app,false)
            
            hf1 = figure('visible','off');       
            app.StoredData.EMTabAxes = gca;
            cla(app.StoredData.EMTabAxes);
            copyobj(app.UIAxes3.Children,app.StoredData.EMTabAxes);
            
        end

        % Button pushed function: DeleteAllNodesButton
        function DeleteAllNodesButtonPushed(app, event)

            answer = uiconfirm(app.ENCoRPUIFigure,'Delete all nodes?','Confirmation','Options',{'Yes','No'},'Icon','warning');
            if strcmp(answer,'Yes')
                % Reset everything
                startupFcn(app)
            end
            
        end

        % Menu selected function: NewModelMenu
        function NewModelMenuSelected(app, event)

            answer = uiconfirm(app.ENCoRPUIFigure,'Start new model or restart interface? All unsaved work will be lost.','Confirmation','Options',{'Restart (Recommended)','Without Restart (Not Recommended)','No'},'Icon','warning');
            if strcmp(answer,'Without Restart (Not Recommended)')

                % Reset everything
                startupFcn(app)
                ResetRMT(app)

                % Change to nodes tab if defaulted to wires
                app.TabGroup2.SelectedTab = app.NodesTab_3;

                % Also reset camera view
                app.XEditField.Value = 0;
                app.YEditField.Value = 0;
                app.ZEditField.Value = 0;
                app.UIAxes3.View = [45,45];

            elseif strcmp(answer,'Restart (Recommended)')

                cd(app.StoredData.PWD)
                delete(app)

                % For some update this switched order to being after
                % delete..? (Now run summons existing interface?)
                run('Interface/ENCoRP')

            end

        end

        % Button pushed function: DeleteAllWiresButton
        function DeleteAllWiresButtonPushed(app, event)

            answer = uiconfirm(app.ENCoRPUIFigure,'Delete all wires? Node definitions will also be reset.','Confirmation','Options',{'Yes','No'},'Icon','warning');
            if strcmp(answer,'Yes')
                % Save nodal locations
                X = app.StoredData.X;
                Y = app.StoredData.Y;
                Z = app.StoredData.Z;

                % Reset everything
                startupFcn(app)

                % Reload nodal data
                app.StoredData.X = X;
                app.StoredData.Y = Y;
                app.StoredData.Z = Z;

                % Update figure
                UpdateWireList(app)

                % Return to wires tab
                app.TabGroup2.SelectedTab = app.WiresTab;

                % Update button accessibility
                if length(app.StoredData.X)>0
                    app.RemoveSelectedButton.Enable = 'on';
                    app.DeleteAllNodesButton.Enable = 'on';
                end
                if length(app.StoredData.X)>1
                    app.Button.Enable = 'on';
                    app.vButton.Enable = 'on';
                end
            end

        end

        % Button pushed function: ClearAllConfigurationsButton
        function ClearAllConfigurationsButtonPushed(app, event)
            
            % Check they're sure
            answer = uiconfirm(app.ENCoRPUIFigure,'Delete all node configurations?','Confirmation','Options',{'Yes','No'},'Icon','warning');
            
            if strcmp(answer,'Yes')            
                % Remove all nodal data
                for rndind = 1:length(app.StoredData.X)
                    try
                        if app.StoredData.Nodes(rndind).LoadType == "X"
                            app.StoredData.Nodes(rndind).Filename = '';
                            app.StoredData.Nodes(rndind).VarSelected = [];
                            app.StoredData.Nodes(rndind).VarList = {};
                            app.FileLabel_2.Text = 'File:  ';
                            app.FileLabel_2.Tooltip = '';
                            app.VariableDropDown.Items = {};
                        end
                    end
                    app.StoredData.Nodes(rndind).LoadTypenum = -1;
                    app.StoredData.Nodes(rndind).LoadParam=[];
                    app.StoredData.Nodes(rndind).LoadType='';
                    app.StoredData.Nodes(rndind).NodeType=0; 
                end
    
                % Update nodes and plot
                app.AvailableNodesListBox.Value = app.AvailableNodesListBox.Items{1};
                AvailableNodesListBoxValueChanged(app, event)

                % Delete saved wire info
                %if isfield(app.StoredData,'WireProps')
                %    ResetWiringTab(app);
                %end

                % Turn off other buttons
                app.WiringDefinitionButton.Enable = 'off';
                app.RunModelButton.Enable = 'off';
                %ResetRMT(app);
                app.SimulateButton.Enable = 'off';
                app.SimulationDetailsPanel.Enable = 'off';
                app.VisualizeResultsButton.Enable = 'off';
                app.EMFieldsButton.Enable = 'off';
                try
                    app.StoredData = rmfield(app.StoredData,'EMTabAxes');
                end
                app.WiringDefinitionButton.Tooltip = 'Define all node types and associated parameters for this tab to unlock.';
                app.RunModelButton.Tooltip = 'Define the properties for all conductors in all wires for this tab to unlock.';
                app.VisualizeResultsButton.Tooltip = 'Save your network and simulate the model for this tab to unlock.';
                app.EMFieldsButton.Tooltip = 'This will unlock once the Visualize Results tab has been visited.';
            
            end
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)

            value = app.EditField.Value;
            
            % If equal or longer, keep, else ignore + warning
            [~,wind] = ismember(app.WireListListBox_2.Value, app.WireListListBox_2.Items);
            Xv=app.StoredData.X(app.StoredData.Wires(wind,:));
            Yv=app.StoredData.Y(app.StoredData.Wires(wind,:));
            Zv=app.StoredData.Z(app.StoredData.Wires(wind,:));
            len = norm([diff(Xv),diff(Yv),diff(Zv)]);
            if value < 0
                % Warning
                app.ErrorType.Text = 'Error';
                app.ErrorType.FontColor = 'r';
                app.ErrorMsg.Text = ['The wire length cannot be negative.' newline newline 'The value has been reset to the physical distance between the nodes.'];
                app.ErrorMsg.FontColor = 'r';
                app.EditField.Value = len;
                app.StoredData.WireLengths(wind) = len;
            elseif value > 1.5*len
                % Warning but accept
                app.ErrorType.Text = 'Warning';
                app.ErrorType.FontColor = [0.5 0.5 0];
                app.ErrorMsg.Text = ['The slack in the wire exceeds 50% of the physical wire length.' newline newline 'Please ensure this is intentional.'];
                app.ErrorMsg.FontColor = [0.5 0.5 0];
                app.StoredData.WireLengths(wind) = value;
            elseif value < len
                app.ErrorType.Text = 'Warning';
                app.ErrorType.FontColor = [0.5 0.5 0];
                app.ErrorMsg.Text = ['The wire length subceeds the physical distance between its nodes.' newline newline 'Please ensure this is intentional.'];
                app.ErrorMsg.FontColor = [0.5 0.5 0];
                app.StoredData.WireLengths(wind) = value;
            else
                % Accept
                app.ErrorType.Text = 'Wiring Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Define the properties of each wire.' newline newline 'Select the number of conductors. Then, swap between the Wires and Conductor tabs or use the ''Apply to All'' buttons to set values.' newline newline 'Once all wires and conductors have been defined, proceed to the ''Run Model'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];
                app.StoredData.WireLengths(wind) = value;
            end

            app.SimulationDetailsPanel.Enable = 'off';
            app.SimulateButton.Enable = 'off';
            app.VisualizeResultsButton.Enable = 'off';
            app.EMFieldsButton.Enable = 'off';
            try
                app.StoredData = rmfield(app.StoredData,'EMTabAxes');
            end

        end

        % Value changed function: SeparationDistanceEditField
        function SeparationDistanceEditFieldValueChanged(app, event)
            
            % Pull value and check it's legitimacy
            value = app.SeparationDistanceEditField.Value;
            valislegit = true;

            % If not empty (empty is fine)
            if ~isempty(value)
                val = str2double(value);

                % Check for nan
                if isnan(val)
                    valislegit = false;
                    errno = 1;
                end
    
                % Check for complex valued
                if valislegit && ~isreal(val)
                    valislegit = false;
                    errno = 2;
                end
    
                % Check for in bounds
                if valislegit && (val<=0 || val>app.StoredData.DCUL)
                    valislegit = false;
                    errno = 3;
                end
                
                % Pull index of wire
                [~,idx1] = ismember(app.WireListListBox_2.Value,app.WireListListBox_2.Items);

                % Check it fits constraints with wire radii
                if valislegit
                    res = PartialCheckRWandDC_FCW(app,5);
                    if res == -1
                        error('Missed check.')
                    elseif res == 1
                        % Everything good
                    else
                        % Value is good but doesn't work with others
                        valislegit = false;
                        errno = 4;
                    end
                end
                
                % If good, set the value
                if valislegit

                    % Set and update various properties, button functionality and update wire list
                    SetDC(app,1,value)
                    CheckApplyBoxes(app)
                    UpdateWireList(app) 
                    
                    % Plot with privilege
                    app.StoredData.lastEntity = 2;
                    o1=app.StoredData.Wires(idx1,1);
                    o2=app.StoredData.Wires(idx1,2);
                    app.StoredData.EntityList = [o1,o2];
                    PlotWires(app)
                    app.StoredData.lastEntity = 0;

                    % Update troubleshooting
                    app.ErrorType.Text = 'Wiring Definition';
                    app.ErrorType.FontColor = [0 0.5 0];
                    app.ErrorMsg.Text = ['Define the properties of each wire.' newline newline 'Select the number of conductors. Then, swap between the Wires and Conductor tabs or use the ''Apply to All'' buttons to set values.' newline newline 'Once all wires and conductors have been defined, proceed to the ''Run Model'' tab.'];
                    app.ErrorMsg.FontColor = [0 0.5 0];

                    % Fix all backgrounds
                    app.SeparationDistanceEditField.BackgroundColor = [1 1 1];
                else

                    % Remove DC value from storage since invalid
                    if errno ~= 4
                        SetDC(app,2,value)
                    else
                        SetDC(app,1,value)
                    end
                     
                    % Set properties and check buttons and update lists
                    CheckApplyBoxes(app)
                    UpdateWireList(app) 
        
                    % Pull index and plot with privilege
                    [~,idx1] = ismember(app.WireListListBox_2.Value,app.WireListListBox_2.Items);
                    app.StoredData.lastEntity = 2;
                    o1=app.StoredData.Wires(idx1,1);
                    o2=app.StoredData.Wires(idx1,2);
                    app.StoredData.EntityList = [o1,o2];
                    PlotWires(app)
                    app.StoredData.lastEntity = 0;

                    % Change color and maintain value
                    if errno == 4
                        app.SeparationDistanceEditField.BackgroundColor = [0.9 0.9 0];
                    else
                        app.SeparationDistanceEditField.BackgroundColor = [1 0.75 0.75];
                    end
                    app.SeparationDistanceEditField.Value = num2str(value);

                    % Troubleshoot based on type of error
                    if errno == 1
                        app.ErrorType.Text = 'Error';
                        app.ErrorType.FontColor = 'r';
                        app.ErrorMsg.Text = 'Separation distance must be a numeric value.';
                        app.ErrorMsg.FontColor = 'r';
                    elseif errno == 2
                        app.ErrorType.Text = 'Error';
                        app.ErrorType.FontColor = 'r';
                        app.ErrorMsg.Text = 'Separation distance must be real-valued.';
                        app.ErrorMsg.FontColor = 'r';
                    elseif errno == 3
                        app.ErrorType.Text = 'Error';
                        app.ErrorType.FontColor = 'r';
                        app.ErrorMsg.Text = ['Separation distance must be in the range (0, ',num2str(app.StoredData.DCUL),'] mm.'];
                        app.ErrorMsg.FontColor = 'r';
                    elseif errno == 4
                        app.ErrorType.Text = 'Error';
                        app.ErrorType.FontColor = 'r';
                        app.ErrorMsg.FontColor = 'r';
                        if abs(res - 0.1) < 1e-6
                            app.ErrorMsg.Text = ['The entered separation distance violates the physical geometry.' newline newline 'The separation distance must be greater than the individual wire radii and the sum of the wire radii.'];
                        elseif abs(res - 0.21) < 1e-6
                            app.ErrorMsg.Text = ['The entered separation distance violates the physical geometry.' newline newline 'The separation distance must be greater than all individual wire radii.' newline newline 'The ground wire''s radius equals or exceeds the separation distance.'];
                        elseif abs(res - 0.22) < 1e-6
                            app.ErrorMsg.Text = ['The entered separation distance violates the physical geometry.' newline newline 'The separation distance must be greater than all individual wire radii.' newline newline 'The first phase wire''s radius equals or exceeds the separation distance.'];
                        elseif abs(res - 0.23) < 1e-6
                            app.ErrorMsg.Text = ['The entered separation distance violates the physical geometry.' newline newline 'The separation distance must be greater than all individual wire radii.' newline newline 'The second phase wire''s radius equals or exceeds the separation distance.'];
                        elseif abs(res - 0.24) < 1e-6
                            app.ErrorMsg.Text = ['The entered separation distance violates the physical geometry.' newline newline 'The separation distance must be greater than all individual wire radii.' newline newline 'The third phase wire''s radius equals or exceeds the separation distance.'];
                        elseif abs(res - 0.3) < 1e-6
                            app.ErrorMsg.Text = ['The entered separation distance violates the physical geometry.' newline newline 'The separation distance must be greater than the sum of neighboring wire radii.' newline newline 'The combined ground and first phase wire''s radii equals or exceeds the separation distance.'];
                        elseif abs(res - 0.4) < 1e-6
                            app.ErrorMsg.Text = ['The entered separation distance violates the physical geometry.' newline newline 'The separation distance must be greater than the sum of neighboring wire radii.' newline newline 'The combined first and second phase wire''s radii equals or exceeds the separation distance.'];
                        elseif abs(res - 0.5) < 1e-6
                            app.ErrorMsg.Text = ['The entered separation distance violates the physical geometry.' newline newline 'The separation distance must be greater than the sum of neighboring wire radii.' newline newline 'The combined second and third phase wire''s radii equals or exceeds the separation distance.'];
                        elseif abs(res - 0.6) < 1e-6
                            app.ErrorMsg.Text = ['The entered separation distance violates the physical geometry.' newline newline 'The separation distance must be greater than the sum of neighboring wire radii.' newline newline 'The combined ground and third phase wire''s radii equals or exceeds the separation distance.'];
                        elseif abs(res - 0.7) < 1e-6
                            app.ErrorMsg.Text = ['The entered separation distance violates the physical geometry.' newline newline 'The diagonal distance implied by the separation distance must be greater than the sum of opposing wire radii.' newline newline 'The combined ground and second phase wire''s radii equals/exceeds the diagonal.'];
                        elseif abs(res - 0.8) < 1e-6
                            app.ErrorMsg.Text = ['The entered separation distance violates the physical geometry.' newline newline 'The diagonal distance implied by the separation distance must be greater than the sum of opposing wire radii.' newline newline 'The combined first and third phase wire''s radii equals/exceeds the diagonal.'];
                        else
                            error('Unexpected error denotation.')
                        end
                    end
                end
            else

                % If empty, set sep value
                SetDC(app,1,value)
                
                % Update buttons and list appropriately
                CheckApplyBoxes(app)
                UpdateWireList(app) 
    
                % Pull index and plot wire with privilege
                [~,idx1] = ismember(app.WireListListBox_2.Value,app.WireListListBox_2.Items);
                app.StoredData.lastEntity = 2;
                o1=app.StoredData.Wires(idx1,1);
                o2=app.StoredData.Wires(idx1,2);
                app.StoredData.EntityList = [o1,o2];
                PlotWires(app)
                app.StoredData.lastEntity = 0;

                % Troubleshoot
                app.ErrorType.Text = 'Wiring Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Define the properties of each wire.' newline newline 'Select the number of conductors. Then, swap between the Wires and Conductor tabs or use the ''Apply to All'' buttons to set values.' newline newline 'Once all wires and conductors have been defined, proceed to the ''Run Model'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];

                % Correct background
                app.SeparationDistanceEditField.BackgroundColor = [1 1 1];
            end

        end

        % Value changed function: sigmaEditField
        function sigmaEditFieldValueChanged(app, event)

            value = app.sigmaEditField.Value;
            legitval = true;
            if value <= 0 || value > app.StoredData.SUL
                legitval = false;
            end
            [~,sdx] = ismember(app.WireListListBox_2.Value,app.WireListListBox_2.Items);
            if legitval
                app.StoredData.SigPrev(sdx,1) = value;
                app.ErrorType.Text = 'Wiring Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Define the properties of each wire.' newline newline 'Select the number of conductors. Then, swap between the Wires and Conductor tabs or use the ''Apply to All'' buttons to set values.' newline newline 'Once all wires and conductors have been defined, proceed to the ''Run Model'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            else
                app.ErrorType.Text = 'Warning';
                app.ErrorType.FontColor = [0.5 0.5 0];
                if value <= 0
                    app.ErrorMsg.Text = ['The relative conductivity of the conductor must be positive.'];
                else
                    app.ErrorMsg.Text = ['The relative conductivity of the conductor cannot exceed ',num2str(app.StoredData.SUL),'.'];
                end
                app.ErrorMsg.FontColor = [0.5 0.5 0];
                app.sigmaEditField.Value = event.PreviousValue;
                app.StoredData.SigPrev(sdx,1) = event.PreviousValue;
            end

            app.SimulationDetailsPanel.Enable = 'off';
            app.SimulateButton.Enable = 'off';
            app.VisualizeResultsButton.Enable = 'off';
            app.EMFieldsButton.Enable = 'off';
            try
                app.StoredData = rmfield(app.StoredData,'EMTabAxes');
            end
            
        end

        % Value changed function: epsilonEditField
        function epsilonEditFieldValueChanged(app, event)

            value = app.epsilonEditField.Value;

            % Check if value is legit (within bounds)
            legitval = true;
            if value <= 0 || value > app.StoredData.EUL
                legitval = false;
            end

            % Pull wire index and assign
            [~,sdx] = ismember(app.WireListListBox_2.Value,app.WireListListBox_2.Items);
            if legitval
                app.StoredData.EpsPrev(sdx) = value;
                app.ErrorType.Text = 'Wiring Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Define the properties of each wire.' newline newline 'Select the number of conductors. Then, swap between the Wires and Conductor tabs or use the ''Apply to All'' buttons to set values.' newline newline 'Once all wires and conductors have been defined, proceed to the ''Run Model'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            else
                app.ErrorType.Text = 'Warning';
                app.ErrorType.FontColor = [0.5 0.5 0];
                if value <= 0
                    app.ErrorMsg.Text = ['The relative permittivity of the insulator must be positive.'];
                else
                    app.ErrorMsg.Text = ['The relative permittivity of the insulator cannot exceed ',num2str(app.StoredData.EUL),'.'];
                end
                app.ErrorMsg.FontColor = [0.5 0.5 0];
                app.epsilonEditField.Value = event.PreviousValue;
                app.StoredData.EpsPrev(sdx) = event.PreviousValue;
            end

            app.SimulationDetailsPanel.Enable = 'off';
            app.SimulateButton.Enable = 'off';
            app.VisualizeResultsButton.Enable = 'off';
            app.EMFieldsButton.Enable = 'off';
            try
                app.StoredData = rmfield(app.StoredData,'EMTabAxes');
            end

        end

        % Value changed function: InsulatorMaterialDropDown
        function InsulatorMaterialDropDownValueChanged(app, event)

            value = app.InsulatorMaterialDropDown.Value;

            % Change based on selection
            [~,sdx] = ismember(app.WireListListBox_2.Value,app.WireListListBox_2.Items);
            if strcmp(value,'PVC')
                app.StoredData.WireProps(sdx).InsMatl = 1;
                app.epsilonEditField.Value = 3.6;
                app.epsilonEditField.Enable = 'off';
            elseif strcmp(value,'Custom')
                app.StoredData.WireProps(sdx).InsMatl = 2;
                if ~isnan(app.StoredData.EpsPrev(sdx))
                    app.epsilonEditField.Value = app.StoredData.EpsPrev(sdx);
                end
                app.epsilonEditField.Enable = 'on';
            else
                error('Unknown insulator selection.')
            end

            app.ErrorType.Text = 'Wiring Definition';
            app.ErrorType.FontColor = [0 0.5 0];
            app.ErrorMsg.Text = ['Define the properties of each wire.' newline newline 'Select the number of conductors. Then, swap between the Wires and Conductor tabs or use the ''Apply to All'' buttons to set values.' newline newline 'Once all wires and conductors have been defined, proceed to the ''Run Model'' tab.'];
            app.ErrorMsg.FontColor = [0 0.5 0];

            app.SimulationDetailsPanel.Enable = 'off';
            app.SimulateButton.Enable = 'off';
            app.VisualizeResultsButton.Enable = 'off';
            app.EMFieldsButton.Enable = 'off';
            try
                app.StoredData = rmfield(app.StoredData,'EMTabAxes');
            end
            
        end

        % Value changed function: sigmaEditField_2
        function sigmaEditField_2ValueChanged(app, event)
            
            value = app.sigmaEditField_2.Value;
            legitval = true;
            if value <= 0 || value > app.StoredData.SUL
                legitval = false;
            end
            [~,sdx] = ismember(app.WireListListBox_2.Value,app.WireListListBox_2.Items);
            if legitval
                app.StoredData.SigPrev(sdx,2) = value;
                app.ErrorType.Text = 'Wiring Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Define the properties of each wire.' newline newline 'Select the number of conductors. Then, swap between the Wires and Conductor tabs or use the ''Apply to All'' buttons to set values.' newline newline 'Once all wires and conductors have been defined, proceed to the ''Run Model'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            else
                app.ErrorType.Text = 'Warning';
                app.ErrorType.FontColor = [0.5 0.5 0];
                if value <= 0
                    app.ErrorMsg.Text = ['The relative conductivity of the conductor must be positive.'];
                else
                    app.ErrorMsg.Text = ['The relative conductivity of the conductor cannot exceed ',num2str(app.StoredData.SUL),'.'];
                end
                app.ErrorMsg.FontColor = [0.5 0.5 0];
                app.sigmaEditField_2.Value = event.PreviousValue;
                app.StoredData.SigPrev(sdx,2) = event.PreviousValue;
            end

            app.SimulationDetailsPanel.Enable = 'off';
            app.SimulateButton.Enable = 'off';
            app.VisualizeResultsButton.Enable = 'off';
            app.EMFieldsButton.Enable = 'off';
            try
                app.StoredData = rmfield(app.StoredData,'EMTabAxes');
            end
            
        end

        % Value changed function: sigmaEditField_3
        function sigmaEditField_3ValueChanged(app, event)
            
            value = app.sigmaEditField_3.Value;
            legitval = true;
            if value <= 0 || value > app.StoredData.SUL
                legitval = false;
            end
            [~,sdx] = ismember(app.WireListListBox_2.Value,app.WireListListBox_2.Items);
            if legitval
                app.StoredData.SigPrev(sdx,3) = value;
                app.ErrorType.Text = 'Wiring Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Define the properties of each wire.' newline newline 'Select the number of conductors. Then, swap between the Wires and Conductor tabs or use the ''Apply to All'' buttons to set values.' newline newline 'Once all wires and conductors have been defined, proceed to the ''Run Model'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            else
                app.ErrorType.Text = 'Warning';
                app.ErrorType.FontColor = [0.5 0.5 0];
                if value <= 0
                    app.ErrorMsg.Text = ['The relative conductivity of the conductor must be positive.'];
                else
                    app.ErrorMsg.Text = ['The relative conductivity of the conductor cannot exceed ',num2str(app.StoredData.SUL),'.'];
                end
                app.ErrorMsg.FontColor = [0.5 0.5 0];
                app.sigmaEditField_3.Value = event.PreviousValue;
                app.StoredData.SigPrev(sdx,3) = event.PreviousValue;
            end      

            app.SimulationDetailsPanel.Enable = 'off';
            app.SimulateButton.Enable = 'off';
            app.VisualizeResultsButton.Enable = 'off';
            app.EMFieldsButton.Enable = 'off';
            try
                app.StoredData = rmfield(app.StoredData,'EMTabAxes');
            end
            
        end

        % Value changed function: sigmaEditField_4
        function sigmaEditField_4ValueChanged(app, event)
            
            value = app.sigmaEditField_4.Value;
            legitval = true;
            if value <= 0 || value > app.StoredData.SUL
                legitval = false;
            end
            [~,sdx] = ismember(app.WireListListBox_2.Value,app.WireListListBox_2.Items);
            if legitval
                app.StoredData.SigPrev(sdx,4) = value;
                app.ErrorType.Text = 'Wiring Definition';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Define the properties of each wire.' newline newline 'Select the number of conductors. Then, swap between the Wires and Conductor tabs or use the ''Apply to All'' buttons to set values.' newline newline 'Once all wires and conductors have been defined, proceed to the ''Run Model'' tab.'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            else
                app.ErrorType.Text = 'Warning';
                app.ErrorType.FontColor = [0.5 0.5 0];
                if value <= 0
                    app.ErrorMsg.Text = ['The relative conductivity of the conductor must be positive.'];
                else
                    app.ErrorMsg.Text = ['The relative conductivity of the conductor cannot exceed ',num2str(app.StoredData.SUL),'.'];
                end
                app.ErrorMsg.FontColor = [0.5 0.5 0];
                app.sigmaEditField_4.Value = event.PreviousValue;
                app.StoredData.SigPrev(sdx,4) = event.PreviousValue;
            end

            app.SimulationDetailsPanel.Enable = 'off';
            app.SimulateButton.Enable = 'off';
            app.VisualizeResultsButton.Enable = 'off';
            app.EMFieldsButton.Enable = 'off';
            try
                app.StoredData = rmfield(app.StoredData,'EMTabAxes');
            end
            
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            
            % Confirmation
            Answer = uiconfirm(app.ENCoRPUIFigure,'Exit the current session? All unsaved progress will be lost.','Confirmation','Options',{'Yes','No'},'Icon','warning');

            if strcmp(Answer,'Yes')
                try
                    delete(findall(0));
                end
                clc

                % Close interface
                delete(app)
            end
            
        end

        % Menu selected function: OpenModelMenu
        function OpenModelMenuSelected(app, event)
            
            % Confirm selection
            if length(app.StoredData.X) ~= 0
                answer = uiconfirm(app.ENCoRPUIFigure,'Opening a model will overwrite existing data. Continue?','Confirmation','Options',{'Yes','No'},'Icon','warning');
            else
                answer = 'Yes';
            end
            % If yes, load and replace
            if strcmp(answer,'Yes')

               try
                    % Request filename and path
                    [filename, pathname, ~] = uigetfile({'*.mat','MAT-files (*.mat)'},'Select Model File'); 
                    if ~isnumeric(filename)
                        load(fullfile(pathname,filename),'SD');
                    end
                    app.ENCoRPUIFigure.focus

                    app.ParasiticsDropDown.Value = SD.ParSel;

                    % Reinitialize
                    startupFcn(app)
                    
                    % Redefine Stored Data from load file
                    
                    if SD.NCond == 2
                        app.NumberofConductorsDropDown.Value = '2';
                        value = '2';

                        % If 2 is chosen
                        if strcmp(value,'2')
                            app.Phase2Tab.Title = [char(8201),char(8201),char(8201),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160)];
                            app.Phase3Tab.Title = [char(8201),char(8201),char(8201),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160)];
                            app.OOLabel.Visible = 'off';
                            app.OOLabel_2.Visible = 'off';
                            app.GLabel.Visible = 'off';
                            app.Label.Visible = 'off';
                            app.Label_2.Visible = 'off';
                            app.Label_3.Visible = 'off';
                            app.SquareLabel.Text = ['Line'];
                            app.Label_4.Visible = 'on';
                            app.GLabel_2.Visible = 'on';
                            app.OOLabel_3.Visible = 'on';
                            app.OOLabel_4.Visible = 'off';
                            app.OLabel.Visible = 'off';
                            app.GLabel_3.Visible = 'off';
                            app.Label_5.Visible = 'off';
                            app.Label_6.Visible = 'off';
                            app.ParasiticsDropDown.Visible = 'off';
                            app.ParasiticsDropDownLabel.Visible = 'off';
                            app.PhaseSelectionButtonGroup.SelectedObject = app.Phase1Button;
                            app.StoredData.plotfcn = 1;
            
                        % If 3 is chosen
                        elseif strcmp(value,'3')
                            app.Phase2Tab.Title = 'Phase 2';
                            app.Phase3Tab.Title = [char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160),char(160)];
                            app.OOLabel.Visible = 'off';
                            app.OOLabel_2.Visible = 'off';
                            app.GLabel.Visible = 'off';
                            app.Label.Visible = 'off';
                            app.Label_2.Visible = 'off';
                            app.Label_3.Visible = 'off';
                            app.SquareLabel.Text = 'Triangle';
                            app.Label_4.Visible = 'off';
                            app.GLabel_2.Visible = 'off';
                            app.OOLabel_3.Visible = 'off';
                            app.OOLabel_4.Visible = 'on';
                            app.OLabel.Visible = 'on';
                            app.GLabel_3.Visible = 'on';
                            app.Label_5.Visible = 'on';
                            app.Label_6.Visible = 'on';
                            app.ParasiticsDropDown.Visible = 'on';
                            app.ParasiticsDropDownLabel.Visible = 'on';
            
                        % If 4 is chosen
                        elseif strcmp(value,'4')
                            app.SquareLabel.Text = ['Square'];
                            app.OOLabel_4.Visible = 'off';
                            app.OLabel.Visible = 'off';
                            app.GLabel_3.Visible = 'off';
                            app.Label_5.Visible = 'off';
                            app.Label_6.Visible = 'off';
                            app.Phase2Tab.Title = 'Phase 2';
                            app.Phase3Tab.Title = 'Phase 3';
                            app.OOLabel.Visible = 'on';
                            app.OOLabel_2.Visible = 'on';
                            app.GLabel.Visible = 'on';
                            app.Label.Visible = 'on';
                            app.Label_2.Visible = 'on';
                            app.Label_3.Visible = 'on';
                            app.Label_4.Visible = 'off';
                            app.GLabel_2.Visible = 'off';
                            app.OOLabel_3.Visible = 'off';
                            app.ParasiticsDropDown.Visible = 'on';
                            app.ParasiticsDropDownLabel.Visible = 'on';              
                        else
                            error('You''ve somehow hacked the matrix...')
                        end
        
                        SetTPCB(app);
                       
                        % Default to ground tab and check apply boxes
                        app.TabGroup3.SelectedTab = app.GroundTab;
            
                        % Check if buttons should be activated
                        CheckApplyBoxes(app)
            
                        % UpdateWireList
                        UpdateWireList(app)
                    end
                    tmpf = app.StoredData.Freq;
                    tmpPWD = app.StoredData.PWD;
                    tmpCP = app.StoredData.COMSOLPATH;
                    app.StoredData = SD;
                    app.StoredData.Freq = tmpf;
                    app.StoredData.PWD = tmpPWD;
                    app.StoredData.COMSOLPATH = tmpCP;
                    
                    % Plot without privilege and update lists
                    app.StoredData.lastEntity = 0;
                    PlotWires(app)
                    UpdateWireList(app)
                    
                    % Change to nodes tab if defaulted to wires
                    app.TabGroup2.SelectedTab = app.NodesTab_3;
    
                    % Also reset camera view
                    app.XEditField.Value = 0;
                    app.YEditField.Value = 0;
                    app.ZEditField.Value = 0;
                    app.UIAxes3.View = [45,45];
                    
                    % Unlock tabs depending on Unlocked
                    app.GeometryDefinitionButton.Enable = 'on';
                    Unlocked = app.StoredData.Unlocked;
                    if Unlocked <= 1
                        app.Button.Visible = 'on';
                        app.vButton.Visible = 'on';
                    else
                        app.Button.Visible = 'off';
                        app.vButton.Visible = 'off';
                    end
                    if Unlocked > 1
                        app.NodeDefinitionButton.Enable = 'on';
                        app.NodeDefinitionButton.Tooltip = '';
                    end
                    if Unlocked > 2
                        app.WiringDefinitionButton.Enable = 'on';
                        app.WiringDefinitionButton.Tooltip = '';
                    end
                    if Unlocked > 3
                        app.RunModelButton.Enable = 'on';
                        app.RunModelButton.Tooltip = '';
                        app.SimulationDetailsPanel.Enable = 'off';
                    end
                    %if Unlocked > 4 % Regenerate files for safety
                    %    app.VisualizeResultsButton.Enable = 'on';
                    %end
                    %if Unlocked > 5
                    %    app.EMFieldsButton.Enable = 'on';
                    %end
                    if length(app.StoredData.X) > 0
                        app.DeleteAllNodesButton.Enable = 'on';
                        app.RemoveSelectedButton.Enable = 'on';
                    else
                        app.DeleteAllNodesButton.Enable = 'off';
                        app.RemoveSelectedButton.Enable = 'off';
                    end
                    if length(app.StoredData.X) > 1
                        app.Button.Enable = 'on';
                        app.vButton.Enable = 'on';
                    else
                        app.Button.Enable = 'off';
                        app.vButton.Enable = 'off';
                    end
                    if size(app.StoredData.Wires,1) > 0
                        app.SwapNodesButton.Enable = 'on';
                        app.RemoveSelectedButton_2.Enable = 'on';
                        app.DeleteAllWiresButton.Enable = 'on';
                    else
                        app.SwapNodesButton.Enable = 'off';
                        app.RemoveSelectedButton_2.Enable = 'off';
                        app.DeleteAllWiresButton.Enable = 'off';
                    end
                end
            end

        end

        % Button pushed function: LoadNodesButton
        function LoadNodesButtonPushed(app, event)
            
            % Request file
            [filename, pathname, ~] = uigetfile({'*.mat','MAT-files (*.mat)'},'Select Nodal Locations Data File'); 
            if ~isnumeric(filename)
                loadeddata = load(fullfile(pathname,filename));
            end
            app.ENCoRPUIFigure.focus

            % Look for valid variables in file
            valid = [];
            try
                Fields = fieldnames(loadeddata);
                for ind = 1:length(Fields)
                    try
                        curfield = eval(['loadeddata.',Fields{ind},';']);
                        tmpsum = sum(size(curfield) == 3);
                        if tmpsum > 0
                            if ismatrix(curfield) && isnumeric(curfield) && isreal(curfield) && (sum(size(curfield)>0) == 2)
                                valid = [valid, ind];
                            end
                        end
                    end
                end

                % Return message to user based on valid variables
                if length(valid) > 1
                    ans2 = uiconfirm(app.ENCoRPUIFigure,'Detected multiple variables potentially containing nodal data. Please ensure there is only one n x 3 or 3 x n variable in the file. Nothing was loaded.','Confirmation','Options',{'Ok','Cancel'},'Icon','error');
                    error('Break out')
                elseif length(valid) == 0
                    ans2 = uiconfirm(app.ENCoRPUIFigure,'Unable to locate a real-valued, n x 3 or 3 x n variable in selected file. Nothing was loaded.','Confirmation','Options',{'Ok','Cancel'},'Icon','error');
                    error('Break out')
                else
                    if length(app.StoredData.X) ~= 0
                        answer = uiconfirm(app.ENCoRPUIFigure,'Loading nodal data will overwrite all current progress. Continue?','Confirmation','Options',{'Yes','No'},'Icon','warning');
                    else
                        answer = 'Yes';
                    end
                end
                if strcmp(answer,'Yes')
                    % Reset everything
                    startupFcn(app)
    
                    % Load the only valid variable
                    nodaldata = eval(['loadeddata.',Fields{valid},';']);
    
                    % Transpose if necessary (real-valued)
                    if size(nodaldata,2) ~= 3
                        nodaldata = nodaldata';
                    end
    
                    % Load into StoredData
                    app.StoredData.X = nodaldata(:,1)';
                    app.StoredData.Y = nodaldata(:,2)';
                    app.StoredData.Z = nodaldata(:,3)';
    
                    % Update plot list
                    PlotWires(app)
    
                    % Update buttons accordingly
                    if length(app.StoredData.X) > 0
                        app.DeleteAllNodesButton.Enable = 'on';
                        app.RemoveSelectedButton.Enable = 'on';
                    end
                    if length(app.StoredData.X) > 1
                        app.Button.Enable = 'on';
                        app.vButton.Enable = 'on';
                    end
                end
            end

            app.ENCoRPUIFigure.focus

        end

        % Button pushed function: LoadWiresButton
        function LoadWiresButtonPushed(app, event)
            
            % Ensure there are at least 2 nodes before proceeding
            if length(app.StoredData.X) < 2
                % Warning
                app.ErrorType.Text = 'Warning';
                app.ErrorType.FontColor = [0.5 0.5 0];
                app.ErrorMsg.Text = ['There must be at least two nodes for wire(s) to be loaded.'];
                app.ErrorMsg.FontColor = [0.5 0.5 0];
                return
            end

            % Request file
            [filename, pathname, ~] = uigetfile({'*.mat','MAT-files (*.mat)'},'Select Wire Data File'); 
            if ~isnumeric(filename)
                loadeddata = load(fullfile(pathname,filename));
            end
            app.ENCoRPUIFigure.focus

            % Look for valid variables in file
            valid = [];
            try
                Fields = fieldnames(loadeddata);
                for ind = 1:length(Fields)
                    try
                        curfield = eval(['loadeddata.',Fields{ind},';']);
                        tmpsum = sum(size(curfield) == 2);
                        if tmpsum > 0
                            if ismatrix(curfield) && isnumeric(curfield) && isreal(curfield) && (sum(size(curfield)>0) == 2) && (norm(curfield-round(curfield)) < 1e-12) && (min(min(curfield)) > 0) && (max(max(curfield)) <= length(app.StoredData.X))
                                valid = [valid, ind];
                            end
                        end
                    end
                end

                % Return message to user based on valid variables
                if length(valid) > 1
                    ans2 = uiconfirm(app.ENCoRPUIFigure,'Detected multiple variables potentially containing wire data. Please ensure there is only one n x 2 or 2 x n variable in the file. Nothing was loaded.','Error','Options',{'Ok','Cancel'},'Icon','error');
                    error('Break out')
                elseif length(valid) == 0
                    ans2 = uiconfirm(app.ENCoRPUIFigure,'Unable to locate a real-valued, n x 2 or 2 x n variable of integers relating to the existing nodes. Nothing was loaded.','Error','Options',{'Ok','Cancel'},'Icon','error');
                    error('Break out')
                else
                    if size(app.StoredData.Wires,1) ~= 0
                        answer = uiconfirm(app.ENCoRPUIFigure,'Loading wire data will overwrite all progress beyond the nodal locations. Continue?','Confirmation','Options',{'Yes','No'},'Icon','warning');
                    else
                        answer = 'Yes';
                    end
                end
                if strcmp(answer,'Yes')

                    % Save nodal locations
                    X = app.StoredData.X;
                    Y = app.StoredData.Y;
                    Z = app.StoredData.Z;
    
                    % Reset everything
                    startupFcn(app)
    
                    % Reload nodal data
                    app.StoredData.X = X;
                    app.StoredData.Y = Y;
                    app.StoredData.Z = Z;

                    % Load the only valid variable
                    wiredata = eval(['loadeddata.',Fields{valid},';']);

                    % Transpose if necessary (real-valued)
                    if size(wiredata,2) ~= 2
                        wiredata = wiredata';
                    end

                    % Remove duplicate wires
                    wd2 = wiredata;
                    tokeep = ones(size(wd2,1),1);
                    for ind2 = 1:size(wd2,1)
                        if wd2(ind2,1) == wd2(ind2,2)
                            tokeep(ind2) = 0;
                        elseif wd2(ind2,1) > wd2(ind2,2)
                            wd2(ind2,:) = [wd2(ind2,2),wd2(ind2,1)];
                        end
                    end
                    [~,itk] = unique(wd2,'rows');
                    for ind2 = 1:size(wd2,1)
                        if ~sum(itk==ind2)
                            tokeep(ind2) = 0;
                        end
                    end
                    wiredata = wiredata(logical(tokeep),:);

                    % Store accepted wires
                    app.StoredData.Wires = wiredata;

                    % Update plot list
                    PlotWires(app)

                    % Update wirelist
                    UpdateWireList(app)

                    % Update buttons accordingly
                    if size(app.StoredData.Wires,1) > 0
                        app.DeleteAllWiresButton.Enable = 'on';
                        app.RemoveSelectedButton_2.Enable = 'on';
                        app.SwapNodesButton.Enable = 'on';
                    end
                    if length(app.StoredData.X) > 0
                        app.DeleteAllNodesButton.Enable = 'on';
                        app.RemoveSelectedButton.Enable = 'on';
                    end
                    if length(app.StoredData.X) > 1
                        app.Button.Enable = 'on';
                        app.vButton.Enable = 'on';
                    end

                    if ~isempty(app.StoredData.X) && (size(app.StoredData.Wires,1) > 0)
                        if size(app.StoredData.Wires,1)+1 == length(app.StoredData.X)
                            app.NodeDefinitionButton.Enable=true;
                            app.NodeDefinitionButton.Tooltip = '';
                        else
                            app.NodeDefinitionButton.Enable = false;
                            app.NodeDefinitionButton.Tooltip = 'Define a network of n nodes and n-1 wires for this tab to unlock.';
                        end
                    end

                    % Warning if anything excluded
                    if size(wiredata,1) ~= length(tokeep)
                        app.ErrorType.Text = 'Warning';
                        app.ErrorType.FontColor = [0.5 0.5 0];
                        app.ErrorMsg.Text = ['At least one wire was not loaded.' newline newline 'Duplicates and wires that connect a node to itself are excluded.'];
                        app.ErrorMsg.FontColor = [0.5 0.5 0];
                    end
                end
            end

        end

        % Button pushed function: ClearAllDefinitionsButton
        function ClearAllDefinitionsButtonPushed(app, event)
            
            Answer = uiconfirm(app.ENCoRPUIFigure,'This will delete or reset all of the parameter values for all of the wires. Proceed?','Confirmation','Options',{'Yes','No'},'Icon','warning');
            if strcmp(Answer,'Yes')
                % Reset wire definitions
                ResetWiringTab(app)
                WiringDefinitionButtonPushed(app, event)
                app.TabGroup3.SelectedTab = app.GroundTab;

                app.WireListListBox_2.Value = app.WireListListBox_2.Items{1};
                app.StoredData.lastEntity = 2;
                app.StoredData.EntityList = app.StoredData.Wires(1,:);
                UpdateWireList(app)
                app.StoredData.lastEntity = 0;
            end

        end

        % Selection changed function: InjectionButtonGroup
        function InjectionButtonGroupSelectionChanged(app, event)

            selectedButton = app.InjectionButtonGroup.SelectedObject;

            if selectedButton == app.CurrentButton_2
                app.VLabel.Text = 'A';
                app.VLabel_2.Text = 'A';
                app.VLabel_3.Text = 'A';
            elseif selectedButton == app.VoltageButton_2
                app.VLabel.Text = 'V';
                app.VLabel_2.Text = 'V';
                app.VLabel_3.Text = 'V';
            end
       
            
        end

        % Value changed function: StartEditField
        function StartEditFieldValueChanged(app, event)

            value = app.StartEditField.Value;

            if value < 1
                app.StartEditField.Value = 1;
                app.ErrorType.Text = 'Error';
                app.ErrorType.FontColor = 'r';
                app.ErrorMsg.Text = 'The starting frequency must be in the range of [1, 1e9] Hz.';
                app.ErrorMsg.FontColor = 'r';
            elseif value > 1e9
                app.StartEditField.Value = 1e9;
                app.ErrorType.Text = 'Error';
                app.ErrorType.FontColor = 'r';
                app.ErrorMsg.Text = 'The starting frequency must be in the range of [1, 1e9] Hz.';
                app.ErrorMsg.FontColor = 'r';
            else
                % Troubleshooting
                app.ErrorType.Text = 'Run Model';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Set the simulation parameters.' newline newline 'Define the frequency, injected signal, resolution of simulation, and check the box if you would like to compare against SPICE (see documentation for details).'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            end

            if app.StartEditField.Value == app.FinishEditField.Value
                app.StoredData.PrevSteps = app.StepsEditField.Value;
                app.StepsEditField.Value = 1;
                app.StepsEditField.Enable = 'off';
            elseif ~app.StepsEditField.Enable
                app.StepsEditField.Enable = 'on';
                try
                    app.StepsEditField.Value = app.StoredData.PrevSteps;
                catch
                    app.StepsEditField.Value = 100;
                end
            end

            if app.StartEditField.Value > app.FinishEditField.Value
                tmp = app.StartEditField.Value;
                app.StartEditField.Value = app.FinishEditField.Value;
                app.FinishEditField.Value = tmp;
            end
            
        end

        % Value changed function: FinishEditField
        function FinishEditFieldValueChanged(app, event)

            value = app.FinishEditField.Value;
            
            if value < 1
                app.FinishEditField.Value = 1;
                app.ErrorType.Text = 'Error';
                app.ErrorType.FontColor = 'r';
                app.ErrorMsg.Text = 'The final frequency must be in the range of [1, 1e9] Hz.';
                app.ErrorMsg.FontColor = 'r';
            elseif value > 1e9
                app.FinishEditField.Value = 1e9;
                app.ErrorType.Text = 'Error';
                app.ErrorType.FontColor = 'r';
                app.ErrorMsg.Text = 'The final frequency must be in the range of [1, 1e9] Hz.';
                app.ErrorMsg.FontColor = 'r';
            else
                % Troubleshooting
                app.ErrorType.Text = 'Run Model';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Set the simulation parameters.' newline newline 'Define the frequency, injected signal, resolution of simulation, and check the box if you would like to compare against SPICE (see documentation for details).'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            end

            if app.StartEditField.Value == app.FinishEditField.Value
                app.StoredData.PrevSteps = app.StepsEditField.Value;
                app.StepsEditField.Value = 1;
                app.StepsEditField.Enable = 'off';
            elseif ~app.StepsEditField.Enable
                app.StepsEditField.Enable = 'on';
                try
                    app.StepsEditField.Value = app.StoredData.PrevSteps;
                catch
                    app.StepsEditField.Value = 100;
                end
            end

            if app.StartEditField.Value > app.FinishEditField.Value
                tmp = app.StartEditField.Value;
                app.StartEditField.Value = app.FinishEditField.Value;
                app.FinishEditField.Value = tmp;
            end

        end

        % Value changed function: StepsEditField
        function StepsEditFieldValueChanged(app, event)

            value = app.StepsEditField.Value;

            value = round(value);
            app.StepsEditField.Value = value;

            if value < 1
                app.StepsEditField.Value = 1;
                app.ErrorType.Text = 'Error';
                app.ErrorType.FontColor = 'r';
                app.ErrorMsg.Text = 'The number of steps must be between 1 and 10,000.';
                app.ErrorMsg.FontColor = 'r';
            elseif value > 1e4
                app.StepsEditField.Value = 1e4;
                app.ErrorType.Text = 'Error';
                app.ErrorType.FontColor = 'r';
                app.ErrorMsg.Text = 'The number of steps must be between 1 and 10,000.';
                app.ErrorMsg.FontColor = 'r';
            else
                % Troubleshooting
                app.ErrorType.Text = 'Run Model';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Set the simulation parameters.' newline newline 'Define the frequency, injected signal, resolution of simulation, and check the box if you would like to compare against SPICE (see documentation for details).'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            end

            if app.StepsEditField.Value == 1 && app.StartEditField.Value ~= app.FinishEditField.Value
                app.ErrorType.Text = 'Warning';
                app.ErrorType.FontColor = [0.5 0.5 0];
                app.ErrorMsg.Text = ['This will simulate only for the final frequency.' newline newline 'Increase the Steps to avoid this outcome.'];
                app.ErrorMsg.FontColor =[0.5 0.5 0];
            end

            % Tooltip for simbutton
            if ~app.UseLoadedFrequenciesCheckBox.Value
                nsimp = 2*(str2num(app.NumberofConductorsDropDown.Value)-1)*app.StepsEditField.Value*sum(app.StoredData.WireLengths)/(app.WireResolutionEditField.Value/100);
            else
                nsimp = 2*(str2num(app.NumberofConductorsDropDown.Value)-1)*length(app.StoredData.Freq)*sum(app.StoredData.WireLengths)/(app.WireResolutionEditField.Value/100);
            end
            app.SimulateButton.Tooltip = ['The current configuration will generate roughly ',sprintf('%1.1e',nsimp),' data points'];

        end

        % Value changed function: SPICECheckBox
        function SPICECheckBoxValueChanged(app, event)

            value = app.SPICECheckBox.Value;

            % Troubleshooting
            app.ErrorType.Text = 'Run Model';
            app.ErrorType.FontColor = [0 0.5 0];
            app.ErrorMsg.Text = ['Set the simulation parameters.' newline newline 'Define the frequency, injected signal, resolution of simulation, and check the box if you would like to compare against SPICE (see documentation for details).'];
            app.ErrorMsg.FontColor = [0 0.5 0];

            if value
                Answer = uiconfirm(app.ENCoRPUIFigure,'This checkbox prepares the simulation for a SPICE comparison, meaning various parameters will be fixed within the code to align with those in the SPICE comparison repository. See the documentation for more details. Continue?','Confirmation','Options',{'Yes','No'},'Icon','warning');
                if strcmp(Answer,'No')
                    app.SPICECheckBox.Value = false;
                end
            end
            
        end

        % Value changed function: WireResolutionEditField
        function WireResolutionEditFieldValueChanged(app, event)

            value = app.WireResolutionEditField.Value;

            if value < 0.1
                Answer = uiconfirm(app.ENCoRPUIFigure,'You have attempted to change the wire resolution to a sub-millimeter scale. Be aware that the computational cost may increase dramatically depending on the size of your network. Continue?','Confirmation','Options',{'Yes','No'},'Icon','warning');
            elseif value >= 100
                Answer = uiconfirm(app.ENCoRPUIFigure,'You have attempted to change the wire resolution to a meter or longer scale. Be aware that such low resolution may result in inaccurate visuals and poor radiative estimates. Continue?','Confirmation','Options',{'Yes','No'},'Icon','warning');
            else
                Answer = 'Yes';
            end

            if strcmp(Answer,'No')
                try 
                    app.WireResolutionEditField.Value = app.StoredData.OldWireRes;
                catch
                    app.WireResolutionEditField.Value = 1;
                end
            else
                app.StoredData.OldWireRes = app.WireResolutionEditField.Value;
            end

            % Troubleshooting
            app.ErrorType.Text = 'Run Model';
            app.ErrorType.FontColor = [0 0.5 0];
            app.ErrorMsg.Text = ['Set the simulation parameters.' newline newline 'Define the frequency, injected signal, resolution of simulation, and check the box if you would like to compare against SPICE (see documentation for details).'];
            app.ErrorMsg.FontColor = [0 0.5 0];

            % Tooltip for simbutton
            if ~app.UseLoadedFrequenciesCheckBox.Value
                nsimp = 2*(str2num(app.NumberofConductorsDropDown.Value)-1)*app.StepsEditField.Value*sum(app.StoredData.WireLengths)/(app.WireResolutionEditField.Value/100);
            else
                nsimp = 2*(str2num(app.NumberofConductorsDropDown.Value)-1)*length(app.StoredData.Freq)*sum(app.StoredData.WireLengths)/(app.WireResolutionEditField.Value/100);
            end
            app.SimulateButton.Tooltip = ['The current configuration will generate roughly ',sprintf('%1.1e',nsimp),' data points'];
            
        end

        % Value changed function: Phase1EditField
        function Phase1EditFieldValueChanged(app, event)

            value = app.Phase1EditField.Value;

            % Check for validity
            if isempty(value)
                app.Phase1EditField.BackgroundColor = [1 1 1];
                
                % Troubleshooting
                app.ErrorType.Text = 'Run Model';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Set the simulation parameters.' newline newline 'Define the frequency, injected signal, resolution of simulation, and check the box if you would like to compare against SPICE (see documentation for details).'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            else
                try
                    val = str2double(value);
                    success = 1;
                catch
                    success = 0;
                end
                if success && ~isnan(val)
                    app.Phase1EditField.BackgroundColor = [1 1 1];

                    % Troubleshooting
                    app.ErrorType.Text = 'Run Model';
                    app.ErrorType.FontColor = [0 0.5 0];
                    app.ErrorMsg.Text = ['Set the simulation parameters.' newline newline 'Define the frequency, injected signal, resolution of simulation, and check the box if you would like to compare against SPICE (see documentation for details).'];
                    app.ErrorMsg.FontColor = [0 0.5 0];
                else
                    app.Phase1EditField.BackgroundColor = [1 0.75 0.75];

                    % Troubleshooting
                    app.ErrorType.Text = 'Error';
                    app.ErrorType.FontColor = 'r';
                    app.ErrorMsg.Text = 'The Phase 1 injection value must be a real or complex number.';
                    app.ErrorMsg.FontColor = 'r';
                end
            end
            
        end

        % Value changed function: Phase2EditField
        function Phase2EditFieldValueChanged(app, event)

            value = app.Phase2EditField.Value;

            % Check for validity
            if isempty(value)
                app.Phase2EditField.BackgroundColor = [1 1 1];

                % Troubleshooting
                app.ErrorType.Text = 'Run Model';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Set the simulation parameters.' newline newline 'Define the frequency, injected signal, resolution of simulation, and check the box if you would like to compare against SPICE (see documentation for details).'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            else
                try
                    val = str2double(value);
                    success = 1;
                catch
                    success = 0;
                end
                if success && ~isnan(val)
                    app.Phase2EditField.BackgroundColor = [1 1 1];

                    % Troubleshooting
                    app.ErrorType.Text = 'Run Model';
                    app.ErrorType.FontColor = [0 0.5 0];
                    app.ErrorMsg.Text = ['Set the simulation parameters.' newline newline 'Define the frequency, injected signal, resolution of simulation, and check the box if you would like to compare against SPICE (see documentation for details).'];
                    app.ErrorMsg.FontColor = [0 0.5 0];
                else
                    app.Phase2EditField.BackgroundColor = [1 0.75 0.75];

                    % Troubleshooting
                    app.ErrorType.Text = 'Error';
                    app.ErrorType.FontColor = 'r';
                    app.ErrorMsg.Text = 'The Phase 2 injection value must be a real or complex number.';
                    app.ErrorMsg.FontColor = 'r';
                end
            end
            
        end

        % Value changed function: Phase3EditField
        function Phase3EditFieldValueChanged(app, event)

            value = app.Phase3EditField.Value;

            % Check for validity
            if isempty(value)
                app.Phase3EditField.BackgroundColor = [1 1 1];

                % Troubleshooting
                app.ErrorType.Text = 'Run Model';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Set the simulation parameters.' newline newline 'Define the frequency, injected signal, resolution of simulation, and check the box if you would like to compare against SPICE (see documentation for details).'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            else
                try
                    val = str2double(value);
                    success = 1;
                catch
                    success = 0;
                end
                if success && ~isnan(val)
                    app.Phase3EditField.BackgroundColor = [1 1 1];

                    % Troubleshooting
                    app.ErrorType.Text = 'Run Model';
                    app.ErrorType.FontColor = [0 0.5 0];
                    app.ErrorMsg.Text = ['Set the simulation parameters.' newline newline 'Define the frequency, injected signal, resolution of simulation, and check the box if you would like to compare against SPICE (see documentation for details).'];
                    app.ErrorMsg.FontColor = [0 0.5 0];
                else
                    app.Phase3EditField.BackgroundColor = [1 0.75 0.75];

                    % Troubleshooting
                    app.ErrorType.Text = 'Error';
                    app.ErrorType.FontColor = 'r';
                    app.ErrorMsg.Text = 'The Phase 3 injection value must be a real or complex number.';
                    app.ErrorMsg.FontColor = 'r';
                end
            end
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            % Request file
            [filename, pathname, ~] = uigetfile({'*.mat','MAT-files (*.mat)'},'Select Frequency Data File'); 
            if ~isnumeric(filename)
                loadeddata = load(fullfile(pathname,filename));
            end
            app.ENCoRPUIFigure.focus

            % Look for valid variables in file
            valid = [];
            try
                Fields = fieldnames(loadeddata);
                for ind = 1:length(Fields)
                    try
                        curfield = eval(['loadeddata.',Fields{ind},';']);
                        if isvector(curfield) && isnumeric(curfield) && isreal(curfield)
                            valid = [valid, ind];
                        end
                    end
                end

                % Return message to user based on valid variables
                if length(valid) > 1
                    ans2 = uiconfirm(app.ENCoRPUIFigure,'Detected multiple variables potentially containing frequency data. Please ensure there is only one n x 1 or 1 x n variable in the file. Nothing was loaded.','Confirmation','Options',{'Ok','Cancel'},'Icon','error');
                    error('Break out')
                elseif length(valid) == 0
                    ans2 = uiconfirm(app.ENCoRPUIFigure,'Unable to locate a real-valued, n x 1 or 1 x n variable in selected file. Nothing was loaded.','Confirmation','Options',{'Ok','Cancel'},'Icon','error');
                    error('Break out')
                else
          
                    % Load the only valid variable
                    freqdata = eval(['loadeddata.',Fields{valid},';']);

                    if max(freqdata) > 1e9
                        ans2 = uiconfirm(app.ENCoRPUIFigure,'At least one frequency in the file exceeds the maximum of 1 GHz. Nothing was loaded.','Confirmation','Options',{'Ok','Cancel'},'Icon','error');
                        error('Break out')
                    elseif min(freqdata) < 1
                        ans2 = uiconfirm(app.ENCoRPUIFigure,'At least one frequency in the file subceeds the minimum of 1 Hz. Nothing was loaded.','Confirmation','Options',{'Ok','Cancel'},'Icon','error');
                        error('Break out')
                    end

                    % Transpose if necessary (real-valued)
                    if size(freqdata,1) ~= 1
                        freqdata = freqdata';
                    end

                    % Load into StoredData (sort and remove dupes)
                    app.StoredData.Freq = unique(freqdata);

                    % Add filename to box and check/enable the box
                    app.UseLoadedFrequenciesCheckBox.Enable = 'on';
                    app.UseLoadedFrequenciesCheckBox.Value = true;
                    app.FileLabel.Visible = true;
                    app.Label_25.Text = filename;
                    app.Label_25.Tooltip = filename;       
                    app.Label_25.FontColor = [0 0 0];
                    app.FileLabel.FontColor = [0 0 0];
                    app.UseLoadedFrequenciesCheckBox.FontColor = [0 0 0];
                end
            end

            % Tooltip for simbutton
            if ~app.UseLoadedFrequenciesCheckBox.Value
                nsimp = 2*(str2num(app.NumberofConductorsDropDown.Value)-1)*app.StepsEditField.Value*sum(app.StoredData.WireLengths)/(app.WireResolutionEditField.Value/100);
            else
                nsimp = 2*(str2num(app.NumberofConductorsDropDown.Value)-1)*length(app.StoredData.Freq)*sum(app.StoredData.WireLengths)/(app.WireResolutionEditField.Value/100);
            end
            app.SimulateButton.Tooltip = ['The current configuration will generate roughly ',sprintf('%1.1e',nsimp),' data points'];

        end

        % Value changed function: UseLoadedFrequenciesCheckBox
        function UseLoadedFrequenciesCheckBoxValueChanged(app, event)

            value = app.UseLoadedFrequenciesCheckBox.Value;

            if value
                app.Label_25.FontColor = [0 0 0];
                app.FileLabel.FontColor = [0 0 0];
                app.UseLoadedFrequenciesCheckBox.FontColor = [0 0 0];
            else
                app.Label_25.FontColor = [0.5 0.5 0.5];
                app.FileLabel.FontColor = [0.5 0.5 0.5];
                app.UseLoadedFrequenciesCheckBox.FontColor = [0.5 0.5 0.5];
            end

            % Tooltip for simbutton
            if ~app.UseLoadedFrequenciesCheckBox.Value
                nsimp = 2*(str2num(app.NumberofConductorsDropDown.Value)-1)*app.StepsEditField.Value*sum(app.StoredData.WireLengths)/(app.WireResolutionEditField.Value/100);
            else
                nsimp = 2*(str2num(app.NumberofConductorsDropDown.Value)-1)*length(app.StoredData.Freq)*sum(app.StoredData.WireLengths)/(app.WireResolutionEditField.Value/100);
            end
            app.SimulateButton.Tooltip = ['The current configuration will generate roughly ',sprintf('%1.1e',nsimp),' data points'];
            
        end

        % Value changed function: LineWidthEditField
        function LineWidthEditFieldValueChanged(app, event)
            
            value = app.LineWidthEditField.Value;
            
            if value < 1
                app.LineWidthEditField.Value = 1;
                app.LineWidthEditField.Tooltip = 'The line width must be a positive integer.';
                app.ErrorType.Text = 'Visualize Results';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Spatial Voltage and Current Tab:' newline 'Use the slider to visualize the results for the nearest available frequency. Optionally export the results in MAT, PNG, or animated GIF form.' newline newline 'Transmission Parameters Tab:' newline 'Use the drop downs and check boxes to display transmission parameters from the transmitter to the receiver.'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            else
                app.LineWidthEditField.Tooltip = '';
                app.ErrorType.Text = 'Visualize Results';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Spatial Voltage and Current Tab:' newline 'Use the slider to visualize the results for the nearest available frequency. Optionally export the results in MAT, PNG, or animated GIF form.' newline newline 'Transmission Parameters Tab:' newline 'Use the drop downs and check boxes to display transmission parameters from the transmitter to the receiver.'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            end

            PlotModelResults(app)


        end

        % Selection change function: TabGroup5
        function TabGroup5SelectionChanged(app, event)

            selectedTab = app.TabGroup5.SelectedTab;

            if selectedTab == app.SpatialVoltageandCurrentTab
                if app.StoredData.TLV
                    app.NetworkDiagramPanel.Visible = 'on';
                    app.NetworkDiagramPanel_2.Visible = 'off';
                    app.StoredData.TLV = 0;
                end
                PlotModelResults(app);
            else
                app.StoredData.TLV = 1;
                app.NetworkDiagramPanel.Visible = 'off';
                if strcmp(app.TransmissionParametersDropDown.Value,'ABCD')
                    app.DropDown.Items = {'A','B','C','D'};
                elseif strcmp(app.TransmissionParametersDropDown.Value,'S')
                    app.DropDown.Items = {['S',char(8321),char(8321)],['S',char(8321),char(8322)],['S',char(8322),char(8321)],['S',char(8322),char(8322)]};
                elseif strcmp(app.TransmissionParametersDropDown.Value,'Z')
                    app.DropDown.Items = {['Z',char(8321),char(8321)],['Z',char(8321),char(8322)],['Z',char(8322),char(8321)],['Z',char(8322),char(8322)]};
                elseif strcmp(app.TransmissionParametersDropDown.Value,'Y')
                    app.DropDown.Items = {['Y',char(8321),char(8321)],['Y',char(8321),char(8322)],['Y',char(8322),char(8321)],['Y',char(8322),char(8322)]};
                else
                    error('Should be unreachable (invalid ABCD selection).')
                end
                if strcmp(app.NumberofConductorsDropDown.Value,'2')
                    app.DropDown.Visible = 'off';
                    app.ofBlockLabel.Visible = 'off';
                else
                    app.DropDown.Visible = 'on';
                    app.ofBlockLabel.Text = ['of Block ',app.DropDown.Value];
                    app.ofBlockLabel.Visible = 'on';
                end
                app.NetworkDiagramPanel_2.Visible = 'on';
                PlotTPResults(app);
            end
            
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            
            try
                Answer = uiconfirm(app.ENCoRPUIFigure,'Would you like to export the data with respect to the network topology or with respect to spatial coordinates? See the documentation for more details.','Question','Options',{'Network','XYZ','Cancel'},'Icon','question');
                if strcmp(Answer, 'Network')
                    [filename, pathname] = uiputfile('*.mat', 'Save As...');
                    Data = load([app.StoredData.PWD,'Data/VIOut.mat']);
                    Data = rmfield(Data,'ABCD');
                    Data.Description = 'VI is a cell containing the complex VI data. Cell {i,j} contains the data corresponding to the i-th frequency and j-th wire. Within each cell is a matrix whose columns are the voltages for the phase wires followed by the currents for the phase wires. The rows correspond to the points along the wire, specified in WireID (the node indices), corresponding to the Nodes (xyz locations), where the first point is the beginning of the wire (WireID first column) and the last point is the end of the wire (WireID second column). Note that the distribution may not appear to match the WireRes if the WireLengths were defined with slack.';
                    save(fullfile(pathname,filename),'Data','-mat');

                elseif strcmp(Answer,'XYZ')
                    
                    % Load data
                    [filename, pathname] = uiputfile('*.mat', 'Save As...');
                    Data2 = load([app.StoredData.PWD,'Data/VIOut.mat']);
                    
                    % Load required data
                    Freq = double(Data2.Freq);
                    Nodes = double(Data2.Nodes); 
                    Wires = double(Data2.WireID);
                    WireRes = double(Data2.WireRes);
                    VI = Data2.VI;
                    ncond = size(VI{1,1},2)/2;
                    ntotal = 0;
                    for ind1 = 1:size(VI,1)
                        for ind2 = 1:size(VI,2)
                            ntotal = ntotal + size(VI{ind1,ind2},1);
                        end
                    end
                    OldW = app.StoredData.Wires;
                    WireLengths = app.StoredData.WireLengths;
                    WL = -ones(size(WireLengths));
                    
                    % Correct wirelength correspondence
                    for ind2 = 1:size(Wires,1)
                        for ind1 = 1:size(OldW,1)
                            if ((OldW(ind1,1) == Wires(ind2,1)) && (OldW(ind1,2) == Wires(ind2,2))) || ((OldW(ind1,2) == Wires(ind2,1)) && (OldW(ind1,1) == Wires(ind2,2)))
                                WL(ind2) = WireLengths(ind1);
                                break;
                            end
                        end
                    end
        
                    % Prepare final matrix
                    FXYZVI = nan(ntotal,4+2*ncond);

                    % Compute unit vectors and true distance for all wires for use below
                    Wuv = nan(size(Wires,1),3);
                    TD = nan(size(WL));
                    for indw = 1:size(Wires,1)
                        % Final node xyz - initial node xyz
                        Wuv(indw,:) = Nodes(Wires(indw,2),:) - Nodes(Wires(indw,1),:);

                        % Compute true distance
                        TD(indw) = norm(Wuv(indw,:));

                        % Normalize
                        Wuv(indw,:) = Wuv(indw,:)/norm(Wuv(indw,:));
                    end

                    % Iterate through all f, all w, all VI
                    rownum = 1;
                    for indf = 1:length(Freq)
                        for indw = 1:size(Wires,1)
                            
                            % Compute x,y,z for wire for SLACK distance
                            % Obtain current uv with wireres
                            curuv = Wuv(indw,:)*WireRes;
                            
                            % Generate with respect to origin
                            XYZ = nan(size(VI{indf,indw},1),3);
                            XYZ(1,:) = [0 0 0];
                            
                            % Iterate through wire res shifts
                            for indxyz = 2:size(VI{indf,indw},1)-1
                                XYZ(indxyz,:) = XYZ(indxyz-1,:) + curuv;
                            end

                            % Final value is full FAKE length
                            XYZ(end,:) = WL(indw)*Wuv(indw,:);

                            % Scale to true distance
                            XYZ = XYZ.*TD(indw)./WL(indw);

                            % Translate to physical location
                            XYZ = XYZ + repmat(Nodes(Wires(indw,1),:),size(XYZ,1),1);

                            % Store values in matrix
                            FXYZVI(rownum:rownum+size(XYZ,1)-1,1) = Freq(indf);
                            FXYZVI(rownum:rownum+size(XYZ,1)-1,2:4) = XYZ;
                            FXYZVI(rownum:rownum+size(XYZ,1)-1,5:(4+2*ncond)) = VI{indf,indw};
                            rownum = rownum + size(XYZ,1);
                            
                        end
                    end

                    Data.FXYZVI = FXYZVI;
                    Data.Description = 'FXYZVI is a CSV-friendly matrix whose columns are Frequency, X, Y, Z, Voltage(s), and Current(s). All in SI units. Note that the spacing between points may not match the specified wire resolution if slack was added (scaled back to physical distance when output in this manner).';
                    save(fullfile(pathname,filename),'Data','-mat');

                end
            end

            app.ENCoRPUIFigure.focus

        end

        % Value changed function: FrameDelayEditField
        function FrameDelayEditFieldValueChanged(app, event)

            value = app.FrameDelayEditField.Value;
            if value < 0.01
                app.FrameDelayEditField.Value = 0.01;
                app.FrameDelayEditField.Tooltip = 'Frame delay cannot be less than 0.01 seconds.';
            else
                app.FrameDelayEditField.Tooltip = '';
            end
            
        end

        % Value changed function: ParasiticsDropDown
        function ParasiticsDropDownValueChanged(app, event)

            value = app.ParasiticsDropDown.Value;

            app.SimulationDetailsPanel.Enable = 'off';
            app.SimulateButton.Enable = 'off';
            app.VisualizeResultsButton.Enable = 'off';
            app.EMFieldsButton.Enable = 'off';
            try
                app.StoredData = rmfield(app.StoredData,'EMTabAxes');
            end
            
        end

        % Button pushed function: ExportDataButton_2
        function ExportDataButton_2Pushed(app, event)
            
            try
                [filename, pathname] = uiputfile('*.mat', 'Save As...');
                Data = load([app.StoredData.PWD,'Data/VIOut.mat']);
                Data = rmfield(Data,'Nodes');
                Data = rmfield(Data,'WireID');
                Data = rmfield(Data,'WireRes');
                Data = rmfield(Data,'VI');
                for Dind = 1:length(Data.Freq)
                    Data.ABCD(:,:,Dind) = inv(Data.ABCD(:,:,Dind));
                end
                if strcmp(app.TransmissionParametersDropDown.Value,'ABCD')
                    Data.Description = 'The ABCD parameters are stored in ABCD, with the pages corresponding to the frequencies in Freq.';
                elseif strcmp(app.TransmissionParametersDropDown.Value,'S')
                    Data.Description = 'The S parameters are stored in S, with the pages corresponding to the frequencies in Freq.';
                    Data.S = abcd2s(Data.ABCD,app.Z_0EditField.Value);
                    Data = rmfield(Data,'ABCD');
                elseif strcmp(app.TransmissionParametersDropDown.Value,'Y')
                    Data.Description = 'The Y parameters are stored in Y, with the pages corresponding to the frequencies in Freq.';
                    Data.Y = abcd2y(Data.ABCD);
                    Data = rmfield(Data,'ABCD');
                elseif strcmp(app.TransmissionParametersDropDown.Value,'Z')
                    Data.Description = 'The Z parameters are stored in Z, with the pages corresponding to the frequencies in Freq.';
                    Data.Z = abcd2z(Data.ABCD);
                    Data = rmfield(Data,'ABCD');
                end
                save(fullfile(pathname,filename),'Data','-mat');
            end

            app.ENCoRPUIFigure.focus

        end

        % Value changed function: TransmissionParametersDropDown
        function TransmissionParametersDropDownValueChanged(app, event)

            value = app.TransmissionParametersDropDown.Value;

            % Enable Z0 based on selection
            if strcmp(value,'S')
                app.Z_0EditField.Visible = 'on';
                app.Label_26.Visible = 'off';
            else
                app.Z_0EditField.Visible = 'off';
                app.Label_26.Visible = 'on';
            end
            if strcmp(value,'ABCD')
                app.DropDown.Items = {'A','B','C','D'};
            elseif strcmp(value,'S')
                app.DropDown.Items = {['S',char(8321),char(8321)],['S',char(8321),char(8322)],['S',char(8322),char(8321)],['S',char(8322),char(8322)]};
            elseif strcmp(value,'Z')
                app.DropDown.Items = {['Z',char(8321),char(8321)],['Z',char(8321),char(8322)],['Z',char(8322),char(8321)],['Z',char(8322),char(8322)]};
            elseif strcmp(value,'Y')
                app.DropDown.Items = {['Y',char(8321),char(8321)],['Y',char(8321),char(8322)],['Y',char(8322),char(8321)],['Y',char(8322),char(8322)]};
            else
                error('Should be unreachable (invalid ABCD selection).')
            end
            app.ofBlockLabel.Text = ['of Block ',app.DropDown.Value];

            try
                PlotTPResults(app);
            catch
                app.Panel_4.Enable = 'on';
                if strcmp(value,'Z') || strcmp(value,'Y')
                    clc
                    app.TransmissionParametersDropDown.Value = 'ABCD';
                    TransmissionParametersDropDownValueChanged(app,event);
                    Answer = uiconfirm(app.ENCoRPUIFigure,'The impedance and admittance parameters cannot be computed for this network. The ABCD parameters will be plotted instead. See the documentation for more details.','Warning','Options',{'Ok','Cancel'},'Icon','warning');
                else
                    PlotTPResults(app);
                    % Let it break to reveal bug
                end
            end
            
        end

        % Value changed function: Z_0EditField
        function Z_0EditFieldValueChanged(app, event)

            value = app.Z_0EditField.Value;

            if value <= 1e-3
                app.Z_0EditField.Value = 1e-3;
                app.Z_0EditField.Tooltip = ['The reference impedance must be a positive, real value (lower limit of 1 m',char(937),').'];
            else
                app.Z_0EditField.Tooltip = '';
            end

            PlotTPResults(app);
            
        end

        % Button pushed function: TurnOnAllButton
        function TurnOnAllButtonPushed(app, event)
            
            if strcmp(app.NumberofConductorsDropDown.Value,'4')

                % Turn on check boxes

                app.CheckBox_1.Value = true;
                for i = 2:9
                    eval(['app.CheckBox_',num2str(i),'.Value = true;']);
                end

            else

                % Turn on check boxes

                for i = 10:13
                    eval(['app.CheckBox_',num2str(i),'.Value = true;']);
                end

            end

            PlotTPResults(app);

        end

        % Button pushed function: TurnOffAllButton
        function TurnOffAllButtonPushed(app, event)
            
            if strcmp(app.NumberofConductorsDropDown.Value,'4')

                % Turn off check oxes

                app.CheckBox_1.Value = false;
                for i = 2:9
                    eval(['app.CheckBox_',num2str(i),'.Value = false;']);
                end

            else

                % Turn off check boxes

                for i = 10:13
                    eval(['app.CheckBox_',num2str(i),'.Value = false;']);
                end
                
            end

            PlotTPResults(app);

        end

        % Button pushed function: GeneratePNGButton_2
        function GeneratePNGButton_2Pushed(app, event)

            nplots = 0;
            if strcmp(app.NumberofConductorsDropDown.Value,'4')
                for ind = 1:9
                    if eval(['app.CheckBox_',num2str(ind),'.Value'])
                        nplots = nplots + 1;
                    end
                end
            elseif strcmp(app.NumberofConductorsDropDown.Value,'2')
                for ind = 10:13
                    if eval(['app.CheckBox_',num2str(ind),'.Value'])
                        nplots = nplots + 1;
                    end
                end
            end

            if nplots == 0
                Answer = uiconfirm(app.ENCoRPUIFigure,'This picture doesn''t feature any transmission parameters. Continue?','Confirmation','Options',{'Yes, for personal reasons','No'},'Icon','question');
            else
                Answer = 'Yes, for personal reasons';
            end

            if strcmp(Answer,'Yes, for personal reasons')

                try
                    [filename, pathname] = uiputfile('*.png', 'Save As...');

                    try
                        leg = findobj(app.NetworkDiagramPanel_2.Children,'Type','Legend');
                        leg.EdgeColor = [1 1 1];
                        leg.Color = [1 1 1];
                    end

                    try
                        exportgraphics(app.NetworkDiagramPanel_2,fullfile(pathname,filename));
                    catch
                        if ~strcmp(filename(end-3:end),'.png')
                            exportgraphics(app.NetworkDiagramPanel_2,fullfile(pathname,filename,'.png'));
                        end
                    end
                end
                try
                    leg.EdgeColor = [0.94 0.94 0.94];
                    leg.Color = [0.94 0.94 0.94];
                end
            end

            app.ENCoRPUIFigure.focus

        end

        % Value changed function: CheckBox_13
        function CheckBox_13ValueChanged(app, event)

            value = app.CheckBox_13.Value;

            PlotTPResults(app);
            
        end

        % Value changed function: CheckBox_12
        function CheckBox_12ValueChanged(app, event)

            value = app.CheckBox_12.Value;

            PlotTPResults(app);
            
        end

        % Value changed function: CheckBox_11
        function CheckBox_11ValueChanged(app, event)

            value = app.CheckBox_11.Value;

            PlotTPResults(app);
            
        end

        % Value changed function: CheckBox_10
        function CheckBox_10ValueChanged(app, event)

            value = app.CheckBox_10.Value;

            PlotTPResults(app);
            
        end

        % Value changed function: CheckBox_9
        function CheckBox_9ValueChanged(app, event)

            value = app.CheckBox_9.Value;

            PlotTPResults(app);
            
        end

        % Value changed function: CheckBox_8
        function CheckBox_8ValueChanged(app, event)

            value = app.CheckBox_8.Value;

            PlotTPResults(app);
            
        end

        % Value changed function: CheckBox_7
        function CheckBox_7ValueChanged(app, event)

            value = app.CheckBox_7.Value;

            PlotTPResults(app);
            
        end

        % Value changed function: CheckBox_6
        function CheckBox_6ValueChanged(app, event)

            value = app.CheckBox_6.Value;

            PlotTPResults(app);
            
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)

            value = app.CheckBox_5.Value;

            PlotTPResults(app);
            
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)

            value = app.CheckBox_4.Value;

            PlotTPResults(app);
            
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)

            value = app.CheckBox_3.Value;

            PlotTPResults(app);
            
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)

            value = app.CheckBox_2.Value;

            PlotTPResults(app);
            
        end

        % Value changed function: CheckBox_1
        function CheckBox_1ValueChanged(app, event)

            value = app.CheckBox_1.Value;

            PlotTPResults(app);
            
        end

        % Selection changed function: ScalingButtonGroup
        function ScalingButtonGroupSelectionChanged(app, event)

            selectedButton = app.ScalingButtonGroup.SelectedObject;

            PlotModelResults(app)
            
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            
            % Request file
            [filename, pathname, ~] = uigetfile({'*.mat','MAT-files (*.mat)'},'Select Custom Load Data File'); 
            if ~isnumeric(filename)
                loadeddata = load(fullfile(pathname,filename));
            end
            app.ENCoRPUIFigure.focus

            try
                
                % Look for valid variables in file
                valid = [];
                Fields = fieldnames(loadeddata);
                for ind = 1:length(Fields)
                    try
                        curfield = eval(['loadeddata.',Fields{ind},';']);
                        % It's a numeric matrix
                        if isnumeric(curfield)
                            if ((size(curfield,2) == 6) && (length(size(curfield)) == 2)) || ((size(curfield,1) == 3) && (size(curfield,2) == 3) && ((length(size(curfield)) == 3) || (length(size(curfield)) == 2))) || ((size(curfield,2) == 1) && (length(size(curfield)) == 2))
                                valid = [valid, ind];
                            end
                        end
                    end
                end

                % *The node (requires saving)
                [~, idx] = ismember(app.AvailableNodesListBox.Value, app.AvailableNodesListBox.Items);
                app.StoredData.Nodes(idx).VarSelected = '';
                
                % Highlight node
                app.StoredData.lastEntity=1;
                app.StoredData.EntityList = [app.StoredData.X(idx), app.StoredData.Y(idx), app.StoredData.Z(idx)];
                PlotWires(app)
                app.StoredData.lastEntity=0;
    
                % Populate accordingly
                app.FileLabel_2.Text = ['File:  ',filename];
                app.FileLabel_2.Tooltip = filename;
                VDD = [];
                for ind = 1:length(valid)
                    VDD = [VDD, string(Fields{valid(ind)})];
                end
                try
                    app.VariableDropDown.Items = VDD;
                    app.VariableDropDown.Value = app.VariableDropDown.Items{1};
                    app.StoredData.Nodes(idx).tmpLD = loadeddata;
                catch
                    app.VariableDropDown.Items = {};
                end
            end
                
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            value = app.DropDown.Value;

            app.ofBlockLabel.Text = ['of Block ',app.DropDown.Value];
            
            PlotTPResults(app)

        end

        % Menu selected function: HelpMenu_2
        function HelpMenu_2Selected(app, event)
            
            Answer = uiconfirm(app.ENCoRPUIFigure,'Utilize the Troubleshooting panel, tooltips by hovering various buttons, or refer to the documentation for assistance.','Help','Options',{'Okay','That doesn''t help'},'Icon','question');
            if strcmp(Answer,'That doesn''t help')
                Answer2 = uiconfirm(app.ENCoRPUIFigure,'If you suspect you''ve encountered a bug, or if you have questions not addressed in the aforementioned material, send an email to: encorphelp@gmail.com.','More Information','Options',{'Okay','Cancel'},'Icon','info');
            end

        end

        % Value changed function: oEditField
        function oEditFieldValueChanged(app, event)

            value = app.oEditField.Value;
            tol = 1e-3;
            if (abs(value-pi/6) < tol) || (abs(value-pi/4) < tol) || (abs(value-pi/3) < tol) || (abs(value-pi/2) < tol) || (abs(value-2*pi/3) < tol) || (abs(value-3*pi/4) < tol) || (abs(value-5*pi/6) < tol) || (abs(value-pi) < tol) || (abs(value-7*pi/6) < tol) || (abs(value-5*pi/4) < tol) || (abs(value-4*pi/3) < tol) || (abs(value-3*pi/2) < tol) || (abs(value-5*pi/3) < tol) || (abs(value-7*pi/4) < tol) ||- (abs(value-11*pi/6) < tol) || (abs(value-2*pi) < tol)
                app.ErrorType.Text = 'Warning';
                app.ErrorType.FontColor = [0.5 0.5 0];
                app.ErrorMsg.Text = 'Phase shift 1 may have been defined in radians rather than degrees.';
                app.ErrorMsg.FontColor = [0.5 0.5 0];
            else
                % Troubleshooting
                app.ErrorType.Text = 'Run Model';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Set the simulation parameters.' newline newline 'Define the frequency, injected signal, resolution of simulation, and check the box if you would like to compare against SPICE (see documentation for details).'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            end
            
        end

        % Value changed function: oEditField_2
        function oEditField_2ValueChanged(app, event)

            value = app.oEditField_2.Value;
            tol = 1e-3;
            if (abs(value-pi/6) < tol) || (abs(value-pi/4) < tol) || (abs(value-pi/3) < tol) || (abs(value-pi/2) < tol) || (abs(value-2*pi/3) < tol) || (abs(value-3*pi/4) < tol) || (abs(value-5*pi/6) < tol) || (abs(value-pi) < tol) || (abs(value-7*pi/6) < tol) || (abs(value-5*pi/4) < tol) || (abs(value-4*pi/3) < tol) || (abs(value-3*pi/2) < tol) || (abs(value-5*pi/3) < tol) || (abs(value-7*pi/4) < tol) ||- (abs(value-11*pi/6) < tol) || (abs(value-2*pi) < tol)
                app.ErrorType.Text = 'Warning';
                app.ErrorType.FontColor = [0.5 0.5 0];
                app.ErrorMsg.Text = 'Phase shift 2 may have been defined in radians rather than degrees.';
                app.ErrorMsg.FontColor = [0.5 0.5 0];
            else
                % Troubleshooting
                app.ErrorType.Text = 'Run Model';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Set the simulation parameters.' newline newline 'Define the frequency, injected signal, resolution of simulation, and check the box if you would like to compare against SPICE (see documentation for details).'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            end
            
        end

        % Value changed function: oEditField_3
        function oEditField_3ValueChanged(app, event)

            value = app.oEditField_3.Value;
            tol = 1e-3;
            if (abs(value-pi/6) < tol) || (abs(value-pi/4) < tol) || (abs(value-pi/3) < tol) || (abs(value-pi/2) < tol) || (abs(value-2*pi/3) < tol) || (abs(value-3*pi/4) < tol) || (abs(value-5*pi/6) < tol) || (abs(value-pi) < tol) || (abs(value-7*pi/6) < tol) || (abs(value-5*pi/4) < tol) || (abs(value-4*pi/3) < tol) || (abs(value-3*pi/2) < tol) || (abs(value-5*pi/3) < tol) || (abs(value-7*pi/4) < tol) ||- (abs(value-11*pi/6) < tol) || (abs(value-2*pi) < tol)
                app.ErrorType.Text = 'Warning';
                app.ErrorType.FontColor = [0.5 0.5 0];
                app.ErrorMsg.Text = 'Phase shift 3 may have been defined in radians rather than degrees.';
                app.ErrorMsg.FontColor = [0.5 0.5 0];
            else
                % Troubleshooting
                app.ErrorType.Text = 'Run Model';
                app.ErrorType.FontColor = [0 0.5 0];
                app.ErrorMsg.Text = ['Set the simulation parameters.' newline newline 'Define the frequency, injected signal, resolution of simulation, and check the box if you would like to compare against SPICE (see documentation for details).'];
                app.ErrorMsg.FontColor = [0 0.5 0];
            end
            
        end

        % Close request function: ENCoRPUIFigure
        function ENCoRPUIFigureCloseRequest(app, event)
            
            system(['sh ',app.StoredData.PWD,'Radiative/KillCOMSOLServer.sh']);
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ENCoRPUIFigure = uifigure('Visible', 'off');
            app.ENCoRPUIFigure.Name = 'ENCoRP';
            app.ENCoRPUIFigure.CloseRequestFcn = createCallbackFcn(app, @ENCoRPUIFigureCloseRequest, true);
            app.ENCoRPUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.ENCoRPUIFigure);
            app.Menu.Visible = 'off';
            app.Menu.Text = 'Menu';
            app.DebugModeMenu = uimenu(app.Menu);
            app.DebugModeMenu.MenuSelectedFcn = createCallbackFcn(app, @DebugModeMenuSelected, true);
            app.DebugModeMenu.Text = 'Debug Mode';
            app.FileMenu = uimenu(app.ENCoRPUIFigure);
            app.FileMenu.Text = 'File';
            app.NewModelMenu = uimenu(app.FileMenu);
            app.NewModelMenu.MenuSelectedFcn = createCallbackFcn(app, @NewModelMenuSelected, true);
            app.NewModelMenu.Text = 'New Model';
            app.OpenModelMenu = uimenu(app.FileMenu);
            app.OpenModelMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenModelMenuSelected, true);
            app.OpenModelMenu.Text = 'Open Model...';
            app.SaveModelMenu = uimenu(app.FileMenu);
            app.SaveModelMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveModelMenuSelected, true);
            app.SaveModelMenu.Text = 'Save Model...';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.ENCoRPUIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.HelpMenu_2 = uimenu(app.HelpMenu);
            app.HelpMenu_2.MenuSelectedFcn = createCallbackFcn(app, @HelpMenu_2Selected, true);
            app.HelpMenu_2.Text = 'Help';
            app.GridLayout = uigridlayout(app.ENCoRPUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataSelectionLabel = uilabel(app.LeftPanel);
            app.DataSelectionLabel.Text = 'Data Selection';
            app.GeometryDefinitionButton = uibutton(app.LeftPanel, 'push');
            app.GeometryDefinitionButton.ButtonPushedFcn = createCallbackFcn(app, @GeometryDefinitionButtonPushed, true);
            app.GeometryDefinitionButton.Text = 'Geometry Definition';
            app.NodeDefinitionButton = uibutton(app.LeftPanel, 'push');
            app.NodeDefinitionButton.ButtonPushedFcn = createCallbackFcn(app, @NodeDefinitionButtonPushed, true);
            app.NodeDefinitionButton.Enable = 'off';
            app.NodeDefinitionButton.Tooltip = {''};
            app.NodeDefinitionButton.Text = 'Node Definition';
            app.WiringDefinitionButton = uibutton(app.LeftPanel, 'push');
            app.WiringDefinitionButton.ButtonPushedFcn = createCallbackFcn(app, @WiringDefinitionButtonPushed, true);
            app.WiringDefinitionButton.Enable = 'off';
            app.WiringDefinitionButton.Tooltip = {''};
            app.WiringDefinitionButton.Text = 'Wiring Definition';
            app.RunModelButton = uibutton(app.LeftPanel, 'push');
            app.RunModelButton.ButtonPushedFcn = createCallbackFcn(app, @RunModelButtonPushed, true);
            app.RunModelButton.Enable = 'off';
            app.RunModelButton.Tooltip = {''};
            app.RunModelButton.Text = 'Run Model';
            app.VisualizeResultsButton = uibutton(app.LeftPanel, 'push');
            app.VisualizeResultsButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeResultsButtonPushed, true);
            app.VisualizeResultsButton.Enable = 'off';
            app.VisualizeResultsButton.Tooltip = {''};
            app.VisualizeResultsButton.Text = 'Visualize Results';
            app.TroubleshootingPanel = uipanel(app.LeftPanel);
            app.TroubleshootingPanel.Title = 'Troubleshooting';
            app.ErrorType = uilabel(app.TroubleshootingPanel);
            app.ErrorType.Text = ' ';
            app.ErrorMsg = uilabel(app.TroubleshootingPanel);
            app.ErrorMsg.Text = ' ';
            app.EMFieldsButton = uibutton(app.LeftPanel, 'push');
            app.EMFieldsButton.ButtonPushedFcn = createCallbackFcn(app, @EMFieldsButtonPushed, true);
            app.EMFieldsButton.Enable = 'off';
            app.EMFieldsButton.Text = 'E&M Fields';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup4 = uitabgroup(app.RightPanel);
            app.Tab = uitab(app.TabGroup4);
            app.Tab.Title = '                                                                                                                                                     ';
            app.NetworkDiagramPanel_2 = uipanel(app.Tab);
            app.NetworkDiagramPanel_2.Title = 'Network Diagram';
            app.NetworkDiagramPanel = uipanel(app.Tab);
            app.NetworkDiagramPanel.Title = 'Network Diagram';
            app.UIAxes3 = uiaxes(app.NetworkDiagramPanel);
            xlabel(app.UIAxes3, 'X (m)')
            ylabel(app.UIAxes3, 'Y (m)')
            zlabel(app.UIAxes3, 'Z (m)')
            app.Panel_4 = uipanel(app.Tab);
            app.TabGroup = uitabgroup(app.Panel_4);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GeometryTab = uitab(app.TabGroup);
            app.GeometryTab.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GeometryTab.Title = 'Geometry';
            app.TabGroup2 = uitabgroup(app.GeometryTab);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.NodesTab_3 = uitab(app.TabGroup2);
            app.NodesTab_3.Title = 'Nodes';
            app.AddNodeButton = uibutton(app.NodesTab_3, 'push');
            app.AddNodeButton.ButtonPushedFcn = createCallbackFcn(app, @AddNodeButtonPushed, true);
            app.AddNodeButton.Text = 'Add Node';
            app.NodeLocationsLabel = uilabel(app.NodesTab_3);
            app.NodeLocationsLabel.Text = 'Node Locations';
            app.XEditFieldLabel = uilabel(app.NodesTab_3);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.NodesTab_3, 'numeric');
            app.YEditFieldLabel = uilabel(app.NodesTab_3);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.NodesTab_3, 'numeric');
            app.ZEditFieldLabel = uilabel(app.NodesTab_3);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.NodesTab_3, 'numeric');
            app.SystemNodesListBoxLabel = uilabel(app.NodesTab_3);
            app.SystemNodesListBoxLabel.Text = 'System Nodes';
            app.SystemNodesListBox = uilistbox(app.NodesTab_3);
            app.SystemNodesListBox.Items = {};
            app.SystemNodesListBox.ValueChangedFcn = createCallbackFcn(app, @SystemNodesListBoxValueChanged, true);
            app.SystemNodesListBox.Value = {};
            app.RemoveSelectedButton = uibutton(app.NodesTab_3, 'push');
            app.RemoveSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSelectedButtonPushed, true);
            app.RemoveSelectedButton.Enable = 'off';
            app.RemoveSelectedButton.Text = 'Remove Selected';
            app.Button = uibutton(app.NodesTab_3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Enable = 'off';
            app.Button.Text = '^';
            app.vButton = uibutton(app.NodesTab_3, 'push');
            app.vButton.ButtonPushedFcn = createCallbackFcn(app, @vButtonPushed, true);
            app.vButton.Enable = 'off';
            app.vButton.Text = 'v';
            app.mLabel = uilabel(app.NodesTab_3);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.NodesTab_3);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.NodesTab_3);
            app.mLabel_3.Text = 'm';
            app.DeleteAllNodesButton = uibutton(app.NodesTab_3, 'push');
            app.DeleteAllNodesButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllNodesButtonPushed, true);
            app.DeleteAllNodesButton.Enable = 'off';
            app.DeleteAllNodesButton.Text = 'Delete All Nodes';
            app.LoadNodesButton = uibutton(app.NodesTab_3, 'push');
            app.LoadNodesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNodesButtonPushed, true);
            app.LoadNodesButton.Text = 'Load Nodes';
            app.WiresTab = uitab(app.TabGroup2);
            app.WiresTab.Title = 'Wires';
            app.SourceNodeListBoxLabel = uilabel(app.WiresTab);
            app.SourceNodeListBoxLabel.Text = 'Source Node';
            app.SourceNodeListBox = uilistbox(app.WiresTab);
            app.SourceNodeListBox.Items = {'Node 0 = (0,0,1)', 'Node 1 = (1,2,2)'};
            app.SourceNodeListBox.ValueChangedFcn = createCallbackFcn(app, @SourceNodeListBoxValueChanged, true);
            app.SourceNodeListBox.Tooltip = {''};
            app.SourceNodeListBox.Value = 'Node 1 = (1,2,2)';
            app.DestinationNodeListBoxLabel = uilabel(app.WiresTab);
            app.DestinationNodeListBoxLabel.Text = 'Destination Node';
            app.DestinationNodeListBox = uilistbox(app.WiresTab);
            app.DestinationNodeListBox.Items = {'Node 0 = (0,0,1)', 'Node 1 = (1,2,2)'};
            app.DestinationNodeListBox.ValueChangedFcn = createCallbackFcn(app, @DestinationNodeListBoxValueChanged, true);
            app.DestinationNodeListBox.Value = 'Node 1 = (1,2,2)';
            app.AddWireButton = uibutton(app.WiresTab, 'push');
            app.AddWireButton.ButtonPushedFcn = createCallbackFcn(app, @AddWireButtonPushed, true);
            app.AddWireButton.Enable = 'off';
            app.AddWireButton.Text = 'Add Wire';
            app.WireListListBoxLabel = uilabel(app.WiresTab);
            app.WireListListBoxLabel.Text = 'Wire List';
            app.WireListListBox = uilistbox(app.WiresTab);
            app.WireListListBox.Items = {};
            app.WireListListBox.ValueChangedFcn = createCallbackFcn(app, @WireListListBoxValueChanged, true);
            app.WireListListBox.Value = {};
            app.RemoveSelectedButton_2 = uibutton(app.WiresTab, 'push');
            app.RemoveSelectedButton_2.ButtonPushedFcn = createCallbackFcn(app, @RemoveSelectedButton_2Pushed, true);
            app.RemoveSelectedButton_2.Enable = 'off';
            app.RemoveSelectedButton_2.Text = 'Remove Selected';
            app.SwapNodesButton = uibutton(app.WiresTab, 'push');
            app.SwapNodesButton.ButtonPushedFcn = createCallbackFcn(app, @SwapNodesButtonPushed, true);
            app.SwapNodesButton.Enable = 'off';
            app.SwapNodesButton.Text = 'Swap Nodes';
            app.DeleteAllWiresButton = uibutton(app.WiresTab, 'push');
            app.DeleteAllWiresButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllWiresButtonPushed, true);
            app.DeleteAllWiresButton.Enable = 'off';
            app.DeleteAllWiresButton.Text = 'Delete All Wires';
            app.LoadWiresButton = uibutton(app.WiresTab, 'push');
            app.LoadWiresButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWiresButtonPushed, true);
            app.LoadWiresButton.Text = 'Load Wires';
            app.NodesTab = uitab(app.TabGroup);
            app.NodesTab.Title = 'Nodes';
            app.AvailableNodesListBoxLabel = uilabel(app.NodesTab);
            app.AvailableNodesListBoxLabel.Text = 'Available Nodes';
            app.AvailableNodesListBox = uilistbox(app.NodesTab);
            app.AvailableNodesListBox.Items = {'Node 0 = (0,0,1)', 'Node 1 = (1,2,2)'};
            app.AvailableNodesListBox.ValueChangedFcn = createCallbackFcn(app, @AvailableNodesListBoxValueChanged, true);
            app.AvailableNodesListBox.Value = 'Node 1 = (1,2,2)';
            app.NodeTypeDropDownLabel = uilabel(app.NodesTab);
            app.NodeTypeDropDownLabel.Text = 'Node Type';
            app.NodeTypeDropDown = uidropdown(app.NodesTab);
            app.NodeTypeDropDown.Items = {'None', 'Derivation Box', 'Receiver', 'Transmitter', 'Outlet'};
            app.NodeTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @NodeTypeDropDownValueChanged, true);
            app.NodeTypeDropDown.Value = 'None';
            app.LoadDefinitionPanel = uipanel(app.NodesTab);
            app.LoadDefinitionPanel.Title = 'Load Definition';
            app.LoadDefinitionPanel.Visible = 'off';
            app.LoadTypeDropDown = uidropdown(app.LoadDefinitionPanel);
            app.LoadTypeDropDown.Items = {'LoadType1', 'LoadType2'};
            app.LoadTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadTypeDropDownValueChanged, true);
            app.LoadTypeDropDown.Value = 'LoadType1';
            app.LoadTypeLabel = uilabel(app.LoadDefinitionPanel);
            app.LoadTypeLabel.Text = 'Load Type';
            app.Param1EditField = uieditfield(app.LoadDefinitionPanel, 'text');
            app.Param1EditField.ValueChangedFcn = createCallbackFcn(app, @Param1EditFieldValueChanged, true);
            app.Param1EditFieldLabel = uilabel(app.LoadDefinitionPanel);
            app.Param1EditFieldLabel.Text = 'Param1';
            app.Param2EditField = uieditfield(app.LoadDefinitionPanel, 'text');
            app.Param2EditField.ValueChangedFcn = createCallbackFcn(app, @Param2EditFieldValueChanged, true);
            app.Param2EditFieldLabel = uilabel(app.LoadDefinitionPanel);
            app.Param2EditFieldLabel.Text = 'Param2';
            app.Param3EditField = uieditfield(app.LoadDefinitionPanel, 'text');
            app.Param3EditField.ValueChangedFcn = createCallbackFcn(app, @Param3EditFieldValueChanged, true);
            app.Param3EditFieldLabel = uilabel(app.LoadDefinitionPanel);
            app.Param3EditFieldLabel.Text = 'Param3';
            app.Param4EditField = uieditfield(app.LoadDefinitionPanel, 'text');
            app.Param4EditField.ValueChangedFcn = createCallbackFcn(app, @Param4EditFieldValueChanged, true);
            app.Param4EditFieldLabel = uilabel(app.LoadDefinitionPanel);
            app.Param4EditFieldLabel.Text = 'Param4';
            app.Param5EditField = uieditfield(app.LoadDefinitionPanel, 'text');
            app.Param5EditField.ValueChangedFcn = createCallbackFcn(app, @Param5EditFieldValueChanged, true);
            app.Param5EditFieldLabel = uilabel(app.LoadDefinitionPanel);
            app.Param5EditFieldLabel.Text = 'Param5';
            app.Param6EditField = uieditfield(app.LoadDefinitionPanel, 'text');
            app.Param6EditField.ValueChangedFcn = createCallbackFcn(app, @Param6EditFieldValueChanged, true);
            app.Param6EditFieldLabel = uilabel(app.LoadDefinitionPanel);
            app.Param6EditFieldLabel.Text = 'Param6';
            app.Param7EditField = uieditfield(app.LoadDefinitionPanel, 'text');
            app.Param7EditField.ValueChangedFcn = createCallbackFcn(app, @Param7EditFieldValueChanged, true);
            app.Param7EditFieldLabel = uilabel(app.LoadDefinitionPanel);
            app.Param7EditFieldLabel.Text = 'Param7';
            app.Param8EditField = uieditfield(app.LoadDefinitionPanel, 'text');
            app.Param8EditField.ValueChangedFcn = createCallbackFcn(app, @Param8EditFieldValueChanged, true);
            app.Param8EditFieldLabel = uilabel(app.LoadDefinitionPanel);
            app.Param8EditFieldLabel.Text = 'Param8';
            app.Param9EditField = uieditfield(app.LoadDefinitionPanel, 'text');
            app.Param9EditField.ValueChangedFcn = createCallbackFcn(app, @Param9EditFieldValueChanged, true);
            app.Param9EditFieldLabel = uilabel(app.LoadDefinitionPanel);
            app.Param9EditFieldLabel.Text = 'Param9';
            app.Param10EditField = uieditfield(app.LoadDefinitionPanel, 'text');
            app.Param10EditField.ValueChangedFcn = createCallbackFcn(app, @Param10EditFieldValueChanged, true);
            app.Param10EditFieldLabel = uilabel(app.LoadDefinitionPanel);
            app.Param10EditFieldLabel.Text = 'Param10';
            app.Param11EditField = uieditfield(app.LoadDefinitionPanel, 'text');
            app.Param11EditField.ValueChangedFcn = createCallbackFcn(app, @Param11EditFieldValueChanged, true);
            app.Param11EditFieldLabel = uilabel(app.LoadDefinitionPanel);
            app.Param11EditFieldLabel.Text = 'Param11';
            app.Param12EditField = uieditfield(app.LoadDefinitionPanel, 'text');
            app.Param12EditField.ValueChangedFcn = createCallbackFcn(app, @Param12EditFieldValueChanged, true);
            app.Param12EditFieldLabel = uilabel(app.LoadDefinitionPanel);
            app.Param12EditFieldLabel.Text = 'Param12';
            app.SaveLoadConfigurationButton = uibutton(app.LoadDefinitionPanel, 'push');
            app.SaveLoadConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLoadConfigurationButtonPushed, true);
            app.SaveLoadConfigurationButton.Text = 'Save Load Configuration';
            app.PL1Label = uilabel(app.LoadDefinitionPanel);
            app.PL1Label.Text = 'PL1';
            app.PL2Label = uilabel(app.LoadDefinitionPanel);
            app.PL2Label.Text = 'PL1';
            app.PL3Label = uilabel(app.LoadDefinitionPanel);
            app.PL3Label.Text = 'PL1';
            app.PL4Label = uilabel(app.LoadDefinitionPanel);
            app.PL4Label.Text = 'PL1';
            app.PL5Label = uilabel(app.LoadDefinitionPanel);
            app.PL5Label.Text = 'PL1';
            app.PL6Label = uilabel(app.LoadDefinitionPanel);
            app.PL6Label.Text = 'PL1';
            app.PL7Label = uilabel(app.LoadDefinitionPanel);
            app.PL7Label.Text = 'PL1';
            app.PL8Label = uilabel(app.LoadDefinitionPanel);
            app.PL8Label.Text = 'PL1';
            app.PL9Label = uilabel(app.LoadDefinitionPanel);
            app.PL9Label.Text = 'PL1';
            app.LoadButton_2 = uibutton(app.LoadDefinitionPanel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.FileLabel_2 = uilabel(app.LoadDefinitionPanel);
            app.FileLabel_2.Text = 'File: ';
            app.VariableDropDownLabel = uilabel(app.LoadDefinitionPanel);
            app.VariableDropDownLabel.Text = 'Variable';
            app.VariableDropDown = uidropdown(app.LoadDefinitionPanel);
            app.VariableDropDown.Items = {' '};
            app.VariableDropDown.Value = ' ';
            app.ClearNodeConfigurationButton = uibutton(app.NodesTab, 'push');
            app.ClearNodeConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @ClearNodeConfigurationButtonPushed, true);
            app.ClearNodeConfigurationButton.Text = 'Clear Node Configuration';
            app.SaveNodeConfigurationButton = uibutton(app.NodesTab, 'push');
            app.SaveNodeConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNodeConfigurationButtonPushed, true);
            app.SaveNodeConfigurationButton.Text = 'Save Node Configuration';
            app.ClearAllConfigurationsButton = uibutton(app.NodesTab, 'push');
            app.ClearAllConfigurationsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllConfigurationsButtonPushed, true);
            app.ClearAllConfigurationsButton.Text = 'Clear All Configurations';
            app.WiringTab = uitab(app.TabGroup);
            app.WiringTab.Title = 'Wiring';
            app.Panel = uipanel(app.WiringTab);
            app.WireListListBox_2Label = uilabel(app.WiringTab);
            app.WireListListBox_2Label.Text = 'Wire List';
            app.WireListListBox_2 = uilistbox(app.WiringTab);
            app.WireListListBox_2.Items = {};
            app.WireListListBox_2.ValueChangedFcn = createCallbackFcn(app, @WireListListBox_2ValueChanged, true);
            app.WireListListBox_2.Value = {};
            app.NumberofConductorsLabel = uilabel(app.WiringTab);
            app.NumberofConductorsLabel.Text = 'Number of Conductors';
            app.NumberofConductorsDropDown = uidropdown(app.WiringTab);
            app.NumberofConductorsDropDown.Items = {'2', '4'};
            app.NumberofConductorsDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofConductorsDropDownValueChanged, true);
            app.NumberofConductorsDropDown.Value = '4';
            app.TabGroup3 = uitabgroup(app.WiringTab);
            app.TabGroup3.SelectionChangedFcn = createCallbackFcn(app, @TabGroup3SelectionChanged, true);
            app.GroundTab = uitab(app.TabGroup3);
            app.GroundTab.Title = 'Ground';
            app.WireRadiusEditFieldLabel = uilabel(app.GroundTab);
            app.WireRadiusEditFieldLabel.Text = 'Wire Radius';
            app.WireRadiusEditField_1 = uieditfield(app.GroundTab, 'text');
            app.WireRadiusEditField_1.ValueChangedFcn = createCallbackFcn(app, @WireRadiusEditField_1ValueChanged, true);
            app.WireMaterialDropDownLabel = uilabel(app.GroundTab);
            app.WireMaterialDropDownLabel.Text = 'Wire Material';
            app.WireMaterialDropDown_1 = uidropdown(app.GroundTab);
            app.WireMaterialDropDown_1.Items = {'Copper', 'Aluminum', 'Gold', 'Unobtainium'};
            app.WireMaterialDropDown_1.ValueChangedFcn = createCallbackFcn(app, @WireMaterialDropDown_1ValueChanged, true);
            app.WireMaterialDropDown_1.Value = 'Copper';
            app.mmLabel_2 = uilabel(app.GroundTab);
            app.mmLabel_2.Text = 'mm';
            app.sigmaEditFieldLabel = uilabel(app.GroundTab);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.GroundTab, 'numeric');
            app.sigmaEditField.ValueChangedFcn = createCallbackFcn(app, @sigmaEditFieldValueChanged, true);
            app.sigmaEditField.Value = 5.85;
            app.SmLabel = uilabel(app.GroundTab);
            app.SmLabel.Text = ' 10   S/m';
            app.xLabel = uilabel(app.GroundTab);
            app.xLabel.Text = 'x';
            app.Label_8 = uilabel(app.GroundTab);
            app.Label_8.Text = '7';
            app.Phase1Tab = uitab(app.TabGroup3);
            app.Phase1Tab.Title = 'Phase 1';
            app.WireRadiusEditField_6Label = uilabel(app.Phase1Tab);
            app.WireRadiusEditField_6Label.Text = 'Wire Radius';
            app.WireRadiusEditField_2 = uieditfield(app.Phase1Tab, 'text');
            app.WireRadiusEditField_2.ValueChangedFcn = createCallbackFcn(app, @WireRadiusEditField_2ValueChanged, true);
            app.WireMaterialDropDown_3Label = uilabel(app.Phase1Tab);
            app.WireMaterialDropDown_3Label.Text = 'Wire Material';
            app.WireMaterialDropDown_2 = uidropdown(app.Phase1Tab);
            app.WireMaterialDropDown_2.Items = {'Copper', 'Aluminum', 'Gold', 'Unobtainium'};
            app.WireMaterialDropDown_2.ValueChangedFcn = createCallbackFcn(app, @WireMaterialDropDown_2ValueChanged, true);
            app.WireMaterialDropDown_2.Value = 'Copper';
            app.mmLabel_3 = uilabel(app.Phase1Tab);
            app.mmLabel_3.Text = 'mm';
            app.SmLabel_2 = uilabel(app.Phase1Tab);
            app.SmLabel_2.Text = ' 10   S/m';
            app.xLabel_2 = uilabel(app.Phase1Tab);
            app.xLabel_2.Text = 'x';
            app.Label_9 = uilabel(app.Phase1Tab);
            app.Label_9.Text = '7';
            app.sigmaEditField_2Label = uilabel(app.Phase1Tab);
            app.sigmaEditField_2Label.Text = 'sigma';
            app.sigmaEditField_2 = uieditfield(app.Phase1Tab, 'numeric');
            app.sigmaEditField_2.ValueChangedFcn = createCallbackFcn(app, @sigmaEditField_2ValueChanged, true);
            app.sigmaEditField_2.Value = 5.85;
            app.Phase2Tab = uitab(app.TabGroup3);
            app.Phase2Tab.Title = 'Phase 2';
            app.WireRadiusEditField_7Label = uilabel(app.Phase2Tab);
            app.WireRadiusEditField_7Label.Text = 'Wire Radius';
            app.WireRadiusEditField_3 = uieditfield(app.Phase2Tab, 'text');
            app.WireRadiusEditField_3.ValueChangedFcn = createCallbackFcn(app, @WireRadiusEditField_3ValueChanged, true);
            app.WireMaterialDropDown_4Label = uilabel(app.Phase2Tab);
            app.WireMaterialDropDown_4Label.Text = 'Wire Material';
            app.WireMaterialDropDown_3 = uidropdown(app.Phase2Tab);
            app.WireMaterialDropDown_3.Items = {'Copper', 'Aluminum', 'Gold', 'Unobtainium'};
            app.WireMaterialDropDown_3.ValueChangedFcn = createCallbackFcn(app, @WireMaterialDropDown_3ValueChanged, true);
            app.WireMaterialDropDown_3.Value = 'Copper';
            app.mmLabel_4 = uilabel(app.Phase2Tab);
            app.mmLabel_4.Text = 'mm';
            app.SmLabel_3 = uilabel(app.Phase2Tab);
            app.SmLabel_3.Text = ' 10   S/m';
            app.xLabel_3 = uilabel(app.Phase2Tab);
            app.xLabel_3.Text = 'x';
            app.Label_10 = uilabel(app.Phase2Tab);
            app.Label_10.Text = '7';
            app.sigmaEditField_3Label = uilabel(app.Phase2Tab);
            app.sigmaEditField_3Label.Text = 'sigma';
            app.sigmaEditField_3 = uieditfield(app.Phase2Tab, 'numeric');
            app.sigmaEditField_3.ValueChangedFcn = createCallbackFcn(app, @sigmaEditField_3ValueChanged, true);
            app.sigmaEditField_3.Value = 5.85;
            app.Phase3Tab = uitab(app.TabGroup3);
            app.Phase3Tab.Title = 'Phase 3';
            app.mmLabel_5 = uilabel(app.Phase3Tab);
            app.mmLabel_5.Text = 'mm';
            app.SmLabel_4 = uilabel(app.Phase3Tab);
            app.SmLabel_4.Text = ' 10   S/m';
            app.xLabel_4 = uilabel(app.Phase3Tab);
            app.xLabel_4.Text = 'x';
            app.Label_11 = uilabel(app.Phase3Tab);
            app.Label_11.Text = '7';
            app.WireRadiusEditField_8Label = uilabel(app.Phase3Tab);
            app.WireRadiusEditField_8Label.Text = 'Wire Radius';
            app.WireRadiusEditField_4 = uieditfield(app.Phase3Tab, 'text');
            app.WireRadiusEditField_4.ValueChangedFcn = createCallbackFcn(app, @WireRadiusEditField_4ValueChanged, true);
            app.WireMaterialDropDown_5Label = uilabel(app.Phase3Tab);
            app.WireMaterialDropDown_5Label.Text = 'Wire Material';
            app.WireMaterialDropDown_4 = uidropdown(app.Phase3Tab);
            app.WireMaterialDropDown_4.Items = {'Copper', 'Aluminum', 'Gold', 'Unobtainium'};
            app.WireMaterialDropDown_4.ValueChangedFcn = createCallbackFcn(app, @WireMaterialDropDown_4ValueChanged, true);
            app.WireMaterialDropDown_4.Value = 'Copper';
            app.sigmaEditField_4Label = uilabel(app.Phase3Tab);
            app.sigmaEditField_4Label.Text = 'sigma';
            app.sigmaEditField_4 = uieditfield(app.Phase3Tab, 'numeric');
            app.sigmaEditField_4.ValueChangedFcn = createCallbackFcn(app, @sigmaEditField_4ValueChanged, true);
            app.sigmaEditField_4.Value = 5.85;
            app.WireLengthLabel_2 = uilabel(app.WiringTab);
            app.WireLengthLabel_2.Text = 'Wire Length';
            app.ApplytoAllWiresButton = uibutton(app.WiringTab, 'push');
            app.ApplytoAllWiresButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoAllWiresButtonPushed, true);
            app.ApplytoAllWiresButton.Text = 'Apply to All Wires';
            app.ApplytoAllConductorsButton = uibutton(app.WiringTab, 'push');
            app.ApplytoAllConductorsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoAllConductorsButtonPushed, true);
            app.ApplytoAllConductorsButton.Text = 'Apply to All Conductors';
            app.RemoveWireDefinitionButton = uibutton(app.WiringTab, 'push');
            app.RemoveWireDefinitionButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveWireDefinitionButtonPushed, true);
            app.RemoveWireDefinitionButton.Text = 'Remove Wire Definition';
            app.EditField = uieditfield(app.WiringTab, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.SquareLabel = uilabel(app.WiringTab);
            app.SquareLabel.Text = 'Square';
            app.ClearAllDefinitionsButton = uibutton(app.WiringTab, 'push');
            app.ClearAllDefinitionsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllDefinitionsButtonPushed, true);
            app.ClearAllDefinitionsButton.Text = 'Clear All Definitions';
            app.WireArrangementLabel = uilabel(app.WiringTab);
            app.WireArrangementLabel.Text = 'Wire Arrangement';
            app.OOLabel_2 = uilabel(app.WiringTab);
            app.OOLabel_2.Text = 'O  O';
            app.OOLabel = uilabel(app.WiringTab);
            app.OOLabel.Text = 'O  O';
            app.GLabel = uilabel(app.WiringTab);
            app.GLabel.Text = 'G';
            app.Label = uilabel(app.WiringTab);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.WiringTab);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.WiringTab);
            app.Label_3.Text = '3';
            app.GLabel_2 = uilabel(app.WiringTab);
            app.GLabel_2.Text = 'G';
            app.Label_4 = uilabel(app.WiringTab);
            app.Label_4.Text = '1';
            app.OOLabel_3 = uilabel(app.WiringTab);
            app.OOLabel_3.Text = 'O  O';
            app.GLabel_3 = uilabel(app.WiringTab);
            app.GLabel_3.Text = 'G';
            app.Label_5 = uilabel(app.WiringTab);
            app.Label_5.Text = '2';
            app.OOLabel_4 = uilabel(app.WiringTab);
            app.OOLabel_4.Text = 'O  O';
            app.OLabel = uilabel(app.WiringTab);
            app.OLabel.Text = 'O ';
            app.Label_6 = uilabel(app.WiringTab);
            app.Label_6.Text = '1';
            app.Label_7 = uilabel(app.WiringTab);
            app.Label_7.Text = '|---------|';
            app.mmLabel = uilabel(app.WiringTab);
            app.mmLabel.Text = 'mm';
            app.mLabel_12 = uilabel(app.WiringTab);
            app.mLabel_12.Text = 'm';
            app.SeparationDistanceEditField_2Label = uilabel(app.WiringTab);
            app.SeparationDistanceEditField_2Label.Text = 'Separation Distance';
            app.SeparationDistanceEditField = uieditfield(app.WiringTab, 'text');
            app.SeparationDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @SeparationDistanceEditFieldValueChanged, true);
            app.InsulatorMaterialDropDownLabel = uilabel(app.WiringTab);
            app.InsulatorMaterialDropDownLabel.Text = 'Insulator Material';
            app.InsulatorMaterialDropDown = uidropdown(app.WiringTab);
            app.InsulatorMaterialDropDown.Items = {'PVC', 'Custom'};
            app.InsulatorMaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @InsulatorMaterialDropDownValueChanged, true);
            app.InsulatorMaterialDropDown.Value = 'PVC';
            app.eps0FmLabel = uilabel(app.WiringTab);
            app.eps0FmLabel.Text = 'eps0 (F/m)';
            app.epsilonEditFieldLabel = uilabel(app.WiringTab);
            app.epsilonEditFieldLabel.Text = 'epsilon';
            app.epsilonEditField = uieditfield(app.WiringTab, 'numeric');
            app.epsilonEditField.ValueChangedFcn = createCallbackFcn(app, @epsilonEditFieldValueChanged, true);
            app.epsilonEditField.Value = 3.6;
            app.ParasiticsDropDownLabel = uilabel(app.WiringTab);
            app.ParasiticsDropDownLabel.Text = 'Parasitics';
            app.ParasiticsDropDown = uidropdown(app.WiringTab);
            app.ParasiticsDropDown.Items = {'On', 'Minimal'};
            app.ParasiticsDropDown.ValueChangedFcn = createCallbackFcn(app, @ParasiticsDropDownValueChanged, true);
            app.ParasiticsDropDown.Value = 'On';
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.SimulationDetailsPanel = uipanel(app.ModelTab);
            app.SimulationDetailsPanel.Title = 'Simulation Details';
            app.Panel_2 = uipanel(app.SimulationDetailsPanel);
            app.Panel_3 = uipanel(app.Panel_2);
            app.WireResolutionEditFieldLabel = uilabel(app.Panel_3);
            app.WireResolutionEditFieldLabel.Text = 'Wire Resolution';
            app.WireResolutionEditField = uieditfield(app.Panel_3, 'numeric');
            app.WireResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @WireResolutionEditFieldValueChanged, true);
            app.WireResolutionEditField.Value = 1;
            app.FrequencyButtonGroup = uibuttongroup(app.SimulationDetailsPanel);
            app.FrequencyButtonGroup.Title = 'Frequency';
            app.LogButton = uitogglebutton(app.FrequencyButtonGroup);
            app.LogButton.Text = 'Log';
            app.LogButton.Value = true;
            app.LinearButton_2 = uitogglebutton(app.FrequencyButtonGroup);
            app.LinearButton_2.Text = 'Linear';
            app.StartEditFieldLabel = uilabel(app.FrequencyButtonGroup);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.FrequencyButtonGroup, 'numeric');
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @StartEditFieldValueChanged, true);
            app.StartEditField.Value = 1;
            app.FinishEditFieldLabel = uilabel(app.FrequencyButtonGroup);
            app.FinishEditFieldLabel.Text = 'Finish';
            app.FinishEditField = uieditfield(app.FrequencyButtonGroup, 'numeric');
            app.FinishEditField.ValueChangedFcn = createCallbackFcn(app, @FinishEditFieldValueChanged, true);
            app.FinishEditField.Value = 100000000;
            app.StepsEditFieldLabel = uilabel(app.FrequencyButtonGroup);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.StepsEditField = uieditfield(app.FrequencyButtonGroup, 'numeric');
            app.StepsEditField.ValueChangedFcn = createCallbackFcn(app, @StepsEditFieldValueChanged, true);
            app.StepsEditField.Value = 100;
            app.ORLabel = uilabel(app.FrequencyButtonGroup);
            app.ORLabel.Text = 'OR';
            app.LoadButton = uibutton(app.FrequencyButtonGroup, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.UseLoadedFrequenciesCheckBox = uicheckbox(app.FrequencyButtonGroup);
            app.UseLoadedFrequenciesCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseLoadedFrequenciesCheckBoxValueChanged, true);
            app.UseLoadedFrequenciesCheckBox.Text = {' Use Loaded '; ' Frequencies'};
            app.HzLabel_2 = uilabel(app.FrequencyButtonGroup);
            app.HzLabel_2.Text = 'Hz';
            app.HzLabel_3 = uilabel(app.FrequencyButtonGroup);
            app.HzLabel_3.Text = 'Hz';
            app.FileLabel = uilabel(app.FrequencyButtonGroup);
            app.FileLabel.Text = 'File:';
            app.Label_25 = uilabel(app.FrequencyButtonGroup);
            app.Label_25.Text = '   ';
            app.InjectionButtonGroup = uibuttongroup(app.SimulationDetailsPanel);
            app.InjectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InjectionButtonGroupSelectionChanged, true);
            app.InjectionButtonGroup.Title = 'Injection';
            app.VoltageButton_2 = uitogglebutton(app.InjectionButtonGroup);
            app.VoltageButton_2.Text = 'Voltage';
            app.VoltageButton_2.Value = true;
            app.CurrentButton_2 = uitogglebutton(app.InjectionButtonGroup);
            app.CurrentButton_2.Text = 'Current';
            app.Phase1EditFieldLabel = uilabel(app.InjectionButtonGroup);
            app.Phase1EditFieldLabel.Text = 'Phase 1';
            app.Phase1EditField = uieditfield(app.InjectionButtonGroup, 'text');
            app.Phase1EditField.ValueChangedFcn = createCallbackFcn(app, @Phase1EditFieldValueChanged, true);
            app.Phase2EditFieldLabel = uilabel(app.InjectionButtonGroup);
            app.Phase2EditFieldLabel.Text = 'Phase 2';
            app.Phase2EditField = uieditfield(app.InjectionButtonGroup, 'text');
            app.Phase2EditField.ValueChangedFcn = createCallbackFcn(app, @Phase2EditFieldValueChanged, true);
            app.Phase3EditFieldLabel = uilabel(app.InjectionButtonGroup);
            app.Phase3EditFieldLabel.Text = 'Phase 3';
            app.Phase3EditField = uieditfield(app.InjectionButtonGroup, 'text');
            app.Phase3EditField.ValueChangedFcn = createCallbackFcn(app, @Phase3EditFieldValueChanged, true);
            app.VLabel = uilabel(app.InjectionButtonGroup);
            app.VLabel.Text = 'V';
            app.VLabel_2 = uilabel(app.InjectionButtonGroup);
            app.VLabel_2.Text = 'V';
            app.VLabel_3 = uilabel(app.InjectionButtonGroup);
            app.VLabel_3.Text = 'V';
            app.oEditFieldLabel = uilabel(app.InjectionButtonGroup);
            app.oEditFieldLabel.Text = 'o';
            app.oEditField = uieditfield(app.InjectionButtonGroup, 'numeric');
            app.oEditField.ValueChangedFcn = createCallbackFcn(app, @oEditFieldValueChanged, true);
            app.oEditField_2 = uieditfield(app.InjectionButtonGroup, 'numeric');
            app.oEditField_2.ValueChangedFcn = createCallbackFcn(app, @oEditField_2ValueChanged, true);
            app.oEditField_2Label = uilabel(app.InjectionButtonGroup);
            app.oEditField_2Label.Text = 'o';
            app.oEditField_3 = uieditfield(app.InjectionButtonGroup, 'numeric');
            app.oEditField_3.ValueChangedFcn = createCallbackFcn(app, @oEditField_3ValueChanged, true);
            app.oEditField_3Label = uilabel(app.InjectionButtonGroup);
            app.oEditField_3Label.Text = 'o';
            app.cmLabel = uilabel(app.SimulationDetailsPanel);
            app.cmLabel.Text = 'cm';
            app.SPICECheckBox = uicheckbox(app.SimulationDetailsPanel);
            app.SPICECheckBox.ValueChangedFcn = createCallbackFcn(app, @SPICECheckBoxValueChanged, true);
            app.SPICECheckBox.Text = 'SPICE';
            app.SaveModelButton = uibutton(app.ModelTab, 'push');
            app.SaveModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButtonPushed, true);
            app.SaveModelButton.Text = 'Save Model';
            app.SimulateButton = uibutton(app.ModelTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.TabGroup5 = uitabgroup(app.VisualizationTab);
            app.TabGroup5.SelectionChangedFcn = createCallbackFcn(app, @TabGroup5SelectionChanged, true);
            app.SpatialVoltageandCurrentTab = uitab(app.TabGroup5);
            app.SpatialVoltageandCurrentTab.Title = 'Spatial Voltage and Current';
            app.AnimatedOutputFilePanel = uipanel(app.SpatialVoltageandCurrentTab);
            app.AnimatedOutputFilePanel.Title = 'Animated Output File';
            app.GenerateAnimatedGIFButton = uibutton(app.AnimatedOutputFilePanel, 'push');
            app.GenerateAnimatedGIFButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateAnimatedGIFButtonPushed, true);
            app.GenerateAnimatedGIFButton.Text = 'Generate Animated GIF';
            app.FrameDelayEditFieldLabel = uilabel(app.AnimatedOutputFilePanel);
            app.FrameDelayEditFieldLabel.Text = 'Frame Delay';
            app.FrameDelayEditField = uieditfield(app.AnimatedOutputFilePanel, 'numeric');
            app.FrameDelayEditField.ValueChangedFcn = createCallbackFcn(app, @FrameDelayEditFieldValueChanged, true);
            app.FrameDelayEditField.Value = 0.15;
            app.sLabel = uilabel(app.AnimatedOutputFilePanel);
            app.sLabel.Text = 's';
            app.PhaseSelectionButtonGroup = uibuttongroup(app.SpatialVoltageandCurrentTab);
            app.PhaseSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PhaseSelectionButtonGroupSelectionChanged, true);
            app.PhaseSelectionButtonGroup.Title = 'Phase Selection';
            app.Phase1Button = uitogglebutton(app.PhaseSelectionButtonGroup);
            app.Phase1Button.Text = 'Phase 1';
            app.Phase1Button.Value = true;
            app.Phase2Button = uitogglebutton(app.PhaseSelectionButtonGroup);
            app.Phase2Button.Text = 'Phase 2';
            app.Phase3Button = uitogglebutton(app.PhaseSelectionButtonGroup);
            app.Phase3Button.Text = 'Phase 3';
            app.GroundButton = uitogglebutton(app.PhaseSelectionButtonGroup);
            app.GroundButton.Text = 'Ground';
            app.LineWidthEditFieldLabel = uilabel(app.SpatialVoltageandCurrentTab);
            app.LineWidthEditFieldLabel.Text = 'Line Width';
            app.LineWidthEditField = uieditfield(app.SpatialVoltageandCurrentTab, 'numeric');
            app.LineWidthEditField.RoundFractionalValues = 'on';
            app.LineWidthEditField.ValueChangedFcn = createCallbackFcn(app, @LineWidthEditFieldValueChanged, true);
            app.LineWidthEditField.Value = 5;
            app.FrequencySliderLabel = uilabel(app.SpatialVoltageandCurrentTab);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.SpatialVoltageandCurrentTab);
            app.FrequencySlider.Limits = [1 30];
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.FrequencySlider.Value = 20;
            app.GeneratePNGButton = uibutton(app.SpatialVoltageandCurrentTab, 'push');
            app.GeneratePNGButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePNGButtonPushed, true);
            app.GeneratePNGButton.Text = 'Generate PNG';
            app.OutputTypeButtonGroup = uibuttongroup(app.SpatialVoltageandCurrentTab);
            app.OutputTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OutputTypeButtonGroupSelectionChanged, true);
            app.OutputTypeButtonGroup.Title = 'Output Type';
            app.CurrentButton = uitogglebutton(app.OutputTypeButtonGroup);
            app.CurrentButton.Text = 'Current';
            app.CurrentButton.Value = true;
            app.VoltageButton = uitogglebutton(app.OutputTypeButtonGroup);
            app.VoltageButton.Text = 'Voltage';
            app.ExportDataButton = uibutton(app.SpatialVoltageandCurrentTab, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.HzLabel_4 = uilabel(app.SpatialVoltageandCurrentTab);
            app.HzLabel_4.Text = 'Hz';
            app.ScalingButtonGroup = uibuttongroup(app.SpatialVoltageandCurrentTab);
            app.ScalingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScalingButtonGroupSelectionChanged, true);
            app.ScalingButtonGroup.Title = 'Scaling';
            app.LogButton_2 = uitogglebutton(app.ScalingButtonGroup);
            app.LogButton_2.Text = 'Log';
            app.LogButton_2.Value = true;
            app.LinearButton_3 = uitogglebutton(app.ScalingButtonGroup);
            app.LinearButton_3.Text = 'Linear';
            app.TransmissionParametersTab = uitab(app.TabGroup5);
            app.TransmissionParametersTab.Title = 'Transmission Parameters';
            app.TransmissionParametersDropDownLabel = uilabel(app.TransmissionParametersTab);
            app.TransmissionParametersDropDownLabel.Text = 'Transmission Parameters';
            app.TransmissionParametersDropDown = uidropdown(app.TransmissionParametersTab);
            app.TransmissionParametersDropDown.Items = {'ABCD', 'S', 'Z', 'Y'};
            app.TransmissionParametersDropDown.ValueChangedFcn = createCallbackFcn(app, @TransmissionParametersDropDownValueChanged, true);
            app.TransmissionParametersDropDown.Value = 'ABCD';
            app.Z_0EditFieldLabel = uilabel(app.TransmissionParametersTab);
            app.Z_0EditFieldLabel.Text = 'Z_0';
            app.Z_0EditField = uieditfield(app.TransmissionParametersTab, 'numeric');
            app.Z_0EditField.ValueChangedFcn = createCallbackFcn(app, @Z_0EditFieldValueChanged, true);
            app.Z_0EditField.Value = 50;
            app.ReferenceImpedanceLabel = uilabel(app.TransmissionParametersTab);
            app.ReferenceImpedanceLabel.Text = 'Reference Impedance';
            app.OhmsLabel = uilabel(app.TransmissionParametersTab);
            app.OhmsLabel.Text = 'Ohms';
            app.PlottedElementsPanel = uipanel(app.TransmissionParametersTab);
            app.PlottedElementsPanel.Title = 'Plotted Elements';
            app.CheckBox_1 = uicheckbox(app.PlottedElementsPanel);
            app.CheckBox_1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_1ValueChanged, true);
            app.CheckBox_1.Text = '';
            app.CheckBox_2 = uicheckbox(app.PlottedElementsPanel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.PlottedElementsPanel);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.PlottedElementsPanel);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '';
            app.CheckBox_5 = uicheckbox(app.PlottedElementsPanel);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Text = '';
            app.CheckBox_6 = uicheckbox(app.PlottedElementsPanel);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @CheckBox_6ValueChanged, true);
            app.CheckBox_6.Text = '';
            app.CheckBox_7 = uicheckbox(app.PlottedElementsPanel);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBox_7ValueChanged, true);
            app.CheckBox_7.Text = '';
            app.CheckBox_8 = uicheckbox(app.PlottedElementsPanel);
            app.CheckBox_8.ValueChangedFcn = createCallbackFcn(app, @CheckBox_8ValueChanged, true);
            app.CheckBox_8.Text = '';
            app.CheckBox_9 = uicheckbox(app.PlottedElementsPanel);
            app.CheckBox_9.ValueChangedFcn = createCallbackFcn(app, @CheckBox_9ValueChanged, true);
            app.CheckBox_9.Text = '';
            app.CheckBox_10 = uicheckbox(app.PlottedElementsPanel);
            app.CheckBox_10.ValueChangedFcn = createCallbackFcn(app, @CheckBox_10ValueChanged, true);
            app.CheckBox_10.Text = '';
            app.CheckBox_11 = uicheckbox(app.PlottedElementsPanel);
            app.CheckBox_11.ValueChangedFcn = createCallbackFcn(app, @CheckBox_11ValueChanged, true);
            app.CheckBox_11.Text = '';
            app.CheckBox_12 = uicheckbox(app.PlottedElementsPanel);
            app.CheckBox_12.ValueChangedFcn = createCallbackFcn(app, @CheckBox_12ValueChanged, true);
            app.CheckBox_12.Text = '';
            app.CheckBox_13 = uicheckbox(app.PlottedElementsPanel);
            app.CheckBox_13.ValueChangedFcn = createCallbackFcn(app, @CheckBox_13ValueChanged, true);
            app.CheckBox_13.Text = '';
            app.Label_12 = uilabel(app.PlottedElementsPanel);
            app.Label_12.Text = '11';
            app.Label_13 = uilabel(app.PlottedElementsPanel);
            app.Label_13.Text = '12';
            app.Label_14 = uilabel(app.PlottedElementsPanel);
            app.Label_14.Text = '13';
            app.Label_15 = uilabel(app.PlottedElementsPanel);
            app.Label_15.Text = '21';
            app.Label_16 = uilabel(app.PlottedElementsPanel);
            app.Label_16.Text = '22';
            app.Label_17 = uilabel(app.PlottedElementsPanel);
            app.Label_17.Text = '23';
            app.Label_20 = uilabel(app.PlottedElementsPanel);
            app.Label_20.Text = '33';
            app.Label_18 = uilabel(app.PlottedElementsPanel);
            app.Label_18.Text = '31';
            app.Label_19 = uilabel(app.PlottedElementsPanel);
            app.Label_19.Text = '32';
            app.Label_21 = uilabel(app.PlottedElementsPanel);
            app.Label_21.Text = '11';
            app.Label_22 = uilabel(app.PlottedElementsPanel);
            app.Label_22.Text = '12';
            app.Label_23 = uilabel(app.PlottedElementsPanel);
            app.Label_23.Text = '21';
            app.Label_24 = uilabel(app.PlottedElementsPanel);
            app.Label_24.Text = '22';
            app.ofBlockLabel = uilabel(app.PlottedElementsPanel);
            app.ofBlockLabel.Text = 'of Block ';
            app.TurnOnAllButton = uibutton(app.TransmissionParametersTab, 'push');
            app.TurnOnAllButton.ButtonPushedFcn = createCallbackFcn(app, @TurnOnAllButtonPushed, true);
            app.TurnOnAllButton.Text = 'Turn On All';
            app.TurnOffAllButton = uibutton(app.TransmissionParametersTab, 'push');
            app.TurnOffAllButton.ButtonPushedFcn = createCallbackFcn(app, @TurnOffAllButtonPushed, true);
            app.TurnOffAllButton.Text = 'Turn Off All';
            app.GeneratePNGButton_2 = uibutton(app.TransmissionParametersTab, 'push');
            app.GeneratePNGButton_2.ButtonPushedFcn = createCallbackFcn(app, @GeneratePNGButton_2Pushed, true);
            app.GeneratePNGButton_2.Text = 'Generate PNG';
            app.ExportDataButton_2 = uibutton(app.TransmissionParametersTab, 'push');
            app.ExportDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButton_2Pushed, true);
            app.ExportDataButton_2.Text = 'Export Data';
            app.Label_26 = uilabel(app.TransmissionParametersTab);
            app.Label_26.Text = '______';
            app.DropDown = uidropdown(app.TransmissionParametersTab);
            app.DropDown.Items = {'A', 'B', 'C', 'D'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'A';
            app.EMTab = uitab(app.TabGroup);
            app.EMTab.Title = 'E&M';
            app.DomainBoundsPanel = uipanel(app.EMTab);
            app.DomainBoundsPanel.Title = 'Domain Bounds';
            app.XminEditFieldLabel = uilabel(app.DomainBoundsPanel);
            app.XminEditFieldLabel.Text = 'X min';
            app.XminEditField = uieditfield(app.DomainBoundsPanel, 'numeric');
            app.XminEditField.ValueChangedFcn = createCallbackFcn(app, @XminEditFieldValueChanged, true);
            app.XmaxEditFieldLabel = uilabel(app.DomainBoundsPanel);
            app.XmaxEditFieldLabel.Text = 'X max';
            app.XmaxEditField = uieditfield(app.DomainBoundsPanel, 'numeric');
            app.XmaxEditField.ValueChangedFcn = createCallbackFcn(app, @XmaxEditFieldValueChanged, true);
            app.YminEditFieldLabel = uilabel(app.DomainBoundsPanel);
            app.YminEditFieldLabel.Text = 'Y min';
            app.YminEditField = uieditfield(app.DomainBoundsPanel, 'numeric');
            app.YminEditField.ValueChangedFcn = createCallbackFcn(app, @YminEditFieldValueChanged, true);
            app.YmaxEditFieldLabel = uilabel(app.DomainBoundsPanel);
            app.YmaxEditFieldLabel.Text = 'Y max';
            app.YmaxEditField = uieditfield(app.DomainBoundsPanel, 'numeric');
            app.YmaxEditField.ValueChangedFcn = createCallbackFcn(app, @YmaxEditFieldValueChanged, true);
            app.ZminEditFieldLabel = uilabel(app.DomainBoundsPanel);
            app.ZminEditFieldLabel.Text = 'Z min';
            app.ZminEditField = uieditfield(app.DomainBoundsPanel, 'numeric');
            app.ZminEditField.ValueChangedFcn = createCallbackFcn(app, @ZminEditFieldValueChanged, true);
            app.ZmaxEditFieldLabel = uilabel(app.DomainBoundsPanel);
            app.ZmaxEditFieldLabel.Text = 'Z max';
            app.ZmaxEditField = uieditfield(app.DomainBoundsPanel, 'numeric');
            app.ZmaxEditField.ValueChangedFcn = createCallbackFcn(app, @ZmaxEditFieldValueChanged, true);
            app.mLabel_13 = uilabel(app.DomainBoundsPanel);
            app.mLabel_13.Text = 'm';
            app.mLabel_14 = uilabel(app.DomainBoundsPanel);
            app.mLabel_14.Text = 'm';
            app.mLabel_15 = uilabel(app.DomainBoundsPanel);
            app.mLabel_15.Text = 'm';
            app.mLabel_16 = uilabel(app.DomainBoundsPanel);
            app.mLabel_16.Text = 'm';
            app.mLabel_17 = uilabel(app.DomainBoundsPanel);
            app.mLabel_17.Text = 'm';
            app.mLabel_18 = uilabel(app.DomainBoundsPanel);
            app.mLabel_18.Text = 'm';
            app.QuiverDensityPanel = uipanel(app.EMTab);
            app.QuiverDensityPanel.Title = 'Quiver Density';
            app.XEditField_2Label = uilabel(app.QuiverDensityPanel);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.QuiverDensityPanel, 'numeric');
            app.XEditField_2.RoundFractionalValues = 'on';
            app.XEditField_2.ValueDisplayFormat = '%d';
            app.XEditField_2.ValueChangedFcn = createCallbackFcn(app, @XEditField_2ValueChanged, true);
            app.XEditField_2.Value = 10;
            app.YEditField_2Label = uilabel(app.QuiverDensityPanel);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.QuiverDensityPanel, 'numeric');
            app.YEditField_2.RoundFractionalValues = 'on';
            app.YEditField_2.ValueDisplayFormat = '%d';
            app.YEditField_2.ValueChangedFcn = createCallbackFcn(app, @YEditField_2ValueChanged, true);
            app.YEditField_2.Value = 10;
            app.ZEditField_2Label = uilabel(app.QuiverDensityPanel);

            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.QuiverDensityPanel, 'numeric');
            app.ZEditField_2.RoundFractionalValues = 'on';
            app.ZEditField_2.ValueDisplayFormat = '%d';
            app.ZEditField_2.ValueChangedFcn = createCallbackFcn(app, @ZEditField_2ValueChanged, true);
            app.ZEditField_2.Value = 10;
            app.SimulateRemotelyButton = uibutton(app.EMTab, 'push');
            app.SimulateRemotelyButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateRemotelyButtonPushed, true);
            app.SimulateRemotelyButton.Text = 'Simulate (Remotely)';
            app.EXPORTTOCOMSOL_2Button = uibutton(app.EMTab, 'push');
            app.EXPORTTOCOMSOL_2Button.ButtonPushedFcn = createCallbackFcn(app, @EXPORTTOCOMSOL_2ButtonPushed, true);
            app.EXPORTTOCOMSOL_2Button.Text = 'Export to COMSOL Session';
            app.FieldButtonGroup = uibuttongroup(app.EMTab);
            app.FieldButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FieldButtonGroupSelectionChanged, true);
            app.FieldButtonGroup.Title = 'Field';
            app.ElectricButton = uitogglebutton(app.FieldButtonGroup);
            app.ElectricButton.Text = 'Electric';
            app.ElectricButton.Value = true;
            app.MagneticButton = uitogglebutton(app.FieldButtonGroup);
            app.MagneticButton.Text = 'Magnetic';
            app.FrequencyEditField_2Label = uilabel(app.EMTab);
            app.FrequencyEditField_2Label.Text = 'Frequency';
            app.FrequencyEditField_2 = uieditfield(app.EMTab, 'numeric');
            app.FrequencyEditField_2.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditField_2ValueChanged, true);
            app.FrequencyEditField_2.Value = 30000000;
            app.HzLabel = uilabel(app.EMTab);
            app.HzLabel.Text = 'Hz';
            % Show the figure after all components are created
            app.ENCoRPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ENCoRP

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ENCoRPUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.ENCoRPUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ENCoRPUIFigure)
        end
    end
end